classdef epimech_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EpiMechUIFigure                 matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        EnableallMenu                   matlab.ui.container.Menu
        ResetMenu                       matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        FoldersMenu                     matlab.ui.container.Menu
        OpenvideofolderMenu             matlab.ui.container.Menu
        OpenresultsfolderMenu           matlab.ui.container.Menu
        ExtraMenu                       matlab.ui.container.Menu
        ScalebarsettingsMenu            matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        logoImage                       matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        SimulateTab                     matlab.ui.container.Tab
        ImportnamestaticLabel           matlab.ui.control.Label
        ImportnameLabel                 matlab.ui.control.Label
        SimulationsettingstipsImage     matlab.ui.control.Image
        ExportoptionsImage              matlab.ui.control.Image
        PlottingtipsImage_1             matlab.ui.control.Image
        InitialstatetipsImage           matlab.ui.control.Image
        ComponentstipsImage             matlab.ui.control.Image
        ParameterstipsImage             matlab.ui.control.Image
        SimulationtypeDropDown          matlab.ui.control.DropDown
        SimulationtypeDropDownLabel     matlab.ui.control.Label
        NewsimulationButton             matlab.ui.control.Button
        LoadprevioussimulationButton    matlab.ui.control.Button
        SimulationprogresstimeLabel     matlab.ui.control.Label
        SimulationprogressLabel         matlab.ui.control.Label
        ProgressbarPanel                matlab.ui.container.Panel
        ProgressLabel                   matlab.ui.control.Label
        PauseButton                     matlab.ui.control.StateButton
        StopButton                      matlab.ui.control.StateButton
        SimulateButton                  matlab.ui.control.Button
        SimulationsettingsPanel         matlab.ui.container.Panel
        TimestepplotCheckBox            matlab.ui.control.CheckBox
        ReloadButton                    matlab.ui.control.Button
        StopandpauseCheckBox            matlab.ui.control.CheckBox
        TimesimulationCheckBox          matlab.ui.control.CheckBox
        ProgressbarCheckBox             matlab.ui.control.CheckBox
        SolverDropDown                  matlab.ui.control.DropDown
        SolverDropDownLabel             matlab.ui.control.Label
        SimulationnameEditField         matlab.ui.control.EditField
        SimulationnameEditFieldLabel    matlab.ui.control.Label
        SimulationtimestepDropDown      matlab.ui.control.DropDown
        SimulationtimeDropDown          matlab.ui.control.DropDown
        SimulationtimeEditField         matlab.ui.control.NumericEditField
        SimulationtimeEditFieldLabel    matlab.ui.control.Label
        TimestepEditField               matlab.ui.control.NumericEditField
        TimestepLabel                   matlab.ui.control.Label
        InitialstatePanel               matlab.ui.container.Panel
        InitialstateButtongroup         matlab.ui.container.ButtonGroup
        PlacecellsButton                matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        PlacemultiplecellsButton        matlab.ui.control.RadioButton
        SinglecellButton                matlab.ui.control.RadioButton
        ExportoptionsPanel              matlab.ui.container.Panel
        RealexportstepLabel             matlab.ui.control.Label
        EditexportoptionsButton         matlab.ui.control.Button
        ExporteddataDropDown            matlab.ui.control.DropDown
        ExportstepEditField             matlab.ui.control.NumericEditField
        ExportstepEditFieldLabel        matlab.ui.control.Label
        xdtLabel_2                      matlab.ui.control.Label
        ExportdataCheckBox              matlab.ui.control.CheckBox
        PlottingoptionsPanel            matlab.ui.container.Panel
        AutomaticsizeCheckBox           matlab.ui.control.CheckBox
        ResetviewButton                 matlab.ui.control.Button
        WindowsizeText_3                matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        MiddlepointyEditField           matlab.ui.control.NumericEditField
        MiddlepointxEditField           matlab.ui.control.NumericEditField
        MiddlepointLabel                matlab.ui.control.Label
        ScalebarCheckBox                matlab.ui.control.CheckBox
        RealplottingstepLabel           matlab.ui.control.Label
        WindowsizeEditField             matlab.ui.control.NumericEditField
        CompressvideoCheckBox           matlab.ui.control.CheckBox
        SubstratestyleDropDown          matlab.ui.control.DropDown
        SubstratestyleLabel             matlab.ui.control.Label
        WindowsizeText                  matlab.ui.control.Label
        PlottingoptionsButton           matlab.ui.control.Button
        WindowsizeSlider                matlab.ui.control.Slider
        WindowsizeSliderLabel           matlab.ui.control.Label
        CellstyleDropDown               matlab.ui.control.DropDown
        CellstyleDropDownLabel          matlab.ui.control.Label
        xdtLabel                        matlab.ui.control.Label
        PlottingstepEditField           matlab.ui.control.NumericEditField
        PlottingstepEditFieldLabel      matlab.ui.control.Label
        SavevideoCheckBox               matlab.ui.control.CheckBox
        ShowanimationCheckBox           matlab.ui.control.CheckBox
        InitialstatePanel_2             matlab.ui.container.Panel
        Panel_3                         matlab.ui.container.Panel
        ResetcellremovalButton          matlab.ui.control.Button
        RemovecellsButton               matlab.ui.control.Button
        TimetoloadDropDown              matlab.ui.control.DropDown
        TimetoloadDropDownLabel         matlab.ui.control.Label
        CentercellsCheckBox             matlab.ui.control.CheckBox
        PlotButton                      matlab.ui.control.Button
        ComponentsettingsPanel          matlab.ui.container.Panel
        GlassActivationPanel            matlab.ui.container.Panel
        Panel_4                         matlab.ui.container.Panel
        LoadactivationareaButton_2      matlab.ui.control.Button
        SaveactivationareaButton_2      matlab.ui.control.Button
        ResetActivationAreaButton_2     matlab.ui.control.Button
        ActivationareaButton_2          matlab.ui.control.Button
        ActivationprofileButton_2       matlab.ui.control.Button
        OptogeneticsPanel               matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        LoadactivationareaButton        matlab.ui.control.Button
        SaveactivationareaButton        matlab.ui.control.Button
        ResetActivationAreaButton       matlab.ui.control.Button
        ActivationareaButton            matlab.ui.control.Button
        ActivationprofileButton         matlab.ui.control.Button
        FocalAdhesionsPanel             matlab.ui.container.Panel
        Panel_2                         matlab.ui.container.Panel
        LoadfocaladhesionsButton        matlab.ui.control.Button
        ResetfocaladhesionsButton       matlab.ui.control.Button
        EditfocaladhesionsButton        matlab.ui.control.Button
        SubstrateStiffnessPanel         matlab.ui.container.Panel
        UseimportedsubstratedataCheckBox  matlab.ui.control.CheckBox
        EditstiffnessButton             matlab.ui.control.Button
        StiffnessstyleButtonGroup       matlab.ui.container.ButtonGroup
        GradientButton                  matlab.ui.control.RadioButton
        HeterogeneousButton             matlab.ui.control.RadioButton
        ConstantButton                  matlab.ui.control.RadioButton
        kPaLabel                        matlab.ui.control.Label
        YoungsmodulusEditField          matlab.ui.control.NumericEditField
        YoungsmodulusEditFieldLabel     matlab.ui.control.Label
        PointlikesettingsPanel          matlab.ui.container.Panel
        UseimportedmovementdataCheckBox  matlab.ui.control.CheckBox
        PointlikeFileNameLabel          matlab.ui.control.Label
        SelectedcellEditField           matlab.ui.control.NumericEditField
        SelectedcellEditFieldLabel      matlab.ui.control.Label
        SelectcentercellCheckBox        matlab.ui.control.CheckBox
        LoadmovementButton              matlab.ui.control.Button
        SelectcellButton                matlab.ui.control.Button
        CellsizesettingsPanel           matlab.ui.container.Panel
        CellsizeButtonGroup             matlab.ui.container.ButtonGroup
        MDCKsizingButton                matlab.ui.control.RadioButton
        UniformsizingButton             matlab.ui.control.RadioButton
        SubstrateSizePanel              matlab.ui.container.Panel
        UseimportedsubstratedataCheckBox_2  matlab.ui.control.CheckBox
        ManualsizeCheckBox              matlab.ui.control.CheckBox
        SubstratesizeEditField          matlab.ui.control.NumericEditField
        SubstratesizeEditFieldLabel     matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        SubstrateTypeButtonGroup        matlab.ui.container.ButtonGroup
        SquareButton                    matlab.ui.control.RadioButton
        FittedButton                    matlab.ui.control.RadioButton
        ComparesizeButton               matlab.ui.control.Button
        SubstrateparametersButton       matlab.ui.control.Button
        StretchSettingsPanel            matlab.ui.container.Panel
        EditstretchButton               matlab.ui.control.Button
        CompressionAxisButtonGroup      matlab.ui.container.ButtonGroup
        BiaxialButton                   matlab.ui.control.RadioButton
        UniaxialButton                  matlab.ui.control.RadioButton
        DivisionsettingsPanel           matlab.ui.container.Panel
        UniformdivisiontimesCheckBox    matlab.ui.control.CheckBox
        DivideuntilEditField            matlab.ui.control.NumericEditField
        DivideuntilDropDown             matlab.ui.control.DropDown
        DivisiontypeButtongroup         matlab.ui.container.ButtonGroup
        DivisionuntilButton             matlab.ui.control.RadioButton
        NormaldivisionButton            matlab.ui.control.RadioButton
        ComponentsettingsDropdown       matlab.ui.control.DropDown
        ParametersPanel                 matlab.ui.container.Panel
        EditsystemparametersButton      matlab.ui.control.Button
        EditcellparametersButton        matlab.ui.control.Button
        PlotandanalyzeTab               matlab.ui.container.Tab
        AnimationtipsImage              matlab.ui.control.Image
        PostplottingtipsImage           matlab.ui.control.Image
        PostplottingoptionstipsImage    matlab.ui.control.Image
        AnalysistipsImage               matlab.ui.control.Image
        SpecialplottingtipsImage        matlab.ui.control.Image
        PostparameterstipsImage         matlab.ui.control.Image
        ParametersPanel_2               matlab.ui.container.Panel
        ShowButton                      matlab.ui.control.Button
        ImportParameterDropDown         matlab.ui.control.DropDown
        LoadedfilesDropDown             matlab.ui.control.DropDown
        ClearButton                     matlab.ui.control.Button
        AnalysisPanel                   matlab.ui.container.Panel
        GlassAnalysisPanel              matlab.ui.container.Panel
        ActivationareacellforcesPanel   matlab.ui.container.Panel
        BufferEditField                 matlab.ui.control.NumericEditField
        BufferEditFieldLabel            matlab.ui.control.Label
        TypeDropDown_2                  matlab.ui.control.DropDown
        TypeDropDown_2Label             matlab.ui.control.Label
        ForceDropDown_3                 matlab.ui.control.DropDown
        ForceDropDown_3Label            matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        PlotButton_3                    matlab.ui.control.Button
        DisplacementareaplotButton      matlab.ui.control.Button
        MaxdisplacementsButton          matlab.ui.control.Button
        OptogeneticAnalysisPanel        matlab.ui.container.Panel
        DisplacementforceanalysisButton_2  matlab.ui.control.Button
        ValidationPanel_2               matlab.ui.container.Panel
        AnalyzejunctionlengthButton     matlab.ui.control.Button
        CopypreviouscellsButton         matlab.ui.control.Button
        ResetactivationselectionButton  matlab.ui.control.Button
        SelectcellsButton_2             matlab.ui.control.Button
        PointlikeAnalysisPanel          matlab.ui.container.Panel
        DisplacementforceanalysisButton  matlab.ui.control.Button
        ValidationPanel                 matlab.ui.container.Panel
        AllButton                       matlab.ui.control.Button
        DistrubtionButton               matlab.ui.control.Button
        SingleButton                    matlab.ui.control.Button
        SubstratestiffnessDropDown      matlab.ui.control.DropDown
        SubstratestiffnessDropDownLabel  matlab.ui.control.Label
        kPaLabel_2                      matlab.ui.control.Label
        CellForcesAnalysisPanel         matlab.ui.container.Panel
        PlotButton_2                    matlab.ui.control.Button
        CellsListBox                    matlab.ui.control.ListBox
        CellsListBoxLabel               matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        TypeDropDownLabel               matlab.ui.control.Label
        ForceDropDown                   matlab.ui.control.DropDown
        ForceDropDownLabel              matlab.ui.control.Label
        GeometryAnalysisPanel           matlab.ui.container.Panel
        AreavstimeButton                matlab.ui.control.Button
        PerimetervstimeButton           matlab.ui.control.Button
        PerimeterButton                 matlab.ui.control.Button
        AreaButton                      matlab.ui.control.Button
        NeighborsValueLabel             matlab.ui.control.Label
        NeighborsLabel                  matlab.ui.control.Label
        PerimeterValueLabel             matlab.ui.control.Label
        AreaValueLabel                  matlab.ui.control.Label
        DropDown2                       matlab.ui.control.DropDown
        SpecialplottingoptionsPanel     matlab.ui.container.Panel
        CellshapedescriptorsPanel       matlab.ui.container.Panel
        MaximummagnitudeEditField_2     matlab.ui.control.NumericEditField
        MinimummagnitudeEditField_2     matlab.ui.control.NumericEditField
        MinimummagnitudeEditField_2Label  matlab.ui.control.Label
        MaximummagnitudeEditField_2Label  matlab.ui.control.Label
        DescriptorDropDown              matlab.ui.control.DropDown
        DescriptorDropDownLabel         matlab.ui.control.Label
        ForcemagnitudeplottingPanel     matlab.ui.container.Panel
        MarkersizeEditField             matlab.ui.control.NumericEditField
        MarkersizeEditFieldLabel        matlab.ui.control.Label
        MinimummagnitudeEditField       matlab.ui.control.NumericEditField
        MinimummagnitudeEditFieldLabel  matlab.ui.control.Label
        MaximummagnitudeEditField       matlab.ui.control.NumericEditField
        MaximummagnitudeEditFieldLabel  matlab.ui.control.Label
        ForceDropDown_2                 matlab.ui.control.DropDown
        ForceDropDown_2Label            matlab.ui.control.Label
        ShowcelllineagePanel            matlab.ui.container.Panel
        ShowcelllineageListBox          matlab.ui.control.ListBox
        ShowcelllineageListBoxLabel     matlab.ui.control.Label
        HighlightcellsPanel             matlab.ui.container.Panel
        ChoosecellstohighlightListBox   matlab.ui.control.ListBox
        ChoosecellstohighlightListBoxLabel  matlab.ui.control.Label
        SpecialplotDropDown             matlab.ui.control.DropDown
        ClickopentochoosethesimulationstoloadLabel  matlab.ui.control.Label
        OpenButton                      matlab.ui.control.Button
        AnimationPanel                  matlab.ui.container.Panel
        Reload2Button                   matlab.ui.control.Button
        EndDropDown                     matlab.ui.control.DropDown
        EndDropDownLabel                matlab.ui.control.Label
        StartDropDown                   matlab.ui.control.DropDown
        StartDropDownLabel              matlab.ui.control.Label
        StopButton_2                    matlab.ui.control.StateButton
        PauseButton_2                   matlab.ui.control.StateButton
        CompressvideoCheckBox_2         matlab.ui.control.CheckBox
        SavevideoCheckBox_2             matlab.ui.control.CheckBox
        AnimateButton                   matlab.ui.control.Button
        VideonameEditField              matlab.ui.control.EditField
        VideonameEditFieldLabel         matlab.ui.control.Label
        PlotPanel                       matlab.ui.container.Panel
        SingletimepointButton           matlab.ui.control.Button
        TimepointDropDown               matlab.ui.control.DropDown
        TimepointDropDownLabel          matlab.ui.control.Label
        BrowsetimepointsButton          matlab.ui.control.Button
        PlottingoptionsPanel_2          matlab.ui.container.Panel
        DefaultButton                   matlab.ui.control.Button
        ResetviewButton_2               matlab.ui.control.Button
        WindowsizeText_4                matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        MiddlepointyEditField_2         matlab.ui.control.NumericEditField
        MiddlepointxEditField_2         matlab.ui.control.NumericEditField
        MiddlepointLabel_2              matlab.ui.control.Label
        ScalebarCheckBox_2              matlab.ui.control.CheckBox
        AutomaticsizeCheckBox_2         matlab.ui.control.CheckBox
        WindowsizeEditField_2           matlab.ui.control.NumericEditField
        SubstratestyleDropDown_2        matlab.ui.control.DropDown
        SubstratestyleDropDown_2Label   matlab.ui.control.Label
        WindowsizeText_2                matlab.ui.control.Label
        PlottingoptionsButton_2         matlab.ui.control.Button
        xdtLabel_3                      matlab.ui.control.Label
        WindowsizeSlider_2              matlab.ui.control.Slider
        WindowsizeSlider_2Label         matlab.ui.control.Label
        CellstyleDropDown_2             matlab.ui.control.DropDown
        CellstyleDropDown_2Label        matlab.ui.control.Label
        PlottingstepEditField_2         matlab.ui.control.NumericEditField
        PlottingstepEditField_2Label    matlab.ui.control.Label
        TablePanel                      matlab.ui.container.Panel
        tableTipsImage                  matlab.ui.control.Image
        ResettodefaultButton            matlab.ui.control.Button
        DiscardchangesButton            matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        SavechangesButton               matlab.ui.control.Button
        SavetofileButton                matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        EpiMechLabel                    matlab.ui.control.Label
    end


    properties (Access = public)
        version = '1.0.6';
        defaultPath = '';
        cellParameters = struct();
        defaultCellParameters = struct();
        specificCellParameters = struct();
        systemParameters = struct();
        substrateParameters = struct();
        pointlikeProperties = struct();
        plottingOptions = struct();
        customExportOptions = struct();
        cellCenters = [];
        enabledObjects = 0;
        tableData = '';
        simulationType = '';
        plotHandle = 0;
        modelCase = '';
        simulating = 0;
        simulationStopped = 0;
        simulationClosed = 0;
        animating = 0;
        animationStopped = 0;
        animationClosed = 0;
        folderName = '';
        import = struct();
        plotImport = struct('folderName', '');
        appTask = 'simulate';
        firstStartup = 1;
        scaleBarSettings = struct();
        firstStartupPA = 1;
        newImports = [];
        importPlottingOptions = struct();
        selectedFile = 0;
        selectedCells = [];
        stiffnessGradientInformation = [];
        heterogeneousStiffness = [];
        fFAInfo = [];
        optoVertices = struct();
        optoActivation = [];
        optoShapes = {};
        glassActivationShapesForceDropDown = {};
        glassActivation = [];
        stretch = struct();
        analysisFigureHandle = 1;
        numberOfAnalysisPlots = 0;
        cellDescriptorLimits = [];
    end

    properties (Access = private)
        DialogApp % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % set the default path of the app
            app.defaultPath = [fileparts(mfilename('fullpath')) '/'];

            % add Functions and Setting folders to path when not deployed
            if ~isdeployed
                addpath(app.defaultPath);
                %                 addpath(genpath([app.defaultPath 'Functions']));
                %                 addpath(genpath([app.defaultPath 'Settings']));
            end

            % make the GUI invisible momentarily before moving it to the
            % center of the screen
            app.EpiMechUIFigure.Visible = 'Off';

            % set all the panels invisible
            set_object_properties_function(app,{'ParametersPanel','ComponentsettingsPanel','PlottingoptionsPanel','ExportoptionsPanel', 'InitialstatePanel', 'InitialstatePanel_2', 'SimulationsettingsPanel','SimulateButton', 'SimulateButton', 'StopButton', 'PauseButton', 'TablePanel'},'Visible',{'Off'});
            set_object_properties_function(app,{'PlotPanel','PlottingoptionsPanel_2','AnimationPanel','SpecialplottingoptionsPanel','StopButton_2','PauseButton_2','AnalysisPanel','LoadedfilesDropDown','ParametersPanel_2'},'Visible',{'Off'});

            app.logoImage.ImageSource = [app.defaultPath 'settings/images/logo_v2.png'];
            app.EpiMechUIFigure.Icon = [app.defaultPath 'settings/images/logo_v2.png'];
            app.EpiMechUIFigure.Name = ['EpiMech - v' app.version];
            app.ReloadButton.Icon = [app.defaultPath 'settings/images/reload.png'];
            app.StopButton.Icon = [app.defaultPath 'settings/images/stop.png'];
            app.PauseButton.Icon = [app.defaultPath 'settings/images/pause.png'];
            app.AnimateButton.Icon = [app.defaultPath 'settings/images/play.png'];
            app.PauseButton_2.Icon = [app.defaultPath 'settings/images/pause.png'];
            app.StopButton_2.Icon = [app.defaultPath 'settings/images/stop.png'];
            app.Reload2Button.Icon = [app.defaultPath 'settings/images/reload.png'];
            app.ResetviewButton.Icon = [app.defaultPath 'settings/images/reload.png'];
            app.ResetviewButton_2.Icon = [app.defaultPath 'settings/images/reload.png'];
            app.ResetActivationAreaButton.Icon = [app.defaultPath 'settings/images/reload.png'];
            app.LoadactivationareaButton.Icon = [app.defaultPath 'settings/images/load.png'];
            app.SaveactivationareaButton.Icon = [app.defaultPath 'settings/images/save.png'];
            app.ResetActivationAreaButton_2.Icon = [app.defaultPath 'settings/images/reload.png'];
            app.LoadactivationareaButton_2.Icon = [app.defaultPath 'settings/images/load.png'];
            app.SaveactivationareaButton_2.Icon = [app.defaultPath 'settings/images/save.png'];
            app.ResetfocaladhesionsButton.Icon = [app.defaultPath 'settings/images/reload.png'];
            app.LoadfocaladhesionsButton.Icon = [app.defaultPath 'settings/images/load.png'];
            app.ResetcellremovalButton.Icon = [app.defaultPath 'settings/images/reload.png'];
            app.ResetactivationselectionButton.Icon = [app.defaultPath 'settings/images/reload.png'];
            app.ResetButton.Icon = [app.defaultPath 'settings/images/reload.png'];
            app.CopypreviouscellsButton.Icon = [app.defaultPath 'settings/images/copy.png'];
            app.ComponentstipsImage.ImageSource = [app.defaultPath 'settings/images/tips.png'];
            app.ParameterstipsImage.ImageSource = [app.defaultPath 'settings/images/tips.png'];
            app.InitialstatetipsImage.ImageSource = [app.defaultPath 'settings/images/tips.png'];
            app.PlottingtipsImage_1.ImageSource = [app.defaultPath 'settings/images/tips.png'];
            app.ExportoptionsImage.ImageSource = [app.defaultPath 'settings/images/tips.png'];
            app.SimulationsettingstipsImage.ImageSource = [app.defaultPath 'settings/images/tips.png'];
            app.AnalysistipsImage.ImageSource = [app.defaultPath 'settings/images/tips.png'];
            app.SpecialplottingtipsImage.ImageSource = [app.defaultPath 'settings/images/tips.png'];
            app.PostparameterstipsImage.ImageSource = [app.defaultPath 'settings/images/tips.png'];
            app.PostplottingtipsImage.ImageSource = [app.defaultPath 'settings/images/tips.png'];
            app.PostplottingoptionstipsImage.ImageSource = [app.defaultPath 'settings/images/tips.png'];
            app.AnimationtipsImage.ImageSource = [app.defaultPath 'settings/images/tips.png'];
            app.tableTipsImage.ImageSource = [app.defaultPath 'settings/images/tips.png'];

            app.cellDescriptorLimits = csvread([app.defaultPath 'settings/misc/cell_descriptor_limits.csv']);


            % center the GUI
            set(0,'units','pixels')
            resolution = get(0,'ScreenSize');
            app.EpiMechUIFigure.Position(1) = round(resolution(3)/2 - app.EpiMechUIFigure.Position(3)/2);
            app.EpiMechUIFigure.Position(2) = round(resolution(4)/2 - app.EpiMechUIFigure.Position(4)/2);

            app.PointlikesettingsPanel.Position(1:2) = [1 1];
            app.CellsizesettingsPanel.Position(1:2) = [1 1];
            app.SubstrateSizePanel.Position(1:2) = [1 1];
            app.StretchSettingsPanel.Position(1:2) = [1 1];
            app.DivisionsettingsPanel.Position(1:2) = [1 1];
            app.GeometryAnalysisPanel.Position(1:2) = [1 1];
            app.PointlikeAnalysisPanel.Position(1:2) = [1 1];
            app.OptogeneticAnalysisPanel.Position(1:2) = [1 1];
            app.GlassAnalysisPanel.Position(1:2) = [1 1];
            app.CellForcesAnalysisPanel.Position(1:2) = [1 1];
            app.SubstrateStiffnessPanel.Position(1:2) = [1 1];
            app.FocalAdhesionsPanel.Position(1:2) = [1 1];
            app.OptogeneticsPanel.Position(1:2) = [1 1];
            app.InitialstatePanel_2.Position(1:2) = app.InitialstatePanel.Position(1:2);
            app.HighlightcellsPanel.Position(1:2) = [1 1];
            app.ShowcelllineagePanel.Position(1:2) = [1 1];
            app.ForcemagnitudeplottingPanel.Position(1:2) = [1 1];
            app.CellshapedescriptorsPanel.Position(1:2) = [1 1];
            app.GlassActivationPanel.Position(1:2) = [1 1];
            

            app.logoImage.Tag = 'no_disable';
            app.Menu.Tag = 'no_disable';
            app.ResetMenu.Tag = 'no_disable';
            app.ExitMenu.Tag = 'no_disable';
            app.EpiMechLabel.Tag = 'no_disable';
            app.EnableallMenu.Tag = 'no_disable';
            app.CloseButton.Tag = 'no_disable';

            % make the GUI visible after centering
            app.EpiMechUIFigure.Visible = 'On';

        end

        % Button pushed function: NewsimulationButton
        function NewsimulationButtonPushed(app, event)

            % reset the component settings panels
            set_component_settings_panel(app,'reset');

            % set simulation case
            app.modelCase = 'new';

            % load settings and parameters
            load_settings_and_parameters(app,1);

            % set ui values based on imported values
            set_ui_edit_values(app);

            % set the visibilities
            set_panel_visibilities(app);

            % set component settings panel
            set_component_settings_panel(app);

        end

        % Button pushed function: LoadprevioussimulationButton
        function LoadprevioussimulationButtonPushed(app, event)

            % reset the component settings panels
            set_component_settings_panel(app,'reset');

            % choosen and unzip simulation
            folderLoaded = unzip_results(app);
            if ~folderLoaded
                % set component settings panel
                if ~strcmp(app.modelCase, '')
                    set_component_settings_panel(app);
                end
                return;
            end

            % set simulation case
            app.modelCase = 'import';

            % load settings and parameters
            folderLoaded = load_settings_and_parameters(app,1);
            if ~folderLoaded
                % set component settings panel
                set_component_settings_panel(app);
                app.modelCase = '';
                return;
            end

            % set ui values based on imported values
            set_ui_edit_values(app);

            % set the visibilities
            set_panel_visibilities(app);

            % set component settings panel
            set_component_settings_panel(app);

        end

        % Value changed function: SimulationtypeDropDown
        function SimulationtypeDropDownValueChanged(app, event)
            if strcmp(app.modelCase,'new') || strcmp(app.modelCase,'import')

                % reset the component settings panels
                set_component_settings_panel(app,'reset');

                % load settings and parameters
                changeAllowed = load_settings_and_parameters(app,0);
                if ~changeAllowed
                    app.SimulationtypeDropDown.Value = event.PreviousValue;
                    % set component settings panel
                    set_component_settings_panel(app);
                    return;
                end
                % set ui values based on imported values
                set_ui_edit_values(app);

                % set the visibilities
                set_panel_visibilities(app);

                % set component settings panel
                set_component_settings_panel(app);
            end
        end

        % Button pushed function: EditcellparametersButton
        function EditcellparametersButtonPushed(app, event)

            % show table with cell parameters
            show_table(app,'cellParameters');
        end

        % Button pushed function: EditsystemparametersButton
        function EditsystemparametersButtonPushed(app, event)

            % show table with system parameters
            show_table(app,'systemParameters');
        end

        % Button pushed function: SavechangesButton
        function SavechangesButtonPushed(app, event)

            % save settings from the table into the structs and the GUI
            % edit fields
            apply_setting_changes(app);

            % hide  the table
            hide_table(app)
        end

        % Button pushed function: SavetofileButton
        function SavetofileButtonPushed(app, event)

            % save settings to file from the table
            write_settings_to_file(app);
        end

        % Selection changed function: DivisiontypeButtongroup
        function DivisiontypeButtongroupSelectionChanged(app, event)

            % enable or disable divide until edit fields and labels based
            % on the division type
            switch app.DivisiontypeButtongroup.SelectedObject.Text
                case 'Normal division'
                    set_object_properties_function(app,{'DivideuntilEditField','DivideuntilDropDown'},'Enable',{'Off'});
                case 'Division until'
                    set_object_properties_function(app,{'DivideuntilEditField','DivideuntilDropDown'},'Enable',{'On'});
            end
        end

        % Button pushed function: PlacecellsButton
        function PlacecellsButtonPushed(app, event)

            disable_enable_all_function(app,'Off');

            d = [];

            d = scale_parameters(app,d);

            d = setup_plotting_options(app,d,'add_cells');

            d = initialize_cells(app,d);

            plotting_loop(app,d,'add_cells');

            disable_enable_all_function(app,'On');

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)

            % reset added cells
            app.cellCenters = [];

            % disable simulate button and select cells button (for
            % pointlike) since there are no cells
            app.SimulateButton.Enable = 'Off';
            app.SelectcellButton.Enable = 'Off';
        end

        % Selection changed function: InitialstateButtongroup
        function InitialstateButtongroupSelectionChanged(app, event)
            switch app.InitialstateButtongroup.SelectedObject.Text
                case 'Single cell'
                    set_object_properties_function(app,{'ResetButton', 'PlacecellsButton'},'Enable',{'Off'});
                    app.SimulateButton.Enable = 'On';
                    app.SelectcellButton.Enable = 'On';
                case 'Place multiple cells'
                    set_object_properties_function(app,{'ResetButton', 'PlacecellsButton', 'SelectcellButton'},'Enable',{'On'});
                    if isempty(app.cellCenters)
                        app.SimulateButton.Enable = 'Off';
                        app.SelectcellButton.Enable = 'Off';
                    end
                    if strcmp(app.simulationType,'pointlike') || strcmp(app.simulationType,'stretch') || strcmp(app.simulationType,'opto')
                        app.SubstrateTypeButtonGroup.SelectedObject = app.SquareButton;
                    end
            end
        end

        % Value changed function: ShowanimationCheckBox
        function ShowanimationCheckBoxValueChanged(app, event)
            if app.ShowanimationCheckBox.Value
                set_object_properties_function(app,{'SavevideoCheckBox', 'PlottingstepEditField', 'PlottingstepEditFieldLabel', 'xdtLabel', 'CellstyleDropDown', 'CellstyleDropDownLabel', 'ScalebarCheckBox', 'WindowsizeSlider', 'WindowsizeSliderLabel', 'PlottingoptionsButton','WindowsizeEditField','WindowsizeText','RealplottingstepLabel'},'Enable', {'On'});
                if strcmp(app.simulationType,'pointlike') || strcmp(app.simulationType,'stretch') || strcmp(app.simulationType,'opto')
                    set_object_properties_function(app,{'SubstratestyleDropDown','SubstratestyleLabel'},'Enable',{'On'});
                end
            else
                set_object_properties_function(app,{'SavevideoCheckBox', 'CompressvideoCheckBox', 'PlottingstepEditField', 'PlottingstepEditFieldLabel', 'xdtLabel', 'CellstyleDropDown', 'CellstyleDropDownLabel', 'ScalebarCheckBox', 'WindowsizeSlider', 'WindowsizeSliderLabel', 'PlottingoptionsButton','WindowsizeEditField','WindowsizeText','RealplottingstepLabel'},'Enable', {'Off'});
                app.SavevideoCheckBox.Value = 0;
                app.CompressvideoCheckBox.Value = 0;
                if strcmp(app.simulationType,'pointlike') || strcmp(app.simulationType,'stretch') || strcmp(app.simulationType,'opto')
                    set_object_properties_function(app,{'SubstratestyleDropDown','SubstratestyleLabel'},'Enable',{'Off'});
                end
            end
        end

        % Value changed function: SavevideoCheckBox
        function SavevideoCheckBoxValueChanged(app, event)
            if app.SavevideoCheckBox.Value
                app.SimulationnameEditField.Enable = 'On';
                app.CompressvideoCheckBox.Enable = 'On';
                app.ReloadButton.Enable = 'On';
            else
                if ~app.ExportdataCheckBox.Value
                    app.SimulationnameEditField.Enable = 'Off';
                    app.ReloadButton.Enable = 'Off';
                end
                app.CompressvideoCheckBox.Enable = 'Off';
            end

        end

        % Value changed function: PlottingstepEditField
        function PlottingstepEditFieldValueChanged(app, event)
            value = app.PlottingstepEditField.Value;
            if ~( mod(value,1) == 0 && value > 0 )
                uialert(app.EpiMechUIFigure,'Plotting time step must be a positive integer.','Bad Input');
                app.PlottingstepEditField.Value = event.PreviousValue;
            else
                app.plottingOptions.plotDtMultiplier = value;
                app.RealplottingstepLabel.Text = set_real_time_steps(app,'plotting');
            end
        end

        % Value changed function: CellstyleDropDown
        function CellstyleDropDownValueChanged(app, event)
            switch app.CellstyleDropDown.Value
                case 'Grey filled'
                    app.plottingOptions.cellStyle = 1;
                case 'Red outline'
                    app.plottingOptions.cellStyle = 2;
                case 'Number of neighbors'
                    app.plottingOptions.cellStyle = 3;
                case 'Black'
                    app.plottingOptions.cellStyle = 4;
                case 'Lines'
                    app.plottingOptions.cellStyle = 5;
                case 'No cells'
                    app.plottingOptions.cellStyle = 0;
            end
        end

        % Value changing function: WindowsizeSlider
        function WindowsizeSliderValueChanging(app, event)
            newWindowSize = 30+10*77^event.Value;
            app.WindowsizeEditField.Value = (round(newWindowSize));
            app.plottingOptions.windowSize = newWindowSize*1e-6;
        end

        % Button pushed function: PlottingoptionsButton
        function PlottingoptionsButtonPushed(app, event)
            show_table(app,'plottingOptions');
        end

        % Value changed function: ExportdataCheckBox
        function ExportdataCheckBoxValueChanged(app, event)
            if app.ExportdataCheckBox.Value
                set_object_properties_function(app,{'xdtLabel_2', 'ExportstepEditField', 'ExportstepEditFieldLabel', 'RealexportstepLabel', 'ExporteddataDropDown'},'Enable', {'On'});
                app.SimulationnameEditField.Enable = 'On';
                app.ReloadButton.Enable = 'On';
                if strcmp(app.ExporteddataDropDown.Value,'Custom export')
                    app.EditexportoptionsButton.Enable = 1;
                end
            else
                set_object_properties_function(app,{'xdtLabel_2', 'ExportstepEditField', 'ExportstepEditFieldLabel', 'RealexportstepLabel', 'ExporteddataDropDown', 'EditexportoptionsButton'},'Enable', {'Off'});
                if ~app.SavevideoCheckBox.Value
                    app.SimulationnameEditField.Enable = 'Off';
                    app.ReloadButton.Enable = 'Off';
                end
            end

        end

        % Value changed function: ExportstepEditField
        function ExportstepEditFieldValueChanged(app, event)
            value = app.ExportstepEditField.Value;
            if ~( mod(value,1) == 0 && value > 0 )
                uialert(app.EpiMechUIFigure,'Export time step must be positive integer.','Bad Input');
                app.ExportstepEditField.Value = event.PreviousValue;
            else
                app.customExportOptions.exportDtMultiplier = value;
                app.RealexportstepLabel.Text = set_real_time_steps(app,'export');
            end
        end

        % Value changed function: ExporteddataDropDown
        function ExporteddataDropDownValueChanged(app, event)
            switch app.ExporteddataDropDown.Value
                case 'Suitable for import'
                    app.EditexportoptionsButton.Enable = 0;
                case 'Suitable for basic plotting'
                    app.EditexportoptionsButton.Enable = 0;
                case 'Full export'
                    app.EditexportoptionsButton.Enable = 0;
                case 'Custom export'
                    app.EditexportoptionsButton.Enable = 1;
            end
        end

        % Button pushed function: EditexportoptionsButton
        function EditexportoptionsButtonPushed(app, event)
            show_table(app,'customExportOptions');
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)

            d = setup_simulation(app);

            if ~isstruct(d)
                if d == 0
                    uialert(app.EpiMechUIFigure,'Increase the substrate size so that all cell fit on it.','Bad Input');
                end
                return;
            end

            disable_enable_all_function(app,'Off')

            if d.simset.stopOrPause
                set_object_properties_function(app,{'StopButton', 'PauseButton'},'Visible',{'On'});
                set_object_properties_function(app,{'StopButton', 'PauseButton'},'Enable',{'On'});
            end
            if d.simset.progressBar.active
                set_object_properties_function(app,{'ProgressbarPanel', 'SimulationprogressLabel', 'SimulationprogresstimeLabel'},'Visible',{'On'});
                set_object_properties_function(app,{'ProgressLabel', 'SimulationprogressLabel', 'SimulationprogresstimeLabel'},'Enable',{'On'});
            end

            app.simulationStopped = 0;

            app.simulating = 1;


            drawnow

            % SIMULATE

            d = main_simulation(app,d);

            zip_results(app,d);

            finalize_video(app,d)

            if d.simset.progressBar.active
                set_object_properties_function(app,{'ProgressbarPanel', 'SimulationprogressLabel', 'SimulationprogresstimeLabel'},'Visible',{'Off'});
                set_object_properties_function(app,{'SimulationprogressLabel', 'SimulationprogresstimeLabel', 'ProgressLabel'},'Text',{''});
            end

            disable_enable_all_function(app,'On');

            if d.simset.stopOrPause
                set_object_properties_function(app,{'StopButton', 'PauseButton'},'Visible',{'Off'});
                set_object_properties_function(app,{'StopButton', 'PauseButton'},'Enable',{'Off'});
                set_object_properties_function(app,{'StopButton', 'PauseButton'},'Value',{0});
            end
            if app.simulationClosed
                app.simulating = 0;
                EpiMechUIFigureCloseRequest(app)
                return
            end

            if app.simulationStopped == 0
                app.SimulationnameEditField.FontColor = [0.8 0.8 0.8];
                app.SimulationnameEditField.Value = 'Give name';
            end

            app.simulating = 0;
            app.simulationStopped = 0;

        end

        % Value changed function: SimulationtimeEditField
        function SimulationtimeEditFieldValueChanged(app, event)
            value = app.SimulationtimeEditField.Value;
            if value <= 0
                uialert(app.EpiMechUIFigure,'Simulation time must be above 0','Bad Input');
                app.SimulationtimeEditField.Value = scale_time_units(app, 'SimulationtimeDropDown', app.systemParameters.simulationTime, 'field');
            elseif scale_time_units(app, 'SimulationtimeDropDown', value, 'parameters') <= app.systemParameters.maximumTimeStep
                uialert(app.EpiMechUIFigure,'Simulation time must be larger than the time step','Bad Input');
                app.SimulationtimeEditField.Value = scale_time_units(app, 'SimulationtimeDropDown', app.systemParameters.simulationTime, 'field');
            else
                app.systemParameters.simulationTime = scale_time_units(app, 'SimulationtimeDropDown', value, 'parameters');
            end
        end

        % Value changed function: TimestepEditField
        function TimestepEditFieldValueChanged(app, event)
            value = app.TimestepEditField.Value;
            if value <= 0
                uialert(app.EpiMechUIFigure,'Default time step must be above 0','Bad Input');
                app.TimestepEditField.Value = scale_time_units(app, 'SimulationtimestepDropDown', app.systemParameters.maximumTimeStep, 'field');
            elseif scale_time_units(app, 'SimulationtimestepDropDown', value, 'parameters') >= app.systemParameters.simulationTime
                uialert(app.EpiMechUIFigure,'Simulation time step must be smaller than the simulation time','Bad Input');
                app.TimestepEditField.Value = scale_time_units(app, 'SimulationtimestepDropDown', app.systemParameters.maximumTimeStep, 'field');
            else
                app.systemParameters.maximumTimeStep = scale_time_units(app, 'SimulationtimestepDropDown', value, 'parameters');
                app.RealplottingstepLabel.Text = set_real_time_steps(app,'plotting');
                app.RealexportstepLabel.Text = set_real_time_steps(app,'export');
            end
        end

        % Value changed function: DivideuntilEditField
        function DivideuntilEditFieldValueChanged(app, event)
            value = app.DivideuntilEditField.Value;
            if value < 0
                uialert(app.EpiMechUIFigure,'Division stop time must be 0 or positive','Bad Input');
                app.DivideuntilEditField.Value = app.systemParameters.stopDivisionTime;
            else
                app.systemParameters.stopDivisionTime = scale_time_units(app, 'DivideuntilDropDown', value, 'parameters');
            end
        end

        % Value changed function: SimulationtimeDropDown
        function SimulationtimeDropDownValueChanged(app, event)
            if scale_time_units(app, 'SimulationtimeDropDown', app.SimulationtimeEditField.Value, 'parameters') <= app.systemParameters.maximumTimeStep
                uialert(app.EpiMechUIFigure,'Simulation time must be larger than the time step','Bad Input');
                app.SimulationtimeDropDown.Value = event.PreviousValue;
            else
                app.systemParameters.simulationTime = scale_time_units(app, 'SimulationtimeDropDown', app.SimulationtimeEditField.Value, 'parameters');
            end
        end

        % Value changed function: SimulationtimestepDropDown
        function SimulationtimestepDropDownValueChanged(app, event)
            if scale_time_units(app, 'SimulationtimestepDropDown', app.TimestepEditField.Value, 'parameters') >= app.systemParameters.simulationTime
                uialert(app.EpiMechUIFigure,'Simulation time step must be smaller than the simulation time','Bad Input');
                app.SimulationtimestepDropDown.Value = event.PreviousValue;
            else
                app.systemParameters.maximumTimeStep = scale_time_units(app, 'SimulationtimestepDropDown', app.TimestepEditField.Value, 'parameters');
                app.RealplottingstepLabel.Text = set_real_time_steps(app,'plotting');
                app.RealexportstepLabel.Text = set_real_time_steps(app,'export');
            end
        end

        % Callback function: EpiMechUIFigure, ExitMenu
        function EpiMechUIFigureCloseRequest(app, event)
            switch app.appTask
                case 'simulate'

                    if app.simulating
                        userResponse = uiconfirm(app.EpiMechUIFigure,'Simulation is running, do you want to stop it?', 'Stop simulation', 'Options', {'Stop','Continue','Force stop'}, 'DefaultOption', 1);
                        switch userResponse
                            case 'Stop'
                                app.StopButton.Value = 1;
                                app.simulationClosed = 1;
                                pause(0.5)
                                return;
                            case 'Continue'
                                return;
                            case 'Force stop'
                                pause(0.5)
                        end
                    end
                case 'plotAndAnalyze'
                    if app.animating
                        userResponse = uiconfirm(app.EpiMechUIFigure,'Animation is running, do you want to stop it?', 'Stop animation', 'Options', {'Stop','Continue','Force stop'}, 'DefaultOption', 1);
                        switch userResponse
                            case 'Stop'
                                app.StopButton_2.Value = 1;
                                app.animationClosed = 1;
                                pause(0.5)
                                return;
                            case 'Continue'
                                return;
                            case 'Force stop'
                                pause(0.5)
                        end
                    end
            end

            if strcmp(app.modelCase,'import') && ~strcmp(app.import.folderName,'')
                remove_folder(app.import.folderName);
            end
            if length(app.plotImport) >= 1 && ~strcmp(app.plotImport(1).folderName,'')
                progressdlg = uiprogressdlg(app.EpiMechUIFigure,'Title','Removing the unzipped files');
                for i = 1:length(app.plotImport)
                    name = app.LoadedfilesDropDown.Items(i);

                    progressdlg.Message = name;
                    progressdlg.Value = (i-1)/length(app.plotImport);
                    progressdlg.Title = ['Removing the unzipped files (' num2str(i) '/' num2str(length(app.plotImport)) ')' ];

                    if ~(isfield(app.import,'folderName') && strcmp(app.import.folderName,app.plotImport(i).folderName))
                        remove_folder(app.plotImport(i).folderName);
                    end
                end
                close(progressdlg);
            end
            if app.plotHandle ~= 0
                delete(app.plotHandle)
            end

            delete(app.DialogApp)
            delete(app)

        end

        % Callback function
        function TimepointtoloadDropDownValueChanged(app, event)
            app.TimetoloadEditField.Value = convert_import_time(app,app.import.currentTimePoint,'numberToTime');
        end

        % Callback function
        function TimetoloadEditFieldValueChanged(app, event)
            value = app.TimetoloadEditField.Value;
            if value > convert_import_time(app,app.import.nTimePoints,'numberToTime')
                uialert(app.EpiMechUIFigure,[num2str(value) ' ' app.TimepointtoloadDropDown.Value ' is beyond the simulated times of the import.'],'Bad Input');
                app.TimetoloadEditField.Value = convert_import_time(app,app.import.nTimePoints,'numberToTime');
            else
                app.import.currentTimePoint = round(convert_import_time(app,value,'timeToNumber'));
                app.import.originalCellNumbers = get_original_cell_numbers_function(app);
                app.import.nCells = length(app.import.originalCellNumbers);
                app.TimetoloadEditField.Value = convert_import_time(app,app.import.currentTimePoint,'numberToTime');
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)


            disable_enable_all_function(app,'Off');

            d = scale_parameters(app);
            d = setup_plotting_options(app,d,'browse');

            plotting_loop(app,d,'browse_pre_simulation')

            try
                figure(app.EpiMechUIFigure)
            catch
                return
            end
            disable_enable_all_function(app,'On');

        end

        % Button pushed function: ResetcellremovalButton
        function ResetcellremovalButtonPushed(app, event)
            if sum(app.import.removedCells) > 0

                if strcmp(app.UseimportedsubstratedataCheckBox_2.Enable,'Off')
                    app.UseimportedsubstratedataCheckBox_2.Enable = 'On';
                    app.UseimportedsubstratedataCheckBox.Enable = 'On';
                end
            end
            app.import.removedCells = [];

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)

            tempTableData = app.UITable.Data;
            switch app.tableData
                case 'cellParameters'
                    check_table_edit_cell_parameters(app,event,tempTableData);
                case 'systemParameters'
                    check_table_edit_system_parameters(app,event,tempTableData);
                case 'plottingOptions'
                    check_table_edit_plotting_options(app,event,tempTableData);
                case 'customExportOptions'
                    check_table_edit_export_options(app,event,tempTableData);
                case 'substrateParameters'
                    check_table_edit_substrate_parameters(app,event,tempTableData);
                case 'fFAInfo'
                    check_table_edit_focal_adhesions(app,event,tempTableData);
            end


        end

        % Button pushed function: SubstrateparametersButton
        function SubstrateparametersButtonPushed(app, event)
            show_table(app,'substrateParameters');
        end

        % Callback function
        function YoungsmodulusEditFieldValueChanged(app, event)
            value = app.YoungsmodulusEditField.Value;
            if value <= 0
                uialert(app.EpiMechUIFigure,'Young''s modulus must be above 0','Bad Input');
                app.YoungsmodulusEditField.Value = app.substrateParameters.youngsModulus/1000;
            else
                app.substrateParameters.youngsModulus = app.YoungsmodulusEditField.Value*1000;
            end
        end

        % Value changed function: SubstratesizeEditField
        function SubstratesizeEditFieldValueChanged(app, event)
            value = app.SubstratesizeEditField.Value;
            if value <= 0
                uialert(app.EpiMechUIFigure,'Substrate size must be above 0','Bad Input');
                app.SubstratesizeEditField.Value = event.previousValue;
            end
        end

        % Button pushed function: ComparesizeButton
        function ComparesizeButtonPushed(app, event)


            d = scale_parameters(app);
            d = setup_plotting_options(app,d,'show_substrate_size');

            d.simset.substrateSize = app.SubstratesizeEditField.Value*1e-6/app.systemParameters.scalingLength;


            switch app.modelCase
                case 'new'
                    d.cells = initialize_cells_struct;
                    switch app.InitialstateButtongroup.SelectedObject.Text
                        case 'Single cell'
                            d = create_cell(d,[0;0]);
                        case 'Place multiple cells'
                            if ~isempty(app.cellCenters)
                                for i = 1:size(app.cellCenters,1)
                                    d = create_cell(d,app.cellCenters(i,:));
                                end
                            end
                    end
                case 'import'
                    d.cells = import_cells(app,d,'basic_plotting');
                    d = remove_cells_gui(app, d,'basic_plotting');
            end

            plot_function(d, 0);
        end

        % Value changed function: ComponentsettingsDropdown
        function ComponentsettingsDropdownValueChanged(app, event)
            switch app.ComponentsettingsDropdown.Value
                case 'Division'
                    app.DivisionsettingsPanel.Visible = 'On';
                    app.SubstrateSizePanel.Visible = 'Off';
                    app.PointlikesettingsPanel.Visible = 'Off';
                    app.StretchSettingsPanel.Visible = 'Off';
                    app.CellsizesettingsPanel.Visible = 'Off';
                    app.SubstrateStiffnessPanel.Visible = 'Off';
                    app.FocalAdhesionsPanel.Visible = 'Off';
                    app.OptogeneticsPanel.Visible = 'Off';
                    app.GlassActivationPanel.Visible = 'Off';
                case 'Substrate size'
                    app.DivisionsettingsPanel.Visible = 'Off';
                    app.SubstrateSizePanel.Visible = 'On';
                    app.PointlikesettingsPanel.Visible = 'Off';
                    app.StretchSettingsPanel.Visible = 'Off';
                    app.CellsizesettingsPanel.Visible = 'Off';
                    app.SubstrateStiffnessPanel.Visible = 'Off';
                    app.FocalAdhesionsPanel.Visible = 'Off';
                    app.OptogeneticsPanel.Visible = 'Off';
                    app.GlassActivationPanel.Visible = 'Off';
                case 'Pointlike micromanipulation'
                    app.DivisionsettingsPanel.Visible = 'Off';
                    app.SubstrateSizePanel.Visible = 'Off';
                    app.PointlikesettingsPanel.Visible = 'On';
                    app.StretchSettingsPanel.Visible = 'Off';
                    app.CellsizesettingsPanel.Visible = 'Off';
                    app.SubstrateStiffnessPanel.Visible = 'Off';
                    app.FocalAdhesionsPanel.Visible = 'Off';
                    app.OptogeneticsPanel.Visible = 'Off';
                    app.GlassActivationPanel.Visible = 'Off';
                case 'Lateral compression and stretching'
                    app.DivisionsettingsPanel.Visible = 'Off';
                    app.SubstrateSizePanel.Visible = 'Off';
                    app.PointlikesettingsPanel.Visible = 'Off';
                    app.StretchSettingsPanel.Visible = 'On';
                    app.CellsizesettingsPanel.Visible = 'Off';
                    app.SubstrateStiffnessPanel.Visible = 'Off';
                    app.FocalAdhesionsPanel.Visible = 'Off';
                    app.OptogeneticsPanel.Visible = 'Off';
                    app.GlassActivationPanel.Visible = 'Off';
                case 'Cell size'
                    app.DivisionsettingsPanel.Visible = 'Off';
                    app.SubstrateSizePanel.Visible = 'Off';
                    app.PointlikesettingsPanel.Visible = 'Off';
                    app.StretchSettingsPanel.Visible = 'Off';
                    app.CellsizesettingsPanel.Visible = 'On';
                    app.SubstrateStiffnessPanel.Visible = 'Off';
                    app.FocalAdhesionsPanel.Visible = 'Off';
                    app.OptogeneticsPanel.Visible = 'Off';
                    app.GlassActivationPanel.Visible = 'Off';
                case 'Substrate stiffness'
                    app.DivisionsettingsPanel.Visible = 'Off';
                    app.SubstrateSizePanel.Visible = 'Off';
                    app.PointlikesettingsPanel.Visible = 'Off';
                    app.StretchSettingsPanel.Visible = 'Off';
                    app.CellsizesettingsPanel.Visible = 'Off';
                    app.SubstrateStiffnessPanel.Visible = 'On';
                    app.FocalAdhesionsPanel.Visible = 'Off';
                    app.OptogeneticsPanel.Visible = 'Off';
                    app.GlassActivationPanel.Visible = 'Off';
                case 'Focal adhesions'
                    app.DivisionsettingsPanel.Visible = 'Off';
                    app.SubstrateSizePanel.Visible = 'Off';
                    app.PointlikesettingsPanel.Visible = 'Off';
                    app.StretchSettingsPanel.Visible = 'Off';
                    app.CellsizesettingsPanel.Visible = 'Off';
                    app.SubstrateStiffnessPanel.Visible = 'Off';
                    app.FocalAdhesionsPanel.Visible = 'On';
                    app.OptogeneticsPanel.Visible = 'Off';
                    app.GlassActivationPanel.Visible = 'Off';
                case 'Optogenetics'
                    app.DivisionsettingsPanel.Visible = 'Off';
                    app.SubstrateSizePanel.Visible = 'Off';
                    app.PointlikesettingsPanel.Visible = 'Off';
                    app.StretchSettingsPanel.Visible = 'Off';
                    app.CellsizesettingsPanel.Visible = 'Off';
                    app.SubstrateStiffnessPanel.Visible = 'Off';
                    app.FocalAdhesionsPanel.Visible = 'Off';
                    app.OptogeneticsPanel.Visible = 'On';
                    app.GlassActivationPanel.Visible = 'Off';
                 case 'Glass activation'
                    app.DivisionsettingsPanel.Visible = 'Off';
                    app.SubstrateSizePanel.Visible = 'Off';
                    app.PointlikesettingsPanel.Visible = 'Off';
                    app.StretchSettingsPanel.Visible = 'Off';
                    app.CellsizesettingsPanel.Visible = 'Off';
                    app.SubstrateStiffnessPanel.Visible = 'Off';
                    app.FocalAdhesionsPanel.Visible = 'Off';
                    app.OptogeneticsPanel.Visible = 'Off';
                    app.GlassActivationPanel.Visible = 'On';
            end

            change_panel_tooltips(app,'components_panel')

        end

        % Button pushed function: SelectcellButton
        function SelectcellButtonPushed(app, event)

            disable_enable_all_function(app,'Off');

            d = scale_parameters(app);
            d = setup_plotting_options(app,d,'select_pointlike_cell');


            switch app.modelCase
                case 'new'

                    d.cells = initialize_cells_struct;

                    switch app.InitialstateButtongroup.SelectedObject.Text
                        case 'Single cell'
                            d = create_cell(d,[0;0]);
                        case 'Place multiple cells'
                            if ~isempty(app.cellCenters)
                                for i = 1:size(app.cellCenters,1)
                                    d = create_cell(d,app.cellCenters(i,:));
                                end
                            end
                    end
                case 'import'
                    d.cells = import_cells(app,d,'basic_plotting');
                    d = remove_cells_gui(app, d,'basic_plotting');
            end

            plot_function(d,0);

            while 1

                try
                    coords = ginput(1);
                    for k = 1:length(d.cells)
                        if check_if_inside(d.cells(k).verticesX,d.cells(k).verticesY,coords(:,1),coords(:,2))

                            app.pointlikeProperties.cell = k;

                            oldHighlight = d.pl.highlightedCells;
                            d.pl.highlightedCells = k;

                            hold on
                            highlight_cell(d,oldHighlight,1);
                            highlight_cell(d,k);
                            plot_cell_numbers(d,k);
                            if numel(oldHighlight) == 1
                                plot_cell_numbers(d,oldHighlight);
                            end
                            hold off

                            app.SelectedcellEditField.Value = app.pointlikeProperties.cell;

                            break;
                        end
                    end
                catch
                    break;
                end
            end

            disable_enable_all_function(app,'On');

        end

        % Button pushed function: LoadmovementButton
        function LoadmovementButtonPushed(app, event)
            % choose file
            [filename, filepath] = uigetfile('*.txt','Choose simulation to load',[app.defaultPath 'settings/pointlike_movements/']);
            if ~ischar(filename)
                return
            end
            figure(app.EpiMechUIFigure)

            importedData = csvread([filepath '/' filename]);

            app.pointlikeProperties.movementTime = importedData(:,1);
            app.pointlikeProperties.movementY = importedData(:,2);

            app.SimulateButton.Enable = 'On';

        end

        % Value changed function: CompressvideoCheckBox
        function CompressvideoCheckBoxValueChanged(app, event)
            if app.CompressvideoCheckBox.Value
                % Checks if ffmpeg is installed and found in path
                [status, ~] = system('ffmpeg -version');
                if status
                    uialert(app.EpiMechUIFigure,'Install FFmpeg and at it to the system path to use compression.','No FFmpeg');
                    app.CompressvideoCheckBox.Value = 0;
                end
            end
        end

        % Value changed function: SubstratestyleDropDown
        function SubstratestyleDropDownValueChanged(app, event)
            switch app.SubstratestyleDropDown.Value
                case 'Simple'
                    app.plottingOptions.substrateStyle = 1;
                case 'With lines'
                    if strcmp(app.simulationType,'stretch')
                        uialert(app.EpiMechUIFigure,'substareStyle cannot be "with lines" for stretch simulation (point interaction data not present).','Bad Input');
                        app.SubstratestyleDropDown.Value = event.PreviousValue;
                    else
                        app.plottingOptions.substrateStyle = 2;
                    end
                case 'No substrate'
                    app.plottingOptions.substrateStyle = 0;
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            if strcmp(app.TablePanel.Visible,'on')
                app.TabGroup.SelectedTab = event.OldValue;
            else
                switch app.TabGroup.SelectedTab.Title
                    case 'Simulate'
                        app.appTask = 'simulate';
                    case 'Plot and analyze'
                        app.appTask = 'plotAndAnalyze';
                end
            end

        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)

            set_component_settings_panel(app,'reset');

            % choosen and unzip simulation
            folderLoaded = unzip_results(app);
            if ~folderLoaded || isempty(app.newImports)
                return;
            end

            % load settings and parameters
            folderLoaded = load_settings_and_parameters(app,1);
            if ~folderLoaded
                return;
            end

            % set ui values based on imported values
            set_ui_edit_values(app);

            set_component_settings_panel(app)

            % set the visibilities
            set_panel_visibilities(app);
        end

        % Value changed function: PlottingstepEditField_2
        function PlottingstepEditField_2ValueChanged(app, event)
            value = app.PlottingstepEditField_2.Value;
            if ~( mod(value,1) == 0 && value > 0 )
                uialert(app.EpiMechUIFigure,'Plotting time step must be a positive integer.','Bad Input');
                app.PlottingstepEditField_2.Value = event.PreviousValue;
            else
                app.importPlottingOptions.plotDtMultiplier = value;
            end
        end

        % Callback function
        function TimetoloadEditField_2ValueChanged(app, event)
            value = app.TimetoloadEditField_2.Value;
            if value > convert_import_time(app,app.plotImport(app.selectedFile).nTimePoints,'numberToTime')
                uialert(app.EpiMechUIFigure,[num2str(value) ' ' app.TimepointtoloadDropDown_2.Value ' is beyond the simulated times of the import.'],'Bad Input');
                app.TimetoloadEditField_2.Value = convert_import_time(app,app.plotImport(app.selectedFile).nTimePoints,'numberToTime');
            else
                app.plotImport(app.selectedFile).currentTimePoint = round(convert_import_time(app,value,'timeToNumber'));
                app.TimetoloadEditField_2.Value = convert_import_time(app,app.plotImport(app.selectedFile).currentTimePoint,'numberToTime');
            end
        end

        % Callback function
        function TimepointtoloadDropDown_2ValueChanged(app, event)
            app.TimetoloadEditField_2.Value = convert_import_time(app,app.plotImport(app.selectedFile).currentTimePoint,'numberToTime');
        end

        % Value changed function: SavevideoCheckBox_2
        function SavevideoCheckBox_2ValueChanged(app, event)
            if app.SavevideoCheckBox_2.Value
                app.VideonameEditField.Enable = 'On';
                app.VideonameEditFieldLabel.Enable = 'On';
                app.CompressvideoCheckBox_2.Enable = 'On';
                app.Reload2Button.Enable = 'On';
            else
                app.VideonameEditField.Enable = 'Off';
                app.VideonameEditFieldLabel.Enable = 'Off';
                app.CompressvideoCheckBox_2.Enable = 'Off';
                app.CompressvideoCheckBox_2.Value = 0;
                app.Reload2Button.Enable = 'Off';
            end
        end

        % Value changed function: CompressvideoCheckBox_2
        function CompressvideoCheckBox_2ValueChanged(app, event)
            if app.CompressvideoCheckBox_2.Value
                % Checks if ffmpeg is installed and found in path
                [status, ~] = system('ffmpeg -version');
                if status
                    uialert(app.EpiMechUIFigure,'Install FFmpeg and at it to the system path to use compression.','No FFmpeg');
                    app.CompressvideoCheckBox_2.Value = 0;
                end
            end
        end

        % Value changing function: WindowsizeSlider_2
        function WindowsizeSlider_2ValueChanging(app, event)
            newWindowSize = 30+10*77^event.Value;
            app.WindowsizeEditField_2.Value = (round(newWindowSize));
            app.importPlottingOptions.windowSize = newWindowSize*1e-6;
        end

        % Value changed function: WindowsizeEditField
        function WindowsizeEditFieldValueChanged(app, event)
            value = app.WindowsizeEditField.Value;
            if ~(value > 0)
                uialert(app.EpiMechUIFigure,'Window size must be a positive.','Bad Input');
                app.WindowsizeEditField.Value = event.PreviousValue;
            else
                app.plottingOptions.windowSize = value*1e-6;
                sliderValue = log((app.plottingOptions.windowSize/1e-6-30)/10)/log(77);
                if ~isreal(sliderValue)
                    app.WindowsizeSlider.Value = 0;
                elseif sliderValue > 1
                    app.WindowsizeSlider.Value = 1;
                else
                    app.WindowsizeSlider.Value = sliderValue;
                end
            end
        end

        % Value changed function: WindowsizeEditField_2
        function WindowsizeEditField_2ValueChanged(app, event)
            value = app.WindowsizeEditField_2.Value;
            if ~(value > 0)
                uialert(app.EpiMechUIFigure,'Window size must be a positive.','Bad Input');
                app.WindowsizeEditField_2.Value = event.PreviousValue;
            else
                app.importPlottingOptions.windowSize = value*1e-6;
                sliderValue = log((app.importPlottingOptions.windowSize/1e-6-30)/10)/log(77);
                if ~isreal(sliderValue)
                    app.WindowsizeSlider_2.Value = 0;
                elseif sliderValue > 1
                    app.WindowsizeSlider_2.Value = 1;
                else
                    app.WindowsizeSlider_2.Value = sliderValue;
                end
            end
        end

        % Value changed function: CellstyleDropDown_2
        function CellstyleDropDown_2ValueChanged(app, event)
            switch app.CellstyleDropDown_2.Value
                case 'Grey filled'
                    app.importPlottingOptions.cellStyle = 1;
                case 'Red outline'
                    app.importPlottingOptions.cellStyle = 2;
                case 'Number of neighbors'
                    if exist([app.plotImport(app.selectedFile).folderName '/junctions/'],'dir') ~= 7
                        uialert(app.EpiMechUIFigure,'No junction data, so number of neighbors cannot be determined.','Bad Input');
                        app.CellstyleDropDown_2.Value = event.PreviousValue;
                        app.importPlottingOptions.cellStyle = 1;
                    else
                        app.importPlottingOptions.cellStyle = 3;
                    end
                case 'Black'
                    app.importPlottingOptions.cellStyle = 4;
                case 'Lines'
                    app.importPlottingOptions.cellStyle = 5;
                case 'No cells'
                    app.importPlottingOptions.cellStyle = 0;
            end
        end

        % Button pushed function: PlottingoptionsButton_2
        function PlottingoptionsButton_2Pushed(app, event)
            show_table(app,'plottingOptions');
        end

        % Button pushed function: SingletimepointButton
        function SingletimepointButtonPushed(app, event)

            d.spar.scalingTime = app.plotImport(app.selectedFile).scaledParameters.scalingTime;
            d.spar.scalingLength = app.plotImport(app.selectedFile).scaledParameters.scalingLength;
            d.spar.rCell = app.plotImport(app.selectedFile).scaledParameters.rCell;
            d.spar.simulationTime = app.plotImport(app.selectedFile).scaledParameters.simulationTime;

            switch app.plotImport(app.selectedFile).simulationType
                case 'growth'
                    d.simset.simulationType = 1;
                    d.simset.substrateIncluded = 0;
                    d.simset.substrateSolved = 0;
                case 'pointlike'
                    d.simset.simulationType = 2;
                    d.simset.substrateIncluded = 1;
                    d.simset.substrateSolved = 1;
                case 'stretch'
                    d.simset.simulationType = 3;
                    d.simset.substrateIncluded = 1;
                    d.simset.substrateSolved = 0;
                case 'opto'
                    d.simset.simulationType = 5;
                    d.simset.substrateIncluded = 1;
                    d.simset.substrateSolved = 1;
                case 'glass'
                    d.simset.simulationType = 6;
                    d.simset.substrateIncluded = 1;
                    d.simset.substrateSolved = 1;
            end

            if strcmp(app.SpecialplotDropDown.Value,'Force magnitude plotting')
                prevPlottingOptions = app.importPlottingOptions;
                d = setup_plotting_options(app,d,'force_magnitude');
                app.importPlottingOptions = d.pl;
            elseif strcmp(app.SpecialplotDropDown.Value,'Cell shape descriptors')
                d = setup_plotting_options(app,d,'cell_descriptors');
            else
                d = setup_plotting_options(app,d,'post');
            end

            if app.AutomaticsizeCheckBox_2.Value == 1
                d.pl.windowSize = get_automatic_window_size(d.cells,d.spar);
            end


            d.cells = import_cells(app,d,'post_plotting');

            d = import_substrate(app,d);
            d = import_pointlike_data(app,d);
            d = import_opto_data(app,d);
            d = import_glass_data(app,d);

            plot_function(d, (app.plotImport(app.selectedFile).currentTimePoint-1)*app.plotImport(app.selectedFile).exportOptions.exportDt);

            if strcmp(app.SpecialplotDropDown.Value,'Force magnitude plotting')
                app.importPlottingOptions = prevPlottingOptions;
            end
        end

        % Button pushed function: BrowsetimepointsButton
        function BrowsetimepointsButtonPushed(app, event)

            disable_enable_all_function(app,'Off');

            switch app.plotImport(app.selectedFile).simulationType
                case 'growth'
                    d.simset.simulationType = 1;
                    d.simset.substrateIncluded = 0;
                    d.simset.substrateSolved = 0;
                case 'pointlike'
                    d.simset.simulationType = 2;
                    d.simset.substrateIncluded = 1;
                    d.simset.substrateSolved = 1;
                case 'stretch'
                    d.simset.simulationType = 3;
                    d.simset.substrateIncluded = 1;
                    d.simset.substrateSolved = 0;
                case 'opto'
                    d.simset.simulationType = 5;
                    d.simset.substrateIncluded = 1;
                    d.simset.substrateSolved = 1;
                case 'glass'
                    d.simset.simulationType = 6;
                    d.simset.substrateIncluded = 1;
                    d.simset.substrateSolved = 1;
            end

            d.spar.scalingTime = app.plotImport(app.selectedFile).scaledParameters.scalingTime;
            d.spar.scalingLength = app.plotImport(app.selectedFile).scaledParameters.scalingLength;
            d.spar.rCell = app.plotImport(app.selectedFile).scaledParameters.rCell;
            d.spar.simulationTime = app.plotImport(app.selectedFile).scaledParameters.simulationTime;


            if strcmp(app.SpecialplotDropDown.Value,'Force magnitude plotting')
                prevPlottingOptions = app.importPlottingOptions;
                d = setup_plotting_options(app,d,'force_magnitude','browse');
                app.importPlottingOptions = d.pl;
            elseif strcmp(app.SpecialplotDropDown.Value,'Cell shape descriptors')
                d = setup_plotting_options(app,d,'cell_descriptors','browse');
            else
                d = setup_plotting_options(app,d,'post_browse');
            end


            plotAgain = 1;

            while 1

                if plotAgain
                    d.cells = import_cells(app,d,'post_plotting');
                    d = import_substrate(app,d);
                    d = import_pointlike_data(app,d);
                    d = import_opto_data(app,d);
                    d = import_glass_data(app,d);
                    if app.AutomaticsizeCheckBox_2.Value == 1
                        d.pl.windowSize = get_automatic_window_size(d.cells,d.spar);
                    end

                    plot_function(d,(app.plotImport(app.selectedFile).currentTimePoint-1)*app.plotImport(app.selectedFile).exportOptions.exportDt);

                end

                try
                    w = waitforbuttonpress;
                catch
                    break
                end
                if w == 1
                    [plotAgain, stop] = get_key_presses(app,d.pl);
                    if stop
                        break
                    end
                else
                    plotAgain = 0;
                end
            end

            try
                figure(app.EpiMechUIFigure)
            catch
                return
            end

            if strcmp(app.SpecialplotDropDown.Value,'Force magnitude plotting')
                app.importPlottingOptions = prevPlottingOptions;
            end

            disable_enable_all_function(app,'On');

        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)

            disable_enable_all_function(app,'Off');

            switch app.plotImport(app.selectedFile).simulationType
                case 'growth'
                    d.simset.simulationType = 1;
                    d.simset.substrateIncluded = 0;
                    d.simset.substrateSolved = 0;
                case 'pointlike'
                    d.simset.simulationType = 2;
                    d.simset.substrateIncluded = 1;
                    d.simset.substrateSolved = 1;
                case 'stretch'
                    d.simset.simulationType = 3;
                    d.simset.substrateIncluded = 1;
                    d.simset.substrateSolved = 0;
                case 'opto'
                    d.simset.simulationType = 5;
                    d.simset.substrateIncluded = 1;
                    d.simset.substrateSolved = 1;
                case 'glass'
                    d.simset.simulationType = 6;
                    d.simset.substrateIncluded = 1;
                    d.simset.substrateSolved = 1;
            end

            d.spar.scalingTime = app.plotImport(app.selectedFile).scaledParameters.scalingTime;
            d.spar.scalingLength = app.plotImport(app.selectedFile).scaledParameters.scalingLength;
            d.spar.rCell = app.plotImport(app.selectedFile).scaledParameters.rCell;
            d.spar.simulationTime = app.plotImport(app.selectedFile).scaledParameters.simulationTime;


            if strcmp(app.SpecialplotDropDown.Value,'Force magnitude plotting')
                prevPlottingOptions = app.importPlottingOptions;
                d = setup_plotting_options(app,d,'force_magnitude_video');
                app.importPlottingOptions = d.pl;
            elseif strcmp(app.SpecialplotDropDown.Value,'Cell shape descriptors')
                d = setup_plotting_options(app,d,'cell_descriptors_video');
            else
                d = setup_plotting_options(app,d,'post_animation');
            end

            set_object_properties_function(app,{'StopButton_2', 'PauseButton_2'},'Visible',{'On'});
            set_object_properties_function(app,{'StopButton_2', 'PauseButton_2'},'Enable',{'On'});

            app.animating = 1;

            starting = find(strcmp(app.StartDropDown.Items, app.StartDropDown.Value));
            ending = find(strcmp(app.EndDropDown.Items, app.EndDropDown.Value));

            oldCurrentTimePoint = app.plotImport(app.selectedFile).currentTimePoint;

            for i = starting:app.PlottingstepEditField_2.Value:ending

                pause_or_stop_simulation(app)
                if app.animationStopped
                    break
                end

                app.plotImport(app.selectedFile).currentTimePoint = i;

                d.cells = import_cells(app,d,'post_plotting');
                d = import_substrate(app,d);
                d = import_pointlike_data(app,d);
                d = import_opto_data(app,d);
                d = import_glass_data(app,d);
                    d.pl.videoObject = plot_function(d, (app.plotImport(app.selectedFile).currentTimePoint-1)*app.plotImport(app.selectedFile).exportOptions.exportDt);

            end

            app.plotImport(app.selectedFile).currentTimePoint = oldCurrentTimePoint;

            finalize_video(app,d)
            app.animationStopped = 0;
            app.animating = 0;

            disable_enable_all_function(app,'On');

            if d.pl.plot
                set(d.pl.figureHandle,'CloseRequestFcn','closereq');
            end

            if app.simulationClosed
                EpiMechUIFigureCloseRequest(app)
            end

            set_object_properties_function(app,{'StopButton_2', 'PauseButton_2'},'Visible',{'Off'});
            set_object_properties_function(app,{'StopButton_2', 'PauseButton_2'},'Enable',{'Off'});
            set_object_properties_function(app,{'StopButton_2', 'PauseButton_2'},'Value',{0});

            if strcmp(app.SpecialplotDropDown.Value,'Force magnitude plotting')
                app.importPlottingOptions = prevPlottingOptions;
            end

            if app.animationClosed
                EpiMechUIFigureCloseRequest(app)
            end

        end

        % Value changed function: SpecialplotDropDown
        function SpecialplotDropDownValueChanged(app, event)
            switch app.SpecialplotDropDown.Value
                case 'Normal'
                    set_object_properties_function(app,{'HighlightcellsPanel', 'ShowcelllineagePanel','ForcemagnitudeplottingPanel','CellshapedescriptorsPanel'},'Visible',{'Off'})
                    set_object_properties_function(app,{'BrowsetimepointsButton','SingletimepointButton','AnimateButton'},'Enable',{'On'})
                    if strcmp(app.plotImport(app.selectedFile).simulationType,'pointlike') || strcmp(app.plotImport(app.selectedFile).simulationType,'opto') || strcmp(app.plotImport(app.selectedFile).simulationType,'stretch')
                        set_object_properties_function(app,{'SubstratestyleDropDown_2','SubstratestyleDropDown_2Label'},'Enable',{'On'})
                    end

                case 'Highlight cells'
                    set_object_properties_function(app,{'ShowcelllineagePanel'},'Visible',{'Off'})
                    set_object_properties_function(app,{'HighlightcellsPanel'},'Visible',{'On'})
                    set_object_properties_function(app,{'ForcemagnitudeplottingPanel'},'Visible',{'Off'})
                    set_object_properties_function(app,{'CellshapedescriptorsPanel'},'Visible',{'Off'})
                    set_object_properties_function(app,{'BrowsetimepointsButton','SingletimepointButton','AnimateButton'},'Enable',{'On'})

                case 'Show lineage'
                    if exist([app.plotImport(app.selectedFile).folderName '/lineage'],'dir') ~= 7
                        uialert(app.EpiMechUIFigure,'No lineage data available.','Bad Input');
                        app.SpecialplotDropDown.Value = event.PreviousValue;
                        return
                    end
                    set_object_properties_function(app,{'ShowcelllineagePanel'},'Visible',{'On'})
                    set_object_properties_function(app,{'HighlightcellsPanel'},'Visible',{'Off'})
                    set_object_properties_function(app,{'ForcemagnitudeplottingPanel'},'Visible',{'Off'})
                    set_object_properties_function(app,{'CellshapedescriptorsPanel'},'Visible',{'Off'})
                    set_object_properties_function(app,{'BrowsetimepointsButton','SingletimepointButton','AnimateButton'},'Enable',{'On'});

                case 'Force magnitude plotting'
                    set_object_properties_function(app,{'ShowcelllineagePanel'},'Visible',{'Off'})
                    set_object_properties_function(app,{'HighlightcellsPanel'},'Visible',{'Off'})
                    set_object_properties_function(app,{'ForcemagnitudeplottingPanel'},'Visible',{'On'})
                    set_object_properties_function(app,{'CellshapedescriptorsPanel'},'Visible',{'Off'})
                    if isempty(app.ForceDropDown_2.Items)
                        set_object_properties_function(app,{'BrowsetimepointsButton','SingletimepointButton','AnimateButton'},'Enable',{'Off'})
                    end
                case 'Cell shape descriptors'
                    set_object_properties_function(app,{'ShowcelllineagePanel'},'Visible',{'Off'})
                    set_object_properties_function(app,{'HighlightcellsPanel'},'Visible',{'Off'})
                    set_object_properties_function(app,{'ForcemagnitudeplottingPanel'},'Visible',{'Off'})
                    set_object_properties_function(app,{'CellshapedescriptorsPanel'},'Visible',{'On'})
                    set_object_properties_function(app,{'BrowsetimepointsButton','SingletimepointButton','AnimateButton'},'Enable',{'On'})
            end

            change_panel_tooltips(app,'special_plotting_panel')

        end

        % Value changing function: SimulationnameEditField
        function SimulationnameEditFieldValueChanging(app, event)
            app.SimulationnameEditField.FontColor = [0 0 0];
        end

        % Value changed function: SimulationnameEditField
        function SimulationnameEditFieldValueChanged(app, event)
            value = app.SimulationnameEditField.Value;
            unallowed = {'<', '>', ':', '"', '/', '\', '|', '?', '*'};
            if contains(value, unallowed)
                uialert(app.EpiMechUIFigure,'File name cannot include <, >, :, ", /, \, |, ?, or *.','Bad Input');
                if strcmp('Give name',event.PreviousValue)
                    app.SimulationnameEditField.FontColor = [0.8 0.8 0.8];
                end
                app.SimulationnameEditField.Value = event.PreviousValue;

            end

            if strcmp(value,'')
                app.SimulationnameEditField.FontColor = [0.8 0.8 0.8];
                app.SimulationnameEditField.Value = 'Give name';
            end
        end

        % Button pushed function: AreavstimeButton
        function AreavstimeButtonPushed(app, event)
            area_plot(app)
        end

        % Value changed function: SubstratestyleDropDown_2
        function SubstratestyleDropDown_2ValueChanged(app, event)
            switch app.SubstratestyleDropDown_2.Value
                case 'Simple'
                    app.importPlottingOptions.substrateStyle = 1;
                case 'With lines'
                    if strcmp(app.plotImport(app.selectedFile).simulationType,'stretch')
                        uialert(app.EpiMechUIFigure,'substareStyle cannot be "with lines" for stretch simulation (point interaction data not present).','Bad Input');
                        app.SubstratestyleDropDown_2.Value = event.PreviousValue;
                    else
                        app.importPlottingOptions.substrateStyle = 2;
                    end
                    app.importPlottingOptions.substrateStyle = 2;
                case 'No substrate'
                    app.importPlottingOptions.substrateStyle = 0;
            end
        end

        % Menu selected function: OpenvideofolderMenu
        function OpenvideofolderMenuSelected(app, event)
            if exist([app.defaultPath 'videos']) ~= 7 %#ok<EXIST>
                mkdir(app.defaultPath, 'videos');
            end

            if ispc
                winopen([app.defaultPath 'videos/']);
            elseif ismac
                system(['open ' app.defaultPath 'videos/'])
            elseif isunix
                system(['xdg-open ' app.defaultPath  'videos/'])
            end
        end

        % Button pushed function: RemovecellsButton
        function RemovecellsButtonPushed(app, event)

            disable_enable_all_function(app,'Off');

            app.DialogApp = removeCellsDialog(app);

        end

        % Selection changed function: SubstrateTypeButtonGroup
        function SubstrateTypeButtonGroupSelectionChanged(app, event)
            switch app.SubstrateTypeButtonGroup.SelectedObject.Text
                case 'Fitted'
                    if strcmp(app.InitialstateButtongroup.SelectedObject.Text,'Place multiple cells')
                        uialert(app.EpiMechUIFigure,'Must be square when ''Place multiple cells'' is selected as initial state.','Bad Input');
                        app.SubstrateTypeButtonGroup.SelectedObject = app.SquareButton;
                        return
                    end
                    set_object_properties_function(app,{'ManualsizeCheckBox', 'SubstratesizeEditField','SubstratesizeEditFieldLabel','ComparesizeButton','mLabel'},'Enable',{'Off'});
                case 'Square'
                    set_object_properties_function(app,{'ManualsizeCheckBox'},'Enable',{'On'});
                    if app.ManualsizeCheckBox.Value == 1
                        set_object_properties_function(app,{'SubstratesizeEditField','SubstratesizeEditFieldLabel','ComparesizeButton','mLabel'},'Enable',{'On'});
                    end
            end

        end

        % Value changed function: ManualsizeCheckBox
        function ManualsizeCheckBoxValueChanged(app, event)
            if app.ManualsizeCheckBox.Value
                set_object_properties_function(app,{'SubstratesizeEditField','SubstratesizeEditFieldLabel','ComparesizeButton','mLabel'},'Enable',{'On'});
            else
                set_object_properties_function(app,{'SubstratesizeEditField','SubstratesizeEditFieldLabel','ComparesizeButton','mLabel'},'Enable',{'Off'});
            end
        end

        % Value changed function: AutomaticsizeCheckBox_2
        function AutomaticsizeCheckBox_2ValueChanged(app, event)
            if app.AutomaticsizeCheckBox_2.Value
                set_object_properties_function(app,{'WindowsizeSlider_2','WindowsizeSlider_2Label','WindowsizeEditField_2','WindowsizeText_2','MiddlepointxEditField_2','MiddlepointyEditField_2','MiddlepointLabel_2','ResetviewButton_2','WindowsizeText_4'},'Enable',{'Off'});
            else
                set_object_properties_function(app,{'WindowsizeSlider_2','WindowsizeSlider_2Label','WindowsizeEditField_2','WindowsizeText_2','MiddlepointxEditField_2','MiddlepointyEditField_2','MiddlepointLabel_2','ResetviewButton_2','WindowsizeText_4'},'Enable',{'On'});
            end
        end

        % Callback function
        function DisplacementTypeButtonGroupSelectionChanged(app, event)
            if strcmp(app.DisplacementTypeButtonGroup.SelectedObject.Text,'Substrate')
                if ~(exist([app.plotImport(app.selectedFile).folderName '/substrate/'],'dir') == 7 && exist([app.plotImport(app.selectedFile).folderName '/substrate_auxiliary/'],'dir') == 7)
                    uialert(app.EpiMechUIFigure,'Substrate data not found.','Bad Input');
                    app.CellsButton.Value = 1;
                end
            end

        end

        % Value changed function: SelectcentercellCheckBox
        function SelectcentercellCheckBoxValueChanged(app, event)
            switch app.SelectcentercellCheckBox.Value
                case 1
                    set_object_properties_function(app,{'SelectedcellEditFieldLabel','SelectedcellEditField','SelectcellButton'},'Enable',{'Off'});
                case 0
                    set_object_properties_function(app,{'SelectedcellEditFieldLabel','SelectedcellEditField','SelectcellButton'},'Enable',{'On'});
            end
        end

        % Menu selected function: ScalebarsettingsMenu
        function ScalebarsettingsMenuSelected(app, event)

            disable_enable_all_function(app,'Off');

            app.DialogApp = scaleBarSettingsDialog(app);
        end

        % Button pushed function: PerimetervstimeButton
        function PerimetervstimeButtonPushed(app, event)
            perimeter_plot(app);
        end

        % Button pushed function: ResetviewButton, ResetviewButton_2
        function ResetviewButtonPushed(app, event)


            switch app.appTask
                case 'simulate'
                    switch app.SimulationtypeDropDown.Value
                        case 'Epithelial growth'
                            tempPlottingOptions = import_settings([app.defaultPath 'settings/plotting/plotting_options_growth.txt']);
                        case 'Pointlike micromanipulation'
                            tempPlottingOptions = import_settings([app.defaultPath 'settings/plotting/plotting_options_pointlike.txt']);
                        case 'Lateral compression and stretching'
                            tempPlottingOptions = import_settings([app.defaultPath 'settings/plotting/plotting_options_stretch.txt']);
                    end
                    app.MiddlepointxEditField.Value = 0;
                    app.MiddlepointyEditField.Value = 0;
                    app.plottingOptions.windowSize = tempPlottingOptions.windowSize;
                    app.WindowsizeEditField.Value = tempPlottingOptions.windowSize*1e6;
                    sliderValue = log((app.plottingOptions.windowSize/1e-6-30)/10)/log(77);
                    if ~isreal(sliderValue)
                        app.WindowsizeSlider.Value = 0;
                    elseif sliderValue > 1
                        app.WindowsizeSlider.Value = 1;
                    else
                        app.WindowsizeSlider.Value = sliderValue;
                    end
                case 'plotAndAnalyze'
                    switch app.plotImport(app.selectedFile).simulationType
                        case 'growth'
                            tempPlottingOptions = import_settings([app.defaultPath 'settings/plotting/post_plotting_options_growth.txt']);
                        case 'pointlike'
                            tempPlottingOptions = import_settings([app.defaultPath 'settings/plotting/post_plotting_options_pointlike.txt']);
                        case 'stretch'
                            tempPlottingOptions = import_settings([app.defaultPath 'settings/plotting/post_plotting_options_stretch.txt']);
                        case 'opto'
                            tempPlottingOptions = import_settings([app.defaultPath 'settings/plotting/post_plotting_options_opto.txt']);
                    end
                    app.MiddlepointxEditField_2.Value = 0;
                    app.MiddlepointyEditField_2.Value = 0;
                    app.importPlottingOptions.windowSize = tempPlottingOptions.windowSize;
                    app.WindowsizeEditField_2.Value = tempPlottingOptions.windowSize*1e6;
                    sliderValue = log((app.importPlottingOptions.windowSize/1e-6-30)/10)/log(77);
                    if ~isreal(sliderValue)
                        app.WindowsizeSlider_2.Value = 0;
                    elseif sliderValue > 1
                        app.WindowsizeSlider_2.Value = 1;
                    else
                        app.WindowsizeSlider_2.Value = sliderValue;
                    end
            end


        end

        % Value changed function: AutomaticsizeCheckBox
        function AutomaticsizeCheckBoxValueChanged(app, event)
            if app.AutomaticsizeCheckBox.Value
                set_object_properties_function(app,{'WindowsizeSlider','WindowsizeSliderLabel','WindowsizeEditField','WindowsizeText','MiddlepointxEditField','MiddlepointyEditField','MiddlepointLabel','ResetviewButton','WindowsizeText_3'},'Enable',{'Off'});
            else
                set_object_properties_function(app,{'WindowsizeSlider','WindowsizeSliderLabel','WindowsizeEditField','WindowsizeText','MiddlepointxEditField','MiddlepointyEditField','MiddlepointLabel','ResetviewButton','WindowsizeText_3'},'Enable',{'On'});
            end
        end

        % Key press function: EpiMechUIFigure
        function EpiMechUIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'n'
                    if isempty(app.tableData)
                        NewsimulationButtonPushed(app, event);
                    end
                case 'c'
                    if isempty(app.tableData)
                        EditcellparametersButtonPushed(app, event);
                    end
                case 's'
                    if isempty(app.tableData)
                        EditsystemparametersButtonPushed(app, event);
                    end
                case 'escape'
                    if ~isempty(app.tableData)
                        CloseButtonPushed(app, event)
                    end
            end
        end

        % Value changed function: TimepointDropDown
        function TimepointDropDownValueChanged(app, event)
            app.plotImport(app.selectedFile).currentTimePoint = find(strcmp(app.TimepointDropDown.Items, app.TimepointDropDown.Value));
            write_area_perimeter(app);
        end

        % Value changed function: TimetoloadDropDown
        function TimetoloadDropDownValueChanged(app, event)
            app.import.currentTimePoint = find(strcmp(app.TimetoloadDropDown.Items, app.TimetoloadDropDown.Value));
        end

        % Value changed function: LoadedfilesDropDown
        function LoadedfilesDropDownValueChanged(app, event)

            progressdlg = uiprogressdlg(app.EpiMechUIFigure,'Title','Changing file',...
                'Indeterminate','on');

            app.selectedFile = find(strcmp(app.LoadedfilesDropDown.Items, app.LoadedfilesDropDown.Value));

            app.TimepointDropDown.Items = {};

            d.spar = app.plotImport(app.selectedFile).scaledParameters;
            numTemp = num2cell(1:app.plotImport(app.selectedFile).nTimePoints);
            timeStrings = cellfun(@(i) get_time_string(d,convert_import_time(app,i,'numberToTime'),0),numTemp,'UniformOutput',false);
            app.TimepointDropDown.Items = timeStrings;
            app.TimepointDropDown.Value = app.TimepointDropDown.Items(app.plotImport(app.selectedFile).currentTimePoint);

            if app.importPlottingOptions.cellStyle == 3
                if exist([app.plotImport(app.selectedFile).folderName '/junctions/'],'dir') ~= 7
                    app.importPlottingOptions.cellStyle = 1;
                end
            end


            set_cell_plotting_style(app,app.importPlottingOptions.cellStyle);

            set_substrate_plotting_style(app,app.importPlottingOptions.substrateStyle)

            cellNumbers = num2cell(app.plotImport(app.selectedFile).cellNumbers);
            for i = 1:length(cellNumbers)
                cellNumbers{i} = num2str(cellNumbers{i});
            end


            app.ChoosecellstohighlightListBox.Items = cellNumbers;
            app.ShowcelllineageListBox.Items = cellNumbers;
            app.CellsListBox.Items = cellNumbers;

            app.importPlottingOptions = check_plotting_options(app);

            set_import_cells_forces(app);

            if (exist([app.plotImport(app.selectedFile).folderName '/substrate/'],'dir') == 7 && exist([app.plotImport(app.selectedFile).folderName '/substrate_auxiliary/'],'dir') == 7) && (strcmp(app.plotImport(app.selectedFile).simulationType,'pointlike') || strcmp(app.plotImport(app.selectedFile).simulationType,'stretch') || strcmp(app.plotImport(app.selectedFile).simulationType,'opto'))
                set_object_properties_function(app,{'SubstratestyleDropDown_2','SubstratestyleDropDown_2Label'},'Enable', {'On'});
            else
                set_object_properties_function(app,{'SubstratestyleDropDown_2','SubstratestyleDropDown_2Label'},'Enable', {'Off'});
            end

            change_import_parameter_panel(app);

            app.StartDropDown.Items = {};
            app.EndDropDown.Items = {};

            d.spar = app.plotImport(app.selectedFile).scaledParameters;
            numTemp = num2cell(1:app.plotImport(app.selectedFile).nTimePoints);
            timeStrings = cellfun(@(i) get_time_string(d,convert_import_time(app,i,'numberToTime'),0),numTemp,'UniformOutput',false);
            app.StartDropDown.Items = timeStrings;
            app.EndDropDown.Items = timeStrings;
            app.StartDropDown.Value = app.StartDropDown.Items(1);
            app.EndDropDown.Value = app.EndDropDown.Items(end);

            write_area_perimeter(app);

            change_analysis_panels(app);

            close(progressdlg);

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            if length(app.plotImport) > 1
                selection = uiconfirm(app.EpiMechUIFigure,'Choose what you want to clear','Clearing loaded files', 'Options',{'Selected','All','Cancel'}, 'DefaultOption',1);
                switch selection
                    case 'Selected'
                        progressdlg = uiprogressdlg(app.EpiMechUIFigure,'Title','Clearing file',...
                            'Indeterminate','on');
                        name = app.LoadedfilesDropDown.Items(app.selectedFile);
                        progressdlg.Message = name;
                        if ~(isfield(app.import,'folderName') && strcmp(app.import.folderName,app.plotImport(app.selectedFile).folderName))
                            remove_folder(app.plotImport(app.selectedFile).folderName);
                        end

                        app.plotImport(app.selectedFile) = [];
                        if ~isempty(app.plotImport)
                            app.LoadedfilesDropDown.Items(strcmp(app.LoadedfilesDropDown.Items, app.LoadedfilesDropDown.Value)) = [];
                            app.LoadedfilesDropDown.Value = app.LoadedfilesDropDown.Items(1);
                        else
                            app.LoadedfilesDropDown.Items = {};
                            app.LoadedfilesDropDown.Value = {};
                            app.SpecialplottingoptionsPanel.Visible = 'Off';
                            app.PlotPanel.Visible = 'Off';
                            app.AnalysisPanel.Visible = 'Off';
                            app.PlottingoptionsPanel_2.Visible = 'Off';
                            app.AnimationPanel.Visible = 'Off';
                            app.LoadedfilesDropDown.Visible = 'Off';
                            app.ClearButton.Visible = 'Off';
                            set_component_settings_panel(app,'reset');
                        end
                        close(progressdlg);
                        if ~isempty(app.plotImport)
                            LoadedfilesDropDownValueChanged(app, event)
                        end
                    case 'All'
                        progressdlg = uiprogressdlg(app.EpiMechUIFigure,'Title','Clearing all files');

                        numFiles = length(app.plotImport);

                        for i = numFiles:-1:1
                            name = app.LoadedfilesDropDown.Items(i);

                            progressdlg.Message = name;
                            progressdlg.Value = (numFiles-i)/numFiles;
                            progressdlg.Title = ['Clearing all files (' num2str(numFiles-i+1) '/' num2str(numFiles) ')' ];

                            if ~(isfield(app.import,'folderName') && strcmp(app.import.folderName,app.plotImport(i).folderName))
                                remove_folder(app.plotImport(i).folderName);
                            end
                            app.plotImport(i) = [];
                        end

                        if ~isa(app.analysisFigureHandle,'double') && isvalid(app.analysisFigureHandle)
                            delete(app.analysisFigureHandle);
                        end

                        app.TimepointDropDown.Items = {};
                        app.StartDropDown.Items = {};
                        app.EndDropDown.Items = {};

                        app.LoadedfilesDropDown.Items = {};
                        app.LoadedfilesDropDown.Value = {};
                        app.SpecialplottingoptionsPanel.Visible = 'Off';
                        app.PlotPanel.Visible = 'Off';
                        app.AnalysisPanel.Visible = 'Off';
                        app.PlottingoptionsPanel_2.Visible = 'Off';
                        app.AnimationPanel.Visible = 'Off';
                        app.LoadedfilesDropDown.Visible = 'Off';
                        app.ClearButton.Visible = 'Off';
                        set_component_settings_panel(app,'reset');
                        app.selectedFile = 0;


                        close(progressdlg);

                end
            else
                progressdlg = uiprogressdlg(app.EpiMechUIFigure,'Title','Clearing file',...
                    'Indeterminate','on');

                name = app.LoadedfilesDropDown.Items(1);
                progressdlg.Message = name;
                if ~(isfield(app.import,'folderName') && strcmp(app.import.folderName,app.plotImport(1).folderName))
                    remove_folder(app.plotImport(1).folderName);
                end
                app.plotImport(1) = [];

                app.TimepointDropDown.Items = {};
                app.StartDropDown.Items = {};
                app.EndDropDown.Items = {};

                app.LoadedfilesDropDown.Items = {};
                app.LoadedfilesDropDown.Value = {};
                app.SpecialplottingoptionsPanel.Visible = 'Off';
                app.PlotPanel.Visible = 'Off';
                app.AnalysisPanel.Visible = 'Off';
                app.PlottingoptionsPanel_2.Visible = 'Off';
                app.AnimationPanel.Visible = 'Off';
                app.LoadedfilesDropDown.Visible = 'Off';
                app.ClearButton.Visible = 'Off';
                set_component_settings_panel(app,'reset');

                app.selectedFile = 1;
                close(progressdlg);
            end
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            switch app.plotImport(app.selectedFile).simulationType
                case 'growth'
                    app.importPlottingOptions = import_settings([app.defaultPath 'settings/plotting/post_plotting_options_growth.txt']);
                    app.importPlottingOptions = settings_to_logical(app.importPlottingOptions,'plot');
                case 'pointlike'
                    app.importPlottingOptions = import_settings([app.defaultPath 'settings/plotting/post_plotting_options_pointlike.txt']);
                    app.importPlottingOptions = settings_to_logical(app.importPlottingOptions,'plot');
                case 'stretch'
                    app.importPlottingOptions = import_settings([app.defaultPath 'settings/plotting/post_plotting_options_stretch.txt']);
                    app.importPlottingOptions = settings_to_logical(app.importPlottingOptions,'plot');
            end

            app.importPlottingOptions = check_plotting_options(app);

            sliderValue = log((app.importPlottingOptions.windowSize/1e-6-30)/10)/log(77);
            if ~isreal(sliderValue)
                app.WindowsizeSlider_2.Value = 0;
            elseif sliderValue > 1
                app.WindowsizeSlider_2.Value = 1;
            else
                app.WindowsizeSlider_2.Value = sliderValue;
            end
            app.WindowsizeEditField_2.Value = round(app.importPlottingOptions.windowSize*1e6);
            app.PlottingstepEditField_2.Value = app.importPlottingOptions.plotDtMultiplier;

            app.TimepointDropDown.Value = app.TimepointDropDown.Items(end);
            app.StartDropDown.Value = app.StartDropDown.Items(1);
            app.EndDropDown.Value = app.EndDropDown.Items(end);
            write_area_perimeter(app);

            if app.importPlottingOptions.cellStyle == 3

                if exist([app.plotImport(app.selectedFile).folderName '/junctions/'],'dir') ~= 7
                    app.importPlottingOptions.cellStyle = 1;
                end
            end
            set_cell_plotting_style(app,app.importPlottingOptions.cellStyle);
            set_substrate_plotting_style(app,app.importPlottingOptions.substrateStyle);

            app.MiddlepointxEditField_2.Value = 0;
            app.MiddlepointyEditField_2.Value = 0;

            app.SavevideoCheckBox_2.Value = 0;
            app.CompressvideoCheckBox_2.Value = 0;
            app.CompressvideoCheckBox_2.Enable = 0;

            set_object_properties_function(app,{'VideonameEditField', 'Reload2Button'},'Enable', {'Off'});
            app.VideonameEditField.Value = 'Give name';
            app.VideonameEditField.FontColor = [0.8 0.8 0.8];

        end

        % Value changing function: VideonameEditField
        function VideonameEditFieldValueChanging(app, event)
            app.VideonameEditField.FontColor = [0 0 0];
        end

        % Value changed function: VideonameEditField
        function VideonameEditFieldValueChanged(app, event)
            value = app.VideonameEditField.Value;
            unallowed = {'<', '>', ':', '"', '/', '\', '|', '?', '*'};
            if contains(value, unallowed)
                uialert(app.EpiMechUIFigure,'File name cannot include <, >, :, ", /, \, |, ?, or *.','Bad Input');

                if strcmp('Give name',event.PreviousValue)
                    app.VideonameEditField.FontColor = [0.8 0.8 0.8];
                end
                app.VideonameEditField.Value = event.PreviousValue;

            end
            if strcmp(value,'')
                app.VideonameEditField.FontColor = [0.8 0.8 0.8];
                app.VideonameEditField.Value = 'Give name';
            end
        end

        % Button pushed function: Reload2Button
        function Reload2ButtonPushed(app, event)
            app.VideonameEditField.FontColor = [0.8 0.8 0.8];
            app.VideonameEditField.Value = 'Give name';
        end

        % Button pushed function: ReloadButton
        function ReloadButtonPushed(app, event)
            app.SimulationnameEditField.FontColor = [0.8 0.8 0.8];
            app.SimulationnameEditField.Value = 'Give name';
        end

        % Button pushed function: AreaButton
        function AreaButtonPushed(app, event)
            area_histogram_plot(app);
        end

        % Button pushed function: PerimeterButton
        function PerimeterButtonPushed(app, event)
            perimeter_histogram_plot(app);
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            disable_enable_all_function(app,'Off');

            app.DialogApp = about_dialog(app);
        end

        % Value changed function: UseimportedsubstratedataCheckBox_2
        function UseimportedsubstratedataCheckBox_2ValueChanged(app, event)
            if ~app.UseimportedsubstratedataCheckBox_2.Value
                set_object_properties_function(app,{'FittedButton','SquareButton', 'ManualsizeCheckBox','SubstratesizeEditField', 'SubstratesizeEditFieldLabel', 'mLabel','ComparesizeButton', 'SubstrateparametersButton'},'Enable',{'On'});
                set_object_properties_function(app,{'EditstiffnessButton'},'Enable',{'Off'});
                set_object_properties_function(app,{'ConstantButton','HeterogeneousButton','GradientButton','YoungsmodulusEditField', 'YoungsmodulusEditFieldLabel','kPaLabel'},'Enable',{'On'});
                app.FittedButton.Value = 1;
                app.UseimportedsubstratedataCheckBox.Value = 0;
            else
                set_object_properties_function(app,{'FittedButton','SquareButton', 'ManualsizeCheckBox','SubstratesizeEditField', 'SubstratesizeEditFieldLabel', 'mLabel','ComparesizeButton', 'SubstrateparametersButton'},'Enable',{'Off'});
                app.FittedButton.Value = 1;
                app.ConstantButton.Value = 1;
                set_object_properties_function(app,{'EditstiffnessButton'},'Enable',{'Off'});
                set_object_properties_function(app,{'ConstantButton','HeterogeneousButton','GradientButton','YoungsmodulusEditField', 'YoungsmodulusEditFieldLabel','kPaLabel'},'Enable',{'Off'});
                app.UseimportedsubstratedataCheckBox.Value = 1;
            end

        end

        % Menu selected function: OpenresultsfolderMenu
        function OpenresultsfolderMenuSelected(app, event)
            if exist([app.defaultPath 'results']) ~= 7 %#ok<EXIST>
                mkdir(app.defaultPath, 'results');
            end

            if ispc
                winopen([app.defaultPath 'results/']);
            elseif ismac
                system(['open ' app.defaultPath 'results/'])
            elseif isunix
                system(['xdg-open ' app.defaultPath  'results/'])
            end
        end

        % Value changed function: SelectedcellEditField
        function SelectedcellEditFieldValueChanged(app, event)
            value = app.SelectedcellEditField.Value;
            if value <= 0 || mod(value,1) ~= 0
                uialert(app.EpiMechUIFigure,'Cell number must be above a positive integer','Bad Input');
                app.SelectedcellEditField.Value = event.PreviousValue;
            elseif value > app.import.nCells
                uialert(app.EpiMechUIFigure,['The number of cells in the system is ' num2str(app.import.nCells)],'Bad Input');
                app.SelectedcellEditField.Value = event.PreviousValue;
            else
                app.pointlikeProperties.cell = app.SelectedcellEditField.Value;
            end
        end

        % Value changed function: DropDown2
        function DropDown2ValueChanged(app, event)
            switch app.DropDown2.Value
                case 'Geometry'
                    app.GeometryAnalysisPanel.Visible = 'On';
                    app.CellForcesAnalysisPanel.Visible = 'Off';
                    app.PointlikeAnalysisPanel.Visible = 'Off';
                    app.OptogeneticAnalysisPanel.Visible = 'Off';
                    app.GlassAnalysisPanel.Visible = 'Off';
                case 'Cell forces'
                    app.GeometryAnalysisPanel.Visible = 'Off';
                    app.CellForcesAnalysisPanel.Visible = 'On';
                    app.PointlikeAnalysisPanel.Visible = 'Off';
                    app.OptogeneticAnalysisPanel.Visible = 'Off';
                    app.GlassAnalysisPanel.Visible = 'Off';
                case 'Pointlike analysis'
                    app.GeometryAnalysisPanel.Visible = 'Off';
                    app.CellForcesAnalysisPanel.Visible = 'Off';
                    app.PointlikeAnalysisPanel.Visible = 'On';
                    app.OptogeneticAnalysisPanel.Visible = 'Off';
                    app.GlassAnalysisPanel.Visible = 'Off';
                case 'Optogenetic analysis'
                    app.GeometryAnalysisPanel.Visible = 'Off';
                    app.CellForcesAnalysisPanel.Visible = 'Off';
                    app.PointlikeAnalysisPanel.Visible = 'Off';
                    app.OptogeneticAnalysisPanel.Visible = 'On';
                    app.GlassAnalysisPanel.Visible = 'Off';
                case 'Glass analysis'
                    app.GeometryAnalysisPanel.Visible = 'Off';
                    app.CellForcesAnalysisPanel.Visible = 'Off';
                    app.PointlikeAnalysisPanel.Visible = 'Off';
                    app.OptogeneticAnalysisPanel.Visible = 'Off';
                    app.GlassAnalysisPanel.Visible = 'On';
            end

            change_panel_tooltips(app,'analysis_panel')

        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            post_plot_forces(app);
        end

        % Menu selected function: ResetMenu
        function ResetMenuSelected(app, event)
            if app.plotHandle ~= 0
                delete(app.plotHandle)
            end

            delete(app.DialogApp)

            disable_enable_all_function(app,'On');

            hide_table(app);

            app.defaultPath = [fileparts(mfilename('fullpath')) '/'];
            app.cellParameters = struct();
            app.systemParameters = struct();
            app.substrateParameters = struct();
            app.pointlikeProperties = struct();
            app.plottingOptions = struct();
            app.customExportOptions = struct();
            app.cellCenters = [];
            app.enabledObjects = 0;
            app.tableData = '';
            app.simulationType = '';
            app.plotHandle = 0;
            app.modelCase = '';
            app.simulating = 0;
            app.simulationStopped = 0;
            app.simulationClosed = 0;
            app.animating = 0;
            app.animationStopped = 0;
            app.animationClosed = 0;
            app.folderName = '';
            app.import = struct();
            app.plotImport = struct('folderName', '');
            app.appTask = 'simulate';
            app.firstStartup = 1;
            app.scaleBarSettings = struct();
            app.firstStartupPA = 1;
            app.newImports = [];
            app.importPlottingOptions = struct();
            app.selectedFile = 0;
            app.optoVertices = struct();
            app.optoActivation = [];
            app.optoShapes = {};
            app.glassActivationShapes = {};
            app.glassActivation = [];

            % make the GUI invisible momentarily before moving it to the
            % center of the screen
            app.EpiMechUIFigure.Visible = 'Off';

            app.StopButton.Value = 0;

            % set all the panels invisible
            set_object_properties_function(app,{'ParametersPanel','ComponentsettingsPanel','PlottingoptionsPanel','ExportoptionsPanel', 'InitialstatePanel', 'InitialstatePanel_2', 'SimulationsettingsPanel','SimulateButton', 'SimulateButton', 'StopButton', 'PauseButton', 'TablePanel'},'Visible',{'Off'});
            set_object_properties_function(app,{'PlotPanel','PlottingoptionsPanel_2','AnimationPanel','SpecialplottingoptionsPanel','StopButton_2','PauseButton_2','AnalysisPanel','LoadedfilesDropDown'},'Visible',{'Off'});

            app.logoImage.ImageSource = './settings/logo_v2.png';
            app.ReloadButton.Icon = './settings/reload.png';
            app.StopButton.Icon = './settings/stop.png';
            app.PauseButton.Icon = './settings/pause.png';
            app.AnimateButton.Icon = './settings/play.png';
            app.PauseButton_2.Icon = './settings/pause.png';
            app.StopButton_2.Icon = './settings/stop.png';
            app.Reload2Button.Icon = './settings/reload.png';
            app.ResetviewButton.Icon = './settings/reload.png';
            app.ResetviewButton_2.Icon = './settings/reload.png';
            app.ComponentstipsImage.ImageSource = [app.defaultPath 'settings/images/tips.png'];
            app.InitialstatetipsImage.ImageSource = [app.defaultPath 'settings/images/tips.png'];
            app.ParameterstipsImage.ImageSource = [app.defaultPath 'settings/images/tips.png'];
            app.PlottingtipsImage_1.ImageSource = [app.defaultPath 'settings/images/tips.png'];
            app.ExportoptionsImage.ImageSource = [app.defaultPath 'settings/images/tips.png'];
            app.AnalysistipsImage.ImageSource = [app.defaultPath 'settings/images/tips.png'];
            app.SpecialplottingtipsImage.ImageSource = [app.defaultPath 'settings/images/tips.png'];
            app.PostparameterstipsImage.ImageSource = [app.defaultPath 'settings/images/tips.png'];
            app.PostplottingtipsImage.ImageSource = [app.defaultPath 'settings/images/tips.png'];
            app.PostplottingoptionstipsImage.ImageSource = [app.defaultPath 'settings/images/tips.png'];
            app.AnimationtipsImage.ImageSource = [app.defaultPath 'settings/images/tips.png'];


            % make the GUI visible after centering
            app.EpiMechUIFigure.Visible = 'On';
        end

        % Value changed function: UseimportedmovementdataCheckBox
        function UseimportedmovementdataCheckBoxValueChanged(app, event)
            if app.UseimportedmovementdataCheckBox.Value
                app.LoadmovementButton.Enable = 'Off';
                app.SelectcentercellCheckBox.Enable = 'Off';
                app.SelectcellButton.Enable = 'Off';
                app.SelectedcellEditField.Enable = 'Off';
                pointlikeData = import_settings([app.import.folderName '/pointlike/pointlike_data.csv']);
                app.SelectedcellEditField.Value = pointlikeData.cell;
            else
                app.LoadmovementButton.Enable = 'On';
                app.SelectcentercellCheckBox.Enable = 'On';
                app.SelectcellButton.Enable = 'On';
                app.SelectedcellEditField.Enable = 'On';
                app.SelectedcellEditField.Value = app.pointlikeProperties.cell;
            end

        end

        % Button pushed function: EditstiffnessButton
        function EditstiffnessButtonPushed(app, event)

            disable_enable_all_function(app,'Off');

            switch app.StiffnessstyleButtonGroup.SelectedObject.Text
                case "Heterogeneous"
                    app.DialogApp = substrateHeterogeneityDialog(app);
                case "Gradient"
                    app.DialogApp = substrateGradientDialog(app);
            end

        end

        % Selection changed function: StiffnessstyleButtonGroup
        function StiffnessstyleButtonGroupSelectionChanged(app, event)
            switch app.StiffnessstyleButtonGroup.SelectedObject.Text
                case "Constant"
                    app.EditstiffnessButton.Enable = 'Off';
                    app.YoungsmodulusEditField.Enable = 'On';
                case "Heterogeneous"
                    app.EditstiffnessButton.Enable = 'On';
                    app.YoungsmodulusEditField.Enable = 'On';
                case "Gradient"
                    app.EditstiffnessButton.Enable = 'On';
                    app.YoungsmodulusEditField.Enable = 'Off';
            end

        end

        % Value changed function: UseimportedsubstratedataCheckBox
        function UseimportedsubstratedataCheckBoxValueChanged(app, event)

            if ~app.UseimportedsubstratedataCheckBox.Value
                set_object_properties_function(app,{'FittedButton','SquareButton', 'ManualsizeCheckBox','SubstratesizeEditField', 'SubstratesizeEditFieldLabel', 'mLabel','ComparesizeButton', 'SubstrateparametersButton'},'Enable',{'On'});
                set_object_properties_function(app,{'EditstiffnessButton'},'Enable',{'Off'});
                set_object_properties_function(app,{'ConstantButton','HeterogeneousButton','GradientButton','YoungsmodulusEditField', 'YoungsmodulusEditFieldLabel','kPaLabel'},'Enable',{'On'});
                app.FittedButton.Value = 1;
                app.UseimportedsubstratedataCheckBox_2.Value = 0;
            else
                set_object_properties_function(app,{'FittedButton','SquareButton', 'ManualsizeCheckBox','SubstratesizeEditField', 'SubstratesizeEditFieldLabel', 'mLabel','ComparesizeButton', 'SubstrateparametersButton'},'Enable',{'Off'});
                app.FittedButton.Value = 1;
                app.ConstantButton.Value = 1;
                set_object_properties_function(app,{'EditstiffnessButton'},'Enable',{'Off'});
                set_object_properties_function(app,{'ConstantButton','HeterogeneousButton','GradientButton','YoungsmodulusEditField', 'YoungsmodulusEditFieldLabel','kPaLabel'},'Enable',{'Off'});
                app.UseimportedsubstratedataCheckBox_2.Value = 1;
            end

        end

        % Button pushed function: EditfocaladhesionsButton
        function EditfocaladhesionsButtonPushed(app, event)
            show_table(app,'fFAInfo');
        end

        % Button pushed function: LoadfocaladhesionsButton
        function LoadfocaladhesionsButtonPushed(app, event)
            [fileName, filePath] = uigetfile('*.csv', 'Select a focal adhesion strength file');
            figure(app.EpiMechUIFigure);
            if fileName == 0
                return;
            end

            data = csvread([filePath fileName]);

            if size(data,2) ~= 2
                errordlg(app.EpiMechUIFigure,'The input file must have two columns','Bad input');
                return;
            elseif size(data,1) == 0
                errordlg(app.EpiMechUIFigure,'The input file must have rows','Bad input');
                return;
            elseif any(data < 0)
                errordlg(app.EpiMechUIFigure,'All values in the file must be nonnegative','Bad input');
                return;
            end

            app.fFAInfo = data;
        end

        % Button pushed function: ResetfocaladhesionsButton
        function ResetfocaladhesionsButtonPushed(app, event)
            app.fFAInfo = csvread([app.defaultPath 'parameters/focal_adhesion_parameters.csv']);
        end

        % Button pushed function: DistrubtionButton
        function DistrubtionButtonPushed(app, event)

            d.pl.substrateStyle = 1;
            d.pl.substrateForcesCentral = 0;
            d.pl.substrateForcesRepulsion = 0;
            d.pl.substrateForcesRestoration = 0;
            d.pl.substrateForcesFocalAdhesions = 0;
            d.pl.substrateForcesTotal = 0;

            d.simset.simulationType = 2;

            currentSelection = app.selectedFile;

            [xq,yq] = meshgrid(-200:1:0,-100:1:100);

            middlePoint = round(size(xq,1)/2);

            yPositions =  yq(:,1);
            xPositions = xq(middlePoint,middlePoint:end)';

            displacementVertical = zeros(length(yPositions),length(app.plotImport));
            areaVertical = zeros(length(yPositions),length(app.plotImport));
            perimeterVertical = zeros(length(yPositions),length(app.plotImport));
            displacementHorizontal = zeros(length(xPositions),length(app.plotImport));
            areaHorizontal = zeros(length(xPositions),length(app.plotImport));
            perimeterHorizontal = zeros(length(xPositions),length(app.plotImport));

            notPointlikeData = zeros(length(app.plotImport),1);

            substrateDisplacementVertical = zeros(length(yPositions),length(app.plotImport));
            substrateDisplacementHorizontal = zeros(length(xPositions),length(app.plotImport));

            pointlikeFileExists = 0;

            for i = 1:length(app.plotImport)

                app.selectedFile = i;

                if strcmp(app.plotImport(app.selectedFile).simulationType,'pointlike')

                    d.spar.scalingLength = app.plotImport(app.selectedFile).scaledParameters.scalingLength;
                    
                    pointlikeFileExists = 1;

                    oldTimePoint = app.plotImport(app.selectedFile).currentTimePoint;

                    app.plotImport(app.selectedFile).currentTimePoint = app.plotImport(app.selectedFile).nTimePoints;


                    initialCells = import_cells(app,d,'post_plotting',1);
                    cells = import_cells(app,d,'post_plotting');

                    initialCellCenterX = zeros(1,length(cells));
                    initialCellCenterY = zeros(1,length(cells));
                    finalCellCenterX = zeros(1,length(cells));
                    finalCellCenterY = zeros(1,length(cells));
                    initialAreas = zeros(1,length(cells));
                    finalAreas = zeros(1,length(cells));
                    initialPerimeters = zeros(1,length(cells));
                    finalPerimeters = zeros(1,length(cells));

                    temp = import_settings([app.plotImport(app.selectedFile).folderName '/pointlike/pointlike_data.csv']);

                    pointLikeCell = temp.cell;

                    initialCells = get_boundary_vectors(initialCells);
                    initialCells = get_boundary_lengths(initialCells);
                    cells = get_boundary_vectors(cells);
                    cells = get_boundary_lengths(cells);

                    for k = 1:length(cells)
                        initialCellCenterX(k) = mean(initialCells(k).verticesX).*app.plotImport(app.selectedFile).scaledParameters.scalingLength.*1e6;
                        initialCellCenterY(k) = mean(initialCells(k).verticesY).*app.plotImport(app.selectedFile).scaledParameters.scalingLength.*1e6;
                        finalCellCenterX(k) = mean(cells(k).verticesX).*app.plotImport(app.selectedFile).scaledParameters.scalingLength.*1e6;
                        finalCellCenterY(k) = mean(cells(k).verticesY).*app.plotImport(app.selectedFile).scaledParameters.scalingLength.*1e6;

                        initialAreas(k) = calculate_area(initialCells(k).verticesX,initialCells(k).verticesY);
                        finalAreas(k) = calculate_area(cells(k).verticesX,cells(k).verticesY);
                        initialPerimeters(k) = sum(initialCells(k).leftLengths);
                        finalPerimeters(k) = sum(cells(k).leftLengths);
                    end

                    displacements = sqrt((initialCellCenterX - finalCellCenterX).^2 + (initialCellCenterY - finalCellCenterY).^2);

                    moveX = -initialCellCenterX(pointLikeCell);
                    moveY = -initialCellCenterY(pointLikeCell);

                    %             for k = 1:length(cells)
                    initialCellCenterX = initialCellCenterX + moveX;
                    initialCellCenterY = initialCellCenterY + moveY;
                    %             end

                    dispData = griddata(initialCellCenterX,initialCellCenterY,displacements,xq,yq,'cubic');

                    changesInArea = finalAreas./initialAreas;
                    areaData = griddata(initialCellCenterX,initialCellCenterY,changesInArea,xq,yq,'cubic');

                    changesInPerimeter = finalPerimeters./initialPerimeters;
                    perimeterData = griddata(initialCellCenterX,initialCellCenterY,changesInPerimeter,xq,yq,'cubic');

                    displacementVertical(:,i) = dispData(:,end);
                    areaVertical(:,i) = areaData(:,end);
                    perimeterVertical(:,i) = perimeterData(:,end);
                    displacementHorizontal(:,i) = dispData(middlePoint,middlePoint:end);
                    areaHorizontal(:,i) = areaData(middlePoint,middlePoint:end);
                    perimeterHorizontal(:,i) = perimeterData(middlePoint,middlePoint:end);

                    app.plotImport(app.selectedFile).currentTimePoint = oldTimePoint;

                    d = import_substrate(app,d);

                    temp = import_settings([app.plotImport(app.selectedFile).folderName '/pointlike/pointlike_data.csv']);

                    pointLikeCell = temp.cell;

                    initialCells = import_cells(app,d,'post_plotting',1);

                    moveX = -mean(initialCells(pointLikeCell).verticesX).*app.plotImport(app.selectedFile).scaledParameters.scalingLength.*1e6;
                    moveY = -mean(initialCells(pointLikeCell).verticesY).*app.plotImport(app.selectedFile).scaledParameters.scalingLength.*1e6;

                    displacements = sqrt((d.sub.pointsX - d.sub.pointsOriginalX).^2 + (d.sub.pointsY - d.sub.pointsOriginalY).^2)*d.spar.scalingLength/1e-6;

                    subX = d.sub.pointsOriginalX*d.spar.scalingLength/1e-6 + moveX;
                    subY = d.sub.pointsOriginalY*d.spar.scalingLength/1e-6 + moveY;

                    dispData = griddata(subX,subY,displacements,xq,yq,'cubic');

                    substrateDisplacementVertical(:,i) = dispData(:,end);
                    substrateDisplacementHorizontal(:,i) = dispData(middlePoint,middlePoint:end);
                else
                    notPointlikeData(i) = 1;
                end
            end

            if pointlikeFileExists

                % load validation data
                switch app.SubstratestiffnessDropDown.Value
                    case '1.1'
                        dispValidation = csvread([app.defaultPath 'settings/validation_data/displacement_validation_data_vertical_1.1kPa.csv']);
                        dispValidationHorz = csvread([app.defaultPath 'settings/validation_data/displacement_validation_data_horizontal_1.1kPa.csv']);
                        substrateDispValidation = csvread([app.defaultPath 'settings/validation_data/1.1_kPa_bead_displacement_vertical.csv']);
                        substrateDispValidationHorz = csvread([app.defaultPath 'settings/validation_data/1.1_kPa_bead_displacement_horizontal.csv']);
                    case '4.5'
                        dispValidation = csvread([app.defaultPath 'settings/validation_data/displacement_validation_data_vertical_4.5kPa.csv']);
                        dispValidationHorz = csvread([app.defaultPath 'settings/validation_data/displacement_validation_data_horizontal_4.5kPa.csv']);
                        substrateDispValidation = csvread([app.defaultPath 'settings/validation_data/4.5_kPa_bead_displacement_vertical.csv']);
                        substrateDispValidationHorz = csvread([app.defaultPath 'settings/validation_data/4.5_kPa_bead_displacement_horizontal.csv']);
                    case '11'
                        dispValidation = csvread([app.defaultPath 'settings/validation_data/displacement_validation_data_vertical_11kPa.csv']);
                        dispValidationHorz = csvread([app.defaultPath 'settings/validation_data/displacement_validation_data_horizontal_11kPa.csv']);
                        substrateDispValidation = csvread([app.defaultPath 'settings/validation_data/11_kPa_bead_displacement_vertical.csv']);
                        substrateDispValidationHorz = csvread([app.defaultPath 'settings/validation_data/11_kPa_bead_displacement_horizontal.csv']);
                    case '35'
                        dispValidation = csvread([app.defaultPath 'settings/validation_data/displacement_validation_data_vertical_35kPa.csv']);
                        dispValidationHorz = csvread([app.defaultPath 'settings/validation_data/displacement_validation_data_horizontal_35kPa.csv']);
                        substrateDispValidation = csvread([app.defaultPath 'settings/validation_data/35_kPa_bead_displacement_vertical.csv']);
                        substrateDispValidationHorz = csvread([app.defaultPath 'settings/validation_data/35_kPa_bead_displacement_horizontal.csv']);
                end


                meanDispVert = mean(displacementVertical(:,~notPointlikeData),2);
                meanDispHorz = mean(displacementHorizontal(:,~notPointlikeData),2);
                stdDispVert = std(displacementVertical(:,~notPointlikeData),0,2);
                stdDispHorz = std(displacementHorizontal(:,~notPointlikeData),0,2);
                meanSubstrateDispVert = mean(substrateDisplacementVertical(:,~notPointlikeData),2);
                meanSubstrateDispHorz = mean(substrateDisplacementHorizontal(:,~notPointlikeData),2);
                stdSubstrateDispVert = std(substrateDisplacementVertical(:,~notPointlikeData),0,2);
                stdSubstrateDispHorz = std(substrateDisplacementHorizontal(:,~notPointlikeData),0,2);


                figure(3);

                subplot(2,2,1);
                errorbar(dispValidation(1:5:end,1),dispValidation(1:5:end,2),dispValidation(1:5:end,3));
                hold on
                errorbar(yPositions(1:5:end),meanDispVert(1:5:end),stdDispVert(1:5:end));
                hold off
                axis square
                title('Diplacement')
                axis([-80 80 0 20])
                xlabel('Position relative to center ({\mu}m)')
                ylabel('Cell displacement in {\mu}m')

                subplot(2,2,3);
                errorbar(substrateDispValidation(1:5:end,1),substrateDispValidation(1:5:end,2),substrateDispValidation(1:5:end,3));
                hold on
                errorbar(yPositions(1:5:end),meanSubstrateDispVert(1:5:end),stdSubstrateDispVert(1:5:end));
                hold off
                axis square
                title('Diplacement')
                axis([-80 80 0 20])
                xlabel('Position relative to center ({\mu}m)')
                ylabel('Substrate displacement in {\mu}m')


                subplot(2,2,2);
                errorbar(dispValidationHorz(1:5:end,1),dispValidationHorz(1:5:end,2),dispValidationHorz(1:5:end,3));
                hold on
                errorbar(xPositions(1:5:end),meanDispHorz(1:5:end),stdDispHorz(1:5:end));
                hold off
                axis square
                title('Diplacement')
                axis([-100 0 0 20])
                xlabel('Position relative to center ({\mu}m)')
                ylabel('Cell displacement in {\mu}m')

                subplot(2,2,4);
                errorbar(substrateDispValidationHorz(1:5:end,1),substrateDispValidationHorz(1:5:end,2),substrateDispValidationHorz(1:5:end,3));
                hold on
                errorbar(xPositions(1:5:end),meanSubstrateDispHorz(1:5:end),stdSubstrateDispHorz(1:5:end));
                hold off
                axis square
                title('Diplacement')
                axis([-100 0 0 20])
                xlabel('Position relative to center ({\mu}m)')
                ylabel('Substrate displacement in {\mu}m')


                res = get(0,'screensize');

                position = get(gcf, 'Position');

                set(gcf, 'Position',  [position(1), position(2), 895, 895])

                position = get(gcf, 'Position');

                if sum(position([2 4])) + 100 > res(4)
                    position(2) = res(4) - position(4) - 100;
                end

                set(gcf, 'Position',  [position(1), position(2), 895, 895])

                app.selectedFile = currentSelection;
                %
                %             set(gcf, 'Position',  [round(res(3)/2 - 1439/2) , round(res(4)/2 - 895/2), 1439, 895])
            end
        end

        % Button pushed function: AllButton
        function AllButtonPushed(app, event)

            d.pl.substrateStyle = 1;
            d.pl.substrateForcesCentral = 0;
            d.pl.substrateForcesRepulsion = 0;
            d.pl.substrateForcesRestoration = 0;
            d.pl.substrateForcesFocalAdhesions = 0;
            d.pl.substrateForcesTotal = 0;

            d.simset.simulationType = 2;

            currentSelection = app.selectedFile;

            [xq,yq] = meshgrid(-200:1:0,-100:1:100);

            middlePoint = round(size(xq,1)/2);

            yPositions =  yq(:,1);
            xPositions = xq(middlePoint,middlePoint:end)';

            displacementVertical = zeros(length(yPositions),length(app.plotImport));
            areaVertical = zeros(length(yPositions),length(app.plotImport));
            perimeterVertical = zeros(length(yPositions),length(app.plotImport));
            displacementHorizontal = zeros(length(xPositions),length(app.plotImport));
            areaHorizontal = zeros(length(xPositions),length(app.plotImport));
            perimeterHorizontal = zeros(length(xPositions),length(app.plotImport));

            substrateDisplacementVertical = zeros(length(yPositions),length(app.plotImport));
            substrateDisplacementHorizontal = zeros(length(xPositions),length(app.plotImport));

            pointlikeFileExists = 0;

            for i = 1:length(app.plotImport)

                app.selectedFile = i;

                if strcmp(app.plotImport(app.selectedFile).simulationType,'pointlike')

                    pointlikeFileExists = 1;

                    d.spar.scalingLength = app.plotImport(app.selectedFile).scaledParameters.scalingLength;
                    
                    oldTimePoint = app.plotImport(app.selectedFile).currentTimePoint;

                    app.plotImport(app.selectedFile).currentTimePoint = app.plotImport(app.selectedFile).nTimePoints;


                    initialCells = import_cells(app,d,'post_plotting',1);
                    cells = import_cells(app,d,'post_plotting');

                    initialCellCenterX = zeros(1,length(cells));
                    initialCellCenterY = zeros(1,length(cells));
                    finalCellCenterX = zeros(1,length(cells));
                    finalCellCenterY = zeros(1,length(cells));
                    initialAreas = zeros(1,length(cells));
                    finalAreas = zeros(1,length(cells));
                    initialPerimeters = zeros(1,length(cells));
                    finalPerimeters = zeros(1,length(cells));

                    temp = import_settings([app.plotImport(app.selectedFile).folderName '/pointlike/pointlike_data.csv']);

                    pointLikeCell = temp.cell;

                    initialCells = get_boundary_vectors(initialCells);
                    initialCells = get_boundary_lengths(initialCells);
                    cells = get_boundary_vectors(cells);
                    cells = get_boundary_lengths(cells);

                    for k = 1:length(cells)
                        initialCellCenterX(k) = mean(initialCells(k).verticesX).*app.plotImport(app.selectedFile).scaledParameters.scalingLength.*1e6;
                        initialCellCenterY(k) = mean(initialCells(k).verticesY).*app.plotImport(app.selectedFile).scaledParameters.scalingLength.*1e6;
                        finalCellCenterX(k) = mean(cells(k).verticesX).*app.plotImport(app.selectedFile).scaledParameters.scalingLength.*1e6;
                        finalCellCenterY(k) = mean(cells(k).verticesY).*app.plotImport(app.selectedFile).scaledParameters.scalingLength.*1e6;

                        initialAreas(k) = calculate_area(initialCells(k).verticesX,initialCells(k).verticesY);
                        finalAreas(k) = calculate_area(cells(k).verticesX,cells(k).verticesY);
                        initialPerimeters(k) = sum(initialCells(k).leftLengths);
                        finalPerimeters(k) = sum(cells(k).leftLengths);
                    end

                    displacements = sqrt((initialCellCenterX - finalCellCenterX).^2 + (initialCellCenterY - finalCellCenterY).^2);

                    moveX = -initialCellCenterX(pointLikeCell);
                    moveY = -initialCellCenterY(pointLikeCell);

                    %             for k = 1:length(cells)
                    initialCellCenterX = initialCellCenterX + moveX;
                    initialCellCenterY = initialCellCenterY + moveY;
                    %             end

                    dispData = griddata(initialCellCenterX,initialCellCenterY,displacements,xq,yq,'cubic');

                    changesInArea = finalAreas./initialAreas;
                    areaData = griddata(initialCellCenterX,initialCellCenterY,changesInArea,xq,yq,'cubic');

                    changesInPerimeter = finalPerimeters./initialPerimeters;
                    perimeterData = griddata(initialCellCenterX,initialCellCenterY,changesInPerimeter,xq,yq,'cubic');

                    displacementVertical(:,i) = dispData(:,end);
                    areaVertical(:,i) = areaData(:,end);
                    perimeterVertical(:,i) = perimeterData(:,end);
                    displacementHorizontal(:,i) = dispData(middlePoint,middlePoint:end);
                    areaHorizontal(:,i) = areaData(middlePoint,middlePoint:end);
                    perimeterHorizontal(:,i) = perimeterData(middlePoint,middlePoint:end);

                    d = import_substrate(app,d);

                    temp = import_settings([app.plotImport(app.selectedFile).folderName '/pointlike/pointlike_data.csv']);

                    pointLikeCell = temp.cell;

                    initialCells = import_cells(app,d,'post_plotting',1);

                    moveX = -mean(initialCells(pointLikeCell).verticesX).*app.plotImport(app.selectedFile).scaledParameters.scalingLength.*1e6;
                    moveY = -mean(initialCells(pointLikeCell).verticesY).*app.plotImport(app.selectedFile).scaledParameters.scalingLength.*1e6;

                    displacements = sqrt((d.sub.pointsX - d.sub.pointsOriginalX).^2 + (d.sub.pointsY - d.sub.pointsOriginalY).^2)*d.spar.scalingLength/1e-6;

                    subX = d.sub.pointsOriginalX*d.spar.scalingLength/1e-6 + moveX;
                    subY = d.sub.pointsOriginalY*d.spar.scalingLength/1e-6 + moveY;

                    dispData = griddata(subX,subY,displacements,xq,yq,'cubic');

                    substrateDisplacementVertical(:,i) = dispData(:,end);
                    substrateDisplacementHorizontal(:,i) = dispData(middlePoint,middlePoint:end);

                    app.plotImport(app.selectedFile).currentTimePoint = oldTimePoint;

                end
            end

            if pointlikeFileExists

                % load validation data
                switch app.SubstratestiffnessDropDown.Value
                    case '1.1'
                        dispValidation = csvread([app.defaultPath 'settings/validation_data/displacement_validation_data_vertical_1.1kPa.csv']);
                        dispValidationHorz = csvread([app.defaultPath 'settings/validation_data/displacement_validation_data_horizontal_1.1kPa.csv']);
                        substrateDispValidation = csvread([app.defaultPath 'settings/validation_data/1.1_kPa_bead_displacement_vertical.csv']);
                        substrateDispValidationHorz = csvread([app.defaultPath 'settings/validation_data/1.1_kPa_bead_displacement_horizontal.csv']);
                    case '4.5'
                        dispValidation = csvread([app.defaultPath 'settings/validation_data/displacement_validation_data_vertical_4.5kPa.csv']);
                        dispValidationHorz = csvread([app.defaultPath 'settings/validation_data/displacement_validation_data_horizontal_4.5kPa.csv']);
                        substrateDispValidation = csvread([app.defaultPath 'settings/validation_data/4.5_kPa_bead_displacement_vertical.csv']);
                        substrateDispValidationHorz = csvread([app.defaultPath 'settings/validation_data/4.5_kPa_bead_displacement_horizontal.csv']);
                    case '11'
                        dispValidation = csvread([app.defaultPath 'settings/validation_data/displacement_validation_data_vertical_11kPa.csv']);
                        dispValidationHorz = csvread([app.defaultPath 'settings/validation_data/displacement_validation_data_horizontal_11kPa.csv']);
                        substrateDispValidation = csvread([app.defaultPath 'settings/validation_data/11_kPa_bead_displacement_vertical.csv']);
                        substrateDispValidationHorz = csvread([app.defaultPath 'settings/validation_data/11_kPa_bead_displacement_horizontal.csv']);
                    case '35'
                        dispValidation = csvread([app.defaultPath 'settings/validation_data/displacement_validation_data_vertical_35kPa.csv']);
                        dispValidationHorz = csvread([app.defaultPath 'settings/validation_data/displacement_validation_data_horizontal_35kPa.csv']);
                        substrateDispValidation = csvread([app.defaultPath 'settings/validation_data/35_kPa_bead_displacement_vertical.csv']);
                        substrateDispValidationHorz = csvread([app.defaultPath 'settings/validation_data/35_kPa_bead_displacement_horizontal.csv']);
                end


                figure(3);

                subplot(2,2,1);
                errorbar(dispValidation(1:5:end,1),dispValidation(1:5:end,2),dispValidation(1:5:end,3),'Color','r','LineWidth',1.5);
                hold on
                plot(yPositions(1:5:end),displacementVertical(1:5:end,:),'-b');
                hold off
                axis square
                title('Diplacement')
                axis([-80 80 0 20])
                xlabel('Position relative to center ({\mu}m)')
                ylabel('Cell ddisplacement in {\mu}m')

                subplot(2,2,3);
                errorbar(-substrateDispValidation(1:5:end,1),substrateDispValidation(1:5:end,2),substrateDispValidation(1:5:end,3),'Color','r','LineWidth',1.5);
                hold on
                plot(yPositions(1:5:end),substrateDisplacementVertical(1:5:end,:),'-b');
                hold off
                axis square
                title('Diplacement')
                axis([-80 80 0 20])
                xlabel('Position relative to center ({\mu}m)')
                ylabel('Substrate displacement in {\mu}m')

                subplot(2,2,2);
                errorbar(dispValidationHorz(1:5:end,1),dispValidationHorz(1:5:end,2),dispValidationHorz(1:5:end,3),'Color','r','LineWidth',1.5);
                hold on
                plot(xPositions(1:5:end),displacementHorizontal(1:5:end,:),'-b');
                hold off
                axis square
                title('Diplacement')
                axis([-100 0 0 20])
                xlabel('Position relative to center ({\mu}m)')
                ylabel('Cell displacement in {\mu}m')

                subplot(2,2,4);
                errorbar(substrateDispValidationHorz(1:5:end,1),substrateDispValidationHorz(1:5:end,2),substrateDispValidationHorz(1:5:end,3),'Color','r','LineWidth',1.5);
                hold on
                plot(xPositions(1:5:end),substrateDisplacementHorizontal(1:5:end,:),'-b');
                hold off
                axis square
                title('Diplacement')
                axis([-100 0 0 20])
                xlabel('Position relative to center ({\mu}m)')
                ylabel('Substrate displacement in {\mu}m')

                res = get(0,'screensize');

                position = get(gcf, 'Position');

                set(gcf, 'Position',  [position(1), position(2), 895, 895])

                position = get(gcf, 'Position');

                if sum(position([2 4])) + 100 > res(4)
                    position(2) = res(4) - position(4) - 100;
                end

                set(gcf, 'Position',  [position(1), position(2), 895, 895])

                app.selectedFile = currentSelection;
                %
                %             set(gcf, 'Position',  [round(res(3)/2 - 1439/2) , round(res(4)/2 - 895/2), 1439, 895])
            end
        end

        % Button pushed function: ResetActivationAreaButton
        function ResetActivationAreaButtonPushed(app, event)
            app.optoVertices.cells = [];
            app.optoVertices.vertices = {};
            app.optoShapes = {};
            app.SaveactivationareaButton.Enable = 'Off';
        end

        % Button pushed function: ActivationareaButton
        function ActivationareaButtonPushed(app, event)
            disable_enable_all_function(app,'Off');

            d.simset.simulationType = 0;
            d = scale_parameters(app,d);


            switch app.modelCase
                case 'new'
                    d.cells = initialize_cells_struct;
                    switch app.InitialstateButtongroup.SelectedObject.Text
                        case 'Single cell'
                            d = create_cell(d,[0;0]);
                        case 'Place multiple cells'
                            if ~isempty(app.cellCenters)
                                for i = 1:size(app.cellCenters,1)
                                    d = create_cell(d,app.cellCenters(i,:));
                                end
                            end
                    end
                case 'import'
                    d.spar.scalingTime = app.import.scaledParameters.scalingTime;
                    if app.CentercellsCheckBox.Value
                        d.cells = center_cells(d.cells);
                    end
                    d.cells = import_cells(app,d,'basic_plotting');
                    d = remove_cells_gui(app, d,'basic_plotting');
            end

            d = setup_plotting_options(app,d,'opto_area');

            plot_function(d, 1);

            hold on
            coords = [];

            areaHandle = {};

            nShapes = length(app.optoShapes);
            shapes = app.optoShapes;
            for i = 1:nShapes
                areaHandle{i} = plot(shapes{i}([1:end 1],1),shapes{i}([1:end 1],2),'-or');
            end
            while 1
                try

                    coordst = ginput(1);
                    if numel(coordst) == 0
                        delete(areaHandle{nShapes+1});
                        break
                    end
                    coords(end+1,:) = coordst;
                    if length(areaHandle) > nShapes
                        delete(areaHandle{nShapes+1});
                    end
                    distance = sqrt((coords(1,1) - coords(end,1))^2 + (coords(1,2) - coords(end,2))^2);
                    if size(coords,1) > 3 && distance < 2e-6/app.systemParameters.scalingLength
                        coords(end,:) = [];
                        for k = 1:length(d.cells)
                            isInside = check_if_inside(coords(:,1),coords(:,2),d.cells(k).verticesX,d.cells(k).verticesY);
                            if any(isInside ~= 0)
                                isInside = find(isInside ~= 0);
                                if any(app.optoVertices.cells == k)
                                    idx = find(app.optoVertices.cells == k);
                                    app.optoVertices.vertices{idx} = get_uniques([app.optoVertices.vertices{idx}; isInside],1:d.cells(k).nVertices,zeros(d.cells(k).nVertices,1));
                                else
                                    app.optoVertices.cells(end+1) = k;
                                    app.optoVertices.vertices{end+1} = isInside;
                                end
                            end
                        end
                        nShapes = nShapes + 1;
                        shapes{nShapes} = coords;
                        d.pl.highlightedCells = app.optoVertices;
                        plot_function(d, 1);
                        hold on
                        for i = 1:nShapes
                            areaHandle{i} = plot(shapes{i}([1:end 1],1),shapes{i}([1:end 1],2),'-or');
                        end
                        coords = [];
                    else
                        areaHandle{nShapes+1} = plot(coords(:,1),coords(:,2),'-or');
                    end

                catch
                    break
                end
            end

            app.optoShapes = shapes;
            if ~isempty(app.optoShapes)
                app.SaveactivationareaButton.Enable = 'On';
            end
            disable_enable_all_function(app,'On');
        end

        % Button pushed function: ActivationprofileButton
        function ActivationprofileButtonPushed(app, event)
            disable_enable_all_function(app,'Off');

            app.DialogApp = optogeneticActivationDialog(app);
        end

        % Button pushed function: SelectcellsButton_2
        function SelectcellsButton_2Pushed(app, event)
            disable_enable_all_function(app,'Off');

            d.spar.scalingTime = app.plotImport(app.selectedFile).scaledParameters.scalingTime;
            d.spar.scalingLength = app.plotImport(app.selectedFile).scaledParameters.scalingLength;
            d.spar.rCell = app.plotImport(app.selectedFile).scaledParameters.rCell;
            d.spar.simulationTime = app.plotImport(app.selectedFile).scaledParameters.simulationTime;

            d = setup_plotting_options(app,d,'opto_post_cell');

            d.cells = import_cells(app,d,'get_neighbors');
            if length(d.cells) < 2
                uialert(app.EpiMechUIFigure,'The import must have more than one cell.','Bad Input');
                disable_enable_all_function(app,'On');
                return
            end

            if app.CentercellsCheckBox.Value
                d.cells = center_cells(d.cells);
            end

            plot_function(d, 1);

            while 1

                selectedCell = [];

                try
                    coords = ginput(1);
                    for k = 1:length(d.cells)
                        if check_if_inside(d.cells(k).verticesX,d.cells(k).verticesY,coords(:,1),coords(:,2))
                            selectedCell = k;
                            break
                        end
                    end
                catch
                    break
                end

                if ~isempty(selectedCell)
                    hold on

                    if numel(app.plotImport(app.selectedFile).optoSelectedCells) == 0
                        app.plotImport(app.selectedFile).optoSelectedCells(end+1) = selectedCell;
                        d.pl.highlightedCells(end+1) = selectedCell;
                        highlight_cell(d,selectedCell);
                    elseif any(app.plotImport(app.selectedFile).optoSelectedCells == selectedCell)
                        app.plotImport(app.selectedFile).optoSelectedCells(app.plotImport(app.selectedFile).optoSelectedCells == selectedCell) = [];
                        d.pl.highlightedCells(d.pl.highlightedCells == selectedCell) = [];
                        highlight_cell(d,selectedCell,1);
                    elseif length(app.plotImport(app.selectedFile).optoSelectedCells) == 1
                        if any(d.cells(app.plotImport(app.selectedFile).optoSelectedCells(1)).junctions.cells(:) == selectedCell)
                            app.plotImport(app.selectedFile).optoSelectedCells(end+1) = selectedCell;
                            d.pl.highlightedCells(end+1) = selectedCell;
                            highlight_cell(d,selectedCell);
                        else
                            uialert(app.EpiMechUIFigure,'The two selected cells are not neighbors.','Bad Input');
                        end
                    elseif length(app.plotImport(app.selectedFile).optoSelectedCells) == 2
                        uialert(app.EpiMechUIFigure,'Deselect one cell before selecting a new neighbor.','Bad Input');
                    end

                    plot_cell_numbers(d,k);
                    hold off
                end
            end

            if length(app.plotImport(app.selectedFile).optoSelectedCells) == 2
                app.AnalyzejunctionlengthButton.Enable = 'On';
            else
                app.AnalyzejunctionlengthButton.Enable = 'Off';
            end

            disable_enable_all_function(app,'On');
        end

        % Button pushed function: ResetactivationselectionButton
        function ResetactivationselectionButtonPushed(app, event)
            app.plotImport(app.selectedFile).optoSelectedCells = [];
            app.AnalyzejunctionlengthButton.Enable = 'Off';
        end

        % Button pushed function: AnalyzejunctionlengthButton
        function AnalyzejunctionlengthButtonPushed(app, event)
            distances = [];

            cell1 = app.plotImport(app.selectedFile).optoSelectedCells(1);
            cell2 = app.plotImport(app.selectedFile).optoSelectedCells(2);

            for i = 1:app.plotImport(app.selectedFile).nTimePoints

                d = [];

                app.plotImport(app.selectedFile).currentTimePoint = i;
                cells = import_cells(app,d,'opto_analysis');

                vertexIDs = or(cells(cell1).junctions.cells(:,1) == cell2,cells(cell1).junctions.cells(:,2) == cell2);

                if sum(vertexIDs) > 1
                    diffs = diff(vertexIDs([1:end 1]));

                    up = find(diffs == 1);
                    down = find(diffs == -1);

                    if length(up) > 1 || length(down) > 1
                        diffs = [diffs;diffs];
                        up = find(diffs == 1);
                        down = find(diffs == -1);
                        if up(1) < down(1)
                            distances2 = up([2:end 1])-down;
                            [~,maxInd] = max(distances2);
                            if down(maxInd) > cells(cell1).nVertices
                                vertex2 = down(maxInd) - cells(cell1).nVertices;
                            else
                                vertex2 = down(maxInd);
                            end
                            if up(maxInd+1) > cells(cell1).nVertices
                                vertex1 = up(maxInd+1) + 1 - cells(cell1).nVertices;
                            else
                                vertex1 = up(maxInd+1) + 1;
                            end
                        else
                            distances2 = up-down;
                            [~,maxInd] = max(distances2);
                            if down(maxInd) > cells(cell1).nVertices
                                vertex2 = down(maxInd) - cells(cell1).nVertices;
                            else
                                vertex2 = down(maxInd);
                            end
                            if up(maxInd) >= cells(cell1).nVertices
                                vertex1 = up(maxInd) + 1 - cells(cell1).nVertices;
                            else
                                vertex1 = up(maxInd) + 1;
                            end
                        end

                    else
                        if up == cells(cell1).nVertices
                            vertex1 = 1;
                        else
                            vertex1 = up + 1;
                        end
                        vertex2 = down;
                    end

                    whichJunction1 = cells(cell1).junctions.cells(vertex1,:) == cell2;
                    whichJunction2 = cells(cell1).junctions.cells(vertex2,:) == cell2;

                    vertex3 = cells(cell1).junctions.vertices(vertex1,whichJunction1);
                    vertex4 = cells(cell1).junctions.vertices(vertex2,whichJunction2);

                    middlePoint13X = 0.5*(cells(cell1).verticesX(vertex1) + cells(cell2).verticesX(vertex3));
                    middlePoint13Y = 0.5*(cells(cell1).verticesY(vertex1) + cells(cell2).verticesY(vertex3));
                    middlePoint24X = 0.5*(cells(cell1).verticesX(vertex2) + cells(cell2).verticesX(vertex4));
                    middlePoint24Y = 0.5*(cells(cell1).verticesY(vertex2) + cells(cell2).verticesY(vertex4));

                    distances(end+1) = sqrt((middlePoint13X-middlePoint24X)^2 + (middlePoint13Y-middlePoint24Y)^2)*app.plotImport(app.selectedFile).systemParameters.scalingLength*1e6;

                else
                    distances(end+1) = 0;
                end

            end
            times = (0:app.plotImport(app.selectedFile).nTimePoints-1)*app.plotImport(app.selectedFile).exportOptions.exportDt*app.plotImport(app.selectedFile).scaledParameters.scalingTime;
            if times(end) <= 60
                timeUnit =  's';
            elseif times(end) <= 2*60*60
                times = times/60;
                timeUnit =  'min';
            elseif times(end) <= 2*24*60*60
                times = times/60/60;
                timeUnit =  'h';
            else
                times = times/60/60/24;
                timeUnit =  'd';
            end

            junction_length_validata_data = csvread([app.folderName 'settings/validation_data/staddon_lengths.csv']);

            junction_length_validata_data(:,1) = junction_length_validata_data(:,1) + 2;

            %                 csvwrite(['./opto_results/' app.plotImport(app.selectedFile).folderName(end-19:end) '_relative_length.csv'],[times' distances']);


            figure(2);

            cla;
            hold on
            plot(junction_length_validata_data(:,1),junction_length_validata_data(:,2),'-k','LineWidth',2)
            plot(times,distances./distances(1),'-r');
            hold off

            ylabel('Border length (\mum)')
            xlabel(['Time (' timeUnit ')'])
            xlim([times(1) times(end)])
            ylim([0.3 1.05])


        end

        % Value changed function: YoungsmodulusEditField
        function YoungsmodulusEditFieldValueChanged2(app, event)
            app.substrateParameters.youngsModulus = app.YoungsmodulusEditField.Value*1000;

        end

        % Button pushed function: CopypreviouscellsButton
        function CopypreviouscellsButtonPushed(app, event)
            app.plotImport(app.selectedFile).optoSelectedCells = app.plotImport(app.selectedFile-1).optoSelectedCells;
            app.AnalyzejunctionlengthButton.Enable = 'On';
        end

        % Button pushed function: SaveactivationareaButton
        function SaveactivationareaButtonPushed(app, event)
            [fileName, filePath] = uiputfile({'*.csv'},'Save the current activation area',[app.defaultPath 'settings/misc/']);

            if fileName == 0
                figure(app.EpiMechUIFigure);
                return;
            end

            filePath = strrep(filePath,'/','\');
            figure(app.EpiMechUIFigure);

            shapeExport = [];

            for i = 1:length(app.optoShapes)
                if i ~= 1
                    shapeExport = [shapeExport; [0 0]];
                end
                shapeExport = [shapeExport ; app.optoShapes{i}];
            end
            if shapeExport(end,1) == 0
                shapeExport(end,:) = [];
            end
            csvwrite([filePath fileName],shapeExport);

        end

        % Button pushed function: LoadactivationareaButton
        function LoadactivationareaButtonPushed(app, event)
            [fileName, filePath] = uigetfile({'*.csv'},'Choose activation shape file to load',[app.defaultPath 'settings/misc/']);

            figure(app.EpiMechUIFigure);
            if fileName == 0
                return;
            end

            shapeData  = csvread([filePath fileName]);

            app.optoShapes = {};
            nShapes = sum(shapeData(:,1) == 0)+1;
            for i = 1:nShapes
                [~,idx] = find(shapeData(:,1) == 0);
                if isempty(idx)
                    app.optoShapes{i} = shapeData;
                    nextZero = 2;
                else
                    nextZero = min(idx);
                    app.optoShapes{i} = shapeData(1:nextZero-1,:);
                end
                shapeData(1:nextZero,:) = [];
            end

        end

        % Menu selected function: EnableallMenu
        function EnableallMenuSelected(app, event)
            disable_enable_all_function(app,'On');
        end

        % Button pushed function: EditstretchButton
        function EditstretchButtonPushed(app, event)
            disable_enable_all_function(app,'Off');

            app.DialogApp = stretchingDialog(app);
        end

        % Button pushed function: SingleButton
        function SingleButtonPushed(app, event)

            d.spar.scalingLength = app.plotImport(app.selectedFile).scaledParameters.scalingLength;

            d.simset.simulationType = 2;
            d.pl.substrateStyle = 1;
            d.pl.substrateForcesCentral = 0;
            d.pl.substrateForcesRepulsion = 0;
            d.pl.substrateForcesRestoration = 0;
            d.pl.substrateForcesFocalAdhesions = 0;
            d.pl.substrateForcesTotal = 0;

            d = import_substrate(app,d);

            [xq,yq] = meshgrid(-200:1:0,-100:1:100);

            initialCells = import_cells(app,d,'post_plotting',1);
            cells = import_cells(app,d,'post_plotting');

            initialCellCenterX = zeros(1,length(cells));
            initialCellCenterY = zeros(1,length(cells));
            finalCellCenterX = zeros(1,length(cells));
            finalCellCenterY = zeros(1,length(cells));

            temp = import_settings([app.plotImport(app.selectedFile).folderName '/pointlike/pointlike_data.csv']);

            pointLikeCell = temp.cell;

            initialCells = get_boundary_vectors(initialCells);
            initialCells = get_boundary_lengths(initialCells);
            cells = get_boundary_vectors(cells);
            cells = get_boundary_lengths(cells);

            for k = 1:length(cells)
                initialCellCenterX(k) = mean(initialCells(k).verticesX).*app.plotImport(app.selectedFile).scaledParameters.scalingLength.*1e6;
                initialCellCenterY(k) = mean(initialCells(k).verticesY).*app.plotImport(app.selectedFile).scaledParameters.scalingLength.*1e6;
                finalCellCenterX(k) = mean(cells(k).verticesX).*app.plotImport(app.selectedFile).scaledParameters.scalingLength.*1e6;
                finalCellCenterY(k) = mean(cells(k).verticesY).*app.plotImport(app.selectedFile).scaledParameters.scalingLength.*1e6;
            end

            displacements = sqrt((initialCellCenterX - finalCellCenterX).^2 + (initialCellCenterY - finalCellCenterY).^2);

            moveX = -initialCellCenterX(pointLikeCell);
            moveY = -initialCellCenterY(pointLikeCell);

            for k = 1:length(cells)
                initialCellCenterX(k) = initialCellCenterX(k) + moveX;
                initialCellCenterY(k) = initialCellCenterY(k) + moveY;
            end

            dispData = griddata(initialCellCenterX,initialCellCenterY,displacements,xq,yq,'cubic');

            displacements = sqrt((d.sub.pointsX - d.sub.pointsOriginalX).^2 + (d.sub.pointsY - d.sub.pointsOriginalY).^2)*d.spar.scalingLength/1e-6;

            subX = d.sub.pointsOriginalX*d.spar.scalingLength/1e-6 + moveX;
            subY = d.sub.pointsOriginalY*d.spar.scalingLength/1e-6 + moveY;

            SubstrateDispData = griddata(subX,subY,displacements,xq,yq,'cubic');


            % load validation data
            switch app.SubstratestiffnessDropDown.Value
                case '1.1'
                    dispValidation = csvread([app.defaultPath 'settings/validation_data/displacement_validation_data_vertical_1.1kPa.csv']);
                    dispValidationHorz = csvread([app.defaultPath 'settings/validation_data/displacement_validation_data_horizontal_1.1kPa.csv']);
                    substrateDispValidation = csvread([app.defaultPath 'settings/validation_data/1.1_kPa_bead_displacement_vertical.csv']);
                    substrateDispValidationHorz = csvread([app.defaultPath 'settings/validation_data/1.1_kPa_bead_displacement_horizontal.csv']);
                case '4.5'
                    dispValidation = csvread([app.defaultPath 'settings/validation_data/displacement_validation_data_vertical_4.5kPa.csv']);
                    dispValidationHorz = csvread([app.defaultPath 'settings/validation_data/displacement_validation_data_horizontal_4.5kPa.csv']);
                    substrateDispValidation = csvread([app.defaultPath 'settings/validation_data/4.5_kPa_bead_displacement_vertical.csv']);
                    substrateDispValidationHorz = csvread([app.defaultPath 'settings/validation_data/4.5_kPa_bead_displacement_horizontal.csv']);

                case '11'
                    dispValidation = csvread([app.defaultPath 'settings/validation_data/displacement_validation_data_vertical_11kPa.csv']);
                    dispValidationHorz = csvread([app.defaultPath 'settings/validation_data/displacement_validation_data_horizontal_11kPa.csv']);
                    substrateDispValidation = csvread([app.defaultPath 'settings/validation_data/11_kPa_bead_displacement_vertical.csv']);
                    substrateDispValidationHorz = csvread([app.defaultPath 'settings/validation_data/11_kPa_bead_displacement_horizontal.csv']);
                case '35'
                    dispValidation = csvread([app.defaultPath 'settings/validation_data/displacement_validation_data_vertical_35kPa.csv']);
                    dispValidationHorz = csvread([app.defaultPath 'settings/validation_data/displacement_validation_data_horizontal_35kPa.csv']);
                    substrateDispValidation = csvread([app.defaultPath 'settings/validation_data/35_kPa_bead_displacement_vertical.csv']);
                    substrateDispValidationHorz = csvread([app.defaultPath 'settings/validation_data/35_kPa_bead_displacement_horizontal.csv']);
            end
            figure(3);

            subplot(2,2,1);
            errorbar(dispValidation(1:5:end,1),dispValidation(1:5:end,2),dispValidation(1:5:end,3));
            hold on
            plot(yq(:,1),dispData(:,end),'LineWidth',2);
            hold off
            axis square
            title('Diplacement')
            axis([-80 80 0 20])
            xlabel('Position relative to center ({\mu}m)')
            ylabel('Cell displacement in {\mu}m')

            subplot(2,2,2);
            errorbar(-substrateDispValidation(1:5:end,1),substrateDispValidation(1:5:end,2),substrateDispValidation(1:5:end,3));
            hold on
            plot(yq(:,1),SubstrateDispData(:,end),'LineWidth',2);
            hold off
            axis square
            title('Diplacement')
            axis([-80 80 0 12])
            xlabel('Position relative to center ({\mu}m)')
            ylabel('Substrate displacement in {\mu}m')

            middlePoint = round(size(xq,1)/2);

            subplot(2,2,3);
            errorbar(dispValidationHorz(1:5:end,1),dispValidationHorz(1:5:end,2),dispValidationHorz(1:5:end,3));
            hold on
            plot(xq(middlePoint,middlePoint:end),dispData(middlePoint,middlePoint:end),'LineWidth',2);
            hold off
            axis square
            title('Diplacement')
            axis([-100 0 0 20])
            xlabel('Position relative to center ({\mu}m)')
            ylabel('Cell displacement in {\mu}m')

            subplot(2,2,4);
            errorbar(substrateDispValidationHorz(1:5:end,1),substrateDispValidationHorz(1:5:end,2),substrateDispValidationHorz(1:5:end,3));
            hold on
            plot(xq(middlePoint,middlePoint:end),SubstrateDispData(middlePoint,middlePoint:end),'LineWidth',2);
            hold off
            axis square
            title('Diplacement')
            axis([-100 0 0 12])
            xlabel('Position relative to center ({\mu}m)')
            ylabel('Substrate displacement in {\mu}m')

            res = get(0,'screensize');

            position = get(gcf, 'Position');

            set(gcf, 'Position',  [position(1), position(2), 895, 895])

            position = get(gcf, 'Position');

            if sum(position([2 4])) + 100 > res(4)
                position(2) = res(4) - position(4) - 100;
            end

            set(gcf, 'Position',  [position(1), position(2), 895, 895])
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            switch app.ImportParameterDropDown.Value
                case 'Cell parameters'
                    parameters2Show = 'cellParametersImport';
                case 'Substrate parameters'
                    parameters2Show = 'substrateParametersImport';
                case 'System parameters'
                    parameters2Show = 'systemParametersImport';
                case 'Scaled parameters'
                    parameters2Show = 'scaledParametersImport';
            end

            show_table(app,parameters2Show);
        end

        % Button pushed function: DisplacementforceanalysisButton
        function DisplacementforceanalysisButtonPushed(app, event)
            disable_enable_all_function(app,'Off');

            app.DialogApp = diplacementAndForceAnalysisDialog(app);
        end

        % Button pushed function: DisplacementforceanalysisButton_2
        function DisplacementforceanalysisButton_2Pushed(app, event)
            disable_enable_all_function(app,'Off');

            app.DialogApp = optoAnalysisDialog(app);
        end

        % Value changed function: MinimummagnitudeEditField
        function MinimummagnitudeEditFieldValueChanged(app, event)
            if app.MinimummagnitudeEditField.Value >= app.MaximummagnitudeEditField.Value
                uialert(app.EpiMechUIFigure,'Minimum must be smaller than maximum','Bad input');
                app.MinimummagnitudeEditField.Value = event.PreviousValue;
            end

        end

        % Value changed function: MaximummagnitudeEditField
        function MaximummagnitudeEditFieldValueChanged(app, event)
            if app.MaximummagnitudeEditField.Value <= app.MinimummagnitudeEditField.Value
                uialert(app.EpiMechUIFigure,'Maximum must be larger than minimum','Bad input');
                app.MaximummagnitudeEditField.Value = event.PreviousValue;
            end
        end

        % Value changed function: MinimummagnitudeEditField_2
        function MinimummagnitudeEditField_2ValueChanged(app, event)
            if app.MinimummagnitudeEditField_2.Value >= app.MaximummagnitudeEditField_2.Value
                uialert(app.EpiMechUIFigure,'Minimum must be smaller than maximum','Bad input');
                app.MinimummagnitudeEditField_2.Value = event.PreviousValue;
            end
            switch app.DescriptorDropDown.Value
                case 'Area'
                    app.cellDescriptorLimits(1,1) = app.MinimummagnitudeEditField_2.Value;

                case 'Area strain'
                    app.cellDescriptorLimits(2,1) = app.MinimummagnitudeEditField_2.Value;

                case 'Perimeter'
                    app.cellDescriptorLimits(3,1) = app.MinimummagnitudeEditField_2.Value;

                case 'Perimeter strain'
                    app.cellDescriptorLimits(4,1) = app.MinimummagnitudeEditField_2.Value;

                case 'Circularity'
                    app.cellDescriptorLimits(5,1) = app.MinimummagnitudeEditField_2.Value;

                case 'Aspect ratio'
                    app.cellDescriptorLimits(6,1) = app.MinimummagnitudeEditField_2.Value;

                case 'Angle'
                    app.cellDescriptorLimits(7,1) = app.MinimummagnitudeEditField_2.Value;
            end
        end

        % Value changed function: MaximummagnitudeEditField_2
        function MaximummagnitudeEditField_2ValueChanged(app, event)
            if app.MaximummagnitudeEditField_2.Value <= app.MinimummagnitudeEditField_2.Value
                uialert(app.EpiMechUIFigure,'Minimum must be smaller than maximum','Bad input');
                app.MaximummagnitudeEditField_2.Value = event.PreviousValue;
            end
            switch app.DescriptorDropDown.Value
                case 'Area'
                    app.cellDescriptorLimits(1,2) = app.MaximummagnitudeEditField_2.Value;

                case 'Area strain'
                    app.cellDescriptorLimits(2,2) = app.MaximummagnitudeEditField_2.Value;

                case 'Perimeter'
                    app.cellDescriptorLimits(3,2) = app.MaximummagnitudeEditField_2.Value;

                case 'Perimeter strain'
                    app.cellDescriptorLimits(4,2) = app.MaximummagnitudeEditField_2.Value;

                case 'Circularity'
                    app.cellDescriptorLimits(5,2) = app.MaximummagnitudeEditField_2.Value;

                case 'Aspect ratio'
                    app.cellDescriptorLimits(6,2) = app.MaximummagnitudeEditField_2.Value;

                case 'Angle'
                    app.cellDescriptorLimits(7,2) = app.MaximummagnitudeEditField_2.Value;
            end
        end

        % Value changed function: DescriptorDropDown
        function DescriptorDropDownValueChanged(app, event)
            set_cell_descriptor_limits(app)
        end

        % Button pushed function: CloseButton, DiscardchangesButton
        function CloseButtonPushed(app, event)
            % hide  the table
            hide_table(app)
        end

        % Button pushed function: ResettodefaultButton
        function ResettodefaultButtonPushed(app, event)
            switch app.tableData
                case 'cellParameters'
                    switch app.modelCase
                        case 'new'
                            cellParametersTemp = import_settings([app.defaultPath 'parameters/cell_parameters.txt']);
                        case 'import'
                            cellParametersTemp = import_settings([app.import.folderName '/cell_parameters.csv']);
                    end
                    switch app.simulationType
                        case 'growth'
                            if strcmp(app.modelCase,'import') && strcmp(app.simulationType,app.import.simulationType)
                                specificCellParametersTemp = app.import.specificCellParameters;
                            else
                                specificCellParametersTemp = import_settings([app.defaultPath 'parameters/specific_parameters_growth.txt']);
                            end
                        case 'pointlike'
                            if strcmp(app.modelCase,'import') && strcmp(app.simulationType,app.import.simulationType)
                                specificCellParametersTemp = app.import.specificCellParameters;
                            else
                                specificCellParametersTemp = import_settings([app.defaultPath 'parameters/specific_parameters_pointlike.txt']);
                            end
                        case 'stretch'
                            if strcmp(app.modelCase,'import') && strcmp(app.simulationType,app.import.simulationType)
                                specificCellParametersTemp = app.import.specificCellParameters;
                            else
                                specificCellParametersTemp = import_settings([app.defaultPath 'parameters/specific_parameters_stretch.txt']);
                            end
                        case 'opto'
                            if strcmp(app.modelCase,'import') && strcmp(app.simulationType,app.import.simulationType)
                                specificCellParametersTemp = app.import.specificCellParameters;
                            else
                                specificCellParametersTemp = import_settings([app.defaultPath 'parameters/specific_parameters_opto.txt']);
                            end
                    end

                    tableDataTemp = [struct_to_table(cellParametersTemp) ; struct_to_table(specificCellParametersTemp)];
                    app.UITable.Data = tableDataTemp;

                case 'systemParameters'
                    switch app.simulationType
                        case 'growth'
                            app.UITable.Data = struct_to_table(import_settings([app.defaultPath 'parameters/system_parameters_growth.txt']));
                        case 'pointlike'
                            app.UITable.Data = struct_to_table(import_settings([app.defaultPath 'parameters/system_parameters_pointlike.txt']));
                        case 'stretch'
                            app.UITable.Data = struct_to_table(import_settings([app.defaultPath 'parameters/system_parameters_stretch.txt']));
                        case 'opto'
                            app.UITable.Data = struct_to_table(import_settings([app.defaultPath 'parameters/system_parameters_opto.txt']));
                    end
                    
                case 'plottingOptions'
                    switch app.appTask
                        case 'simulate'
                            switch app.simulationType
                                case 'growth'
                                    plottingOptionsTemp = import_settings([app.defaultPath 'settings/plotting/plotting_options_growth.txt']);
                                    app.UITable.Data = struct_to_table(settings_to_logical(plottingOptionsTemp,'plot'));
                                case 'pointlike'
                                    plottingOptionsTemp = import_settings([app.defaultPath 'settings/plotting/plotting_options_pointlike.txt']);
                                    app.UITable.Data = struct_to_table(settings_to_logical(plottingOptionsTemp,'plot'));
                                case 'stretch'
                                    plottingOptionsTemp = import_settings([app.defaultPath 'settings/plotting/plotting_options_stretch.txt']);
                                    plottingOptionsTemp = settings_to_logical(plottingOptionsTemp,'plot');
                                    if plottingOptionsTemp.substrateStyle == 2
                                        plottingOptionsTemp.substrateStyle = 1;
                                    end
                                    app.UITable.Data = struct_to_table(plottingOptionsTemp);
                                case 'opto'
                                    plottingOptionsTemp = import_settings([app.defaultPath 'settings/plotting/plotting_options_opto.txt']);
                                    app.UITable.Data = struct_to_table(settings_to_logical(plottingOptionsTemp,'plot'));
                            end
                        case 'plotAndAnalyze'
                            
                            oldPlottingOptions = app.importPlottingOptions;
                            
                            switch app.plotImport(app.selectedFile).simulationType
                                case 'growth'
                                    app.importPlottingOptions = import_settings([app.defaultPath 'settings/plotting/post_plotting_options_growth.txt']);
                                    app.importPlottingOptions = settings_to_logical(app.importPlottingOptions,'plot');
                                case 'pointlike'
                                    app.importPlottingOptions = import_settings([app.defaultPath 'settings/plotting/post_plotting_options_pointlike.txt']);
                                    app.importPlottingOptions = settings_to_logical(app.importPlottingOptions,'plot');
                                case 'stretch'
                                    app.importPlottingOptions = import_settings([app.defaultPath 'settings/plotting/post_plotting_options_stretch.txt']);
                                    app.importPlottingOptions = settings_to_logical(app.importPlottingOptions,'plot');
                                    if app.importPlottingOptions.substrateStyle == 2
                                        app.importPlottingOptions.substrateStyle = 1;
                                    end
                                case 'opto'
                                    app.importPlottingOptions = import_settings([app.defaultPath 'settings/plotting/post_plotting_options_opto.txt']);
                                    app.importPlottingOptions = settings_to_logical(app.importPlottingOptions,'plot');
                            end
                            app.importPlottingOptions = check_plotting_options(app);
                            app.UITable.Data = struct_to_table(app.importPlottingOptions);
                            
                            app.importPlottingOptions = oldPlottingOptions;
                    end
                case 'customExportOptions'
                    switch app.simulationType
                        case 'growth'
                            customExportOptionsTemp = import_settings([app.defaultPath 'settings/export/export_options_custom_growth.txt']);
                            app.UITable.Data = struct_to_table(settings_to_logical(customExportOptionsTemp,'export'));
                        case 'pointlike'
                            customExportOptionsTemp = import_settings([app.defaultPath 'settings/export/export_options_custom_pointlike.txt']);
                            app.UITable.Data = struct_to_table(settings_to_logical(customExportOptionsTemp,'export'));
                        case 'stretch'
                            customExportOptionsTemp = import_settings([app.defaultPath 'settings/export/export_options_custom_stretch.txt']);
                            app.UITable.Data = struct_to_table(settings_to_logical(customExportOptionsTemp,'export'));
                        case 'opto'
                            customExportOptionsTemp = import_settings([app.defaultPath 'settings/export/export_options_custom_opto.txt']);
                            app.UITable.Data = struct_to_table(settings_to_logical(customExportOptionsTemp,'export'));
                    end
                case 'substrateParameters'
                    switch app.simulationType
                        case 'pointlike'
                            switch app.import.simulationType
                                case 'growth'
                                    app.UITable.Data = struct_to_table(import_settings([app.defaultPath 'parameters/substrate_parameters.txt']));
                                case 'pointlike'
                                    app.UITable.Data = struct_to_table(import_settings([app.import.folderName '/substrate_parameters.csv']));
                            end
                        case 'stretch'
                            app.UITable.Data = struct_to_table(import_settings([app.defaultPath 'parameters/substrate_parameters.txt']));
                        case 'opto'
                            app.UITable.Data = struct_to_table(import_settings([app.defaultPath 'parameters/substrate_parameters.txt']));
                    end
                case 'fFAInfo'
                    app.UITable.Data = num2cell(csvread([app.defaultPath 'parameters/focal_adhesion_parameters.csv']));
            end
        end

        % Button pushed function: ActivationareaButton_2
        function ActivationareaButton_2Pushed(app, event)
            disable_enable_all_function(app,'Off');

            d.simset.simulationType = 0;
            d = scale_parameters(app,d);

            d.simset.substrateSize = app.SubstratesizeEditField.Value*1e-6/app.systemParameters.scalingLength;

            switch app.modelCase
                case 'new'
                    d.cells = initialize_cells_struct;
                    switch app.InitialstateButtongroup.SelectedObject.Text
                        case 'Single cell'
                            d = create_cell(d,[0;0]);
                        case 'Place multiple cells'
                            if ~isempty(app.cellCenters)
                                for i = 1:size(app.cellCenters,1)
                                    d = create_cell(d,app.cellCenters(i,:));
                                end
                            end
                    end
                case 'import'
                    d.spar.scalingTime = app.import.scaledParameters.scalingTime;
                    if app.CentercellsCheckBox.Value
                        d.cells = center_cells(d.cells);
                    end
                    d.cells = import_cells(app,d,'basic_plotting');
                    d = remove_cells_gui(app, d,'basic_plotting');
            end

            d = setup_plotting_options(app,d,'glass_area');

            plot_function(d, 1);

            hold on
            coords = [];

            areaHandle = {};

            nShapes = length(app.glassActivationShapes);
            shapes = app.glassActivationShapes;
            for i = 1:nShapes
                areaHandle{i} = plot(shapes{i}([1:end 1],1),shapes{i}([1:end 1],2),'b-');
            end
            while 1
                try

                    coordst = ginput(1);
                    if numel(coordst) == 0
                        delete(areaHandle{nShapes+1});
                        break
                    end
                    coords(end+1,:) = coordst;
                    if length(areaHandle) > nShapes
                        delete(areaHandle{nShapes+1});
                    end
                    distance = sqrt((coords(1,1) - coords(end,1))^2 + (coords(1,2) - coords(end,2))^2);
                    if size(coords,1) > 3 && distance < 10e-6/app.systemParameters.scalingLength
                        coords(end,:) = [];
                       
                        nShapes = nShapes + 1;
                        shapes{nShapes} = coords;
                        delete(areaHandle{nShapes});
                        for i = 1:nShapes
                            areaHandle{i} = plot(shapes{i}([1:end 1],1),shapes{i}([1:end 1],2),'--b');
                        end
                        coords = [];
                    else
                        areaHandle{nShapes+1} = plot(coords(:,1),coords(:,2),'-ob');
                    end

                catch
                    break
                end
            end

            app.glassActivationShapes = shapes;
            if ~isempty(app.glassActivationShapes)
                app.SaveactivationareaButton_2.Enable = 'On';
            end
            disable_enable_all_function(app,'On');
        end

        % Button pushed function: SaveactivationareaButton_2
        function SaveactivationareaButton_2Pushed(app, event)
           [fileName, filePath] = uiputfile({'*.csv'},'Save the current activation area',[app.defaultPath 'settings/misc/']);

            if fileName == 0
                figure(app.EpiMechUIFigure);
                return;
            end

            filePath = strrep(filePath,'/','\');
            figure(app.EpiMechUIFigure);

            shapeExport = [];

            for i = 1:length(app.glassActivationShapes)
                if i ~= 1
                    shapeExport = [shapeExport; [0 0]];
                end
                shapeExport = [shapeExport ; app.glassActivationShapes{i}];
            end
            if shapeExport(end,1) == 0
                shapeExport(end,:) = [];
            end
            csvwrite([filePath fileName],shapeExport);

        end

        % Button pushed function: LoadactivationareaButton_2
        function LoadactivationareaButton_2Pushed(app, event)
            [fileName, filePath] = uigetfile({'*.csv'},'Choose activation shape file to load',[app.defaultPath 'settings/misc/']);

            figure(app.EpiMechUIFigure);
            if fileName == 0
                return;
            end

            shapeData  = csvread([filePath fileName]);

            app.glassActivationShapes = {};
            nShapes = sum(shapeData(:,1) == 0)+1;
            for i = 1:nShapes
                idx = find(shapeData(:,1) == 0);
                if isempty(idx)
                    app.glassActivationShapes{i} = shapeData;
                    nextZero = 2;
                else
                    nextZero = min(idx);
                    app.glassActivationShapes{i} = shapeData(1:nextZero-1,:);
                end
                shapeData(1:nextZero,:) = [];
            end
            if ~isempty(app.glassActivationShapes)
                app.SaveactivationareaButton_2.Enable = 'On';
            end

        end

        % Button pushed function: ResetActivationAreaButton_2
        function ResetActivationAreaButton_2Pushed(app, event)
            app.glassActivationShapes = {};
            app.SaveactivationareaButton_2.Enable = 'Off';
        end

        % Button pushed function: ActivationprofileButton_2
        function ActivationprofileButton_2Pushed(app, event)
            disable_enable_all_function(app,'Off');

            app.DialogApp = glassActivationDialog(app);
        end

        % Button pushed function: MaxdisplacementsButton
        function MaxdisplacementsButtonPushed(app, event)

            dt = app.plotImport(app.selectedFile).exportOptions.exportDt;

            actTimes = app.plotImport(app.selectedFile).glassActivation(:,1);
            actValues = app.plotImport(app.selectedFile).glassActivation(:,2);

            time = 0;

            bottomDisp = zeros(app.plotImport(app.selectedFile).nTimePoints,1);
            topDisp = bottomDisp;
            times = bottomDisp;

            initial = readmatrix([app.plotImport(app.selectedFile).folderName '/substrate/substrate_points_1.csv']);

            for i = 1:app.plotImport(app.selectedFile).nTimePoints

                currentData = readmatrix([app.plotImport(app.selectedFile).folderName '/substrate/substrate_points_' num2str(i) '.csv']);

                disps = sqrt((initial(:,1) - currentData(:,1)).^2 + (initial(:,2) - currentData(:,2)).^2)*app.plotImport(app.selectedFile).systemParameters.scalingLength;
                topDisp(i) = max(disps);

                previousTime = max(actTimes(actTimes <= time));
                nextTime = min(actTimes(actTimes > time));

                previousIdx = find(actTimes == previousTime);
                nextIdx = find(actTimes == nextTime);

                if previousTime == time

                    % get the displacement from the initial position
                    bottomDisp(i) = actValues(previousIdx);

                    % otherwise
                else

                    % interpolate the displacement from the initial position
                    bottomDisp(i) = actValues(previousIdx) + (actValues(nextIdx) - actValues(previousIdx))*(time - actTimes(previousIdx))/(actTimes(nextIdx) - actTimes(previousIdx));
                end


                times(i) = time;

                time = time + dt;

            end

            figure;
            plot(times,topDisp)
            hold on
            stairs(times, bottomDisp*20e-6)
            xlabel('time (s)')
            ylabel('displacement (µm)')
            legend({'top', 'bottom'})

        end

        % Button pushed function: DisplacementareaplotButton
        function DisplacementareaplotButtonPushed(app, event)
            dt = app.plotImport(app.selectedFile).exportOptions.exportDt;

            actTimes = app.plotImport(app.selectedFile).glassActivation(:,1);
            actValues = app.plotImport(app.selectedFile).glassActivation(:,2);

            time = 0;

            bottomDisp = zeros(app.plotImport(app.selectedFile).nTimePoints,1);
            topDisp = bottomDisp;
            times = bottomDisp;

            initial = readmatrix([app.plotImport(app.selectedFile).folderName '/substrate/substrate_points_1.csv']);

            currentData = readmatrix([app.plotImport(app.selectedFile).folderName '/substrate/substrate_points_' num2str(app.plotImport(app.selectedFile).nTimePoints) '.csv']);

            disps = sqrt((initial(:,1) - currentData(:,1)).^2 + (initial(:,2) - currentData(:,2)).^2)*app.plotImport(app.selectedFile).systemParameters.scalingLength;
            
            [X, Y] = meshgrid(min(initial(:,1)):0.1:max(initial(:,1)), min(initial(:,2)):0.1:max(initial(:,2)));
            Z = griddata(initial(:,1), initial(:,2), disps, X, Y, 'cubic');
            contourf(X,Y,Z,10, 'LineStyle', 'none')
            colorbar
            axis equal

        end

        % Button pushed function: PlotButton_3
        function PlotButton_3Pushed(app, event)
            
            oldCurrentTimePoint = app.plotImport(app.selectedFile).currentTimePoint;
            oldPlottingOptions = app.importPlottingOptions;

            switch app.ForceDropDown_3.Value
                case "Total forces"
                    app.importPlottingOptions.cellForcesTotal = true;
                case "Cortical forces"
                    app.importPlottingOptions.cellForcesCortical = true;
                case "Junction forces"
                    app.importPlottingOptions.cellForcesJunctions = true;
                case "Division forces"
                    app.importPlottingOptions.cellForcesDivision = true;
                case "Membrane forces"
                    app.importPlottingOptions.cellForcesMembrane = true;
                case "Contact forces"
                    app.importPlottingOptions.cellForcesContact = true;
                case "Area forces"
                    app.importPlottingOptions.cellForcesArea = true;
                case "Pointlike forces"
                    app.importPlottingOptions.cellForcesPointlike = true;
                case "Focal adhesion forces"
                    app.importPlottingOptions.cellForcesFocalAdhesions = true;
            end

            forceValues = {};

            buffer = app.BufferEditField.Value*1e-6/app.plotImport(app.selectedFile).systemParameters.scalingLength;

            for i = 1:app.plotImport(app.selectedFile).nTimePoints
                forceValueTemp = [];
                
                app.plotImport(app.selectedFile).currentTimePoint = i;

                d = [];
                d.pl.glass = true;
                d.cells = import_cells(app,d,'post_plotting');
                d = import_glass_data(app,d);

                % DOES NOT WORK WITH MULTIPLE POLYGONS
                frame = offset_polygon(d.simset.glass.shapes{1},-buffer);

                for j = 1:length(app.plotImport(app.selectedFile).cellNumbers)

                    verts = find(check_if_inside(frame(:,1), frame(:,2), d.cells(j).verticesX, d.cells(j).verticesY) ~= 0);

                    switch app.ForceDropDown_3.Value
                        case "Total forces"
                            forceValueTemp = [forceValueTemp; sqrt(d.cells(j).forces.totalX(verts).^2 + d.cells(j).forces.totalY(verts).^2)];
                        case "Cortical forces"
                            forceValueTemp = [forceValueTemp; sqrt(d.cells(j).forces.corticalX(verts).^2 + d.cells(j).forces.corticalY(verts).^2)];
                        case "Junction forces"
                            forceValueTemp = [forceValueTemp; sqrt(d.cells(j).forces.junctionX(verts).^2 + d.cells(j).forces.junctionY(verts).^2)];
                        case "Division forces"
                            forceValueTemp = [forceValueTemp; sqrt(d.cells(j).forces.divisionX(verts).^2 + d.cells(j).forces.divisionY(verts).^2)];
                        case "Membrane forces"
                            forceValueTemp = [forceValueTemp; sqrt(d.cells(j).forces.membraneX(verts).^2 + d.cells(j).forces.membraneY(verts).^2)];
                        case "Contact forces"
                            forceValueTemp = [forceValueTemp; sqrt(d.cells(j).forces.contactX(verts).^2 + d.cells(j).forces.contactY(verts).^2)];
                        case "Area forces"
                            forceValueTemp = [forceValueTemp; sqrt(d.cells(j).forces.areaX(verts).^2 + d.cells(j).forces.areaY(verts).^2)];
                        case "Pointlike forces"
                            forceValueTemp = [forceValueTemp; sqrt(d.cells(j).forces.pointlikeX(verts).^2 + d.cells(j).forces.pointlikeY(verts).^2)];
                        case "Focal adhesion forces"
                            forceValueTemp = [forceValueTemp; sqrt(d.cells(j).forces.substrateX(verts).^2 + d.cells(j).forces.substrateY(verts).^2)];
                    end
                    

                end

                forceValues{end+1} = forceValueTemp;
            end

            dt = app.plotImport(app.selectedFile).exportOptions.exportDt;

            times = 0:dt:((app.plotImport(app.selectedFile).nTimePoints-1)*dt);

            figure;
            if app.TypeDropDown_2.Value == "Separate"
                
                nPoints = cellfun(@length,forceValues);
                timesscatter = [];
                for i = 1:length(nPoints)
                    timesscatter = [timesscatter; ones(nPoints(i),1)*times(i)];
                end

                forceValuesV = cell2mat(forceValues');
                
                scatter(timesscatter,forceValuesV)
                
            else
                
                meanForces = cellfun(@mean,forceValues);
                stdForces = cellfun(@std,forceValues);

                errorbar(times,meanForces,stdForces)
                
            end


            app.importPlottingOptions = oldPlottingOptions;
            app.plotImport(app.selectedFile).currentTimePoint = oldCurrentTimePoint;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EpiMechUIFigure = uifigure('Visible', 'off');
            app.EpiMechUIFigure.Name = 'EpiMech';
            app.EpiMechUIFigure.CloseRequestFcn = createCallbackFcn(app, @EpiMechUIFigureCloseRequest, true);
            app.EpiMechUIFigure.KeyPressFcn = createCallbackFcn(app, @EpiMechUIFigureKeyPress, true);
            app.Menu = uimenu(app.EpiMechUIFigure);
            app.Menu.Text = 'Menu';
            app.EnableallMenu = uimenu(app.Menu);
            app.EnableallMenu.MenuSelectedFcn = createCallbackFcn(app, @EnableallMenuSelected, true);
            app.EnableallMenu.Text = 'Enable all';
            app.ResetMenu = uimenu(app.Menu);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetMenu.Text = 'Reset';
            app.ExitMenu = uimenu(app.Menu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @EpiMechUIFigureCloseRequest, true);
            app.ExitMenu.Text = 'Exit';
            app.FoldersMenu = uimenu(app.EpiMechUIFigure);
            app.FoldersMenu.Text = 'Folders';
            app.OpenvideofolderMenu = uimenu(app.FoldersMenu);
            app.OpenvideofolderMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenvideofolderMenuSelected, true);
            app.OpenvideofolderMenu.Text = 'Open video folder';
            app.OpenresultsfolderMenu = uimenu(app.FoldersMenu);
            app.OpenresultsfolderMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenresultsfolderMenuSelected, true);
            app.OpenresultsfolderMenu.Text = 'Open results folder';
            app.ExtraMenu = uimenu(app.EpiMechUIFigure);
            app.ExtraMenu.Text = 'Extra';
            app.ScalebarsettingsMenu = uimenu(app.ExtraMenu);
            app.ScalebarsettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @ScalebarsettingsMenuSelected, true);
            app.ScalebarsettingsMenu.Enable = 'off';
            app.ScalebarsettingsMenu.Text = 'Scale bar settings';
            app.HelpMenu = uimenu(app.EpiMechUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.EpiMechLabel = uilabel(app.EpiMechUIFigure);
            app.EpiMechLabel.Text = 'EpiMech';
            app.TablePanel = uipanel(app.EpiMechUIFigure);
            app.UITable = uitable(app.TablePanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.SavetofileButton = uibutton(app.TablePanel, 'push');
            app.SavetofileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetofileButtonPushed, true);
            app.SavetofileButton.Text = {'Save to file'; ''};
            app.SavechangesButton = uibutton(app.TablePanel, 'push');
            app.SavechangesButton.ButtonPushedFcn = createCallbackFcn(app, @SavechangesButtonPushed, true);
            app.SavechangesButton.Text = 'Save changes';
            app.CloseButton = uibutton(app.TablePanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.DiscardchangesButton = uibutton(app.TablePanel, 'push');
            app.DiscardchangesButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.DiscardchangesButton.Text = 'Discard changes';
            app.ResettodefaultButton = uibutton(app.TablePanel, 'push');
            app.ResettodefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ResettodefaultButtonPushed, true);
            app.ResettodefaultButton.Text = 'Reset to default';
            app.tableTipsImage = uiimage(app.TablePanel);
            app.tableTipsImage.Tooltip = {''};
            app.TabGroup = uitabgroup(app.EpiMechUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SimulateTab = uitab(app.TabGroup);
            app.SimulateTab.Title = 'Simulate';
            app.ParametersPanel = uipanel(app.SimulateTab);
            app.ParametersPanel.Title = 'Parameters';
            app.EditcellparametersButton = uibutton(app.ParametersPanel, 'push');
            app.EditcellparametersButton.ButtonPushedFcn = createCallbackFcn(app, @EditcellparametersButtonPushed, true);
            app.EditcellparametersButton.Text = 'Edit cell parameters';
            app.EditsystemparametersButton = uibutton(app.ParametersPanel, 'push');
            app.EditsystemparametersButton.ButtonPushedFcn = createCallbackFcn(app, @EditsystemparametersButtonPushed, true);
            app.EditsystemparametersButton.Text = 'Edit system parameters';
            app.ComponentsettingsPanel = uipanel(app.SimulateTab);
            app.ComponentsettingsPanel.Title = 'Component settings';
            app.ComponentsettingsDropdown = uidropdown(app.ComponentsettingsPanel);
            app.ComponentsettingsDropdown.Items = {};
            app.ComponentsettingsDropdown.ValueChangedFcn = createCallbackFcn(app, @ComponentsettingsDropdownValueChanged, true);
            app.ComponentsettingsDropdown.Value = {};
            app.DivisionsettingsPanel = uipanel(app.ComponentsettingsPanel);
            app.DivisiontypeButtongroup = uibuttongroup(app.DivisionsettingsPanel);
            app.DivisiontypeButtongroup.SelectionChangedFcn = createCallbackFcn(app, @DivisiontypeButtongroupSelectionChanged, true);
            app.NormaldivisionButton = uiradiobutton(app.DivisiontypeButtongroup);
            app.NormaldivisionButton.Text = 'Normal division';
            app.NormaldivisionButton.Value = true;
            app.DivisionuntilButton = uiradiobutton(app.DivisiontypeButtongroup);
            app.DivisionuntilButton.Text = 'Division until';
            app.DivideuntilDropDown = uidropdown(app.DivisionsettingsPanel);
            app.DivideuntilDropDown.Items = {'days', 'hours', 'mins', 'secs'};
            app.DivideuntilDropDown.Enable = 'off';
            app.DivideuntilDropDown.Value = 'days';
            app.DivideuntilEditField = uieditfield(app.DivisionsettingsPanel, 'numeric');
            app.DivideuntilEditField.ValueChangedFcn = createCallbackFcn(app, @DivideuntilEditFieldValueChanged, true);
            app.DivideuntilEditField.Enable = 'off';
            app.UniformdivisiontimesCheckBox = uicheckbox(app.DivisionsettingsPanel);
            app.UniformdivisiontimesCheckBox.Text = 'Uniform division times';
            app.StretchSettingsPanel = uipanel(app.ComponentsettingsPanel);
            app.CompressionAxisButtonGroup = uibuttongroup(app.StretchSettingsPanel);
            app.UniaxialButton = uiradiobutton(app.CompressionAxisButtonGroup);
            app.UniaxialButton.Text = 'Uniaxial';
            app.UniaxialButton.Value = true;
            app.BiaxialButton = uiradiobutton(app.CompressionAxisButtonGroup);
            app.BiaxialButton.Text = 'Biaxial';
            app.EditstretchButton = uibutton(app.StretchSettingsPanel, 'push');
            app.EditstretchButton.ButtonPushedFcn = createCallbackFcn(app, @EditstretchButtonPushed, true);
            app.EditstretchButton.Text = 'Edit stretch ';
            app.SubstrateSizePanel = uipanel(app.ComponentsettingsPanel);
            app.SubstrateparametersButton = uibutton(app.SubstrateSizePanel, 'push');
            app.SubstrateparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SubstrateparametersButtonPushed, true);
            app.SubstrateparametersButton.Text = 'Substrate parameters';
            app.ComparesizeButton = uibutton(app.SubstrateSizePanel, 'push');
            app.ComparesizeButton.ButtonPushedFcn = createCallbackFcn(app, @ComparesizeButtonPushed, true);
            app.ComparesizeButton.Enable = 'off';
            app.ComparesizeButton.Text = 'Compare';
            app.SubstrateTypeButtonGroup = uibuttongroup(app.SubstrateSizePanel);
            app.SubstrateTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SubstrateTypeButtonGroupSelectionChanged, true);
            app.FittedButton = uiradiobutton(app.SubstrateTypeButtonGroup);
            app.FittedButton.Text = 'Fitted';
            app.FittedButton.Value = true;
            app.SquareButton = uiradiobutton(app.SubstrateTypeButtonGroup);
            app.SquareButton.Text = 'Square';
            app.mLabel = uilabel(app.SubstrateSizePanel);
            app.mLabel.Enable = 'off';
            app.mLabel.Text = 'µm';
            app.SubstratesizeEditFieldLabel = uilabel(app.SubstrateSizePanel);
            app.SubstratesizeEditFieldLabel.Enable = 'off';
            app.SubstratesizeEditFieldLabel.Text = 'Size';
            app.SubstratesizeEditField = uieditfield(app.SubstrateSizePanel, 'numeric');
            app.SubstratesizeEditField.ValueChangedFcn = createCallbackFcn(app, @SubstratesizeEditFieldValueChanged, true);
            app.SubstratesizeEditField.Enable = 'off';
            app.SubstratesizeEditField.Value = 100;
            app.ManualsizeCheckBox = uicheckbox(app.SubstrateSizePanel);
            app.ManualsizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualsizeCheckBoxValueChanged, true);
            app.ManualsizeCheckBox.Enable = 'off';
            app.ManualsizeCheckBox.Text = 'Manual size';
            app.ManualsizeCheckBox.Value = true;
            app.UseimportedsubstratedataCheckBox_2 = uicheckbox(app.SubstrateSizePanel);
            app.UseimportedsubstratedataCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @UseimportedsubstratedataCheckBox_2ValueChanged, true);
            app.UseimportedsubstratedataCheckBox_2.Text = 'Use imported substrate data';
            app.CellsizesettingsPanel = uipanel(app.ComponentsettingsPanel);
            app.CellsizeButtonGroup = uibuttongroup(app.CellsizesettingsPanel);
            app.UniformsizingButton = uiradiobutton(app.CellsizeButtonGroup);
            app.UniformsizingButton.Text = 'Uniform sizing';
            app.MDCKsizingButton = uiradiobutton(app.CellsizeButtonGroup);
            app.MDCKsizingButton.Text = 'MDCK sizing';
            app.MDCKsizingButton.Value = true;
            app.PointlikesettingsPanel = uipanel(app.ComponentsettingsPanel);
            app.SelectcellButton = uibutton(app.PointlikesettingsPanel, 'push');
            app.SelectcellButton.ButtonPushedFcn = createCallbackFcn(app, @SelectcellButtonPushed, true);
            app.SelectcellButton.Text = 'Select cell';
            app.LoadmovementButton = uibutton(app.PointlikesettingsPanel, 'push');
            app.LoadmovementButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmovementButtonPushed, true);
            app.LoadmovementButton.Text = 'Load movement';
            app.SelectcentercellCheckBox = uicheckbox(app.PointlikesettingsPanel);
            app.SelectcentercellCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectcentercellCheckBoxValueChanged, true);
            app.SelectcentercellCheckBox.Text = 'Select center cell';
            app.SelectedcellEditFieldLabel = uilabel(app.PointlikesettingsPanel);
            app.SelectedcellEditFieldLabel.Text = 'Selected cell';
            app.SelectedcellEditField = uieditfield(app.PointlikesettingsPanel, 'numeric');
            app.SelectedcellEditField.ValueChangedFcn = createCallbackFcn(app, @SelectedcellEditFieldValueChanged, true);
            app.PointlikeFileNameLabel = uilabel(app.PointlikesettingsPanel);
            app.PointlikeFileNameLabel.Text = '';
            app.UseimportedmovementdataCheckBox = uicheckbox(app.PointlikesettingsPanel);
            app.UseimportedmovementdataCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseimportedmovementdataCheckBoxValueChanged, true);
            app.UseimportedmovementdataCheckBox.Enable = 'off';
            app.UseimportedmovementdataCheckBox.Text = 'Use imported movement data';
            app.SubstrateStiffnessPanel = uipanel(app.ComponentsettingsPanel);
            app.YoungsmodulusEditFieldLabel = uilabel(app.SubstrateStiffnessPanel);
            app.YoungsmodulusEditFieldLabel.Text = 'Young''s modulus';
            app.YoungsmodulusEditField = uieditfield(app.SubstrateStiffnessPanel, 'numeric');
            app.YoungsmodulusEditField.Limits = [0 Inf];
            app.YoungsmodulusEditField.ValueChangedFcn = createCallbackFcn(app, @YoungsmodulusEditFieldValueChanged2, true);
            app.kPaLabel = uilabel(app.SubstrateStiffnessPanel);
            app.kPaLabel.Text = 'kPa';
            app.StiffnessstyleButtonGroup = uibuttongroup(app.SubstrateStiffnessPanel);
            app.StiffnessstyleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StiffnessstyleButtonGroupSelectionChanged, true);
            app.ConstantButton = uiradiobutton(app.StiffnessstyleButtonGroup);
            app.ConstantButton.Text = 'Constant';
            app.ConstantButton.Value = true;
            app.HeterogeneousButton = uiradiobutton(app.StiffnessstyleButtonGroup);
            app.HeterogeneousButton.Text = 'Heterogeneous';
            app.GradientButton = uiradiobutton(app.StiffnessstyleButtonGroup);
            app.GradientButton.Text = 'Gradient';
            app.EditstiffnessButton = uibutton(app.SubstrateStiffnessPanel, 'push');
            app.EditstiffnessButton.ButtonPushedFcn = createCallbackFcn(app, @EditstiffnessButtonPushed, true);
            app.EditstiffnessButton.Enable = 'off';
            app.EditstiffnessButton.Text = 'Edit';
            app.UseimportedsubstratedataCheckBox = uicheckbox(app.SubstrateStiffnessPanel);
            app.UseimportedsubstratedataCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseimportedsubstratedataCheckBoxValueChanged, true);
            app.UseimportedsubstratedataCheckBox.Text = 'Use imported substrate data';
            app.FocalAdhesionsPanel = uipanel(app.ComponentsettingsPanel);
            app.Panel_2 = uipanel(app.FocalAdhesionsPanel);
            app.EditfocaladhesionsButton = uibutton(app.Panel_2, 'push');
            app.EditfocaladhesionsButton.ButtonPushedFcn = createCallbackFcn(app, @EditfocaladhesionsButtonPushed, true);
            app.EditfocaladhesionsButton.Text = 'Edit focal adhesions';
            app.ResetfocaladhesionsButton = uibutton(app.Panel_2, 'push');
            app.ResetfocaladhesionsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetfocaladhesionsButtonPushed, true);
            app.ResetfocaladhesionsButton.Tooltip = {'Reset'};
            app.ResetfocaladhesionsButton.Text = '';
            app.LoadfocaladhesionsButton = uibutton(app.Panel_2, 'push');
            app.LoadfocaladhesionsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfocaladhesionsButtonPushed, true);
            app.LoadfocaladhesionsButton.Tooltip = {'Load'};
            app.LoadfocaladhesionsButton.Text = '';
            app.OptogeneticsPanel = uipanel(app.ComponentsettingsPanel);
            app.ActivationprofileButton = uibutton(app.OptogeneticsPanel, 'push');
            app.ActivationprofileButton.ButtonPushedFcn = createCallbackFcn(app, @ActivationprofileButtonPushed, true);
            app.ActivationprofileButton.Text = 'Activation profile';
            app.Panel = uipanel(app.OptogeneticsPanel);
            app.ActivationareaButton = uibutton(app.Panel, 'push');
            app.ActivationareaButton.ButtonPushedFcn = createCallbackFcn(app, @ActivationareaButtonPushed, true);
            app.ActivationareaButton.Text = 'Activation area';
            app.ResetActivationAreaButton = uibutton(app.Panel, 'push');
            app.ResetActivationAreaButton.ButtonPushedFcn = createCallbackFcn(app, @ResetActivationAreaButtonPushed, true);
            app.ResetActivationAreaButton.Tooltip = {'Reset'};
            app.ResetActivationAreaButton.Text = '';
            app.SaveactivationareaButton = uibutton(app.Panel, 'push');
            app.SaveactivationareaButton.ButtonPushedFcn = createCallbackFcn(app, @SaveactivationareaButtonPushed, true);
            app.SaveactivationareaButton.Enable = 'off';
            app.SaveactivationareaButton.Tooltip = {'Save'};
            app.SaveactivationareaButton.Text = '';
            app.LoadactivationareaButton = uibutton(app.Panel, 'push');
            app.LoadactivationareaButton.ButtonPushedFcn = createCallbackFcn(app, @LoadactivationareaButtonPushed, true);
            app.LoadactivationareaButton.Tooltip = {'Load'};
            app.LoadactivationareaButton.Text = '';
            app.GlassActivationPanel = uipanel(app.ComponentsettingsPanel);
            app.ActivationprofileButton_2 = uibutton(app.GlassActivationPanel, 'push');
            app.ActivationprofileButton_2.ButtonPushedFcn = createCallbackFcn(app, @ActivationprofileButton_2Pushed, true);
            app.ActivationprofileButton_2.Text = 'Activation profile';
            app.Panel_4 = uipanel(app.GlassActivationPanel);
            app.ActivationareaButton_2 = uibutton(app.Panel_4, 'push');
            app.ActivationareaButton_2.ButtonPushedFcn = createCallbackFcn(app, @ActivationareaButton_2Pushed, true);
            app.ActivationareaButton_2.Text = 'Activation area';
            app.ResetActivationAreaButton_2 = uibutton(app.Panel_4, 'push');
            app.ResetActivationAreaButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetActivationAreaButton_2Pushed, true);
            app.ResetActivationAreaButton_2.Tooltip = {'Reset'};
            app.ResetActivationAreaButton_2.Text = '';
            app.SaveactivationareaButton_2 = uibutton(app.Panel_4, 'push');
            app.SaveactivationareaButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveactivationareaButton_2Pushed, true);
            app.SaveactivationareaButton_2.Enable = 'off';
            app.SaveactivationareaButton_2.Tooltip = {'Save'};
            app.SaveactivationareaButton_2.Text = '';
            app.LoadactivationareaButton_2 = uibutton(app.Panel_4, 'push');
            app.LoadactivationareaButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadactivationareaButton_2Pushed, true);
            app.LoadactivationareaButton_2.Tooltip = {'Load'};
            app.LoadactivationareaButton_2.Text = '';
            app.InitialstatePanel_2 = uipanel(app.SimulateTab);
            app.InitialstatePanel_2.Title = 'Initial state';
            app.PlotButton = uibutton(app.InitialstatePanel_2, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.CentercellsCheckBox = uicheckbox(app.InitialstatePanel_2);
            app.CentercellsCheckBox.Text = 'Center cells';
            app.TimetoloadDropDownLabel = uilabel(app.InitialstatePanel_2);
            app.TimetoloadDropDownLabel.Text = 'Time to load';
            app.TimetoloadDropDown = uidropdown(app.InitialstatePanel_2);
            app.TimetoloadDropDown.Items = {};
            app.TimetoloadDropDown.ValueChangedFcn = createCallbackFcn(app, @TimetoloadDropDownValueChanged, true);
            app.TimetoloadDropDown.Value = {};
            app.Panel_3 = uipanel(app.InitialstatePanel_2);
            app.RemovecellsButton = uibutton(app.Panel_3, 'push');
            app.RemovecellsButton.ButtonPushedFcn = createCallbackFcn(app, @RemovecellsButtonPushed, true);
            app.RemovecellsButton.Text = 'Remove cells';
            app.ResetcellremovalButton = uibutton(app.Panel_3, 'push');
            app.ResetcellremovalButton.ButtonPushedFcn = createCallbackFcn(app, @ResetcellremovalButtonPushed, true);
            app.ResetcellremovalButton.Tooltip = {'Reset'};
            app.ResetcellremovalButton.Text = '';
            app.PlottingoptionsPanel = uipanel(app.SimulateTab);
            app.PlottingoptionsPanel.Title = 'Plotting options';
            app.ShowanimationCheckBox = uicheckbox(app.PlottingoptionsPanel);
            app.ShowanimationCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowanimationCheckBoxValueChanged, true);
            app.ShowanimationCheckBox.Text = 'Show animation';
            app.ShowanimationCheckBox.Value = true;
            app.SavevideoCheckBox = uicheckbox(app.PlottingoptionsPanel);
            app.SavevideoCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavevideoCheckBoxValueChanged, true);
            app.SavevideoCheckBox.Text = 'Save video';
            app.PlottingstepEditFieldLabel = uilabel(app.PlottingoptionsPanel);
            app.PlottingstepEditFieldLabel.Text = 'Plotting step';
            app.PlottingstepEditField = uieditfield(app.PlottingoptionsPanel, 'numeric');
            app.PlottingstepEditField.ValueChangedFcn = createCallbackFcn(app, @PlottingstepEditFieldValueChanged, true);
            app.PlottingstepEditField.Value = 30;
            app.xdtLabel = uilabel(app.PlottingoptionsPanel);
            app.xdtLabel.Text = 'x dt';
            app.CellstyleDropDownLabel = uilabel(app.PlottingoptionsPanel);
            app.CellstyleDropDownLabel.Text = 'Cell style';
            app.CellstyleDropDown = uidropdown(app.PlottingoptionsPanel);
            app.CellstyleDropDown.Items = {'Grey filled', 'Red outline', 'Number of neighbors', 'Black', 'Lines', 'No cells'};
            app.CellstyleDropDown.ValueChangedFcn = createCallbackFcn(app, @CellstyleDropDownValueChanged, true);
            app.CellstyleDropDown.Value = 'Grey filled';
            app.WindowsizeSliderLabel = uilabel(app.PlottingoptionsPanel);
            app.WindowsizeSliderLabel.Text = 'Window size';
            app.WindowsizeSlider = uislider(app.PlottingoptionsPanel);
            app.WindowsizeSlider.Limits = [0 1];
            app.WindowsizeSlider.MajorTicks = [];
            app.WindowsizeSlider.MajorTickLabels = {'50', '100', '200', '800'};
            app.WindowsizeSlider.ValueChangingFcn = createCallbackFcn(app, @WindowsizeSliderValueChanging, true);
            app.WindowsizeSlider.MinorTicks = [];
            app.PlottingoptionsButton = uibutton(app.PlottingoptionsPanel, 'push');
            app.PlottingoptionsButton.ButtonPushedFcn = createCallbackFcn(app, @PlottingoptionsButtonPushed, true);
            app.PlottingoptionsButton.Text = 'Plotting options';
            app.WindowsizeText = uilabel(app.PlottingoptionsPanel);
            app.WindowsizeText.Text = 'µm';
            app.SubstratestyleLabel = uilabel(app.PlottingoptionsPanel);
            app.SubstratestyleLabel.Enable = 'off';
            app.SubstratestyleLabel.Text = 'Substrate style';
            app.SubstratestyleDropDown = uidropdown(app.PlottingoptionsPanel);
            app.SubstratestyleDropDown.Items = {'Simple', 'With lines', 'No substrate'};
            app.SubstratestyleDropDown.ValueChangedFcn = createCallbackFcn(app, @SubstratestyleDropDownValueChanged, true);
            app.SubstratestyleDropDown.Enable = 'off';
            app.SubstratestyleDropDown.Value = 'Simple';
            app.CompressvideoCheckBox = uicheckbox(app.PlottingoptionsPanel);
            app.CompressvideoCheckBox.ValueChangedFcn = createCallbackFcn(app, @CompressvideoCheckBoxValueChanged, true);
            app.CompressvideoCheckBox.Enable = 'off';
            app.CompressvideoCheckBox.Text = 'Compress video';
            app.WindowsizeEditField = uieditfield(app.PlottingoptionsPanel, 'numeric');
            app.WindowsizeEditField.ValueChangedFcn = createCallbackFcn(app, @WindowsizeEditFieldValueChanged, true);
            app.RealplottingstepLabel = uilabel(app.PlottingoptionsPanel);
            app.RealplottingstepLabel.Text = '= ';
            app.ScalebarCheckBox = uicheckbox(app.PlottingoptionsPanel);
            app.ScalebarCheckBox.Text = 'Scale bar';
            app.MiddlepointLabel = uilabel(app.PlottingoptionsPanel);
            app.MiddlepointLabel.Text = 'Middle point: ';
            app.MiddlepointxEditField = uieditfield(app.PlottingoptionsPanel, 'numeric');
            app.MiddlepointyEditField = uieditfield(app.PlottingoptionsPanel, 'numeric');
            app.Label = uilabel(app.PlottingoptionsPanel);
            app.Label.Text = ',';
            app.WindowsizeText_3 = uilabel(app.PlottingoptionsPanel);
            app.WindowsizeText_3.Text = 'µm';
            app.ResetviewButton = uibutton(app.PlottingoptionsPanel, 'push');
            app.ResetviewButton.ButtonPushedFcn = createCallbackFcn(app, @ResetviewButtonPushed, true);
            app.ResetviewButton.Tooltip = {'Reset'};
            app.ResetviewButton.Text = '';
            app.AutomaticsizeCheckBox = uicheckbox(app.PlottingoptionsPanel);
            app.AutomaticsizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutomaticsizeCheckBoxValueChanged, true);
            app.AutomaticsizeCheckBox.Text = 'Automatic size';
            app.ExportoptionsPanel = uipanel(app.SimulateTab);
            app.ExportoptionsPanel.Title = 'Export options';
            app.ExportdataCheckBox = uicheckbox(app.ExportoptionsPanel);
            app.ExportdataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExportdataCheckBoxValueChanged, true);
            app.ExportdataCheckBox.Text = 'Export data';
            app.xdtLabel_2 = uilabel(app.ExportoptionsPanel);
            app.xdtLabel_2.Text = 'x dt';
            app.ExportstepEditFieldLabel = uilabel(app.ExportoptionsPanel);
            app.ExportstepEditFieldLabel.Enable = 'off';
            app.ExportstepEditFieldLabel.Text = 'Export step';
            app.ExportstepEditField = uieditfield(app.ExportoptionsPanel, 'numeric');
            app.ExportstepEditField.ValueChangedFcn = createCallbackFcn(app, @ExportstepEditFieldValueChanged, true);
            app.ExportstepEditField.Enable = 'off';
            app.ExporteddataDropDown = uidropdown(app.ExportoptionsPanel);
            app.ExporteddataDropDown.Items = {'Suitable for import', 'Suitable for basic plotting', 'Full export', 'Custom export'};
            app.ExporteddataDropDown.ValueChangedFcn = createCallbackFcn(app, @ExporteddataDropDownValueChanged, true);
            app.ExporteddataDropDown.Enable = 'off';
            app.ExporteddataDropDown.Value = 'Suitable for import';
            app.EditexportoptionsButton = uibutton(app.ExportoptionsPanel, 'push');
            app.EditexportoptionsButton.ButtonPushedFcn = createCallbackFcn(app, @EditexportoptionsButtonPushed, true);
            app.EditexportoptionsButton.Enable = 'off';
            app.EditexportoptionsButton.Text = 'Edit export options';
            app.RealexportstepLabel = uilabel(app.ExportoptionsPanel);
            app.RealexportstepLabel.Text = '= ';
            app.InitialstatePanel = uipanel(app.SimulateTab);
            app.InitialstatePanel.Title = 'Initial state';
            app.InitialstateButtongroup = uibuttongroup(app.InitialstatePanel);
            app.InitialstateButtongroup.SelectionChangedFcn = createCallbackFcn(app, @InitialstateButtongroupSelectionChanged, true);
            app.SinglecellButton = uiradiobutton(app.InitialstateButtongroup);
            app.SinglecellButton.Text = 'Single cell';
            app.SinglecellButton.Value = true;
            app.PlacemultiplecellsButton = uiradiobutton(app.InitialstateButtongroup);
            app.PlacemultiplecellsButton.Text = 'Place multiple cells';
            app.ResetButton = uibutton(app.InitialstateButtongroup, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Tooltip = {'Reset'};
            app.ResetButton.Text = '';
            app.PlacecellsButton = uibutton(app.InitialstateButtongroup, 'push');
            app.PlacecellsButton.ButtonPushedFcn = createCallbackFcn(app, @PlacecellsButtonPushed, true);
            app.PlacecellsButton.Enable = 'off';
            app.PlacecellsButton.Text = 'Place cells';
            app.SimulationsettingsPanel = uipanel(app.SimulateTab);
            app.SimulationsettingsPanel.Title = 'Simulation settings';
            app.TimestepLabel = uilabel(app.SimulationsettingsPanel);
            app.TimestepLabel.Text = 'Time step';
            app.TimestepEditField = uieditfield(app.SimulationsettingsPanel, 'numeric');
            app.TimestepEditField.ValueChangedFcn = createCallbackFcn(app, @TimestepEditFieldValueChanged, true);
            app.SimulationtimeEditFieldLabel = uilabel(app.SimulationsettingsPanel);
            app.SimulationtimeEditFieldLabel.Text = 'Simulation time';
            app.SimulationtimeEditField = uieditfield(app.SimulationsettingsPanel, 'numeric');
            app.SimulationtimeEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationtimeEditFieldValueChanged, true);
            app.SimulationtimeDropDown = uidropdown(app.SimulationsettingsPanel);
            app.SimulationtimeDropDown.Items = {'days', 'hours', 'mins', 'secs'};
            app.SimulationtimeDropDown.ValueChangedFcn = createCallbackFcn(app, @SimulationtimeDropDownValueChanged, true);
            app.SimulationtimeDropDown.Value = 'days';
            app.SimulationtimestepDropDown = uidropdown(app.SimulationsettingsPanel);
            app.SimulationtimestepDropDown.Items = {'days', 'hours', 'mins', 'secs'};
            app.SimulationtimestepDropDown.ValueChangedFcn = createCallbackFcn(app, @SimulationtimestepDropDownValueChanged, true);
            app.SimulationtimestepDropDown.Value = 'mins';
            app.SimulationnameEditFieldLabel = uilabel(app.SimulationsettingsPanel);
            app.SimulationnameEditFieldLabel.Text = 'Simulation name';
            app.SimulationnameEditField = uieditfield(app.SimulationsettingsPanel, 'text');
            app.SimulationnameEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationnameEditFieldValueChanged, true);
            app.SimulationnameEditField.ValueChangingFcn = createCallbackFcn(app, @SimulationnameEditFieldValueChanging, true);
            app.SimulationnameEditField.Enable = 'off';
            app.SimulationnameEditField.Value = 'Give name';
            app.SolverDropDownLabel = uilabel(app.SimulationsettingsPanel);
            app.SolverDropDownLabel.Text = 'Solver';
            app.SolverDropDown = uidropdown(app.SimulationsettingsPanel);
            app.SolverDropDown.Items = {'RK2', 'RK4'};
            app.SolverDropDown.Value = 'RK2';
            app.ProgressbarCheckBox = uicheckbox(app.SimulationsettingsPanel);
            app.ProgressbarCheckBox.Text = 'Progress bar';
            app.TimesimulationCheckBox = uicheckbox(app.SimulationsettingsPanel);
            app.TimesimulationCheckBox.Text = 'Time simulation';
            app.StopandpauseCheckBox = uicheckbox(app.SimulationsettingsPanel);
            app.StopandpauseCheckBox.Tooltip = {''};
            app.StopandpauseCheckBox.Text = 'Stop and pause';
            app.StopandpauseCheckBox.Value = true;
            app.ReloadButton = uibutton(app.SimulationsettingsPanel, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.ReloadButton.Enable = 'off';
            app.ReloadButton.Tooltip = {'Reset'};
            app.ReloadButton.Text = '';
            app.TimestepplotCheckBox = uicheckbox(app.SimulationsettingsPanel);
            app.TimestepplotCheckBox.Text = 'Time step plot';
            app.SimulateButton = uibutton(app.SimulateTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.StopButton = uibutton(app.SimulateTab, 'state');
            app.StopButton.Enable = 'off';
            app.StopButton.Text = '';
            app.PauseButton = uibutton(app.SimulateTab, 'state');
            app.PauseButton.Enable = 'off';
            app.PauseButton.Text = '';
            app.ProgressbarPanel = uipanel(app.SimulateTab);
            app.ProgressbarPanel.Visible = 'off';
            app.ProgressLabel = uilabel(app.ProgressbarPanel);
            app.ProgressLabel.Tooltip = {''};
            app.ProgressLabel.Text = '';
            app.SimulationprogressLabel = uilabel(app.SimulateTab);
            app.SimulationprogressLabel.Text = '';
            app.SimulationprogresstimeLabel = uilabel(app.SimulateTab);
            app.SimulationprogresstimeLabel.Text = '';
            app.LoadprevioussimulationButton = uibutton(app.SimulateTab, 'push');
            app.LoadprevioussimulationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadprevioussimulationButtonPushed, true);
            app.LoadprevioussimulationButton.Text = 'Load previous simulation';
            app.NewsimulationButton = uibutton(app.SimulateTab, 'push');
            app.NewsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @NewsimulationButtonPushed, true);
            app.NewsimulationButton.Tooltip = {''};
            app.NewsimulationButton.Text = 'New simulation';
            app.SimulationtypeDropDownLabel = uilabel(app.SimulateTab);
            app.SimulationtypeDropDownLabel.Text = 'Simulation type';
            app.SimulationtypeDropDown = uidropdown(app.SimulateTab);
            app.SimulationtypeDropDown.Items = {'Epithelial growth', 'Pointlike micromanipulation', 'Optogenetic activation', 'Lateral compression and stretching', 'Glass activation'};
            app.SimulationtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SimulationtypeDropDownValueChanged, true);
            app.SimulationtypeDropDown.Value = 'Epithelial growth';
            app.ParameterstipsImage = uiimage(app.SimulateTab);
            app.ParameterstipsImage.Visible = 'off';
            app.ParameterstipsImage.Tooltip = {''};
            app.ComponentstipsImage = uiimage(app.SimulateTab);
            app.ComponentstipsImage.Visible = 'off';
            app.ComponentstipsImage.Tooltip = {''};
            app.InitialstatetipsImage = uiimage(app.SimulateTab);
            app.InitialstatetipsImage.Visible = 'off';
            app.InitialstatetipsImage.Tooltip = {''};
            app.PlottingtipsImage_1 = uiimage(app.SimulateTab);
            app.PlottingtipsImage_1.Visible = 'off';
            app.PlottingtipsImage_1.Tooltip = {''};
            app.ExportoptionsImage = uiimage(app.SimulateTab);
            app.ExportoptionsImage.Visible = 'off';
            app.ExportoptionsImage.Tooltip = {''};
            app.SimulationsettingstipsImage = uiimage(app.SimulateTab);
            app.SimulationsettingstipsImage.Visible = 'off';
            app.SimulationsettingstipsImage.Tooltip = {''};
            app.ImportnameLabel = uilabel(app.SimulateTab);
            app.ImportnameLabel.Text = '';
            app.ImportnamestaticLabel = uilabel(app.SimulateTab);
            app.ImportnamestaticLabel.Text = '';
            app.PlotandanalyzeTab = uitab(app.TabGroup);
            app.PlotandanalyzeTab.Title = 'Plot and analyze';
            app.PlottingoptionsPanel_2 = uipanel(app.PlotandanalyzeTab);
            app.PlottingoptionsPanel_2.Title = 'Plotting options';
            app.PlottingstepEditField_2Label = uilabel(app.PlottingoptionsPanel_2);
            app.PlottingstepEditField_2Label.Text = 'Plotting step';
            app.PlottingstepEditField_2 = uieditfield(app.PlottingoptionsPanel_2, 'numeric');
            app.PlottingstepEditField_2.ValueChangedFcn = createCallbackFcn(app, @PlottingstepEditField_2ValueChanged, true);
            app.PlottingstepEditField_2.Value = 30;
            app.CellstyleDropDown_2Label = uilabel(app.PlottingoptionsPanel_2);
            app.CellstyleDropDown_2Label.Text = 'Cell style';
            app.CellstyleDropDown_2 = uidropdown(app.PlottingoptionsPanel_2);
            app.CellstyleDropDown_2.Items = {'Grey filled', 'Red outline', 'Number of neighbors', 'Black', 'Lines', 'No cells'};
            app.CellstyleDropDown_2.ValueChangedFcn = createCallbackFcn(app, @CellstyleDropDown_2ValueChanged, true);
            app.CellstyleDropDown_2.Value = 'Grey filled';
            app.WindowsizeSlider_2Label = uilabel(app.PlottingoptionsPanel_2);
            app.WindowsizeSlider_2Label.Text = 'Window size';
            app.WindowsizeSlider_2 = uislider(app.PlottingoptionsPanel_2);
            app.WindowsizeSlider_2.Limits = [0 1];
            app.WindowsizeSlider_2.MajorTicks = [];
            app.WindowsizeSlider_2.MajorTickLabels = {'50', '100', '200', '800'};
            app.WindowsizeSlider_2.ValueChangingFcn = createCallbackFcn(app, @WindowsizeSlider_2ValueChanging, true);
            app.WindowsizeSlider_2.MinorTicks = [];
            app.xdtLabel_3 = uilabel(app.PlottingoptionsPanel_2);
            app.xdtLabel_3.Text = 'x dt';
            app.PlottingoptionsButton_2 = uibutton(app.PlottingoptionsPanel_2, 'push');
            app.PlottingoptionsButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlottingoptionsButton_2Pushed, true);
            app.PlottingoptionsButton_2.Text = 'Plotting options';
            app.WindowsizeText_2 = uilabel(app.PlottingoptionsPanel_2);
            app.WindowsizeText_2.Text = 'µm';
            app.SubstratestyleDropDown_2Label = uilabel(app.PlottingoptionsPanel_2);
            app.SubstratestyleDropDown_2Label.Enable = 'off';
            app.SubstratestyleDropDown_2Label.Text = 'Substrate style';
            app.SubstratestyleDropDown_2 = uidropdown(app.PlottingoptionsPanel_2);
            app.SubstratestyleDropDown_2.Items = {'Simple', 'With lines', 'No substrate'};
            app.SubstratestyleDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SubstratestyleDropDown_2ValueChanged, true);
            app.SubstratestyleDropDown_2.Enable = 'off';
            app.SubstratestyleDropDown_2.Value = 'Simple';
            app.WindowsizeEditField_2 = uieditfield(app.PlottingoptionsPanel_2, 'numeric');
            app.WindowsizeEditField_2.ValueChangedFcn = createCallbackFcn(app, @WindowsizeEditField_2ValueChanged, true);
            app.AutomaticsizeCheckBox_2 = uicheckbox(app.PlottingoptionsPanel_2);
            app.AutomaticsizeCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @AutomaticsizeCheckBox_2ValueChanged, true);
            app.AutomaticsizeCheckBox_2.Text = 'Automatic size';
            app.ScalebarCheckBox_2 = uicheckbox(app.PlottingoptionsPanel_2);
            app.ScalebarCheckBox_2.Text = 'Scale bar';
            app.MiddlepointLabel_2 = uilabel(app.PlottingoptionsPanel_2);
            app.MiddlepointLabel_2.Text = 'Middle point: ';
            app.MiddlepointxEditField_2 = uieditfield(app.PlottingoptionsPanel_2, 'numeric');
            app.MiddlepointyEditField_2 = uieditfield(app.PlottingoptionsPanel_2, 'numeric');
            app.Label_2 = uilabel(app.PlottingoptionsPanel_2);
            app.Label_2.Text = ',';
            app.WindowsizeText_4 = uilabel(app.PlottingoptionsPanel_2);
            app.WindowsizeText_4.Text = 'µm';
            app.ResetviewButton_2 = uibutton(app.PlottingoptionsPanel_2, 'push');
            app.ResetviewButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetviewButtonPushed, true);
            app.ResetviewButton_2.Text = '';
            app.DefaultButton = uibutton(app.PlottingoptionsPanel_2, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Reset';
            app.PlotPanel = uipanel(app.PlotandanalyzeTab);
            app.PlotPanel.Title = 'Plot';
            app.BrowsetimepointsButton = uibutton(app.PlotPanel, 'push');
            app.BrowsetimepointsButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsetimepointsButtonPushed, true);
            app.BrowsetimepointsButton.Text = 'Browse time points';
            app.TimepointDropDownLabel = uilabel(app.PlotPanel);
            app.TimepointDropDownLabel.Text = 'Time point';
            app.TimepointDropDown = uidropdown(app.PlotPanel);
            app.TimepointDropDown.Items = {};
            app.TimepointDropDown.ValueChangedFcn = createCallbackFcn(app, @TimepointDropDownValueChanged, true);
            app.TimepointDropDown.Value = {};
            app.SingletimepointButton = uibutton(app.PlotPanel, 'push');
            app.SingletimepointButton.ButtonPushedFcn = createCallbackFcn(app, @SingletimepointButtonPushed, true);
            app.SingletimepointButton.Text = 'Single time point';
            app.AnimationPanel = uipanel(app.PlotandanalyzeTab);
            app.AnimationPanel.Title = 'Animation';
            app.VideonameEditFieldLabel = uilabel(app.AnimationPanel);
            app.VideonameEditFieldLabel.Enable = 'off';
            app.VideonameEditFieldLabel.Text = 'Video name';
            app.VideonameEditField = uieditfield(app.AnimationPanel, 'text');
            app.VideonameEditField.ValueChangedFcn = createCallbackFcn(app, @VideonameEditFieldValueChanged, true);
            app.VideonameEditField.ValueChangingFcn = createCallbackFcn(app, @VideonameEditFieldValueChanging, true);
            app.VideonameEditField.Enable = 'off';
            app.VideonameEditField.Value = 'Give name';
            app.AnimateButton = uibutton(app.AnimationPanel, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = '';
            app.SavevideoCheckBox_2 = uicheckbox(app.AnimationPanel);
            app.SavevideoCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @SavevideoCheckBox_2ValueChanged, true);
            app.SavevideoCheckBox_2.Text = 'Save video';
            app.CompressvideoCheckBox_2 = uicheckbox(app.AnimationPanel);
            app.CompressvideoCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CompressvideoCheckBox_2ValueChanged, true);
            app.CompressvideoCheckBox_2.Enable = 'off';
            app.CompressvideoCheckBox_2.Text = 'Compress video';
            app.PauseButton_2 = uibutton(app.AnimationPanel, 'state');
            app.PauseButton_2.Enable = 'off';
            app.PauseButton_2.Text = '';
            app.StopButton_2 = uibutton(app.AnimationPanel, 'state');
            app.StopButton_2.Enable = 'off';
            app.StopButton_2.Text = '';
            app.StartDropDownLabel = uilabel(app.AnimationPanel);
            app.StartDropDownLabel.Text = 'Start';
            app.StartDropDown = uidropdown(app.AnimationPanel);
            app.StartDropDown.Items = {};
            app.StartDropDown.Value = {};
            app.EndDropDownLabel = uilabel(app.AnimationPanel);
            app.EndDropDownLabel.Text = 'End';
            app.EndDropDown = uidropdown(app.AnimationPanel);
            app.EndDropDown.Items = {};
            app.EndDropDown.Value = {};
            app.Reload2Button = uibutton(app.AnimationPanel, 'push');
            app.Reload2Button.ButtonPushedFcn = createCallbackFcn(app, @Reload2ButtonPushed, true);
            app.Reload2Button.Enable = 'off';
            app.Reload2Button.Tooltip = {'Reset'};
            app.Reload2Button.Text = '';
            app.OpenButton = uibutton(app.PlotandanalyzeTab, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.ClickopentochoosethesimulationstoloadLabel = uilabel(app.PlotandanalyzeTab);
            app.ClickopentochoosethesimulationstoloadLabel.Text = 'Click open to choose the simulation(s) to load';
            app.SpecialplottingoptionsPanel = uipanel(app.PlotandanalyzeTab);
            app.SpecialplottingoptionsPanel.Title = 'Special plotting options';
            app.SpecialplotDropDown = uidropdown(app.SpecialplottingoptionsPanel);
            app.SpecialplotDropDown.Items = {'Normal', 'Highlight cells', 'Show lineage', 'Force magnitude plotting', 'Cell shape descriptors'};
            app.SpecialplotDropDown.ValueChangedFcn = createCallbackFcn(app, @SpecialplotDropDownValueChanged, true);
            app.SpecialplotDropDown.Value = 'Normal';
            app.HighlightcellsPanel = uipanel(app.SpecialplottingoptionsPanel);
            app.ChoosecellstohighlightListBoxLabel = uilabel(app.HighlightcellsPanel);
            app.ChoosecellstohighlightListBoxLabel.Text = 'Choose cells to highlight';
            app.ChoosecellstohighlightListBox = uilistbox(app.HighlightcellsPanel);
            app.ChoosecellstohighlightListBox.Items = {};
            app.ChoosecellstohighlightListBox.Multiselect = 'on';
            app.ChoosecellstohighlightListBox.Tooltip = {'Use Ctrl to select multiple cells'};
            app.ChoosecellstohighlightListBox.Value = {};
            app.ShowcelllineagePanel = uipanel(app.SpecialplottingoptionsPanel);
            app.ShowcelllineageListBoxLabel = uilabel(app.ShowcelllineagePanel);
            app.ShowcelllineageListBoxLabel.Text = 'Show cell lineage';
            app.ShowcelllineageListBox = uilistbox(app.ShowcelllineagePanel);
            app.ShowcelllineageListBox.Items = {};
            app.ShowcelllineageListBox.Multiselect = 'on';
            app.ShowcelllineageListBox.Tooltip = {'Use Ctrl to select multiple cells'};
            app.ShowcelllineageListBox.Value = {};
            app.ForcemagnitudeplottingPanel = uipanel(app.SpecialplottingoptionsPanel);
            app.ForceDropDown_2Label = uilabel(app.ForcemagnitudeplottingPanel);
            app.ForceDropDown_2Label.Text = 'Force';
            app.ForceDropDown_2 = uidropdown(app.ForcemagnitudeplottingPanel);
            app.ForceDropDown_2.Items = {};
            app.ForceDropDown_2.Value = {};
            app.MaximummagnitudeEditFieldLabel = uilabel(app.ForcemagnitudeplottingPanel);
            app.MaximummagnitudeEditFieldLabel.Text = 'Maximum magnitude';
            app.MaximummagnitudeEditField = uieditfield(app.ForcemagnitudeplottingPanel, 'numeric');
            app.MaximummagnitudeEditField.Limits = [0 Inf];
            app.MaximummagnitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MaximummagnitudeEditFieldValueChanged, true);
            app.MaximummagnitudeEditField.Value = 10;
            app.MinimummagnitudeEditFieldLabel = uilabel(app.ForcemagnitudeplottingPanel);
            app.MinimummagnitudeEditFieldLabel.Text = 'Minimum magnitude';
            app.MinimummagnitudeEditField = uieditfield(app.ForcemagnitudeplottingPanel, 'numeric');
            app.MinimummagnitudeEditField.Limits = [0 Inf];
            app.MinimummagnitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MinimummagnitudeEditFieldValueChanged, true);
            app.MarkersizeEditFieldLabel = uilabel(app.ForcemagnitudeplottingPanel);
            app.MarkersizeEditFieldLabel.Text = 'Marker size';
            app.MarkersizeEditField = uieditfield(app.ForcemagnitudeplottingPanel, 'numeric');
            app.MarkersizeEditField.Limits = [0 Inf];
            app.MarkersizeEditField.Value = 50;
            app.CellshapedescriptorsPanel = uipanel(app.SpecialplottingoptionsPanel);
            app.DescriptorDropDownLabel = uilabel(app.CellshapedescriptorsPanel);
            app.DescriptorDropDownLabel.Text = 'Descriptor';
            app.DescriptorDropDown = uidropdown(app.CellshapedescriptorsPanel);
            app.DescriptorDropDown.Items = {'Area', 'Area strain', 'Perimeter', 'Perimeter strain', 'Circularity', 'Aspect ratio', 'Angle'};
            app.DescriptorDropDown.ValueChangedFcn = createCallbackFcn(app, @DescriptorDropDownValueChanged, true);
            app.DescriptorDropDown.Value = 'Area';
            app.MaximummagnitudeEditField_2Label = uilabel(app.CellshapedescriptorsPanel);
            app.MaximummagnitudeEditField_2Label.Text = 'Maximum magnitude';
            app.MinimummagnitudeEditField_2Label = uilabel(app.CellshapedescriptorsPanel);
            app.MinimummagnitudeEditField_2Label.Text = 'Minimum magnitude';
            app.MinimummagnitudeEditField_2 = uieditfield(app.CellshapedescriptorsPanel, 'numeric');
            app.MinimummagnitudeEditField_2.ValueChangedFcn = createCallbackFcn(app, @MinimummagnitudeEditField_2ValueChanged, true);
            app.MaximummagnitudeEditField_2 = uieditfield(app.CellshapedescriptorsPanel, 'numeric');
            app.MaximummagnitudeEditField_2.ValueChangedFcn = createCallbackFcn(app, @MaximummagnitudeEditField_2ValueChanged, true);
            app.MaximummagnitudeEditField_2.Value = 10;
            app.AnalysisPanel = uipanel(app.PlotandanalyzeTab);
            app.AnalysisPanel.Title = 'Analysis';
            app.DropDown2 = uidropdown(app.AnalysisPanel);
            app.DropDown2.Items = {'Geometry', 'Cell forces'};
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @DropDown2ValueChanged, true);
            app.DropDown2.Value = 'Geometry';
            app.GeometryAnalysisPanel = uipanel(app.AnalysisPanel);
            app.AreaValueLabel = uilabel(app.GeometryAnalysisPanel);
            app.AreaValueLabel.Text = '0';
            app.PerimeterValueLabel = uilabel(app.GeometryAnalysisPanel);
            app.PerimeterValueLabel.Text = '0';
            app.NeighborsLabel = uilabel(app.GeometryAnalysisPanel);
            app.NeighborsLabel.Text = 'Neighbors';
            app.NeighborsValueLabel = uilabel(app.GeometryAnalysisPanel);
            app.NeighborsValueLabel.Text = '-';
            app.AreaButton = uibutton(app.GeometryAnalysisPanel, 'push');
            app.AreaButton.ButtonPushedFcn = createCallbackFcn(app, @AreaButtonPushed, true);
            app.AreaButton.Text = 'Area';
            app.PerimeterButton = uibutton(app.GeometryAnalysisPanel, 'push');
            app.PerimeterButton.ButtonPushedFcn = createCallbackFcn(app, @PerimeterButtonPushed, true);
            app.PerimeterButton.Text = 'Perimeter';
            app.PerimetervstimeButton = uibutton(app.GeometryAnalysisPanel, 'push');
            app.PerimetervstimeButton.ButtonPushedFcn = createCallbackFcn(app, @PerimetervstimeButtonPushed, true);
            app.PerimetervstimeButton.Text = 'Perimeter vs time';
            app.AreavstimeButton = uibutton(app.GeometryAnalysisPanel, 'push');
            app.AreavstimeButton.ButtonPushedFcn = createCallbackFcn(app, @AreavstimeButtonPushed, true);
            app.AreavstimeButton.Text = 'Area vs time';
            app.CellForcesAnalysisPanel = uipanel(app.AnalysisPanel);
            app.ForceDropDownLabel = uilabel(app.CellForcesAnalysisPanel);
            app.ForceDropDownLabel.Text = 'Force';
            app.ForceDropDown = uidropdown(app.CellForcesAnalysisPanel);
            app.ForceDropDown.Items = {};
            app.ForceDropDown.Value = {};
            app.TypeDropDownLabel = uilabel(app.CellForcesAnalysisPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.CellForcesAnalysisPanel);
            app.TypeDropDown.Items = {'Average', 'Maximum', 'Minimum'};
            app.TypeDropDown.Value = 'Average';
            app.CellsListBoxLabel = uilabel(app.CellForcesAnalysisPanel);
            app.CellsListBoxLabel.Text = 'Cells';
            app.CellsListBox = uilistbox(app.CellForcesAnalysisPanel);
            app.CellsListBox.Multiselect = 'on';
            app.CellsListBox.Value = {'Item 1'};
            app.PlotButton_2 = uibutton(app.CellForcesAnalysisPanel, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.PointlikeAnalysisPanel = uipanel(app.AnalysisPanel);
            app.ValidationPanel = uipanel(app.PointlikeAnalysisPanel);
            app.ValidationPanel.Title = 'Validation';
            app.kPaLabel_2 = uilabel(app.ValidationPanel);
            app.kPaLabel_2.Text = 'kPa';
            app.SubstratestiffnessDropDownLabel = uilabel(app.ValidationPanel);
            app.SubstratestiffnessDropDownLabel.Text = 'Substrate stiffness';
            app.SubstratestiffnessDropDown = uidropdown(app.ValidationPanel);
            app.SubstratestiffnessDropDown.Items = {'1.1', '4.5', '11', '35'};
            app.SubstratestiffnessDropDown.Value = '1.1';
            app.SingleButton = uibutton(app.ValidationPanel, 'push');
            app.SingleButton.ButtonPushedFcn = createCallbackFcn(app, @SingleButtonPushed, true);
            app.SingleButton.Text = 'Single';
            app.DistrubtionButton = uibutton(app.ValidationPanel, 'push');
            app.DistrubtionButton.ButtonPushedFcn = createCallbackFcn(app, @DistrubtionButtonPushed, true);
            app.DistrubtionButton.Text = 'Distrubtion';
            app.AllButton = uibutton(app.ValidationPanel, 'push');
            app.AllButton.ButtonPushedFcn = createCallbackFcn(app, @AllButtonPushed, true);
            app.AllButton.Text = 'All';
            app.DisplacementforceanalysisButton = uibutton(app.PointlikeAnalysisPanel, 'push');
            app.DisplacementforceanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @DisplacementforceanalysisButtonPushed, true);
            app.DisplacementforceanalysisButton.Text = 'Displacement & force analysis';
            app.OptogeneticAnalysisPanel = uipanel(app.AnalysisPanel);
            app.ValidationPanel_2 = uipanel(app.OptogeneticAnalysisPanel);
            app.ValidationPanel_2.Title = 'Validation';
            app.SelectcellsButton_2 = uibutton(app.ValidationPanel_2, 'push');
            app.SelectcellsButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectcellsButton_2Pushed, true);
            app.SelectcellsButton_2.Text = 'Select cells';
            app.ResetactivationselectionButton = uibutton(app.ValidationPanel_2, 'push');
            app.ResetactivationselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ResetactivationselectionButtonPushed, true);
            app.ResetactivationselectionButton.Tooltip = {'Reset'};
            app.ResetactivationselectionButton.Text = '';
            app.CopypreviouscellsButton = uibutton(app.ValidationPanel_2, 'push');
            app.CopypreviouscellsButton.ButtonPushedFcn = createCallbackFcn(app, @CopypreviouscellsButtonPushed, true);
            app.CopypreviouscellsButton.Tooltip = {'Copy the previous cell selections'};
            app.CopypreviouscellsButton.Text = '';
            app.AnalyzejunctionlengthButton = uibutton(app.ValidationPanel_2, 'push');
            app.AnalyzejunctionlengthButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzejunctionlengthButtonPushed, true);
            app.AnalyzejunctionlengthButton.Enable = 'off';
            app.AnalyzejunctionlengthButton.Text = 'Analyze junction length';
            app.DisplacementforceanalysisButton_2 = uibutton(app.OptogeneticAnalysisPanel, 'push');
            app.DisplacementforceanalysisButton_2.ButtonPushedFcn = createCallbackFcn(app, @DisplacementforceanalysisButton_2Pushed, true);
            app.DisplacementforceanalysisButton_2.Text = 'Displacement & force analysis';
            app.GlassAnalysisPanel = uipanel(app.AnalysisPanel);
            app.MaxdisplacementsButton = uibutton(app.GlassAnalysisPanel, 'push');
            app.MaxdisplacementsButton.ButtonPushedFcn = createCallbackFcn(app, @MaxdisplacementsButtonPushed, true);
            app.MaxdisplacementsButton.Text = 'Max displacements';
            app.DisplacementareaplotButton = uibutton(app.GlassAnalysisPanel, 'push');
            app.DisplacementareaplotButton.ButtonPushedFcn = createCallbackFcn(app, @DisplacementareaplotButtonPushed, true);
            app.DisplacementareaplotButton.Text = 'Displacement area plot';
            app.ActivationareacellforcesPanel = uipanel(app.GlassAnalysisPanel);
            app.ActivationareacellforcesPanel.Title = 'Activation area cell forces';
            app.PlotButton_3 = uibutton(app.ActivationareacellforcesPanel, 'push');
            app.PlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_3Pushed, true);
            app.PlotButton_3.Text = 'Plot';
            app.mLabel_2 = uilabel(app.ActivationareacellforcesPanel);
            app.mLabel_2.Text = 'µm';
            app.ForceDropDown_3Label = uilabel(app.ActivationareacellforcesPanel);
            app.ForceDropDown_3Label.Text = 'Force';
            app.ForceDropDown_3 = uidropdown(app.ActivationareacellforcesPanel);
            app.ForceDropDown_3.Items = {};
            app.ForceDropDown_3.Value = {};
            app.TypeDropDown_2Label = uilabel(app.ActivationareacellforcesPanel);
            app.TypeDropDown_2Label.Text = 'Type';
            app.TypeDropDown_2 = uidropdown(app.ActivationareacellforcesPanel);
            app.TypeDropDown_2.Items = {'Distribution', 'Separate'};
            app.TypeDropDown_2.Value = 'Distribution';
            app.BufferEditFieldLabel = uilabel(app.ActivationareacellforcesPanel);
            app.BufferEditFieldLabel.Text = 'Buffer';
            app.BufferEditField = uieditfield(app.ActivationareacellforcesPanel, 'numeric');
            app.BufferEditField.Value = 10;
            app.ClearButton = uibutton(app.PlotandanalyzeTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Enable = 'off';
            app.ClearButton.Visible = 'off';
            app.ClearButton.Text = 'Clear';
            app.LoadedfilesDropDown = uidropdown(app.PlotandanalyzeTab);
            app.LoadedfilesDropDown.Items = {};
            app.LoadedfilesDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadedfilesDropDownValueChanged, true);
            app.LoadedfilesDropDown.Value = {};
            app.ParametersPanel_2 = uipanel(app.PlotandanalyzeTab);
            app.ParametersPanel_2.Title = 'Parameters';
            app.ImportParameterDropDown = uidropdown(app.ParametersPanel_2);
            app.ImportParameterDropDown.Items = {};
            app.ImportParameterDropDown.Value = {};
            app.ShowButton = uibutton(app.ParametersPanel_2, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.PostparameterstipsImage = uiimage(app.PlotandanalyzeTab);
            app.PostparameterstipsImage.Visible = 'off';
            app.PostparameterstipsImage.Tooltip = {''};
            app.SpecialplottingtipsImage = uiimage(app.PlotandanalyzeTab);
            app.SpecialplottingtipsImage.Visible = 'off';
            app.SpecialplottingtipsImage.Tooltip = {''};
            app.AnalysistipsImage = uiimage(app.PlotandanalyzeTab);
            app.AnalysistipsImage.Visible = 'off';
            app.AnalysistipsImage.Tooltip = {''};
            app.PostplottingoptionstipsImage = uiimage(app.PlotandanalyzeTab);
            app.PostplottingoptionstipsImage.Visible = 'off';
            app.PostplottingoptionstipsImage.Tooltip = {''};
            app.PostplottingtipsImage = uiimage(app.PlotandanalyzeTab);
            app.PostplottingtipsImage.Visible = 'off';
            app.PostplottingtipsImage.Tooltip = {''};
            app.AnimationtipsImage = uiimage(app.PlotandanalyzeTab);
            app.AnimationtipsImage.Visible = 'off';
            app.AnimationtipsImage.Tooltip = {''};
            app.logoImage = uiimage(app.EpiMechUIFigure);
            app.logoImage.Tooltip = {''};
            % Show the figure after all components are created
            app.EpiMechUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = epimech_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EpiMechUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EpiMechUIFigure)
        end
    end
end