classdef about_dialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AboutUIFigure            matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        LogobyTVRohdosLabel      matlab.ui.control.Label
        byAapoTervonen2023Label  matlab.ui.control.Label
        VersionLabel             matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        CloseButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
        
    end
    
    properties (Access = public)
        enabledObjects = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.AboutUIFigure.Visible = 'Off';
            app.CallingApp = mainapp;
            mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;

            app.AboutUIFigure.Position(1) = mainWindowPosition(1) + mainWindowPosition(3)/2 - app.AboutUIFigure.Position(3)/2;
            app.AboutUIFigure.Position(2) = mainWindowPosition(2) + mainWindowPosition(4)/2 - app.AboutUIFigure.Position(4)/2;
                      
            app.VersionLabel.Text = ['EpiMech v' app.CallingApp.version];
            
            app.AboutUIFigure.Icon = [app.CallingApp.defaultPath 'settings/images/logo_v2.png'];
            
            app.Image.ImageSource = [app.CallingApp.defaultPath 'settings/images/logo_v2.png'];
            
            app.AboutUIFigure.Visible = 'On';
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            disable_enable_all_function(app.CallingApp,'On');

            figure(app.CallingApp.EpiMechUIFigure)
            delete(app)
        end

        % Close request function: AboutUIFigure
        function AboutUIFigureCloseRequest(app, event)
            disable_enable_all_function(app.CallingApp,'On');

            figure(app.CallingApp.EpiMechUIFigure)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AboutUIFigure = uifigure('Visible', 'off');
            app.AboutUIFigure.Name = 'About EpiMechanics';
            app.AboutUIFigure.CloseRequestFcn = createCallbackFcn(app, @AboutUIFigureCloseRequest, true);
            app.CloseButton = uibutton(app.AboutUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Panel = uipanel(app.AboutUIFigure);
            app.Image = uiimage(app.Panel);
            app.VersionLabel = uilabel(app.Panel);
            app.VersionLabel.Text = 'EpiMech - v1.0.6';
            app.byAapoTervonen2023Label = uilabel(app.Panel);
            app.byAapoTervonen2023Label.Text = 'by Aapo Tervonen 2023';
            app.LogobyTVRohdosLabel = uilabel(app.Panel);
            app.LogobyTVRohdosLabel.Text = 'Logo by T.V. Rohdos';
            % Show the figure after all components are created
            app.AboutUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = about_dialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AboutUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AboutUIFigure)
        end
    end
end