classdef diplacementAndForceAnalysisDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SavetofileButton              matlab.ui.control.Button
        RemovelastplotButton          matlab.ui.control.Button
        SimulationListBox             matlab.ui.control.ListBox
        TabGroup                      matlab.ui.container.TabGroup
        DisplacementTab               matlab.ui.container.Tab
        ContoursEditField             matlab.ui.control.EditField
        ContoursEditFieldLabel        matlab.ui.control.Label
        mLabel                        matlab.ui.control.Label
        MaximumdisplacementEditField  matlab.ui.control.NumericEditField
        MaximumdisplacementEditFieldLabel  matlab.ui.control.Label
        mLabel_2                      matlab.ui.control.Label
        WindowsizeEditField           matlab.ui.control.NumericEditField
        WindowsizeEditFieldLabel      matlab.ui.control.Label
        EndDropDown                   matlab.ui.control.DropDown
        EndDropDownLabel              matlab.ui.control.Label
        StartDropDown                 matlab.ui.control.DropDown
        StartDropDownLabel            matlab.ui.control.Label
        AreaplotPanel                 matlab.ui.container.Panel
        ContoursCheckBox              matlab.ui.control.CheckBox
        AreaplotButton                matlab.ui.control.Button
        DisplacementTypeButtonGroup   matlab.ui.container.ButtonGroup
        SubstrateButton               matlab.ui.control.RadioButton
        CellsButton                   matlab.ui.control.RadioButton
        DplotButtonGroup              matlab.ui.container.ButtonGroup
        ColorpickerCheckBox           matlab.ui.control.CheckBox
        LegendEditField               matlab.ui.control.EditField
        LegendEditFieldLabel          matlab.ui.control.Label
        NewplotCheckBox               matlab.ui.control.CheckBox
        LogyaxisCheckBox              matlab.ui.control.CheckBox
        PlotmeanButton                matlab.ui.control.Button
        PlotallButton                 matlab.ui.control.Button
        HorizontalCheckBox            matlab.ui.control.CheckBox
        VerticalfullButton            matlab.ui.control.RadioButton
        VerticalhalfButton            matlab.ui.control.RadioButton
        ForcesTab                     matlab.ui.container.Tab
        TypeDropDown                  matlab.ui.control.DropDown
        TypeDropDownLabel             matlab.ui.control.Label
        ForceDropDown                 matlab.ui.control.DropDown
        ForceDropDownLabel            matlab.ui.control.Label
        WindowsizeEditField_2         matlab.ui.control.NumericEditField
        WindowsizeEditField_2Label    matlab.ui.control.Label
        TimePointDropDown             matlab.ui.control.DropDown
        TimePointDropDownLabel        matlab.ui.control.Label
        mLabel_3                      matlab.ui.control.Label
        AreaplotPanel_2               matlab.ui.container.Panel
        AreaplotButton_2              matlab.ui.control.Button
        DplotButtonGroup_2            matlab.ui.container.ButtonGroup
        ColorpickerCheckBox_2         matlab.ui.control.CheckBox
        LegendEditField_2             matlab.ui.control.EditField
        LegendEditField_2Label        matlab.ui.control.Label
        NewplotCheckBox_2             matlab.ui.control.CheckBox
        LogyaxisCheckBox_2            matlab.ui.control.CheckBox
        PlotmeanButton_2              matlab.ui.control.Button
        PlotallButton_2               matlab.ui.control.Button
        HorizontalCheckBox_2          matlab.ui.control.CheckBox
        VerticalfullButton_2          matlab.ui.control.RadioButton
        VerticalhalfButton_2          matlab.ui.control.RadioButton
        CloseButton                   matlab.ui.control.Button
    end


    properties (Access = private)
        CallingApp % Description
        analysisFigureHandle = 0;
        numberOfAnalysisPlots = 0;
        barChartData = [];
        plotHandle = {};
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)

            app.UIFigure.Visible = 'Off';
            app.CallingApp = mainapp;

            mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;

            app.UIFigure.Position(1) = mainWindowPosition(1) + mainWindowPosition(3)/2 - app.UIFigure.Position(3)/2;
            app.UIFigure.Position(2) = mainWindowPosition(2) + mainWindowPosition(4)/2 - app.UIFigure.Position(4)/2;

            for i = 1:length(app.CallingApp.plotImport)
                if strcmp(app.CallingApp.plotImport(i).simulationType,'pointlike')
                    path = fileparts(app.CallingApp.plotImport(i).folderName);
                    fileName = app.CallingApp.plotImport(i).folderName(length(path)+2:end);
                    app.SimulationListBox.Items{end+1} = fileName;
                end
            end
            app.UIFigure.Visible = 'On';

        end

        % Callback function: CloseButton, UIFigure
        function UIFigureCloseRequest(app, event)
            disable_enable_all_function(app.CallingApp,'On');

            figure(app.CallingApp.EpiMechUIFigure);
            delete(app);
        end

        % Value changed function: SimulationListBox
        function SimulationListBoxValueChanged(app, event)

            updateTimes = false;
            if length(app.SimulationListBox.Value) == 1

                for ii = 1:length(app.CallingApp.LoadedfilesDropDown.Items)
                    if isa(length(app.SimulationListBox.Value),'char')
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value(i))
                            fileIdx = ii;
                            break
                        end
                    else
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value)
                            fileIdx = ii;
                            break
                        end
                    end
                end

                if ~isempty(app.StartDropDown.Items)

                    if length(app.StartDropDown.Items) == app.CallingApp.plotImport(fileIdx).nTimePoints
                        d.spar = app.CallingApp.plotImport(fileIdx).scaledParameters;
                        timeScalingFactor = 1/(app.CallingApp.plotImport(fileIdx).systemParameters.scalingTime*app.CallingApp.plotImport(fileIdx).exportOptions.exportDt);
                        timeString = separate_times(d,(app.CallingApp.plotImport(fileIdx).nTimePoints-1)./timeScalingFactor);
                        if ~strcmp(timeString,app.StartDropDown.Items{end})
                            updateTimes = true;
                        end
                    else
                        updateTimes = true;
                    end
                else
                    updateTimes = true;

                end
                if updateTimes

                    app.StartDropDown.Items = {};
                    app.EndDropDown.Items = {};

                    app.TimePointDropDown.Items = {};

                    d.spar = app.CallingApp.plotImport(fileIdx).scaledParameters;
                    numTemp = num2cell(1:app.CallingApp.plotImport(fileIdx).nTimePoints);

                    timeScalingFactor = 1/(app.CallingApp.plotImport(fileIdx).systemParameters.scalingTime*app.CallingApp.plotImport(fileIdx).exportOptions.exportDt);

                    timeStrings = cellfun(@(i) separate_times(d,(i-1)./timeScalingFactor),numTemp,'UniformOutput',false);
                    app.StartDropDown.Items = timeStrings;
                    app.EndDropDown.Items = timeStrings;
                    app.StartDropDown.Value = app.StartDropDown.Items(1);
                    app.EndDropDown.Value = app.EndDropDown.Items(end);

                    app.TimePointDropDown.Items = timeStrings;
                    app.TimePointDropDown.Value = app.TimePointDropDown.Items(end);

                end
            end

            for i = 1:length(app.SimulationListBox.Value)


                for ii = 1:length(app.CallingApp.LoadedfilesDropDown.Items)
                    if isa(length(app.SimulationListBox.Value),'char')
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value(i))
                            fileIdx = ii;
                            break
                        end
                    else
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value{i})
                            fileIdx = ii;
                            break
                        end
                    end
                end

                folderName = app.CallingApp.plotImport(fileIdx).folderName;

                forceItems = app.ForceDropDown.Items;


                if exist([folderName '/cell_forces/total/'],'dir') == 7
                    temp = strcmp(forceItems,'Total forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Total forces'];
                    end
                else
                    temp = strcmp(forceItems,'Total forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/cell_forces/cortical/'],'dir') == 7
                    temp = strcmp(forceItems,'Cortical forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Cortical forces'];
                    end
                else
                    temp = strcmp(forceItems,'Cortical forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/cell_forces/junction/'],'dir') == 7
                    temp = strcmp(forceItems,'Junction forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Junction forces'];
                    end
                else
                    temp = strcmp(forceItems,'Junction forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/cell_forces/division/'],'dir') == 7
                    temp = strcmp(forceItems,'Division forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Division forces'];
                    end
                else
                    temp = strcmp(forceItems,'Division forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/cell_forces/membrane/'],'dir') == 7
                    temp = strcmp(forceItems,'Membrane forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Membrane forces'];
                    end
                else
                    temp = strcmp(forceItems,'Membrane forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/cell_forces/contact/'],'dir') == 7
                    temp = strcmp(forceItems,'Contact forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Contact forces'];
                    end
                else
                    temp = strcmp(forceItems,'Contact forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/cell_forces/area/'],'dir') == 7
                    temp = strcmp(forceItems,'Area forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Area forces'];
                    end
                else
                    temp = strcmp(forceItems,'Area forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/cell_forces/pointlike/'],'dir') == 7
                    temp = strcmp(forceItems,'Pointlike forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Pointlike forces'];
                    end
                else
                    temp = strcmp(forceItems,'Pointlike forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/cell_forces/focal_adhesion/'],'dir') == 7
                    temp = strcmp(forceItems,'Focal adhesion forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Focal adhesion forces'];
                    end
                else
                    temp = strcmp(forceItems,'Focal adhesion forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/substrate_forces/total/'],'dir') == 7
                    temp = strcmp(forceItems,'Total substrate forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Total substrate forces'];
                    end
                else
                    temp = strcmp(forceItems,'Total substrate forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/substrate_forces/direct/'],'dir') == 7
                    temp = strcmp(forceItems,'Direct substrate forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Direct substrate forces'];
                    end
                else
                    temp = strcmp(forceItems,'Direct substrate forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/substrate_forces/restoration/'],'dir') == 7
                    temp = strcmp(forceItems,'Restorative substrate forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Restorative substrate forces'];
                    end
                else
                    temp = strcmp(forceItems,'Restorative substrate forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/substrate_forces/repulsion/'],'dir') == 7
                    temp = strcmp(forceItems,'Repulsion substrate forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Repulsion substrate forces'];
                    end
                else
                    temp = strcmp(forceItems,'Repulsion substrate forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/substrate_forces/focal_adhesion/'],'dir') == 7
                    temp = strcmp(forceItems,'Focal adhesion substrate forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Focal adhesion substrate forces'];
                    end
                else
                    temp = strcmp(forceItems,'Focal adhesion substrate forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

            end

            app.ForceDropDown.Items = forceItems;


            if ~isempty(app.SimulationListBox.Value)
                app.AreaplotButton.Enable = 'on';
                app.AreaplotButton_2.Enable = 'on';
                app.PlotallButton.Enable = 'on';
                app.PlotallButton_2.Enable = 'on';
                app.PlotmeanButton.Enable = 'on';
                app.PlotmeanButton_2.Enable = 'on';
            end
        end

        % Button pushed function: AreaplotButton
        function AreaplotButtonPushed(app, event)
            [xq,yq] = meshgrid(-400:1:400,-400:1:400);

            initialTime = find(strcmp(app.StartDropDown.Items, app.StartDropDown.Value));
            endTime = find(strcmp(app.EndDropDown.Items, app.EndDropDown.Value));


            dispData = zeros(length(xq(:,1)),length(xq(1,:)),length(app.SimulationListBox.Value));

            previousSelected = app.CallingApp.selectedFile;

            for i = 1:length(app.SimulationListBox.Value)

                for ii = 1:length(app.CallingApp.LoadedfilesDropDown.Items)
                    if isa(length(app.SimulationListBox.Value),'char')
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value(i))
                            fileIdx = ii;
                            break
                        end
                    else
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value{i})
                            fileIdx = ii;
                            break
                        end
                    end
                end

                app.CallingApp.selectedFile = fileIdx;

                d.spar.scalingLength = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength;
                d.spar.scalingTime = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingTime;
                d.spar.rCell = app.CallingApp.plotImport(fileIdx).scaledParameters.rCell;


                d.pl.substrateStyle = 1;
                d.pl.substrateForcesDirect = 0;
                d.pl.substrateForcesRepulsion = 0;
                d.pl.substrateForcesRestoration = 0;
                d.pl.substrateForcesFocalAdhesions = 0;
                d.pl.substrateForcesTotal = 0;
                d.pl.titleType = 0;
                d.pl.windowSize = app.CallingApp.importPlottingOptions.windowSize;
                d.pl.axisLimits = [app.CallingApp.MiddlepointxEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointxEditField_2.Value*1e-6+d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointyEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointyEditField_2.Value*1e-6+d.pl.windowSize/2]/d.spar.scalingLength;


                d.simset.simulationType = 2;

                oldTimePoint = app.CallingApp.plotImport(fileIdx).currentTimePoint;

                temp = import_settings([app.CallingApp.plotImport(fileIdx).folderName '/pointlike/pointlike_data.csv']);

                pointLikeCell = temp.cell;

                if app.CellsButton.Value
                    app.CallingApp.plotImport(fileIdx).currentTimePoint = initialTime;

                    initialCells = import_cells(app.CallingApp,'post_plotting');

                    app.CallingApp.plotImport(fileIdx).currentTimePoint = endTime;

                    endCells = import_cells(app.CallingApp,'post_plotting');

                    initialCellCenterX = zeros(1,length(initialCells));
                    initialCellCenterY = zeros(1,length(initialCells));
                    finalCellCenterX = zeros(1,length(endCells));
                    finalCellCenterY = zeros(1,length(endCells));


                    for k = 1:length(endCells)
                        initialCellCenterX(k) = mean(initialCells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                        initialCellCenterY(k) = mean(initialCells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                        finalCellCenterX(k) = mean(endCells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                        finalCellCenterY(k) = mean(endCells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                    end

                    displacements = sqrt((initialCellCenterX - finalCellCenterX).^2 + (initialCellCenterY - finalCellCenterY).^2);

                    moveX = -initialCellCenterX(pointLikeCell);
                    moveY = -initialCellCenterY(pointLikeCell);

                    initialCellCenterX = initialCellCenterX + moveX;
                    initialCellCenterY = initialCellCenterY + moveY;

                    dispData(:,:,i) = griddata(initialCellCenterX,initialCellCenterY,displacements,xq,yq,'cubic');

                else
                    if exist([app.CallingApp.plotImport(fileIdx).folderName '/substrate'],'dir') == 7

                        importedPoints = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate/substrate_points_' num2str(initialTime) '.csv']);
                        initialPointsX = importedPoints(:,1);
                        initialPointsY = importedPoints(:,2);

                        importedPoints = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate/substrate_points_' num2str(endTime) '.csv']);
                        endPointsX = importedPoints(:,1);
                        endPointsY = importedPoints(:,2);

                        initialCells = import_cells(app.CallingApp,'post_plotting','initial');

                        moveX = -mean(initialCells(pointLikeCell).verticesX).*d.spar.scalingLength.*1e6;
                        moveY = -mean(initialCells(pointLikeCell).verticesY).*d.spar.scalingLength.*1e6;

                        displacements = sqrt((endPointsX - initialPointsX).^2 + (endPointsY - initialPointsY).^2)*d.spar.scalingLength/1e-6;

                        subX = initialPointsX*d.spar.scalingLength/1e-6 + moveX;
                        subY = initialPointsY*d.spar.scalingLength/1e-6 + moveY;

                        dispData(:,:,i) = griddata(subX,subY,displacements,xq,yq,'cubic');

                    else
                        app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;
                        app.CallingApp.selectedFile = previousSelected;
                        uialert(app.UIFigure, ['Selected simulation number ' num2str(i) ' does not have substrate data.'],'Data missing');
                        return
                    end

                end
                app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;

            end

            dispData = mean(dispData,3);


            if (~ishandle(app.CallingApp.plotHandle) || app.CallingApp.plotHandle == 0) && ~ishandle(1)
                mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;
                d.pl.figureHandle = figure(1);
                d.pl.figureHandle.Position(1) = mainWindowPosition(1) + 600;
                d.pl.figureHandle.Position(2) = mainWindowPosition(2) + 200;
                app.CallingApp.plotHandle = figure(1);
            else
                d.pl.figureHandle = figure(1);
                clf;
                app.CallingApp.plotHandle = figure(1);
            end

            d.pl.axesHandle = axes;

            figureSize = d.pl.figureHandle.Position(3:4);
            bottomGap = 10/figureSize(2);
            topGap = 10/figureSize(2);
            horzGap = 10/figureSize(1);
            axisHeight = 1 - bottomGap - topGap;
            axisWidth = 1 - 2*horzGap;
            d.pl.axesHandle.Position = [horzGap bottomGap axisWidth axisHeight];

            axisHeight = d.pl.figureHandle.Position(4)*d.pl.axesHandle.Position(4);
            axisWidth = d.pl.figureHandle.Position(3)*d.pl.axesHandle.Position(3);
            if axisHeight > axisWidth
                axisSizeInPixels = axisWidth;
            else
                axisSizeInPixels = axisHeight;
            end

            titleFontSize = axisSizeInPixels/40;
            if titleFontSize > 15
                titleFontSize = 15;
            elseif titleFontSize < 10
                titleFontSize = 10;
            end

            d.pl.axesHandle.Title.FontSize = titleFontSize;

            if any(d.pl.titleType == [2 4 8 10])
                topGap = 20/figureSize(2) + 2*d.pl.axesHandle.Title.FontSize/figureSize(2);
            else
                topGap = 20/figureSize(2) + d.pl.axesHandle.Title.FontSize/figureSize(2);
            end
            d.pl.axesHandle.Position(4) = 1 - bottomGap - topGap;

            axis(app.WindowsizeEditField.Value.*[-0.5 0.5 -0.5 0.5])

            axis square

            d.pl.axesHandle.TickLength = [0 0];
            d.pl.axesHandle.XTick = [];
            d.pl.axesHandle.XTickLabel = [];
            d.pl.axesHandle.YTick = [];
            d.pl.axesHandle.YTickLabel = [];
            d.pl.axesHandle.XColor = 'none';
            d.pl.axesHandle.YColor = 'none';

            set_figure_callbacks(d,app.CallingApp)

            %             set_figure_callbacks(d,app.CallingApp)
            hold on
            if ~app.ContoursCheckBox.Value
                contourf(d.pl.axesHandle,xq,yq,dispData,256,'LineColor','none');
            else
                levels = textscan(app.ContoursEditField.Value,'%f');
                contourf(d.pl.axesHandle,xq,yq,dispData,levels{1},'LineColor','none');
            end

            mymap = csvread('./settings/Misc/mymap.csv');
            colormap(mymap)
            colorbar
            caxis([0, app.MaximumdisplacementEditField.Value]);
            hold off

            app.CallingApp.selectedFile = previousSelected;
            %             plot_title(d,time,0,0);

            %             plot_scale_bar(d)

        end

        % Button pushed function: PlotallButton
        function PlotallButtonPushed(app, event)

            colorspec = {'r','b','g','c','m','k'};

            initialTime = find(strcmp(app.StartDropDown.Items, app.StartDropDown.Value));
            endTime = find(strcmp(app.EndDropDown.Items, app.EndDropDown.Value));

            if app.VerticalhalfButton.Value
                [xq,yq] = meshgrid(-400:1:0,-400:1:0);
                middlePoint = size(yq,1);
            else
                [xq,yq] = meshgrid(-400:1:0,-400:1:400);
                middlePoint = round(size(yq,1)/2);
            end

            yPositions =  yq(:,1);
            xPositions = xq(1,:);

            if isa(app.analysisFigureHandle,'double') || (~isa(app.analysisFigureHandle,'double') && ~isvalid(app.analysisFigureHandle)) || app.NewplotCheckBox.Value
                app.analysisFigureHandle = figure(2);
                clf
                app.numberOfAnalysisPlots = 0;
                mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;
                app.plotHandle = {};

                app.analysisFigureHandle.Position(1) = mainWindowPosition(1) + 600;
                app.analysisFigureHandle.Position(2) = mainWindowPosition(2);

                res = get(0,'screensize');

                position = get(gcf, 'Position');

                set(gcf, 'Position',  [position(1), position(2), 895, 895])

                position = get(gcf, 'Position');

                if sum(position([2 4])) + 100 > res(4)
                    position(2) = res(4) - position(4) - 100;
                end
                app.analysisFigureHandle.Position(2) = position(2);

            else
                app.analysisFigureHandle;
            end

            if ~app.ColorpickerCheckBox.Value
                if app.numberOfAnalysisPlots >= length(colorspec)
                    color = 'r';
                else
                    app.numberOfAnalysisPlots = app.numberOfAnalysisPlots + 1;
                    color = colorspec{app.numberOfAnalysisPlots};
                end
            else

                color = uisetcolor;
            end


            if ~app.HorizontalCheckBox.Value
                app.analysisFigureHandle.Position(3:4) = [400 800];
            else
                app.analysisFigureHandle.Position(3:4) = [800 800];
            end

            %             cellVertical = zeros(size(yq,1),length(app.SimulationListBox.Value));
            %             subVertical = cellVertical;
            %
            %             if app.HorizontalCheckBox.Value
            %                 cellHorizontal = zeros(size(xq,2),length(app.SimulationListBox.Value));
            %                 subHorizontal = cellHorizontal;
            %             end

            previousSelected = app.CallingApp.selectedFile;

            for i = 1:length(app.SimulationListBox.Value)

                for ii = 1:length(app.CallingApp.LoadedfilesDropDown.Items)
                    if isa(length(app.SimulationListBox.Value),'char')
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value(i))
                            fileIdx = ii;
                            break
                        end
                    else
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value{i})
                            fileIdx = ii;
                            break
                        end
                    end
                end

                app.CallingApp.selectedFile = fileIdx;

                d.spar.scalingLength = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength;
                d.spar.scalingTime = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingTime;
                d.spar.rCell = app.CallingApp.plotImport(fileIdx).scaledParameters.rCell;

                d.pl.substrateStyle = 1;
                d.pl.substrateForcesDirect = 0;
                d.pl.substrateForcesRepulsion = 0;
                d.pl.substrateForcesRestoration = 0;
                d.pl.substrateForcesFocalAdhesions = 0;
                d.pl.substrateForcesTotal = 0;
                d.pl.titleType = 0;
                d.pl.windowSize = app.CallingApp.importPlottingOptions.windowSize;
                d.pl.axisLimits = [app.CallingApp.MiddlepointxEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointxEditField_2.Value*1e-6+d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointyEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointyEditField_2.Value*1e-6+d.pl.windowSize/2]/d.spar.scalingLength;


                d.simset.simulationType = 2;

                oldTimePoint = app.CallingApp.plotImport(fileIdx).currentTimePoint;

                temp = import_settings([app.CallingApp.plotImport(fileIdx).folderName '/pointlike/pointlike_data.csv']);

                pointLikeCell = temp.cell;

                app.CallingApp.plotImport(fileIdx).currentTimePoint = initialTime;

                initialCells = import_cells(app.CallingApp,'post_plotting');

                app.CallingApp.plotImport(fileIdx).currentTimePoint = endTime;

                endCells = import_cells(app.CallingApp,'post_plotting');

                initialCellCenterX = zeros(1,length(initialCells));
                initialCellCenterY = zeros(1,length(initialCells));
                finalCellCenterX = zeros(1,length(endCells));
                finalCellCenterY = zeros(1,length(endCells));

                for k = 1:length(endCells)
                    initialCellCenterX(k) = mean(initialCells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                    initialCellCenterY(k) = mean(initialCells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                    finalCellCenterX(k) = mean(endCells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                    finalCellCenterY(k) = mean(endCells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                end

                displacements = sqrt((initialCellCenterX - finalCellCenterX).^2 + (initialCellCenterY - finalCellCenterY).^2);

                moveX = -initialCellCenterX(pointLikeCell);
                moveY = -initialCellCenterY(pointLikeCell);

                initialCellCenterX = initialCellCenterX + moveX;
                initialCellCenterY = initialCellCenterY + moveY;

                dispData = griddata(initialCellCenterX,initialCellCenterY,displacements,xq,yq,'cubic');

                cellVertical = dispData(:,end);
                if app.HorizontalCheckBox.Value
                    cellHorizontal = dispData(middlePoint,1:end);
                end

                if exist([app.CallingApp.plotImport(fileIdx).folderName '/substrate'],'dir') == 7

                    importedPoints = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate/substrate_points_' num2str(initialTime) '.csv']);
                    initialPointsX = importedPoints(:,1);
                    initialPointsY = importedPoints(:,2);

                    importedPoints = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate/substrate_points_' num2str(endTime) '.csv']);
                    endPointsX = importedPoints(:,1);
                    endPointsY = importedPoints(:,2);

                    initialCells = import_cells(app.CallingApp,'post_plotting','initial');

                    moveX = -mean(initialCells(pointLikeCell).verticesX).*d.spar.scalingLength.*1e6;
                    moveY = -mean(initialCells(pointLikeCell).verticesY).*d.spar.scalingLength.*1e6;

                    displacements = sqrt((endPointsX - initialPointsX).^2 + (endPointsY - initialPointsY).^2)*d.spar.scalingLength/1e-6;

                    subX = initialPointsX*d.spar.scalingLength/1e-6 + moveX;
                    subY = initialPointsY*d.spar.scalingLength/1e-6 + moveY;

                    dispData = griddata(subX,subY,displacements,xq,yq,'cubic');

                    subVertical = dispData(:,end);
                    if app.HorizontalCheckBox.Value
                        subHorizontal = dispData(middlePoint,1:end);
                    end

                else
                    app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;
                    app.CallingApp.selectedFile = previousSelected;
                    uialert(app.UIFigure, ['Selected simulation number ' num2str(i) ' does not have substrate data.'],'Data missing');
                    return
                end

                app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;


                plotHandleTemp = {};

                if app.HorizontalCheckBox.Value

                    app.analysisFigureHandle;
                    subplot(2,2,1);
                    hold on
                    if i == 1 && ~strcmp(app.LegendEditField.Value,'')
                        fh = plot(yPositions(1:5:end),cellVertical(1:5:end),'Color',color,'LineWidth',1,'DisplayName',app.LegendEditField.Value);
                        legend('show', 'Interpreter', 'none');
                        app.LegendEditField.Value = '';
                    else
                        fh.CellVertical = plot(yPositions(1:5:end),cellVertical(1:5:end),'Color', color,'LineWidth',1,'HandleVisibility','off');
                    end
                    plotHandleTemp{end+1} = fh;
                    hold off

                    title('Cell vertical diplacement', 'FontSize', 14)
                    if app.VerticalhalfButton.Value
                        if app.LogyaxisCheckBox.Value
                            set(gca,'yscale','log')
                            axis([-app.WindowsizeEditField.Value/2 0 min(min(cellHorizontal(:),min(cellVertical(:))))*0.5 app.MaximumdisplacementEditField.Value])
                        else
                            set(gca,'yscale','linear')
                            axis([-app.WindowsizeEditField.Value/2 0 0 app.MaximumdisplacementEditField.Value])
                        end
                    else
                        if app.LogyaxisCheckBox.Value
                            set(gca,'yscale','log')
                            axis([-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2 min(min(cellHorizontal(:),min(cellVertical(:))))*0.5 app.MaximumdisplacementEditField.Value])
                        else
                            set(gca,'yscale','linear')
                            axis([-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2 0 app.MaximumdisplacementEditField.Value])
                        end
                    end
                    xlabel('Distance downwards ({\mu}m)', 'FontSize', 12)
                    ylabel('Displacement {\mu}m', 'FontSize', 12)


                    subplot(2,2,2);
                    hold on
                    fh = plot(xPositions(1:5:end),cellHorizontal(1:5:end),'Color',color,'LineWidth',1,'HandleVisibility','off');
                    plotHandleTemp{end+1} = fh;
                    hold off

                    title('Cell horizontal diplacement', 'FontSize', 14)
                    if app.LogyaxisCheckBox.Value
                        set(gca,'yscale','log')
                        axis([-app.WindowsizeEditField.Value/2 0 min(min(cellHorizontal(:),min(cellVertical(:))))*0.5 app.MaximumdisplacementEditField.Value])
                    else
                        set(gca,'yscale','linear')
                        axis([-app.WindowsizeEditField.Value/2 0 0 app.MaximumdisplacementEditField.Value])
                    end

                    xlabel('Distance sideways ({\mu}m)', 'FontSize', 12)
                    ylabel('Displacement {\mu}m', 'FontSize', 12)


                    subplot(2,2,3);
                    hold on
                    fh = plot(yPositions(1:5:end),subVertical(1:5:end),'Color',color,'LineWidth',1,'HandleVisibility','off');
                    plotHandleTemp{end+1} = fh;
                    hold off

                    title('Substrate vertical diplacement', 'FontSize', 14)

                    if app.VerticalhalfButton.Value
                        if app.LogyaxisCheckBox.Value
                            set(gca,'yscale','log')
                            axis([-app.WindowsizeEditField.Value/2 0 min(min(subHorizontal(:),min(subVertical(:))))*0.5 app.MaximumdisplacementEditField.Value])
                        else
                            set(gca,'yscale','linear')
                            axis([-app.WindowsizeEditField.Value/2 0 0 app.MaximumdisplacementEditField.Value])
                        end
                    else
                        if app.LogyaxisCheckBox.Value
                            set(gca,'yscale','log')
                            axis([-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2 min(min(subHorizontal(:),min(subVertical(:))))*0.5 app.MaximumdisplacementEditField.Value])
                        else
                            set(gca,'yscale','linear')
                            axis([-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2 0 app.MaximumdisplacementEditField.Value])
                        end
                    end

                    xlabel('Distance downwards ({\mu}m)', 'FontSize', 12)
                    ylabel('Displacement in {\mu}m', 'FontSize', 12)

                    subplot(2,2,4);
                    hold on
                    fh = plot(xPositions(1:5:end),subHorizontal(1:5:end),'Color',color,'LineWidth',1,'HandleVisibility','off');
                    plotHandleTemp{end+1} = fh;
                    hold off

                    title('Substrate horizontal diplacement', 'FontSize', 14)

                    if app.LogyaxisCheckBox.Value
                        set(gca,'yscale','log')
                        axis([-app.WindowsizeEditField.Value/2 0 min(min(subHorizontal(:),min(subVertical(:))))*0.5 app.MaximumdisplacementEditField.Value])
                    else
                        set(gca,'yscale','linear')
                        axis([-app.WindowsizeEditField.Value/2 0 0 app.MaximumdisplacementEditField.Value])
                    end

                    xlabel('Distance sideways ({\mu}m)', 'FontSize', 12)
                    ylabel('Displacement in {\mu}m', 'FontSize', 12)

                else
                    app.analysisFigureHandle;
                    subplot(2,1,1);
                    hold on
                    if i == 1 && ~strcmp(app.LegendEditField.Value,'')
                        fh = plot(yPositions(1:5:end),cellVertical(1:5:end),'Color',color,'LineWidth',1,'DisplayName',app.LegendEditField.Value);
                        legend('show', 'Interpreter', 'none');
                        app.LegendEditField.Value = '';
                    else
                        fh = plot(yPositions(1:5:end),cellVertical(1:5:end),'Color', color,'LineWidth',1,'HandleVisibility','off');
                    end
                    plotHandleTemp{end+1} = fh;
                    hold off

                    title('Cell vertical diplacement', 'FontSize', 14)
                    if app.VerticalhalfButton.Value
                        if app.LogyaxisCheckBox.Value
                            set(gca,'yscale','log')
                            axis([-app.WindowsizeEditField.Value/2 0 min(cellVertical(:))*0.5 app.MaximumdisplacementEditField.Value])
                        else
                            set(gca,'yscale','linear')
                            axis([-app.WindowsizeEditField.Value/2 0 0 app.MaximumdisplacementEditField.Value])
                        end
                    else
                        if app.LogyaxisCheckBox.Value
                            set(gca,'yscale','log')
                            axis([-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2 min(cellVertical(:))*0.5 app.MaximumdisplacementEditField.Value])
                        else
                            set(gca,'yscale','linear')
                            axis([-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2 0 app.MaximumdisplacementEditField.Value])
                        end
                    end
                    xlabel('Distance downwards ({\mu}m)', 'FontSize', 12)
                    ylabel('Displacement {\mu}m', 'FontSize', 12)


                    subplot(2,1,2);
                    hold on
                    fh = plot(yPositions(1:5:end),subVertical(1:5:end),'Color',color,'LineWidth',1,'HandleVisibility','off');
                    plotHandleTemp{end+1} = fh;
                    hold off

                    title('Substrate vertical diplacement', 'FontSize', 14)

                    if app.VerticalhalfButton.Value
                        if app.LogyaxisCheckBox.Value
                            set(gca,'yscale','log')
                            axis([-app.WindowsizeEditField.Value/2 0 min(subVertical(:))*0.5 app.MaximumdisplacementEditField.Value])
                        else
                            set(gca,'yscale','linear')
                            axis([-app.WindowsizeEditField.Value/2 0 0 app.MaximumdisplacementEditField.Value])
                        end
                    else
                        if app.LogyaxisCheckBox.Value
                            set(gca,'yscale','log')
                            axis([-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2 min(subVertical(:))*0.5 app.MaximumdisplacementEditField.Value])
                        else
                            set(gca,'yscale','linear')
                            axis([-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2 0 app.MaximumdisplacementEditField.Value])
                        end
                    end

                    xlabel('Distance downwards ({\mu}m)', 'FontSize', 12)
                    ylabel('Displacement in {\mu}m', 'FontSize', 12)
                end


            end

            app.plotHandle{end+1} = plotHandleTemp;


            app.CallingApp.selectedFile = previousSelected;


        end

        % Button pushed function: PlotmeanButton
        function PlotmeanButtonPushed(app, event)
            colorspec = {'r','b','g','c','m','k'};

            initialTime = find(strcmp(app.StartDropDown.Items, app.StartDropDown.Value));
            endTime = find(strcmp(app.EndDropDown.Items, app.EndDropDown.Value));

            if app.VerticalhalfButton.Value
                [xq,yq] = meshgrid(-400:1:0,-400:1:0);
                middlePoint = size(yq,1);
            else
                [xq,yq] = meshgrid(-400:1:0,-400:1:400);
                middlePoint = round(size(yq,1)/2);
            end

            yPositions =  yq(:,1);
            xPositions = xq(1,:);

            if isa(app.analysisFigureHandle,'double') || (~isa(app.analysisFigureHandle,'double') && ~isvalid(app.analysisFigureHandle)) || app.NewplotCheckBox.Value
                app.analysisFigureHandle = figure(2);
                clf
                app.numberOfAnalysisPlots = 0;
                mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;

                app.plotHandle = {};

                app.analysisFigureHandle.Position(1) = mainWindowPosition(1) + 600;
                app.analysisFigureHandle.Position(2) = mainWindowPosition(2);

                res = get(0,'screensize');

                position = get(gcf, 'Position');

                set(gcf, 'Position',  [position(1), position(2), 895, 895])

                position = get(gcf, 'Position');

                if sum(position([2 4])) + 100 > res(4)
                    position(2) = res(4) - position(4) - 100;
                end
                app.analysisFigureHandle.Position(2) = position(2);

            else
                app.analysisFigureHandle;
            end

            if ~app.ColorpickerCheckBox.Value
                if app.numberOfAnalysisPlots >= length(colorspec)
                    color = 'r';
                else
                    app.numberOfAnalysisPlots = app.numberOfAnalysisPlots + 1;
                    color = colorspec{app.numberOfAnalysisPlots};
                end
            else

                color = uisetcolor;
            end


            if ~app.HorizontalCheckBox.Value
                app.analysisFigureHandle.Position(3:4) = [400 800];
            else
                app.analysisFigureHandle.Position(3:4) = [800 800];
            end

            cellVertical = zeros(size(yq,1),length(app.SimulationListBox.Value));
            subVertical = cellVertical;

            if app.HorizontalCheckBox.Value
                cellHorizontal = zeros(size(xq,2),length(app.SimulationListBox.Value));
                subHorizontal = cellHorizontal;
            end

            previousSelected = app.CallingApp.selectedFile;

            for i = 1:length(app.SimulationListBox.Value)

                for ii = 1:length(app.CallingApp.LoadedfilesDropDown.Items)
                    if isa(length(app.SimulationListBox.Value),'char')
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value(i))
                            fileIdx = ii;
                            break
                        end
                    else
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value{i})
                            fileIdx = ii;
                            break
                        end
                    end
                end

                app.CallingApp.selectedFile = fileIdx;

                d.spar.scalingLength = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength;
                d.spar.scalingTime = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingTime;
                d.spar.rCell = app.CallingApp.plotImport(fileIdx).scaledParameters.rCell;

                d.pl.substrateStyle = 1;
                d.pl.substrateForcesDirect = 0;
                d.pl.substrateForcesRepulsion = 0;
                d.pl.substrateForcesRestoration = 0;
                d.pl.substrateForcesFocalAdhesions = 0;
                d.pl.substrateForcesTotal = 0;
                d.pl.titleType = 0;
                d.pl.windowSize = app.CallingApp.importPlottingOptions.windowSize;
                d.pl.axisLimits = [app.CallingApp.MiddlepointxEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointxEditField_2.Value*1e-6+d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointyEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointyEditField_2.Value*1e-6+d.pl.windowSize/2]/d.spar.scalingLength;


                d.simset.simulationType = 2;

                oldTimePoint = app.CallingApp.plotImport(fileIdx).currentTimePoint;

                temp = import_settings([app.CallingApp.plotImport(fileIdx).folderName '/pointlike/pointlike_data.csv']);

                pointLikeCell = temp.cell;

                app.CallingApp.plotImport(fileIdx).currentTimePoint = initialTime;

                initialCells = import_cells(app.CallingApp,'post_plotting');

                app.CallingApp.plotImport(fileIdx).currentTimePoint = endTime;

                endCells = import_cells(app.CallingApp,'post_plotting');

                initialCellCenterX = zeros(1,length(initialCells));
                initialCellCenterY = zeros(1,length(initialCells));
                finalCellCenterX = zeros(1,length(endCells));
                finalCellCenterY = zeros(1,length(endCells));

                for k = 1:length(endCells)
                    initialCellCenterX(k) = mean(initialCells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                    initialCellCenterY(k) = mean(initialCells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                    finalCellCenterX(k) = mean(endCells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                    finalCellCenterY(k) = mean(endCells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                end

                displacements = sqrt((initialCellCenterX - finalCellCenterX).^2 + (initialCellCenterY - finalCellCenterY).^2);

                moveX = -initialCellCenterX(pointLikeCell);
                moveY = -initialCellCenterY(pointLikeCell);

                initialCellCenterX = initialCellCenterX + moveX;
                initialCellCenterY = initialCellCenterY + moveY;

                dispData = griddata(initialCellCenterX,initialCellCenterY,displacements,xq,yq,'cubic');

                cellVertical(:,i) = dispData(:,end);
                if app.HorizontalCheckBox.Value
                    cellHorizontal(:,i) = dispData(middlePoint,1:end)';
                end


                if exist([app.CallingApp.plotImport(fileIdx).folderName '/substrate'],'dir') == 7

                    importedPoints = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate/substrate_points_' num2str(initialTime) '.csv']);
                    initialPointsX = importedPoints(:,1);
                    initialPointsY = importedPoints(:,2);

                    importedPoints = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate/substrate_points_' num2str(endTime) '.csv']);
                    endPointsX = importedPoints(:,1);
                    endPointsY = importedPoints(:,2);

                    initialCells = import_cells(app.CallingApp,'post_plotting','initial');

                    moveX = -mean(initialCells(pointLikeCell).verticesX).*d.spar.scalingLength.*1e6;
                    moveY = -mean(initialCells(pointLikeCell).verticesY).*d.spar.scalingLength.*1e6;

                    displacements = sqrt((endPointsX - initialPointsX).^2 + (endPointsY - initialPointsY).^2)*d.spar.scalingLength/1e-6;

                    subX = initialPointsX*d.spar.scalingLength/1e-6 + moveX;
                    subY = initialPointsY*d.spar.scalingLength/1e-6 + moveY;

                    dispData = griddata(subX,subY,displacements,xq,yq,'cubic');

                    subVertical(:,i) = dispData(:,end);
                    if app.HorizontalCheckBox.Value
                        subHorizontal(:,i) = dispData(middlePoint,1:end)';
                    end


                else
                    app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;
                    app.CallingApp.selectedFile = previousSelected;
                    uialert(app.UIFigure, ['Selected simulation number ' num2str(i) ' does not have substrate data.'],'Data missing');
                    return
                end
            end


            if size(cellVertical,1) > size(cellVertical,2)
                cellVertMean = mean(cellVertical,2);
            else
                cellVertMean = mean(cellVertical,1);
            end

            if size(subVertical,1) > size(subVertical,2)
                subVertMean = mean(subVertical,2);
            else
                subVertMean = mean(subVertical,1);
            end

            if size(cellVertical,1) > size(cellVertical,2)
                cellVertStd = std(cellVertical,0,2);
            else
                cellVertStd = std(cellVertical,0,1);
            end

            if size(subVertical,1) > size(subVertical,2)
                subVertStd = std(subVertical,0,2);
            else
                subVertStd = std(subVertical,0,1);
            end


            if app.HorizontalCheckBox.Value

                if size(cellHorizontal,1) > size(cellHorizontal,2)
                    cellHorizMean = mean(cellHorizontal,2);
                else
                    cellHorizMean = mean(cellHorizontal,1);
                end

                if size(subHorizontal,1) > size(subHorizontal,2)
                    subHorizMean = mean(subHorizontal,2);
                else
                    subHorizMean = mean(subHorizontal,1);
                end

                if size(cellHorizontal,1) > size(cellHorizontal,2)
                    cellHorizStd = std(cellHorizontal,0,2);
                else
                    cellHorizStd = std(cellHorizontal,0,1);
                end

                if size(subHorizontal,1) > size(subHorizontal,2)
                    subHorizStd = std(subHorizontal,0,2);
                else
                    subHorizStd = std(subHorizontal,0,1);
                end

            end

            app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;

            plotHandleTemp = {};

            if app.HorizontalCheckBox.Value

                app.analysisFigureHandle;
                subplot(2,2,1);
                hold on
                if ~strcmp(app.LegendEditField.Value,'')
                    fh = errorbar(yPositions(1:1:end),cellVertMean(1:1:end),cellVertStd(1:1:end),'Color',color,'LineWidth',1,'DisplayName',app.LegendEditField.Value);
                    legend('show', 'Interpreter', 'none');
                    app.LegendEditField.Value = '';
                else
                    fh = errorbar(yPositions(1:1:end),cellVertMean(1:1:end),cellVertStd(1:1:end),'Color',color,'LineWidth',1,'HandleVisibility','off');
                end
                plotHandleTemp{end+1} = fh;
                hold off

                title('Cell vertical diplacement', 'FontSize', 14)
                if app.VerticalhalfButton.Value
                    if app.LogyaxisCheckBox.Value
                        set(gca,'yscale','log')
                        axis([-app.WindowsizeEditField.Value/2 0 min(min(cellHorizMean(:),min(cellVertMean(:))))*0.5 app.MaximumdisplacementEditField.Value])
                    else
                        set(gca,'yscale','linear')
                        axis([-app.WindowsizeEditField.Value/2 0 0 app.MaximumdisplacementEditField.Value])
                    end
                else
                    if app.LogyaxisCheckBox.Value
                        set(gca,'yscale','log')
                        axis([-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2 min(min(cellHorizMean(:),min(cellVertMean(:))))*0.5 app.MaximumdisplacementEditField.Value])
                    else
                        set(gca,'yscale','linear')
                        axis([-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2 0 app.MaximumdisplacementEditField.Value])
                    end
                end

                xlabel('Distance downwards ({\mu}m)', 'FontSize', 12)
                ylabel('Displacement {\mu}m', 'FontSize', 12)


                subplot(2,2,2);
                hold on
                fh = errorbar(xPositions(1:1:end),cellHorizMean(1:1:end),cellHorizStd(1:1:end),'Color',color,'LineWidth',1,'HandleVisibility','off');
                plotHandleTemp{end+1} = fh;
                hold off

                title('Cell horizontal diplacement', 'FontSize', 14)

                if app.LogyaxisCheckBox.Value
                    set(gca,'yscale','log')
                    axis([-app.WindowsizeEditField.Value/2 0 min(min(cellHorizMean(:),min(cellVertMean(:))))*0.5 app.MaximumdisplacementEditField.Value])
                else
                    set(gca,'yscale','linear')
                    axis([-app.WindowsizeEditField.Value/2 0 0 app.MaximumdisplacementEditField.Value])
                end

                xlabel('Distance sideways ({\mu}m)', 'FontSize', 12)
                ylabel('Displacement {\mu}m', 'FontSize', 12)


                subplot(2,2,3);
                hold on
                fh = errorbar(yPositions(1:1:end),subVertMean(1:1:end),subVertStd(1:1:end),'Color',color,'LineWidth',1,'HandleVisibility','off');
                plotHandleTemp{end+1} = fh;
                hold off

                title('Substrate vertical diplacement', 'FontSize', 14)

                if app.VerticalhalfButton.Value
                    if app.LogyaxisCheckBox.Value
                        set(gca,'yscale','log')
                        axis([-app.WindowsizeEditField.Value/2 0 min(min(subHorizMean(:),min(subVertMean(:))))*0.5 app.MaximumdisplacementEditField.Value])
                    else
                        set(gca,'yscale','linear')
                        axis([-app.WindowsizeEditField.Value/2 0 0 app.MaximumdisplacementEditField.Value])
                    end
                else
                    if app.LogyaxisCheckBox.Value
                        set(gca,'yscale','log')
                        axis([-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2 min(min(subHorizMean(:),min(subVertMean(:))))*0.5 app.MaximumdisplacementEditField.Value])
                    else
                        set(gca,'yscale','linear')
                        axis([-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2 0 app.MaximumdisplacementEditField.Value])
                    end
                end

                xlabel('Distance downwards ({\mu}m)', 'FontSize', 12)
                ylabel('Displacement in {\mu}m', 'FontSize', 12)


                subplot(2,2,4);
                hold on
                fh = errorbar(xPositions(1:1:end),subHorizMean(1:1:end),subHorizStd(1:1:end),'Color',color,'LineWidth',1,'HandleVisibility','off');
                plotHandleTemp{end+1} = fh;
                hold off

                title('Substrate horizontal diplacement', 'FontSize', 14)

                if app.LogyaxisCheckBox.Value
                    set(gca,'yscale','log')
                    axis([-app.WindowsizeEditField.Value/2 0 min(min(subHorizMean(:),min(subVertMean(:))))*0.5 app.MaximumdisplacementEditField.Value])
                else
                    set(gca,'yscale','linear')
                    axis([-app.WindowsizeEditField.Value/2 0 0 app.MaximumdisplacementEditField.Value])
                end

                xlabel('Distance sideways ({\mu}m)', 'FontSize', 12)
                ylabel('Displacement in {\mu}m', 'FontSize', 12)


            else
                app.analysisFigureHandle;
                subplot(2,1,1);
                hold on
                if ~strcmp(app.LegendEditField.Value,'')
                    fh = errorbar(yPositions(1:1:end),cellVertMean(1:1:end),cellVertStd(1:1:end),'Color',color,'LineWidth',1,'DisplayName',app.LegendEditField.Value);
                    legend('show', 'Interpreter', 'none');
                    app.LegendEditField.Value = '';
                else
                    fh = errorbar(yPositions(1:1:end),cellVertMean(1:1:end),cellVertStd(1:1:end),'Color',color,'LineWidth',1,'HandleVisibility','off');
                end
                plotHandleTemp{end+1} = fh;
                hold off

                title('Cell vertical diplacement', 'FontSize', 14)


                xlabel('Distance downwards ({\mu}m)', 'FontSize', 12)
                ylabel('Displacement {\mu}m', 'FontSize', 12)

                if app.VerticalhalfButton.Value
                    if app.LogyaxisCheckBox.Value
                        set(gca,'yscale','log')
                        axis([-app.WindowsizeEditField.Value/2 0 min(cellVertMean(:))*0.5 app.MaximumdisplacementEditField.Value])
                    else
                        set(gca,'yscale','linear')
                        axis([-app.WindowsizeEditField.Value/2 0 0 app.MaximumdisplacementEditField.Value])
                    end
                else
                    if app.LogyaxisCheckBox.Value
                        set(gca,'yscale','log')
                        axis([-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2 min(cellVertMean(:))*0.5 app.MaximumdisplacementEditField.Value])
                    else
                        set(gca,'yscale','linear')
                        axis([-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2 0 app.MaximumdisplacementEditField.Value])
                    end
                end


                subplot(2,1,2);
                hold on
                fh = errorbar(yPositions(1:1:end),subVertMean(1:1:end),subVertStd(1:1:end),'Color',color,'LineWidth',1,'HandleVisibility','off');
                plotHandleTemp{end+1} = fh;
                hold off

                title('Substrate vertical diplacement', 'FontSize', 14)

                if app.VerticalhalfButton.Value
                    if app.LogyaxisCheckBox.Value
                        set(gca,'yscale','log')
                        axis([-app.WindowsizeEditField.Value/2 0 min(subVertMean(:))*0.5 app.MaximumdisplacementEditField.Value])
                    else
                        set(gca,'yscale','linear')
                        axis([-app.WindowsizeEditField.Value/2 0 0 app.MaximumdisplacementEditField.Value])
                    end
                else
                    if app.LogyaxisCheckBox.Value
                        set(gca,'yscale','log')
                        axis([-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2 min(subVertMean(:))*0.5 app.MaximumdisplacementEditField.Value])
                    else
                        set(gca,'yscale','linear')
                        axis([-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2 0 app.MaximumdisplacementEditField.Value])
                    end
                end

                xlabel('Distance downwards ({\mu}m)', 'FontSize', 12)
                ylabel('Displacement in {\mu}m', 'FontSize', 12)

            end


            app.plotHandle{end+1} = plotHandleTemp;

            warning('on','MATLAB:Axes:NegativeLimitsInLogAxis');


            app.CallingApp.selectedFile = previousSelected;

        end

        % Value changed function: ContoursEditField
        function ContoursEditFieldValueChanged(app, event)
            value = app.ContoursEditField.Value;
            if strcmp(value,'')
                app.ContoursCheckBox.Enable = 'off';
                app.ContoursCheckBox.Value = 0;
            else
                app.ContoursCheckBox.Enable = 'on';
            end
        end

        % Button pushed function: AreaplotButton_2
        function AreaplotButton_2Pushed(app, event)

            switch app.ForceDropDown.Value
                case 'Total forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesTotal;
                    app.CallingApp.importPlottingOptions.cellForcesTotal = true;
                case 'Cortical forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesCortical;
                    app.CallingApp.importPlottingOptions.cellForcesCortical = true;
                case 'Junction forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesJunctions;
                    app.CallingApp.importPlottingOptions.cellForcesJunctions = true;
                case 'Division forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesDivision;
                    app.CallingApp.importPlottingOptions.cellForcesDivision = true;
                case 'Membrane forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesMembrane;
                    app.CallingApp.importPlottingOptions.cellForcesMembrane = true;
                case 'Contact forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesContact;
                    app.CallingApp.importPlottingOptions.cellForcesContact = true;
                case 'Area forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesArea;
                    app.CallingApp.importPlottingOptions.cellForcesArea = true;
                case 'Pointlike forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesPointlike;
                    app.CallingApp.importPlottingOptions.cellForcesPointlike = true;
                case 'Focal adhesion forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesFocalAdhesions;
                    app.CallingApp.importPlottingOptions.cellForcesFocalAdhesions = true;
                case 'Total substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesTotal;
                    app.CallingApp.importPlottingOptions.substrateForcesTotal = true;
                case 'Direct substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesDirect;
                    app.CallingApp.importPlottingOptions.substrateForcesDirect = true;
                case 'Restorative substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesRepulsion;
                    app.CallingApp.importPlottingOptions.substrateForcesRepulsion = true;
                case 'Repulsion substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesRestoration;
                    app.CallingApp.importPlottingOptions.substrateForcesRestoration = true;
                case 'Focal adhesion substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesFocalAdhesions;
                    app.CallingApp.importPlottingOptions.substrateForcesFocalAdhesions = true;
            end


            [xq,yq] = meshgrid(-400:1:400,-400:1:400);

            timePoint = find(strcmp(app.TimePointDropDown.Items, app.TimePointDropDown.Value));


            dispData = zeros(length(xq(:,1)),length(xq(1,:)),length(app.SimulationListBox.Value));

            previousSelected = app.CallingApp.selectedFile;

            for i = 1:length(app.SimulationListBox.Value)

                for ii = 1:length(app.CallingApp.LoadedfilesDropDown.Items)
                    if isa(length(app.SimulationListBox.Value),'char')
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value(i))
                            fileIdx = ii;
                            break
                        end
                    else
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value{i})
                            fileIdx = ii;
                            break
                        end
                    end
                end

                app.CallingApp.selectedFile = fileIdx;

                d.spar.scalingLength = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength;
                d.spar.scalingTime = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingTime;
                d.spar.rCell = app.CallingApp.plotImport(fileIdx).scaledParameters.rCell;


                d.pl.substrateStyle = 1;
                d.pl.substrateForcesDirect = 0;
                d.pl.substrateForcesRepulsion = 0;
                d.pl.substrateForcesRestoration = 0;
                d.pl.substrateForcesFocalAdhesions = 0;
                d.pl.substrateForcesTotal = 0;
                d.pl.titleType = 0;
                d.pl.windowSize = app.CallingApp.importPlottingOptions.windowSize;
                d.pl.axisLimits = [app.CallingApp.MiddlepointxEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointxEditField_2.Value*1e-6+d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointyEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointyEditField_2.Value*1e-6+d.pl.windowSize/2]/d.spar.scalingLength;


                d.simset.simulationType = 2;

                oldTimePoint = app.CallingApp.plotImport(fileIdx).currentTimePoint;

                temp = import_settings([app.CallingApp.plotImport(fileIdx).folderName '/pointlike/pointlike_data.csv']);

                pointLikeCell = temp.cell;

                %                 if app.CellsButton.Value
                app.CallingApp.plotImport(fileIdx).currentTimePoint = timePoint;

                substrateForces = {'Total substrate forces','Direct substrate forces','Restorative substrate forces','Repulsion substrate forces','Focal adhesion substrate forces'};

                if ~any(strcmp(app.ForceDropDown.Value,substrateForces))

                    cells = import_cells(app.CallingApp,'post_plotting');

                    switch app.TypeDropDown.Value

                        case 'Average'
                            cellPositionsX = zeros(1,length(cells));
                            cellPositionsY = zeros(1,length(cells));

                            forces = zeros(1,length(cells));


                            for k = 1:length(cells)
                                cellPositionsX(k) = mean(cells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                                cellPositionsY(k) = mean(cells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;

                                switch app.ForceDropDown.Value
                                    case 'Total forces'
                                        forces(k) = mean(sqrt((cells(k).forces.totalX).^2 + (cells(k).forces.totalY).^2));
                                    case 'Cortical forces'
                                        forces(k) = mean(sqrt((cells(k).forces.corticalX).^2 + (cells(k).forces.corticalY).^2));
                                    case 'Junction forces'
                                        forces(k) = mean(sqrt((cells(k).forces.junctionX).^2 + (cells(k).forces.junctionY).^2));
                                    case 'Division forces'
                                        forces(k) = mean(sqrt((cells(k).forces.divisionX).^2 + (cells(k).forces.divisionY).^2));
                                    case 'Membrane forces'
                                        forces(k) = mean(sqrt((cells(k).forces.membraneX).^2 + (cells(k).forces.membraneY).^2));
                                    case 'Contact forces'
                                        forces(k) = mean(sqrt((cells(k).forces.contactX).^2 + (cells(k).forces.contactY).^2));
                                    case 'Area forces'
                                        forces(k) = mean(sqrt((cells(k).forces.areaX).^2 + (cells(k).forces.areaY).^2));
                                    case 'Pointlike forces'
                                        forces(k) = mean(sqrt((cells(k).forces.pointlikeX).^2 + (cells(k).forces.pointlikeY).^2));
                                    case 'Focal adhesion forces'
                                        forces(k) = mean(sqrt((cells(k).forces.substrateX).^2 + (cells(k).forces.substrateY).^2));
                                end

                            end
                        case 'Maximum'
                            cellPositionsX = zeros(1,length(cells));
                            cellPositionsY = zeros(1,length(cells));

                            forces = zeros(1,length(cells));


                            for k = 1:length(cells)
                                cellPositionsX(k) = mean(cells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                                cellPositionsY(k) = mean(cells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;

                                switch app.ForceDropDown.Value
                                    case 'Total forces'
                                        forces(k) = max(sqrt((cells(k).forces.totalX).^2 + (cells(k).forces.totalY).^2));
                                    case 'Cortical forces'
                                        forces(k) = max(sqrt((cells(k).forces.corticalX).^2 + (cells(k).forces.corticalY).^2));
                                    case 'Junction forces'
                                        forces(k) = max(sqrt((cells(k).forces.junctionX).^2 + (cells(k).forces.junctionY).^2));
                                    case 'Division forces'
                                        forces(k) = max(sqrt((cells(k).forces.divisionX).^2 + (cells(k).forces.divisionY).^2));
                                    case 'Membrane forces'
                                        forces(k) = max(sqrt((cells(k).forces.membraneX).^2 + (cells(k).forces.membraneY).^2));
                                    case 'Contact forces'
                                        forces(k) = max(sqrt((cells(k).forces.contactX).^2 + (cells(k).forces.contactY).^2));
                                    case 'Area forces'
                                        forces(k) = max(sqrt((cells(k).forces.areaX).^2 + (cells(k).forces.areaY).^2));
                                    case 'Pointlike forces'
                                        forces(k) = max(sqrt((cells(k).forces.pointlikeX).^2 + (cells(k).forces.pointlikeY).^2));
                                    case 'Focal adhesion forces'
                                        forces(k) = max(sqrt((cells(k).forces.substrateX).^2 + (cells(k).forces.substrateY).^2));
                                end

                            end
                        case 'Minimum'
                            cellPositionsX = zeros(1,length(cells));
                            cellPositionsY = zeros(1,length(cells));

                            forces = zeros(1,length(cells));


                            for k = 1:length(cells)
                                cellPositionsX(k) = mean(cells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                                cellPositionsY(k) = mean(cells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;

                                switch app.ForceDropDown.Value
                                    case 'Total forces'
                                        forces(k) = min(sqrt((cells(k).forces.totalX).^2 + (cells(k).forces.totalY).^2));
                                    case 'Cortical forces'
                                        forces(k) = min(sqrt((cells(k).forces.corticalX).^2 + (cells(k).forces.corticalY).^2));
                                    case 'Junction forces'
                                        forces(k) = min(sqrt((cells(k).forces.junctionX).^2 + (cells(k).forces.junctionY).^2));
                                    case 'Division forces'
                                        forces(k) = min(sqrt((cells(k).forces.divisionX).^2 + (cells(k).forces.divisionY).^2));
                                    case 'Membrane forces'
                                        forces(k) = min(sqrt((cells(k).forces.membraneX).^2 + (cells(k).forces.membraneY).^2));
                                    case 'Contact forces'
                                        forces(k) = min(sqrt((cells(k).forces.contactX).^2 + (cells(k).forces.contactY).^2));
                                    case 'Area forces'
                                        forces(k) = min(sqrt((cells(k).forces.areaX).^2 + (cells(k).forces.areaY).^2));
                                    case 'Pointlike forces'
                                        forces(k) = min(sqrt((cells(k).forces.pointlikeX).^2 + (cells(k).forces.pointlikeY).^2));
                                    case 'Focal adhesion forces'
                                        forces(k) = min(sqrt((cells(k).forces.substrateX).^2 + (cells(k).forces.substrateY).^2));
                                end

                            end
                        case 'Individual'
                            cellPositionsX = [];
                            cellPositionsY = [];

                            forces = [];


                            for k = 1:length(cells)
                                cellPositionsX = [cellPositionsX ; cells(k).verticesX.*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6];
                                cellPositionsY = [cellPositionsY ; cells(k).verticesY.*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6];

                                switch app.ForceDropDown.Value
                                    case 'Total forces'
                                        forces = [forces ; sqrt((cells(k).forces.totalX).^2 + (cells(k).forces.totalY).^2)];
                                    case 'Cortical forces'
                                        forces = [forces ; sqrt((cells(k).forces.corticalX).^2 + (cells(k).forces.corticalY).^2)];
                                    case 'Junction forces'
                                        forces = [forces ; sqrt((cells(k).forces.junctionX).^2 + (cells(k).forces.junctionY).^2)];
                                    case 'Division forces'
                                        forces = [forces ; sqrt((cells(k).forces.divisionX).^2 + (cells(k).forces.divisionY).^2)];
                                    case 'Membrane forces'
                                        forces = [forces ; sqrt((cells(k).forces.membraneX).^2 + (cells(k).forces.membraneY).^2)];
                                    case 'Contact forces'
                                        forces = [forces ; sqrt((cells(k).forces.contactX).^2 + (cells(k).forces.contactY).^2)];
                                    case 'Area forces'
                                        forces = [forces ; sqrt((cells(k).forces.areaX).^2 + (cells(k).forces.areaY).^2)];
                                    case 'Pointlike forces'
                                        forces = [forces ; sqrt((cells(k).forces.pointlikeX).^2 + (cells(k).forces.pointlikeY).^2)];
                                    case 'Focal adhesion forces'
                                        forces = [forces ; sqrt((cells(k).forces.substrateX).^2 + (cells(k).forces.substrateY).^2)];
                                end

                            end

                    end


                    moveX = -mean(cells(pointLikeCell).verticesX);
                    moveY = -mean(cells(pointLikeCell).verticesY);

                    cellPositionsX = cellPositionsX + moveX;
                    cellPositionsY = cellPositionsY + moveY;

                    dispData(:,:,i) = griddata(cellPositionsX,cellPositionsY,forces,xq,yq,'cubic');

                else

                    if exist([app.CallingApp.plotImport(fileIdx).folderName '/substrate'],'dir') == 7

                        importedPoints = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate/substrate_points_' num2str(timePoint) '.csv']);
                        pointsX = importedPoints(:,1);
                        pointsY = importedPoints(:,2);

                        initialCells = import_cells(app.CallingApp,'post_plotting','initial');

                        moveX = -mean(initialCells(pointLikeCell).verticesX).*d.spar.scalingLength.*1e6;
                        moveY = -mean(initialCells(pointLikeCell).verticesY).*d.spar.scalingLength.*1e6;

                        switch app.ForceDropDown.Value
                            case 'Total substrate forces'
                                importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/total/total_' num2str(timePoint) '.csv']);
                                forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);
                            case 'Direct substrate forces'
                                importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/direct/direct_' num2str(timePoint) '.csv']);
                                forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);
                            case 'Restorative substrate forces'
                                importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/restoration/restoration_' num2str(timePoint) '.csv']);
                                forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);
                            case 'Repulsion substrate forces'
                                importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/repulsion/repulsion_' num2str(timePoint) '.csv']);
                                forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);
                            case 'Focal adhesion substrate forces'
                                importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/focal_adhesion/focal_adhesion_' num2str(timePoint) '.csv']);
                                forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);

                        end

                        subX = pointsX*d.spar.scalingLength/1e-6 + moveX;
                        subY = pointsY*d.spar.scalingLength/1e-6 + moveY;

                        dispData(:,:,i) = griddata(subX,subY,forces,xq,yq,'cubic');

                    else
                        app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;
                        app.CallingApp.selectedFile = previousSelected;
                        uialert(app.UIFigure, ['Selected simulation number ' num2str(i) ' does not have substrate data.'],'Data missing');
                        return
                    end

                end

            end
            app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;

            dispData = mean(dispData,3);


            if (~ishandle(app.CallingApp.plotHandle) || app.CallingApp.plotHandle == 0) && ~ishandle(1)
                mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;
                d.pl.figureHandle = figure(1);
                d.pl.figureHandle.Position(1) = mainWindowPosition(1) + 600;
                d.pl.figureHandle.Position(2) = mainWindowPosition(2) + 200;
                app.CallingApp.plotHandle = figure(1);
            else
                d.pl.figureHandle = figure(1);
                clf;
                app.CallingApp.plotHandle = figure(1);
            end

            d.pl.axesHandle = axes;

            figureSize = d.pl.figureHandle.Position(3:4);
            bottomGap = 10/figureSize(2);
            topGap = 10/figureSize(2);
            horzGap = 10/figureSize(1);
            axisHeight = 1 - bottomGap - topGap;
            axisWidth = 1 - 2*horzGap;
            d.pl.axesHandle.Position = [horzGap bottomGap axisWidth axisHeight];

            axisHeight = d.pl.figureHandle.Position(4)*d.pl.axesHandle.Position(4);
            axisWidth = d.pl.figureHandle.Position(3)*d.pl.axesHandle.Position(3);
            if axisHeight > axisWidth
                axisSizeInPixels = axisWidth;
            else
                axisSizeInPixels = axisHeight;
            end

            titleFontSize = axisSizeInPixels/40;
            if titleFontSize > 15
                titleFontSize = 15;
            elseif titleFontSize < 10
                titleFontSize = 10;
            end

            d.pl.axesHandle.Title.FontSize = titleFontSize;

            if any(d.pl.titleType == [2 4 8 10])
                topGap = 20/figureSize(2) + 2*d.pl.axesHandle.Title.FontSize/figureSize(2);
            else
                topGap = 20/figureSize(2) + d.pl.axesHandle.Title.FontSize/figureSize(2);
            end
            d.pl.axesHandle.Position(4) = 1 - bottomGap - topGap;

            axis(app.WindowsizeEditField.Value.*[-0.5 0.5 -0.5 0.5])

            axis square

            d.pl.axesHandle.TickLength = [0 0];
            d.pl.axesHandle.XTick = [];
            d.pl.axesHandle.XTickLabel = [];
            d.pl.axesHandle.YTick = [];
            d.pl.axesHandle.YTickLabel = [];
            d.pl.axesHandle.XColor = 'none';
            d.pl.axesHandle.YColor = 'none';

            set_figure_callbacks(d,app.CallingApp)

            %             set_figure_callbacks(d,app.CallingApp)
            hold on
            contourf(d.pl.axesHandle,xq,yq,dispData,256,'LineColor','none');

            mymap = csvread('./settings/Misc/mymap.csv');
            colormap(mymap)
            colorbar
            caxis([0 max(max(dispData(:)),1e-5)]);
            hold off

            app.CallingApp.selectedFile = previousSelected;


            switch app.ForceDropDown.Value
                case 'Total forces'
                    app.CallingApp.importPlottingOptions.cellForcesTotal = pastOption;
                case 'Cortical forces'
                    app.CallingApp.importPlottingOptions.cellForcesCortical = pastOption;
                case 'Junction forces'
                    app.CallingApp.importPlottingOptions.cellForcesJunctions = pastOption;
                case 'Division forces'
                    app.CallingApp.importPlottingOptions.cellForcesDivision = pastOption;
                case 'Membrane forces'
                    app.CallingApp.importPlottingOptions.cellForcesMembrane = pastOption;
                case 'Contact forces'
                    app.CallingApp.importPlottingOptions.cellForcesContact = pastOption;
                case 'Area forces'
                    app.CallingApp.importPlottingOptions.cellForcesArea = pastOption;
                case 'Pointlike forces'
                    app.CallingApp.importPlottingOptions.cellForcesPointlike = pastOption;
                case 'Focal adhesion forces'
                    app.CallingApp.importPlottingOptions.cellForcesFocalAdhesions = pastOption;
                case 'Total substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesTotal = pastOption;
                case 'Direct substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesTotal = pastOption;
                case 'Restorative substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesRepulsion = pastOption;
                case 'Repulsion substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesRestoration = pastOption;
                case 'Focal adhesion substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesFocalAdhesions = pastOption;
            end


        end

        % Value changed function: StartDropDown
        function StartDropDownValueChanged(app, event)
            if app.StartDropDown.Value > app.EndDropDown.Value
                uialert(app.UIFigure,'Start time must be smaller than end time.')
                app.StartDropDown.Value = event.PreviousValue;
            end

        end

        % Value changed function: EndDropDown
        function EndDropDownValueChanged(app, event)
            if app.StartDropDown.Value > app.EndDropDown.Value
                uialert(app.UIFigure,'End time must be larger than start time.')
                app.EndDropDown.Value = event.PreviousValue;
            end
        end

        % Value changed function: WindowsizeEditField
        function WindowsizeEditFieldValueChanged(app, event)
            app.WindowsizeEditField_2.Value = app.WindowsizeEditField.Value;

        end

        % Value changed function: WindowsizeEditField_2
        function WindowsizeEditField_2ValueChanged(app, event)
            app.WindowsizeEditField.Value = app.WindowsizeEditField_2.Value;

        end

        % Button pushed function: PlotallButton_2
        function PlotallButton_2Pushed(app, event)

            colorspec = {'r','b','g','c','m','k'};

            switch app.ForceDropDown.Value
                case 'Total forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesTotal;
                    app.CallingApp.importPlottingOptions.cellForcesTotal = true;
                case 'Cortical forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesCortical;
                    app.CallingApp.importPlottingOptions.cellForcesCortical = true;
                case 'Junction forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesJunctions;
                    app.CallingApp.importPlottingOptions.cellForcesJunctions = true;
                case 'Division forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesDivision;
                    app.CallingApp.importPlottingOptions.cellForcesDivision = true;
                case 'Membrane forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesMembrane;
                    app.CallingApp.importPlottingOptions.cellForcesMembrane = true;
                case 'Contact forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesContact;
                    app.CallingApp.importPlottingOptions.cellForcesContact = true;
                case 'Area forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesArea;
                    app.CallingApp.importPlottingOptions.cellForcesArea = true;
                case 'Pointlike forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesPointlike;
                    app.CallingApp.importPlottingOptions.cellForcesPointlike = true;
                case 'Focal adhesion forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesFocalAdhesions;
                    app.CallingApp.importPlottingOptions.cellForcesFocalAdhesions = true;
                case 'Total substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesTotal;
                    app.CallingApp.importPlottingOptions.substrateForcesTotal = true;
                case 'Direct substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesDirect;
                    app.CallingApp.importPlottingOptions.substrateForcesDirect = true;
                case 'Restorative substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesRepulsion;
                    app.CallingApp.importPlottingOptions.substrateForcesRepulsion = true;
                case 'Repulsion substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesRestoration;
                    app.CallingApp.importPlottingOptions.substrateForcesRestoration = true;
                case 'Focal adhesion substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesFocalAdhesions;
                    app.CallingApp.importPlottingOptions.substrateForcesFocalAdhesions = true;
            end


            timePoint = find(strcmp(app.TimePointDropDown.Items, app.TimePointDropDown.Value));

            if app.VerticalhalfButton_2.Value
                [xq,yq] = meshgrid(-400:1:0,-400:1:0);
                middlePoint = size(yq,1);
            else
                [xq,yq] = meshgrid(-400:1:0,-400:1:400);
                middlePoint = round(size(yq,1)/2);
            end

            yPositions =  yq(:,1);
            xPositions = xq(1,:);

            if isa(app.analysisFigureHandle,'double') || (~isa(app.analysisFigureHandle,'double') && ~isvalid(app.analysisFigureHandle)) || app.NewplotCheckBox_2.Value
                app.analysisFigureHandle = figure(2);
                clf
                app.numberOfAnalysisPlots = 0;
                mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;
                app.plotHandle = {};

                app.analysisFigureHandle.Position(1) = mainWindowPosition(1) + 600;
                app.analysisFigureHandle.Position(2) = mainWindowPosition(2);

                res = get(0,'screensize');

                position = get(gcf, 'Position');

                set(gcf, 'Position',  [position(1), position(2), 895, 895])

                position = get(gcf, 'Position');

                if sum(position([2 4])) + 100 > res(4)
                    position(2) = res(4) - position(4) - 100;
                end
                app.analysisFigureHandle.Position(2) = position(2);

            else
                app.analysisFigureHandle;
            end

            if ~app.ColorpickerCheckBox_2.Value
                if app.numberOfAnalysisPlots >= length(colorspec)
                    color = 'r';
                else
                    app.numberOfAnalysisPlots = app.numberOfAnalysisPlots + 1;
                    color = colorspec{app.numberOfAnalysisPlots};
                end
            else
                color = uisetcolor;
            end


            if ~app.HorizontalCheckBox_2.Value
                app.analysisFigureHandle.Position(3:4) = [400 400];
            else
                app.analysisFigureHandle.Position(3:4) = [800 400];
            end


            previousSelected = app.CallingApp.selectedFile;

            for i = 1:length(app.SimulationListBox.Value)

                for ii = 1:length(app.CallingApp.LoadedfilesDropDown.Items)
                    if isa(length(app.SimulationListBox.Value),'char')
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value)
                            fileIdx = ii;
                            break
                        end
                    else
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value(i))
                            fileIdx = ii;
                            break
                        end
                    end
                end

                app.CallingApp.selectedFile = fileIdx;

                d.spar.scalingLength = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength;
                d.spar.scalingTime = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingTime;
                d.spar.rCell = app.CallingApp.plotImport(fileIdx).scaledParameters.rCell;

                d.pl.substrateStyle = 1;
                d.pl.substrateForcesDirect = 0;
                d.pl.substrateForcesRepulsion = 0;
                d.pl.substrateForcesRestoration = 0;
                d.pl.substrateForcesFocalAdhesions = 0;
                d.pl.substrateForcesTotal = 0;
                d.pl.titleType = 0;
                d.pl.windowSize = app.CallingApp.importPlottingOptions.windowSize;
                d.pl.axisLimits = [app.CallingApp.MiddlepointxEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointxEditField_2.Value*1e-6+d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointyEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointyEditField_2.Value*1e-6+d.pl.windowSize/2]/d.spar.scalingLength;


                d.simset.simulationType = 2;

                oldTimePoint = app.CallingApp.plotImport(fileIdx).currentTimePoint;

                temp = import_settings([app.CallingApp.plotImport(fileIdx).folderName '/pointlike/pointlike_data.csv']);

                pointLikeCell = temp.cell;

                app.CallingApp.plotImport(fileIdx).currentTimePoint = timePoint;

                substrateForces = {'Total substrate forces','Direct substrate forces','Restorative substrate forces','Repulsion substrate forces','Focal adhesion substrate forces'};

                if ~any(strcmp(app.ForceDropDown.Value,substrateForces))

                    cells = import_cells(app.CallingApp,'post_plotting');

                    switch app.TypeDropDown.Value

                        case 'Average'
                            cellPositionsX = zeros(1,length(cells));
                            cellPositionsY = zeros(1,length(cells));

                            forces = zeros(1,length(cells));


                            for k = 1:length(cells)
                                cellPositionsX(k) = mean(cells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                                cellPositionsY(k) = mean(cells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                                switch app.ForceDropDown.Value
                                    case 'Total forces'
                                        forces(k) = mean(sqrt((cells(k).forces.totalX).^2 + (cells(k).forces.totalY).^2));
                                    case 'Cortical forces'
                                        forces(k) = mean(sqrt((cells(k).forces.corticalX).^2 + (cells(k).forces.corticalY).^2));
                                    case 'Junction forces'
                                        forces(k) = mean(sqrt((cells(k).forces.junctionX).^2 + (cells(k).forces.junctionY).^2));
                                    case 'Division forces'
                                        forces(k) = mean(sqrt((cells(k).forces.divisionX).^2 + (cells(k).forces.divisionY).^2));
                                    case 'Membrane forces'
                                        forces(k) = mean(sqrt((cells(k).forces.membraneX).^2 + (cells(k).forces.membraneY).^2));
                                    case 'Contact forces'
                                        forces(k) = mean(sqrt((cells(k).forces.contactX).^2 + (cells(k).forces.contactY).^2));
                                    case 'Area forces'
                                        forces(k) = mean(sqrt((cells(k).forces.areaX).^2 + (cells(k).forces.areaY).^2));
                                    case 'Pointlike forces'
                                        forces(k) = mean(sqrt((cells(k).forces.pointlikeX).^2 + (cells(k).forces.pointlikeY).^2));
                                    case 'Focal adhesion forces'
                                        forces(k) = mean(sqrt((cells(k).forces.substrateX).^2 + (cells(k).forces.substrateY).^2));
                                end

                            end
                        case 'Maximum'
                            cellPositionsX = zeros(1,length(cells));
                            cellPositionsY = zeros(1,length(cells));

                            forces = zeros(1,length(cells));


                            for k = 1:length(cells)
                                cellPositionsX(k) = mean(cells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                                cellPositionsY(k) = mean(cells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;

                                switch app.ForceDropDown.Value
                                    case 'Total forces'
                                        forces(k) = max(sqrt((cells(k).forces.totalX).^2 + (cells(k).forces.totalY).^2));
                                    case 'Cortical forces'
                                        forces(k) = max(sqrt((cells(k).forces.corticalX).^2 + (cells(k).forces.corticalY).^2));
                                    case 'Junction forces'
                                        forces(k) = max(sqrt((cells(k).forces.junctionX).^2 + (cells(k).forces.junctionY).^2));
                                    case 'Division forces'
                                        forces(k) = max(sqrt((cells(k).forces.divisionX).^2 + (cells(k).forces.divisionY).^2));
                                    case 'Membrane forces'
                                        forces(k) = max(sqrt((cells(k).forces.membraneX).^2 + (cells(k).forces.membraneY).^2));
                                    case 'Contact forces'
                                        forces(k) = max(sqrt((cells(k).forces.contactX).^2 + (cells(k).forces.contactY).^2));
                                    case 'Area forces'
                                        forces(k) = max(sqrt((cells(k).forces.areaX).^2 + (cells(k).forces.areaY).^2));
                                    case 'Pointlike forces'
                                        forces(k) = max(sqrt((cells(k).forces.pointlikeX).^2 + (cells(k).forces.pointlikeY).^2));
                                    case 'Focal adhesion forces'
                                        forces(k) = max(sqrt((cells(k).forces.substrateX).^2 + (cells(k).forces.substrateY).^2));
                                end

                            end
                        case 'Minimum'
                            cellPositionsX = zeros(1,length(cells));
                            cellPositionsY = zeros(1,length(cells));

                            forces = zeros(1,length(cells));


                            for k = 1:length(cells)
                                cellPositionsX(k) = mean(cells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                                cellPositionsY(k) = mean(cells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;

                                switch app.ForceDropDown.Value
                                    case 'Total forces'
                                        forces(k) = min(sqrt((cells(k).forces.totalX).^2 + (cells(k).forces.totalY).^2));
                                    case 'Cortical forces'
                                        forces(k) = min(sqrt((cells(k).forces.corticalX).^2 + (cells(k).forces.corticalY).^2));
                                    case 'Junction forces'
                                        forces(k) = min(sqrt((cells(k).forces.junctionX).^2 + (cells(k).forces.junctionY).^2));
                                    case 'Division forces'
                                        forces(k) = min(sqrt((cells(k).forces.divisionX).^2 + (cells(k).forces.divisionY).^2));
                                    case 'Membrane forces'
                                        forces(k) = min(sqrt((cells(k).forces.membraneX).^2 + (cells(k).forces.membraneY).^2));
                                    case 'Contact forces'
                                        forces(k) = min(sqrt((cells(k).forces.contactX).^2 + (cells(k).forces.contactY).^2));
                                    case 'Area forces'
                                        forces(k) = min(sqrt((cells(k).forces.areaX).^2 + (cells(k).forces.areaY).^2));
                                    case 'Pointlike forces'
                                        forces(k) = min(sqrt((cells(k).forces.pointlikeX).^2 + (cells(k).forces.pointlikeY).^2));
                                    case 'Focal adhesion forces'
                                        forces(k) = min(sqrt((cells(k).forces.substrateX).^2 + (cells(k).forces.substrateY).^2));
                                end

                            end
                    end

                    moveX = -cellPositionsX(pointLikeCell);
                    moveY = -cellPositionsY(pointLikeCell);

                    cellPositionsX = cellPositionsX + moveX;
                    cellPositionsY = cellPositionsY + moveY;

                    dispData = griddata(cellPositionsX,cellPositionsY,forces,xq,yq,'cubic');

                    vertical = dispData(:,end);
                    if app.HorizontalCheckBox_2.Value
                        horizontal = dispData(middlePoint,1:end);
                    end

                else
                    if exist([app.CallingApp.plotImport(fileIdx).folderName '/substrate'],'dir') == 7

                        importedPoints = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate/substrate_points_' num2str(timePoint) '.csv']);
                        pointsX = importedPoints(:,1);
                        pointsY = importedPoints(:,2);

                        initialCells = import_cells(app.CallingApp,'post_plotting','initial');

                        moveX = -mean(initialCells(pointLikeCell).verticesX).*d.spar.scalingLength.*1e6;
                        moveY = -mean(initialCells(pointLikeCell).verticesY).*d.spar.scalingLength.*1e6;

                        switch app.ForceDropDown.Value
                            case 'Total substrate forces'
                                importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/total/total_' num2str(timePoint) '.csv']);
                                forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);
                            case 'Direct substrate forces'
                                importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/direct/direct_' num2str(timePoint) '.csv']);
                                forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);
                            case 'Restorative substrate forces'
                                importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/restoration/restoration_' num2str(timePoint) '.csv']);
                                forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);
                            case 'Repulsion substrate forces'
                                importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/repulsion/repulsion_' num2str(timePoint) '.csv']);
                                forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);
                            case 'Focal adhesion substrate forces'
                                importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/focal_adhesion/focal_adhesion_' num2str(timePoint) '.csv']);
                                forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);

                        end


                        subX = pointsX*d.spar.scalingLength/1e-6 + moveX;
                        subY = pointsY*d.spar.scalingLength/1e-6 + moveY;

                        dispData = griddata(subX,subY,forces,xq,yq,'cubic');

                        vertical = dispData(:,end);
                        if app.HorizontalCheckBox_2.Value
                            horizontal = dispData(middlePoint,1:end);
                        end

                    else
                        app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;
                        app.CallingApp.selectedFile = previousSelected;
                        uialert(app.UIFigure, ['Selected simulation number ' num2str(i) ' does not have substrate data.'],'Data missing');
                        return
                    end

                end

                app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;

                plotHandleTemp = {};

                if app.HorizontalCheckBox_2.Value

                    app.analysisFigureHandle;
                    subplot(1,2,1);
                    hold on
                    if i == 1 && ~strcmp(app.LegendEditField_2.Value,'')
                        fh = plot(yPositions(1:1:end),vertical(1:1:end),'Color',color,'LineWidth',1,'DisplayName',app.LegendEditField_2.Value);
                        legend('show', 'Interpreter', 'none');
                        app.LegendEditField_2.Value = '';
                    else
                        fh = plot(yPositions(1:1:end),vertical(1:1:end),'Color', color,'LineWidth',1,'HandleVisibility','off');
                    end
                    plotHandleTemp{end+1} = fh;
                    hold off

                    title(['Vertical ' app.ForceDropDown.Value], 'FontSize', 14)
                    
                    yax = get(gca,'YAxis');
                    
                    yLimits = yax.Limits;
                    
                    if app.VerticalhalfButton_2.Value
                        if app.LogyaxisCheckBox_2.Value
                            set(gca,'yscale','log')

                            if max(max(vertical(:)),max(horizontal(:)))*2 > yax.Limits(2)
                                yLimits(2) = max(max(vertical(:)),max(horizontal(:)))*2;
                            end
                            if min(min(vertical(:)),min(horizontal(:)),'omitnan')*0.8 < yax.Limits(1) || yax.Limits(1) == 0
                                yLimits(1) = min(min(vertical(:)),min(horizontal(:)),'omitnan')*0.8;
                            end
                            set(gca,'XLim',[-app.WindowsizeEditField.Value/2 0])
                            set(gca,'YLim',yLimits);
                        else
                            set(gca,'yscale','linear')

                            if max(max(vertical(:)),max(horizontal(:)))*1.05 > yax.Limits(2)
                               yLimits(2) = max(max(vertical(:)),max(horizontal(:)))*1.05;
                            end
                            yLimits(1) = 0;

                            set(gca,'XLim',[-app.WindowsizeEditField.Value/2 0])
                            set(gca,'YLim',yLimits);
                        end
                    else
                        if app.LogyaxisCheckBox_2.Value
                            set(gca,'yscale','log')

                            if max(max(vertical(:)),max(horizontal(:)))*2 > yax.Limits(2)
                                yLimits(2) = max(max(vertical(:)),max(horizontal(:)))*2;
                            end
                            if min(min(vertical(:)),min(horizontal(:)),'omitnan')*0.8 < yax.Limits(1) || yax.Limits(1) == 0
                                yLimits(1) = min(min(vertical(:)),min(horizontal(:)),'omitnan')*0.8;
                            end
                            set(gca,'XLim',[-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2])
                            set(gca,'YLim',yLimits);
                        else
                            set(gca,'yscale','linear')

                            if max(max(vertical(:)),max(horizontal(:)))*1.05 > yax.Limits(2)
                                yLimits(2) = max(max(vertical(:)),max(horizontal(:)))*1.05;
                            end
                            yLimits(1) = 0;

                            set(gca,'XLim',[-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2])
                            set(gca,'YLim',yLimits);
                        end
                    end
                    xlabel('Distance downwards ({\mu}m)', 'FontSize', 12)
                    ylabel('Force magnitude', 'FontSize', 12)


                    subplot(1,2,2);
                    hold on
                    fh = plot(xPositions(1:1:end),horizontal(1:1:end),'Color',color,'LineWidth',1,'HandleVisibility','off');
                    plotHandleTemp{end+1} = fh;
                    hold off

                    title(['Horizontal ' app.ForceDropDown.Value], 'FontSize', 14)

                    yax = get(gca,'YAxis');

                    yLimits = yax.Limits;

                    if app.LogyaxisCheckBox_2.Value
                        set(gca,'yscale','log')

                        if max(max(vertical(:)),max(horizontal(:)))*2 > yax.Limits(2)
                            yLimits(2) = max(max(vertical(:)),max(horizontal(:)))*2;
                        end
                        if min(min(vertical(:)),min(horizontal(:)),'omitnan')*0.8 < yax.Limits(1) || yax.Limits(1) == 0
                            yLimits(1) = min(min(vertical(:)),min(horizontal(:)),'omitnan')*0.8;
                        end
                        set(gca,'XLim',[-app.WindowsizeEditField.Value/2 0])
                        set(gca,'YLim',yLimits);
                    else
                        set(gca,'yscale','linear')

                        if max(max(vertical(:)),max(horizontal(:)))*1.05 > yax.Limits(2)
                            yLimits(2) = max(max(vertical(:)),max(horizontal(:)))*1.05;
                        end
                        yLimits(1) = 0;

                        set(gca,'XLim',[-app.WindowsizeEditField.Value/2 0])
                        set(gca,'YLim',yLimits);
                    end


                    xlabel('Distance sideways ({\mu}m)', 'FontSize', 12)
                    ylabel('Force magnitude', 'FontSize', 12)


                else
                    app.analysisFigureHandle;

                    hold on
                    if i == 1 && ~strcmp(app.LegendEditField_2.Value,'')
                        fh = plot(yPositions(1:1:end),vertical(1:1:end),'Color',color,'LineWidth',1,'DisplayName',app.LegendEditField_2.Value);
                        legend('show', 'Interpreter', 'none');
                        app.LegendEditField_2.Value = '';
                    else
                        fh = plot(yPositions(1:1:end),vertical(1:1:end),'Color', color,'LineWidth',1,'HandleVisibility','off');
                    end
                    plotHandleTemp{end+1} = fh;
                    hold off

                    yax = get(gca,'YAxis');
                    
                    yLimits = yax.Limits;
                    
                    title(['Vertical ' app.ForceDropDown.Value], 'FontSize', 14)
                    if app.VerticalhalfButton_2.Value
                        if app.LogyaxisCheckBox_2.Value
                            set(gca,'yscale','log')
                        
                            if max(vertical(:))*2 > yax.Limits(2)
                                yLimits(2) = max(vertical(:))*2;
                            end
                            if min(vertical(:),[],'omitnan')*0.8 < yax.Limits(1) || yax.Limits(1) == 0
                                yLimits(1) = min(vertical(:),[],'omitnan')*0.8;
                            end
                            set(gca,'XLim',[-app.WindowsizeEditField.Value/2 0])
                            set(gca,'YLim',yLimits);
                        else
                            set(gca,'yscale','linear')
                            
                            if max(vertical(:))*1.05 > yax.Limits(2)
                                yLimits(2) = max(vertical(:))*1.05;
                            end
                            yLimits(1) = 0;

                            set(gca,'XLim',[-app.WindowsizeEditField.Value/2 0])
                            set(gca,'YLim',yLimits);
                        end
                    else
                        if app.LogyaxisCheckBox_2.Value
                            set(gca,'yscale','log')
                        
                            yax = get(gca,'YAxis');
                            if max(vertical(:))*2 > yax.Limits(2)
                                yLimits(2) = max(vertical(:))*2;
                            end
                            if min(vertical(:),[],'omitnan')*0.8 < yax.Limits(1) || yax.Limits(1) == 0
                                yLimits(1) = min(vertical(:),[],'omitnan')*0.8;
                            end
                            set(gca,'XLim',[-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2])
                            set(gca,'YLim',yLimits);
                        else
                            set(gca,'yscale','linear')
                            
                            yax = get(gca,'YAxis');
                            if max(vertical(:))*1.05 > yax.Limits(2)
                                yLimits(2) = max(vertical(:))*1.05;
                            end
                            yLimits(1) = 0;

                            set(gca,'XLim',[-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2])
                            set(gca,'YLim',yLimits);
                        end
                    end
                    xlabel('Distance downwards ({\mu}m)', 'FontSize', 12)
                    ylabel('Force magnitude', 'FontSize', 12)
                    
                end


            end

            app.plotHandle{end+1} = plotHandleTemp;

            app.CallingApp.selectedFile = previousSelected;

            switch app.ForceDropDown.Value
                case 'Total forces'
                    app.CallingApp.importPlottingOptions.cellForcesTotal = pastOption;
                case 'Cortical forces'
                    app.CallingApp.importPlottingOptions.cellForcesCortical = pastOption;
                case 'Junction forces'
                    app.CallingApp.importPlottingOptions.cellForcesJunctions = pastOption;
                case 'Division forces'
                    app.CallingApp.importPlottingOptions.cellForcesDivision = pastOption;
                case 'Membrane forces'
                    app.CallingApp.importPlottingOptions.cellForcesMembrane = pastOption;
                case 'Contact forces'
                    app.CallingApp.importPlottingOptions.cellForcesContact = pastOption;
                case 'Area forces'
                    app.CallingApp.importPlottingOptions.cellForcesArea = pastOption;
                case 'Pointlike forces'
                    app.CallingApp.importPlottingOptions.cellForcesPointlike = pastOption;
                case 'Focal adhesion forces'
                    app.CallingApp.importPlottingOptions.cellForcesFocalAdhesions = pastOption;
                case 'Total substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesTotal = pastOption;
                case 'Direct substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesTotal = pastOption;
                case 'Restorative substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesRepulsion = pastOption;
                case 'Repulsion substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesRestoration = pastOption;
                case 'Focal adhesion substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesFocalAdhesions = pastOption;
            end

        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            if strcmp(app.TypeDropDown.Value,'Individual')
                app.PlotallButton_2.Enable = 'off';
                app.PlotmeanButton_2.Enable = 'off';
            else
                app.PlotallButton_2.Enable = 'on';
                app.PlotmeanButton_2.Enable = 'on';
            end

        end

        % Button pushed function: PlotmeanButton_2
        function PlotmeanButton_2Pushed(app, event)
            colorspec = {'r','b','g','c','m','k'};

            switch app.ForceDropDown.Value
                case 'Total forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesTotal;
                    app.CallingApp.importPlottingOptions.cellForcesTotal = true;
                case 'Cortical forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesCortical;
                    app.CallingApp.importPlottingOptions.cellForcesCortical = true;
                case 'Junction forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesJunctions;
                    app.CallingApp.importPlottingOptions.cellForcesJunctions = true;
                case 'Division forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesDivision;
                    app.CallingApp.importPlottingOptions.cellForcesDivision = true;
                case 'Membrane forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesMembrane;
                    app.CallingApp.importPlottingOptions.cellForcesMembrane = true;
                case 'Contact forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesContact;
                    app.CallingApp.importPlottingOptions.cellForcesContact = true;
                case 'Area forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesArea;
                    app.CallingApp.importPlottingOptions.cellForcesArea = true;
                case 'Pointlike forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesPointlike;
                    app.CallingApp.importPlottingOptions.cellForcesPointlike = true;
                case 'Focal adhesion forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesFocalAdhesions;
                    app.CallingApp.importPlottingOptions.cellForcesFocalAdhesions = true;
                case 'Total substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesTotal;
                    app.CallingApp.importPlottingOptions.substrateForcesTotal = true;
                case 'Direct substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesDirect;
                    app.CallingApp.importPlottingOptions.substrateForcesDirect = true;
                case 'Restorative substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesRepulsion;
                    app.CallingApp.importPlottingOptions.substrateForcesRepulsion = true;
                case 'Repulsion substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesRestoration;
                    app.CallingApp.importPlottingOptions.substrateForcesRestoration = true;
                case 'Focal adhesion substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesFocalAdhesions;
                    app.CallingApp.importPlottingOptions.substrateForcesFocalAdhesions = true;
            end


            timePoint = find(strcmp(app.TimePointDropDown.Items, app.TimePointDropDown.Value));

            if app.VerticalhalfButton_2.Value
                [xq,yq] = meshgrid(-400:1:0,-400:1:0);
                middlePoint = size(yq,1);
            else
                [xq,yq] = meshgrid(-400:1:0,-400:1:400);
                middlePoint = round(size(yq,1)/2);
            end

            yPositions =  yq(:,1);
            xPositions = xq(1,:);

            if isa(app.analysisFigureHandle,'double') || (~isa(app.analysisFigureHandle,'double') && ~isvalid(app.analysisFigureHandle)) || app.NewplotCheckBox_2.Value
                app.analysisFigureHandle = figure(2);
                clf
                app.numberOfAnalysisPlots = 0;
                mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;
                app.plotHandle = {};

                app.analysisFigureHandle.Position(1) = mainWindowPosition(1) + 600;
                app.analysisFigureHandle.Position(2) = mainWindowPosition(2);

                res = get(0,'screensize');

                position = get(gcf, 'Position');

                set(gcf, 'Position',  [position(1), position(2), 895, 895])

                position = get(gcf, 'Position');

                if sum(position([2 4])) + 100 > res(4)
                    position(2) = res(4) - position(4) - 100;
                end
                app.analysisFigureHandle.Position(2) = position(2);

            else
                app.analysisFigureHandle;
            end

            if ~app.ColorpickerCheckBox_2.Value
                if app.numberOfAnalysisPlots >= length(colorspec)
                    color = 'r';
                else
                    app.numberOfAnalysisPlots = app.numberOfAnalysisPlots + 1;
                    color = colorspec{app.numberOfAnalysisPlots};
                end
            else
                color = uisetcolor;
            end


            if ~app.HorizontalCheckBox_2.Value
                app.analysisFigureHandle.Position(3:4) = [400 400];
            else
                app.analysisFigureHandle.Position(3:4) = [800 400];
            end

            vertical = zeros(size(yq,1),length(app.SimulationListBox.Value));
            if app.HorizontalCheckBox.Value
                horizontal = zeros(size(xq,2),length(app.SimulationListBox.Value));
            end


            previousSelected = app.CallingApp.selectedFile;

            for i = 1:length(app.SimulationListBox.Value)

                for ii = 1:length(app.CallingApp.LoadedfilesDropDown.Items)
                    if isa(length(app.SimulationListBox.Value),'char')
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value)
                            fileIdx = ii;
                            break
                        end
                    else
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value(i))
                            fileIdx = ii;
                            break
                        end
                    end
                end

                app.CallingApp.selectedFile = fileIdx;

                d.spar.scalingLength = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength;
                d.spar.scalingTime = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingTime;
                d.spar.rCell = app.CallingApp.plotImport(fileIdx).scaledParameters.rCell;

                d.pl.substrateStyle = 1;
                d.pl.substrateForcesDirect = 0;
                d.pl.substrateForcesRepulsion = 0;
                d.pl.substrateForcesRestoration = 0;
                d.pl.substrateForcesFocalAdhesions = 0;
                d.pl.substrateForcesTotal = 0;
                d.pl.titleType = 0;
                d.pl.windowSize = app.CallingApp.importPlottingOptions.windowSize;
                d.pl.axisLimits = [app.CallingApp.MiddlepointxEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointxEditField_2.Value*1e-6+d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointyEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                    app.CallingApp.MiddlepointyEditField_2.Value*1e-6+d.pl.windowSize/2]/d.spar.scalingLength;


                d.simset.simulationType = 2;

                oldTimePoint = app.CallingApp.plotImport(fileIdx).currentTimePoint;

                temp = import_settings([app.CallingApp.plotImport(fileIdx).folderName '/pointlike/pointlike_data.csv']);

                pointLikeCell = temp.cell;

                app.CallingApp.plotImport(fileIdx).currentTimePoint = timePoint;

                substrateForces = {'Total substrate forces','Direct substrate forces','Restorative substrate forces','Repulsion substrate forces','Focal adhesion substrate forces'};

                if ~any(strcmp(app.ForceDropDown.Value,substrateForces))

                    cells = import_cells(app.CallingApp,'post_plotting');

                    initialCells = import_cells(app.CallingApp,'post_plotting','initial');

                    switch app.TypeDropDown.Value

                        case 'Average'
                            cellPositionsX = zeros(1,length(cells));
                            cellPositionsY = zeros(1,length(cells));

                            forces = zeros(1,length(cells));


                            for k = 1:length(cells)
                                cellPositionsX(k) = mean(initialCells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                                cellPositionsY(k) = mean(initialCells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                                switch app.ForceDropDown.Value
                                    case 'Total forces'
                                        forces(k) = mean(sqrt((cells(k).forces.totalX).^2 + (cells(k).forces.totalY).^2));
                                    case 'Cortical forces'
                                        forces(k) = mean(sqrt((cells(k).forces.corticalX).^2 + (cells(k).forces.corticalY).^2));
                                    case 'Junction forces'
                                        forces(k) = mean(sqrt((cells(k).forces.junctionX).^2 + (cells(k).forces.junctionY).^2));
                                    case 'Division forces'
                                        forces(k) = mean(sqrt((cells(k).forces.divisionX).^2 + (cells(k).forces.divisionY).^2));
                                    case 'Membrane forces'
                                        forces(k) = mean(sqrt((cells(k).forces.membraneX).^2 + (cells(k).forces.membraneY).^2));
                                    case 'Contact forces'
                                        forces(k) = mean(sqrt((cells(k).forces.contactX).^2 + (cells(k).forces.contactY).^2));
                                    case 'Area forces'
                                        forces(k) = mean(sqrt((cells(k).forces.areaX).^2 + (cells(k).forces.areaY).^2));
                                    case 'Pointlike forces'
                                        forces(k) = mean(sqrt((cells(k).forces.pointlikeX).^2 + (cells(k).forces.pointlikeY).^2));
                                    case 'Focal adhesion forces'
                                        forces(k) = mean(sqrt((cells(k).forces.substrateX).^2 + (cells(k).forces.substrateY).^2));
                                end

                            end
                        case 'Maximum'
                            cellPositionsX = zeros(1,length(cells));
                            cellPositionsY = zeros(1,length(cells));

                            forces = zeros(1,length(cells));


                            for k = 1:length(cells)
                                cellPositionsX(k) = mean(initialCells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                                cellPositionsY(k) = mean(initialCells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;

                                switch app.ForceDropDown.Value
                                    case 'Total forces'
                                        forces(k) = max(sqrt((cells(k).forces.totalX).^2 + (cells(k).forces.totalY).^2));
                                    case 'Cortical forces'
                                        forces(k) = max(sqrt((cells(k).forces.corticalX).^2 + (cells(k).forces.corticalY).^2));
                                    case 'Junction forces'
                                        forces(k) = max(sqrt((cells(k).forces.junctionX).^2 + (cells(k).forces.junctionY).^2));
                                    case 'Division forces'
                                        forces(k) = max(sqrt((cells(k).forces.divisionX).^2 + (cells(k).forces.divisionY).^2));
                                    case 'Membrane forces'
                                        forces(k) = max(sqrt((cells(k).forces.membraneX).^2 + (cells(k).forces.membraneY).^2));
                                    case 'Contact forces'
                                        forces(k) = max(sqrt((cells(k).forces.contactX).^2 + (cells(k).forces.contactY).^2));
                                    case 'Area forces'
                                        forces(k) = max(sqrt((cells(k).forces.areaX).^2 + (cells(k).forces.areaY).^2));
                                    case 'Pointlike forces'
                                        forces(k) = max(sqrt((cells(k).forces.pointlikeX).^2 + (cells(k).forces.pointlikeY).^2));
                                    case 'Focal adhesion forces'
                                        forces(k) = max(sqrt((cells(k).forces.substrateX).^2 + (cells(k).forces.substrateY).^2));
                                end

                            end
                        case 'Minimum'
                            cellPositionsX = zeros(1,length(cells));
                            cellPositionsY = zeros(1,length(cells));

                            forces = zeros(1,length(cells));


                            for k = 1:length(cells)
                                cellPositionsX(k) = mean(initialCells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                                cellPositionsY(k) = mean(initialCells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;

                                switch app.ForceDropDown.Value
                                    case 'Total forces'
                                        forces(k) = min(sqrt((cells(k).forces.totalX).^2 + (cells(k).forces.totalY).^2));
                                    case 'Cortical forces'
                                        forces(k) = min(sqrt((cells(k).forces.corticalX).^2 + (cells(k).forces.corticalY).^2));
                                    case 'Junction forces'
                                        forces(k) = min(sqrt((cells(k).forces.junctionX).^2 + (cells(k).forces.junctionY).^2));
                                    case 'Division forces'
                                        forces(k) = min(sqrt((cells(k).forces.divisionX).^2 + (cells(k).forces.divisionY).^2));
                                    case 'Membrane forces'
                                        forces(k) = min(sqrt((cells(k).forces.membraneX).^2 + (cells(k).forces.membraneY).^2));
                                    case 'Contact forces'
                                        forces(k) = min(sqrt((cells(k).forces.contactX).^2 + (cells(k).forces.contactY).^2));
                                    case 'Area forces'
                                        forces(k) = min(sqrt((cells(k).forces.areaX).^2 + (cells(k).forces.areaY).^2));
                                    case 'Pointlike forces'
                                        forces(k) = min(sqrt((cells(k).forces.pointlikeX).^2 + (cells(k).forces.pointlikeY).^2));
                                    case 'Focal adhesion forces'
                                        forces(k) = min(sqrt((cells(k).forces.substrateX).^2 + (cells(k).forces.substrateY).^2));
                                end

                            end
                    end

                    moveX = -cellPositionsX(pointLikeCell);
                    moveY = -cellPositionsY(pointLikeCell);

                    cellPositionsX = cellPositionsX + moveX;
                    cellPositionsY = cellPositionsY + moveY;

                    dispData = griddata(cellPositionsX,cellPositionsY,forces,xq,yq,'cubic');

                    vertical(:,i) = dispData(:,end);
                    if app.HorizontalCheckBox_2.Value
                        horizontal(:,i) = dispData(middlePoint,1:end);
                    end

                else
                    if exist([app.CallingApp.plotImport(fileIdx).folderName '/substrate'],'dir') == 7

                        importedPoints = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate/substrate_points_' num2str(timePoint) '.csv']);
                        pointsX = importedPoints(:,1);
                        pointsY = importedPoints(:,2);

                        initialCells = import_cells(app.CallingApp,'post_plotting','initial');

                        moveX = -mean(initialCells(pointLikeCell).verticesX).*d.spar.scalingLength.*1e6;
                        moveY = -mean(initialCells(pointLikeCell).verticesY).*d.spar.scalingLength.*1e6;

                        switch app.ForceDropDown.Value
                            case 'Total substrate forces'
                                importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/total/total_' num2str(timePoint) '.csv']);
                                forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);
                            case 'Direct substrate forces'
                                importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/direct/direct_' num2str(timePoint) '.csv']);
                                forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);
                            case 'Restorative substrate forces'
                                importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/restoration/restoration_' num2str(timePoint) '.csv']);
                                forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);
                            case 'Repulsion substrate forces'
                                importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/repulsion/repulsion_' num2str(timePoint) '.csv']);
                                forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);
                            case 'Focal adhesion substrate forces'
                                importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/focal_adhesion/focal_adhesion_' num2str(timePoint) '.csv']);
                                forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);

                        end


                        subX = pointsX*d.spar.scalingLength/1e-6 + moveX;
                        subY = pointsY*d.spar.scalingLength/1e-6 + moveY;

                        dispData = griddata(subX,subY,forces,xq,yq,'cubic');

                        vertical(:,i) = dispData(:,end);
                        if app.HorizontalCheckBox_2.Value
                            horizontal(:,i) = dispData(middlePoint,1:end);
                        end

                    else
                        app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;
                        app.CallingApp.selectedFile = previousSelected;
                        uialert(app.UIFigure, ['Selected simulation number ' num2str(i) ' does not have substrate data.'],'Data missing');
                        return
                    end
                end
            end

            if size(vertical,1) > size(vertical,2)
                vertMean = mean(vertical,2);
            else
                vertMean = mean(vertical,1);
            end

            if size(vertical,1) > size(vertical,2)
                vertStd = std(vertical,0,2);
            else
                vertStd = std(vertical,0,1);
            end

            if app.HorizontalCheckBox_2.Value

                if size(horizontal,1) > size(horizontal,2)
                    horizMean = mean(horizontal,2);
                else
                    horizMean = mean(horizontal,1);
                end

                if size(horizontal,1) > size(horizontal,2)
                    horizStd = std(horizontal,0,2);
                else
                    horizStd = std(horizontal,0,1);
                end
            end


            app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;

            plotHandleTemp = {};

            if app.HorizontalCheckBox_2.Value

                app.analysisFigureHandle;
                subplot(1,2,1);
                hold on
                if ~strcmp(app.LegendEditField_2.Value,'')
                    fh = errorbar(yPositions(1:1:end),vertMean(1:1:end),vertStd(1:1:end),'Color',color,'DisplayName',app.LegendEditField_2.Value);
                    legend('show', 'Interpreter', 'none');
                    app.LegendEditField_2.Value = '';
                else
                    fh = errorbar(yPositions(1:1:end),vertMean(1:1:end),vertStd(1:1:end),'Color',color,'HandleVisibility','off');
                end
                app.LegendEditField_2.Value = '';
                hold off
                plotHandleTemp{end+1} = fh;
                title(['Vertical ' app.ForceDropDown.Value], 'FontSize', 14)

                yax = get(gca,'YAxis');

                yLimits = yax.Limits;

                if app.VerticalhalfButton_2.Value
                    if app.LogyaxisCheckBox_2.Value
                        set(gca,'yscale','log')

                        if max(max(vertMean(:)),max(horizMean(:)))*2 > yax.Limits(2)
                            yLimits(2) = max(max(vertMean(:)),max(horizMean(:)))*2;
                        end
                        if min(min(vertMean(:)),min(horizMean(:)),'omitnan')*0.8 < yax.Limits(1) || yax.Limits(1) == 0
                            yLimits(1) = min(min(vertMean(:)),min(horizMean(:)),'omitnan')*0.8;
                        end
                        set(gca,'XLim',[-app.WindowsizeEditField.Value/2 0])
                        set(gca,'YLim',yLimits);
                    else
                        set(gca,'yscale','linear')

                        if max(max(vertMean(:)),max(horizMean(:)))*1.05 > yax.Limits(2)
                            yLimits(2) = max(max(vertMean(:)),max(horizMean(:)))*1.05;
                        end
                        yLimits(1) = 0;

                        set(gca,'XLim',[-app.WindowsizeEditField.Value/2 0])
                        set(gca,'YLim',yLimits);
                    end
                else
                    if app.LogyaxisCheckBox_2.Value
                        set(gca,'yscale','log')

                        if max(max(vertMean(:)),max(horizMean(:)))*2 > yax.Limits(2)
                            yLimits(2) = max(max(vertMean(:)),max(horizMean(:)))*2;
                        end
                        if min(min(vertMean(:)),min(horizMean(:)),'omitnan')*0.8 < yax.Limits(1) || yax.Limits(1) == 0
                            yLimits(1) = min(min(vertMean(:)),min(horizMean(:)),'omitnan')*0.8;
                        end
                        set(gca,'XLim',[-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2])
                        set(gca,'YLim',yLimits);
                    else
                        set(gca,'yscale','linear')

                        if max(max(vertMean(:)),max(horizMean(:)))*1.05 > yax.Limits(2)
                            yLimits(2) = max(max(vertMean(:)),max(horizMean(:)))*1.05;
                        end
                        yLimits(1) = 0;

                        set(gca,'XLim',[-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2])
                        set(gca,'YLim',yLimits);
                    end
                end


                xlabel('Distance downwards ({\mu}m)', 'FontSize', 12)
                ylabel('Force magnitude', 'FontSize', 12)


                subplot(1,2,2);
                hold on
                fh = errorbar(xPositions(1:1:end),horizMean(1:1:end),horizStd(1:1:end),'Color',color,'HandleVisibility','off');
                app.LegendEditField_2.Value = '';
                hold off
                plotHandleTemp{end+1} = fh;
                title(['Horizontal ' app.ForceDropDown.Value], 'FontSize', 14)


                if app.LogyaxisCheckBox_2.Value
                    set(gca,'yscale','log')

                    if max(max(vertMean(:)),max(horizMean(:)))*2 > yax.Limits(2)
                        yLimits(2) = max(max(vertMean(:)),max(horizMean(:)))*2;
                    end
                    if min(min(vertMean(:)),min(horizMean(:)),'omitnan')*0.8 < yax.Limits(1) || yax.Limits(1) == 0
                        yLimits(1) = min(min(vertMean(:)),min(horizMean(:)),'omitnan')*0.8;
                    end
                    set(gca,'XLim',[-app.WindowsizeEditField.Value/2 0])
                    set(gca,'YLim',yLimits);
                else
                    set(gca,'yscale','linear')

                    if max(max(vertMean(:)),max(horizMean(:)))*1.05 > yax.Limits(2)
                        yLimits(2) = max(max(vertMean(:)),max(horizMean(:)))*1.05;
                    end
                    yLimits(1) = 0;

                    set(gca,'XLim',[-app.WindowsizeEditField.Value/2 0])
                    set(gca,'YLim',yLimits);
                end


                xlabel('Distance sideways ({\mu}m)', 'FontSize', 12)
                ylabel('Force magnitude', 'FontSize', 12)
                
            else
                app.analysisFigureHandle;

                hold on
                if ~strcmp(app.LegendEditField_2.Value,'')
                    fh = errorbar(yPositions(1:1:end),vertMean(1:1:end),vertStd(1:1:end),'Color',color,'DisplayName',app.LegendEditField_2.Value);
                    legend('show', 'Interpreter', 'none');
                    app.LegendEditField_2.Value = '';
                else
                    fh = errorbar(yPositions(1:1:end),vertMean(1:1:end),vertStd(1:1:end),'Color',color,'HandleVisibility','off');
                end
                app.LegendEditField_2.Value = '';
                hold off
                plotHandleTemp{end+1} = fh;
                title(['Vertical ' app.ForceDropDown.Value], 'FontSize', 14)
                
                yax = get(gca,'YAxis');
                    
                    yLimits = yax.Limits;
                    
                    title(['Vertical ' app.ForceDropDown.Value], 'FontSize', 14)
                    if app.VerticalhalfButton_2.Value
                        if app.LogyaxisCheckBox_2.Value
                            set(gca,'yscale','log')
                        
                            if max(vertMean(:))*2 > yax.Limits(2)
                                yLimits(2) = max(vertMean(:))*2;
                            end
                            if min(vertMean(:),[],'omitnan')*0.8 < yax.Limits(1) || yax.Limits(1) == 0
                                yLimits(1) = min(vertMean(:),[],'omitnan')*0.8;
                            end
                            set(gca,'XLim',[-app.WindowsizeEditField.Value/2 0])
                            set(gca,'YLim',yLimits);
                        else
                            set(gca,'yscale','linear')
                            
                            if max(vertMean(:))*1.05 > yax.Limits(2)
                                yLimits(2) = max(vertMean(:))*1.05;
                            end
                            yLimits(1) = 0;

                            set(gca,'XLim',[-app.WindowsizeEditField.Value/2 0])
                            set(gca,'YLim',yLimits);
                        end
                    else
                        if app.LogyaxisCheckBox_2.Value
                            set(gca,'yscale','log')
                        
                            yax = get(gca,'YAxis');
                            if max(vertMean(:))*2 > yax.Limits(2)
                                yLimits(2) = max(vertMean(:))*2;
                            end
                            if min(vertMean(:),[],'omitnan')*0.8 < yax.Limits(1) || yax.Limits(1) == 0
                                yLimits(1) = min(vertMean(:),[],'omitnan')*0.8;
                            end
                            set(gca,'XLim',[-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2])
                            set(gca,'YLim',yLimits);
                        else
                            set(gca,'yscale','linear')
                            
                            yax = get(gca,'YAxis');
                            if max(vertMean(:))*1.05 > yax.Limits(2)
                                yLimits(2) = max(vertMean(:))*1.05;
                            end
                            yLimits(1) = 0;

                            set(gca,'XLim',[-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2])
                            set(gca,'YLim',yLimits);
                        end
                    end
                    
                xlabel('Distance downwards ({\mu}m)', 'FontSize', 12)
                ylabel('Force magnitude', 'FontSize', 12)

            end

            app.plotHandle{end+1} = plotHandleTemp;

            app.CallingApp.selectedFile = previousSelected;

            switch app.ForceDropDown.Value
                case 'Total forces'
                    app.CallingApp.importPlottingOptions.cellForcesTotal = pastOption;
                case 'Cortical forces'
                    app.CallingApp.importPlottingOptions.cellForcesCortical = pastOption;
                case 'Junction forces'
                    app.CallingApp.importPlottingOptions.cellForcesJunctions = pastOption;
                case 'Division forces'
                    app.CallingApp.importPlottingOptions.cellForcesDivision = pastOption;
                case 'Membrane forces'
                    app.CallingApp.importPlottingOptions.cellForcesMembrane = pastOption;
                case 'Contact forces'
                    app.CallingApp.importPlottingOptions.cellForcesContact = pastOption;
                case 'Area forces'
                    app.CallingApp.importPlottingOptions.cellForcesArea = pastOption;
                case 'Pointlike forces'
                    app.CallingApp.importPlottingOptions.cellForcesPointlike = pastOption;
                case 'Focal adhesion forces'
                    app.CallingApp.importPlottingOptions.cellForcesFocalAdhesions = pastOption;
                case 'Total substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesTotal = pastOption;
                case 'Direct substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesTotal = pastOption;
                case 'Restorative substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesRepulsion = pastOption;
                case 'Repulsion substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesRestoration = pastOption;
                case 'Focal adhesion substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesFocalAdhesions = pastOption;
            end
        end

        % Button pushed function: RemovelastplotButton
        function RemovelastplotButtonPushed(app, event)
            if ~isempty(app.plotHandle)
                for i = 1:length(app.plotHandle{end})
                    delete(app.plotHandle{end}{i});
                end
                app.plotHandle(end) = [];
            end

            if isempty(app.plotHandle)
                app.numberOfAnalysisPlots = 0;
            end
        end

        % Button pushed function: SavetofileButton
        function SavetofileButtonPushed(app, event)

            if ~(isa(app.analysisFigureHandle,'double') || (~isa(app.analysisFigureHandle,'double') && ~isvalid(app.analysisFigureHandle)))

                if ~strcmp(app.TabGroup.SelectedTab.Title,'Forces')

                    cellsData = [];
                    subData = [];
                    cellsData_2 = [];
                    subData_2 = [];
                    Names = {};

                    for i = 1:length(app.plotHandle)
                        if ~app.HorizontalCheckBox.Value
                            cellsData = [cellsData app.plotHandle{i}{1}.YData' app.plotHandle{i}{1}.YPositiveDelta'];

                            subData = [subData app.plotHandle{i}{2}.YData' app.plotHandle{i}{2}.YPositiveDelta'];
                        else
                            cellsData = [cellsData app.plotHandle{i}{1}.YData' app.plotHandle{i}{1}.YPositiveDelta'];
                            cellsData_2 = [cellsData_2 app.plotHandle{i}{2}.YData' app.plotHandle{i}{2}.YPositiveDelta'];
                            subData = [subData app.plotHandle{i}{3}.YData' app.plotHandle{i}{3}.YPositiveDelta'];
                            subData_2 = [subData_2 app.plotHandle{i}{4}.YData' app.plotHandle{i}{4}.YPositiveDelta'];
                        end
                        if isempty(app.plotHandle{i}{1}.DisplayName)
                            Names = [Names ['unknown' num2str(i)]];
                            Names = [Names [num2str(i) '+-']];
                        else
                            Names = [Names app.plotHandle{i}{1}.DisplayName];
                            Names = [Names [num2str(i) '+-']];
                        end

                    end

                    Posy = app.plotHandle{1}{1}.XData';

                    Names = ['Position' Names];

                    [file, path] = uiputfile('.csv','Save the data from the figure');

                    [~,name,ext] = fileparts(file);


                    if ~app.HorizontalCheckBox.Value
                        writetable(array2table([Posy cellsData],'VariableNames',Names),[path name '_cell' ext]);
                        writetable(array2table([Posy subData],'VariableNames',Names),[path name '_sub' ext]);
                        fID = fopen([path name '_cell' ext]);

                        A = char(fread(fID,inf)).';
                        fclose(fID);

                        k = strfind(A,'+-');
                        for i = length(k):-1:1
                            A(k(i)-1) = [];
                        end
                        fID = fopen([path name '_cell' ext],'w');
                        fprintf(fID,A);
                        fclose(fID);

                        fID = fopen([path name '_sub' ext]);

                        A = char(fread(fID,inf)).';
                        fclose(fID);

                        k = strfind(A,'+-');
                        for i = length(k):-1:1
                            A(k(i)-1) = [];
                        end
                        fID = fopen([path name '_sub' ext],'w');
                        fprintf(fID,A);
                        fclose(fID);
                    else
                        writetable(array2table([Posy cellsData],'VariableNames',Names),[path name '_cell_vertical' ext]);
                        writetable(array2table([Posy subData],'VariableNames',Names),[path name '_sub_vertical' ext]);
                        writetable(array2table([Posy cellsData_2],'VariableNames',Names),[path name '_cell_horizontal' ext]);
                        writetable(array2table([Posy subData_2],'VariableNames',Names),[path name '_sub_horizontal' ext]);

                        fID = fopen([path name '_cell_vertical' ext]);

                        A = char(fread(fID,inf)).';
                        fclose(fID);

                        k = strfind(A,'+-');
                        for i = length(k):-1:1
                            A(k(i)-1) = [];
                        end
                        fID = fopen([path name '_cell_vertical' ext],'w');
                        fprintf(fID,A);
                        fclose(fID);

                        fID = fopen([path name '_sub_vertical' ext]);

                        A = char(fread(fID,inf)).';
                        fclose(fID);

                        k = strfind(A,'+-');
                        for i = length(k):-1:1
                            A(k(i)-1) = [];
                        end
                        fID = fopen([path name '_sub_vertical' ext],'w');
                        fprintf(fID,A);
                        fclose(fID);


                        fID = fopen([path name '_cell_horizontal' ext]);

                        A = char(fread(fID,inf)).';
                        fclose(fID);

                        k = strfind(A,'+-');
                        for i = length(k):-1:1
                            A(k(i)-1) = [];
                        end
                        fID = fopen([path name '_cell_horizontal' ext],'w');
                        fprintf(fID,A);
                        fclose(fID);

                        fID = fopen([path name '_sub_horizontal' ext]);

                        A = char(fread(fID,inf)).';
                        fclose(fID);

                        k = strfind(A,'+-');
                        for i = length(k):-1:1
                            A(k(i)-1) = [];
                        end
                        fID = fopen([path name '_sub_horizontal' ext],'w');
                        fprintf(fID,A);
                        fclose(fID);
                    end

                else

                    data = [];
                    Names = {};

                    for i = 1:length(app.plotHandle)
                        data = [data app.plotHandle{i}{1}.YData' app.plotHandle{i}{1}.YPositiveDelta'];

                        if isempty(app.plotHandle{i}{1}.DisplayName)
                            Names = [Names ['unknown' num2str(i)]];
                            Names = [Names [num2str(i) '+-']];
                        else
                            Names = [Names app.plotHandle{i}{1}.DisplayName];
                            Names = [Names [num2str(i) '+-']];
                        end

                    end

                    Posy = app.plotHandle{i}{1}.XData';

                    Names = ['Position' Names];

                    [file, path] = uiputfile('.csv','Save the data from the figure');

                    [~,name,ext] = fileparts(file);


                    if ~app.HorizontalCheckBox.Value
                        writetable(array2table([Posy data],'VariableNames',Names),[path name ext]);
                        fID = fopen([path name ext]);

                        A = char(fread(fID,inf)).';
                        fclose(fID);

                        k = strfind(A,'+-');
                        for i = length(k):-1:1
                            A(k(i)-1) = [];
                        end
                        fID = fopen([path name ext],'w');
                        fprintf(fID,A);
                        fclose(fID);

                    end

                end


            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CloseButton.Text = 'Close';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DisplacementTab = uitab(app.TabGroup);
            app.DisplacementTab.Title = 'Displacement';
            app.DplotButtonGroup = uibuttongroup(app.DisplacementTab);
            app.DplotButtonGroup.Title = '1D plot';
            app.VerticalhalfButton = uiradiobutton(app.DplotButtonGroup);
            app.VerticalhalfButton.Text = 'Vertical half';
            app.VerticalhalfButton.Value = true;
            app.VerticalfullButton = uiradiobutton(app.DplotButtonGroup);
            app.VerticalfullButton.Text = 'Vertical full';
            app.HorizontalCheckBox = uicheckbox(app.DplotButtonGroup);
            app.HorizontalCheckBox.Text = 'Horizontal';
            app.PlotallButton = uibutton(app.DplotButtonGroup, 'push');
            app.PlotallButton.ButtonPushedFcn = createCallbackFcn(app, @PlotallButtonPushed, true);
            app.PlotallButton.Enable = 'off';
            app.PlotallButton.Text = 'Plot all';
            app.PlotmeanButton = uibutton(app.DplotButtonGroup, 'push');
            app.PlotmeanButton.ButtonPushedFcn = createCallbackFcn(app, @PlotmeanButtonPushed, true);
            app.PlotmeanButton.Enable = 'off';
            app.PlotmeanButton.Text = 'Plot mean';
            app.LogyaxisCheckBox = uicheckbox(app.DplotButtonGroup);
            app.LogyaxisCheckBox.Text = 'Log y axis';
            app.NewplotCheckBox = uicheckbox(app.DplotButtonGroup);
            app.NewplotCheckBox.Text = 'New plot';
            app.LegendEditFieldLabel = uilabel(app.DplotButtonGroup);
            app.LegendEditFieldLabel.Text = 'Legend';
            app.LegendEditField = uieditfield(app.DplotButtonGroup, 'text');
            app.ColorpickerCheckBox = uicheckbox(app.DplotButtonGroup);
            app.ColorpickerCheckBox.Text = 'Colorpicker';
            app.AreaplotPanel = uipanel(app.DisplacementTab);
            app.AreaplotPanel.Title = 'Area plot';
            app.DisplacementTypeButtonGroup = uibuttongroup(app.AreaplotPanel);
            app.CellsButton = uiradiobutton(app.DisplacementTypeButtonGroup);
            app.CellsButton.Text = 'Cells';
            app.CellsButton.Value = true;
            app.SubstrateButton = uiradiobutton(app.DisplacementTypeButtonGroup);
            app.SubstrateButton.Text = 'Substrate';
            app.AreaplotButton = uibutton(app.AreaplotPanel, 'push');
            app.AreaplotButton.ButtonPushedFcn = createCallbackFcn(app, @AreaplotButtonPushed, true);
            app.AreaplotButton.Enable = 'off';
            app.AreaplotButton.Text = 'Area plot';
            app.ContoursCheckBox = uicheckbox(app.AreaplotPanel);
            app.ContoursCheckBox.Text = 'Contours';
            app.StartDropDownLabel = uilabel(app.DisplacementTab);
            app.StartDropDownLabel.Text = 'Start';
            app.StartDropDown = uidropdown(app.DisplacementTab);
            app.StartDropDown.Items = {};
            app.StartDropDown.ValueChangedFcn = createCallbackFcn(app, @StartDropDownValueChanged, true);
            app.StartDropDown.Value = {};
            app.EndDropDownLabel = uilabel(app.DisplacementTab);
            app.EndDropDownLabel.Text = 'End';
            app.EndDropDown = uidropdown(app.DisplacementTab);
            app.EndDropDown.Items = {};
            app.EndDropDown.ValueChangedFcn = createCallbackFcn(app, @EndDropDownValueChanged, true);
            app.EndDropDown.Value = {};
            app.WindowsizeEditFieldLabel = uilabel(app.DisplacementTab);
            app.WindowsizeEditFieldLabel.Text = 'Window size';
            app.WindowsizeEditField = uieditfield(app.DisplacementTab, 'numeric');
            app.WindowsizeEditField.Limits = [0 Inf];
            app.WindowsizeEditField.ValueChangedFcn = createCallbackFcn(app, @WindowsizeEditFieldValueChanged, true);
            app.WindowsizeEditField.Value = 200;
            app.mLabel_2 = uilabel(app.DisplacementTab);
            app.mLabel_2.Text = 'µm';
            app.MaximumdisplacementEditFieldLabel = uilabel(app.DisplacementTab);
            app.MaximumdisplacementEditFieldLabel.Text = 'Max displacement';
            app.MaximumdisplacementEditField = uieditfield(app.DisplacementTab, 'numeric');
            app.MaximumdisplacementEditField.Value = 30;
            app.mLabel = uilabel(app.DisplacementTab);
            app.mLabel.Text = 'µm';
            app.ContoursEditFieldLabel = uilabel(app.DisplacementTab);
            app.ContoursEditFieldLabel.Text = 'Contours';
            app.ContoursEditField = uieditfield(app.DisplacementTab, 'text');
            app.ContoursEditField.ValueChangedFcn = createCallbackFcn(app, @ContoursEditFieldValueChanged, true);
            app.ContoursEditField.Value = '1 2 5 10 15';
            app.ForcesTab = uitab(app.TabGroup);
            app.ForcesTab.Title = 'Forces';
            app.DplotButtonGroup_2 = uibuttongroup(app.ForcesTab);
            app.DplotButtonGroup_2.Title = '1D plot';
            app.VerticalhalfButton_2 = uiradiobutton(app.DplotButtonGroup_2);
            app.VerticalhalfButton_2.Text = 'Vertical half';
            app.VerticalhalfButton_2.Value = true;
            app.VerticalfullButton_2 = uiradiobutton(app.DplotButtonGroup_2);
            app.VerticalfullButton_2.Text = 'Vertical full';
            app.HorizontalCheckBox_2 = uicheckbox(app.DplotButtonGroup_2);
            app.HorizontalCheckBox_2.Text = 'Horizontal';
            app.PlotallButton_2 = uibutton(app.DplotButtonGroup_2, 'push');
            app.PlotallButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotallButton_2Pushed, true);
            app.PlotallButton_2.Enable = 'off';
            app.PlotallButton_2.Text = 'Plot all';
            app.PlotmeanButton_2 = uibutton(app.DplotButtonGroup_2, 'push');
            app.PlotmeanButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotmeanButton_2Pushed, true);
            app.PlotmeanButton_2.Enable = 'off';
            app.PlotmeanButton_2.Text = 'Plot mean';
            app.LogyaxisCheckBox_2 = uicheckbox(app.DplotButtonGroup_2);
            app.LogyaxisCheckBox_2.Text = 'Log y axis';
            app.NewplotCheckBox_2 = uicheckbox(app.DplotButtonGroup_2);
            app.NewplotCheckBox_2.Text = 'New plot';
            app.LegendEditField_2Label = uilabel(app.DplotButtonGroup_2);
            app.LegendEditField_2Label.Text = 'Legend';
            app.LegendEditField_2 = uieditfield(app.DplotButtonGroup_2, 'text');
            app.ColorpickerCheckBox_2 = uicheckbox(app.DplotButtonGroup_2);
            app.ColorpickerCheckBox_2.Text = 'Colorpicker';
            app.AreaplotPanel_2 = uipanel(app.ForcesTab);
            app.AreaplotPanel_2.Title = 'Area plot';
            app.AreaplotButton_2 = uibutton(app.AreaplotPanel_2, 'push');
            app.AreaplotButton_2.ButtonPushedFcn = createCallbackFcn(app, @AreaplotButton_2Pushed, true);
            app.AreaplotButton_2.Enable = 'off';
            app.AreaplotButton_2.Text = 'Area plot';
            app.mLabel_3 = uilabel(app.ForcesTab);
            app.mLabel_3.Text = 'µm';
            app.TimePointDropDownLabel = uilabel(app.ForcesTab);
            app.TimePointDropDownLabel.Text = 'Time Point';
            app.TimePointDropDown = uidropdown(app.ForcesTab);
            app.TimePointDropDown.Items = {};
            app.TimePointDropDown.Value = {};
            app.WindowsizeEditField_2Label = uilabel(app.ForcesTab);
            app.WindowsizeEditField_2Label.Text = 'Window size';
            app.WindowsizeEditField_2 = uieditfield(app.ForcesTab, 'numeric');
            app.WindowsizeEditField_2.Limits = [0 Inf];
            app.WindowsizeEditField_2.ValueChangedFcn = createCallbackFcn(app, @WindowsizeEditField_2ValueChanged, true);
            app.WindowsizeEditField_2.Value = 200;
            app.ForceDropDownLabel = uilabel(app.ForcesTab);
            app.ForceDropDownLabel.Text = 'Force';
            app.ForceDropDown = uidropdown(app.ForcesTab);
            app.ForceDropDown.Items = {};
            app.ForceDropDown.Value = {};
            app.TypeDropDownLabel = uilabel(app.ForcesTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.ForcesTab);
            app.TypeDropDown.Items = {'Average', 'Maximum', 'Minimum', 'Individual'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Average';
            app.SimulationListBox = uilistbox(app.UIFigure);
            app.SimulationListBox.Items = {};
            app.SimulationListBox.Multiselect = 'on';
            app.SimulationListBox.ValueChangedFcn = createCallbackFcn(app, @SimulationListBoxValueChanged, true);
            app.SimulationListBox.Value = {};
            app.RemovelastplotButton = uibutton(app.UIFigure, 'push');
            app.RemovelastplotButton.ButtonPushedFcn = createCallbackFcn(app, @RemovelastplotButtonPushed, true);
            app.RemovelastplotButton.Text = 'Remove last plot';
            app.SavetofileButton = uibutton(app.UIFigure, 'push');
            app.SavetofileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetofileButtonPushed, true);
            app.SavetofileButton.Text = 'Save to file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = diplacementAndForceAnalysisDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end