classdef glassActivationDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        glassactivationUIFigure       matlab.ui.Figure
        msecLabel                     matlab.ui.control.Label
        GlasstransitiontimeEditField  matlab.ui.control.NumericEditField
        GlasstransitiontimeEditFieldLabel  matlab.ui.control.Label
        TipsImage                     matlab.ui.control.Image
        DefaultButton                 matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        LoadButton                    matlab.ui.control.Button
        RemoverowButton               matlab.ui.control.Button
        AddrowButton                  matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Description
        activationTimes = []; % Description
        movements = [];
        transitionTime = 0;
    end
    
    methods (Access = private)
        
        function plot_movement(app)
            
            stairs(app.UIAxes,[app.activationTimes ; app.activationTimes(end)+1000],[app.movements ; app.movements(end)]);
            axis(app.UIAxes,[0 app.activationTimes(end)+1000 min(app.movements)-1 max(app.movements)+1]);
            
        end
        
        function change_table(app)
            app.UITable.Data = num2cell([app.activationTimes app.movements]);
            if length(app.activationTimes) < 2
                app.RemoverowButton.Enable = 'off';
            else
                app.RemoverowButton.Enable = 'on';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.glassactivationUIFigure.Visible = 'Off';
            app.CallingApp = mainapp;
            
            mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;
            
            app.glassactivationUIFigure.Position(1) = mainWindowPosition(1) + mainWindowPosition(3)/2 - app.glassactivationUIFigure.Position(3)/2;
            app.glassactivationUIFigure.Position(2) = mainWindowPosition(2) + mainWindowPosition(4)/2 - app.glassactivationUIFigure.Position(4)/2;

            
            app.activationTimes = app.CallingApp.glassActivation(:,1).*1000;
            app.movements = app.CallingApp.glassActivation(:,2);
            plot_movement(app);
            change_table(app);
            
            app.LoadButton.Icon = [app.CallingApp.defaultPath 'settings/images/load.png'];
            app.SaveButton.Icon = [app.CallingApp.defaultPath 'settings/images/save.png'];
            app.AddrowButton.Icon = [app.CallingApp.defaultPath 'settings/images/plus.png'];
            app.RemoverowButton.Icon = [app.CallingApp.defaultPath 'settings/images/minus.png'];
            app.ResetButton.Icon = [app.CallingApp.defaultPath 'settings/images/reload.png'];
            app.TipsImage.ImageSource = [app.CallingApp.defaultPath 'settings/images/tips.png']; 
            app.TipsImage.Tooltip = fileread([app.CallingApp.defaultPath 'settings/tooltips/glass_movement_settings.txt']);
            
            app.transitionTime = app.CallingApp.systemParameters.glassMovementTime*1000;

            app.GlasstransitiontimeEditField.Value = app.transitionTime;

            app.glassactivationUIFigure.Icon = [app.CallingApp.defaultPath 'settings/images/logo_v2.png'];
            
            app.glassactivationUIFigure.Visible = 'On';
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.CallingApp.glassActivation = [app.activationTimes./1000 app.movements];
            app.CallingApp.systemParameters.glassMovementTime = app.transitionTime/1000;
            disable_enable_all_function(app.CallingApp,'On');
            
            figure(app.CallingApp.EpiMechUIFigure);
            delete(app);
        end

        % Close request function: glassactivationUIFigure
        function glassactivationUIFigureCloseRequest(app, event)
            app.CallingApp.glassActivation = [app.activationTimes./1000 app.movements];
            app.CallingApp.systemParameters.glassMovementTime = app.transitionTime/1000;
            disable_enable_all_function(app.CallingApp,'On');
            
            figure(app.CallingApp.EpiMechUIFigure);
            delete(app)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            if indices(2) == 1
                if indices(1) > 1
                    if event.NewData <= app.activationTimes(indices(1)-1)
                        app.UITable.Data{indices(1),indices(2)} = event.PreviousData;
                        uialert(app.glassactivationUIFigure,'The time must be larger than previous time','Bad input');
                    else
                        temp = cell2mat(app.UITable.Data);
                        app.activationTimes = temp(:,1);
                        app.movements = temp(:,2);
                    end
                else
                    app.UITable.Data{indices(1),indices(2)} = event.PreviousData;
                    uialert(app.glassactivationUIFigure,'First time point cannot be edited','Bad input');
                end
            else
                if event.NewData > 10
                    app.UITable.Data{indices(1),indices(2)} = event.PreviousData;
                    uialert(app.glassactivationUIFigure,'The absolute movement cannot be more than 10 µm','Bad input');
                elseif event.NewData < -10
                    app.UITable.Data{indices(1),indices(2)} = event.PreviousData;
                    uialert(app.glassactivationUIFigure,'The absolute movement cannot be more than 10 µm','Bad input');
                else
                    temp = cell2mat(app.UITable.Data);
                    app.activationTimes = temp(:,1);
                    app.movements = temp(:,2);
                end
            end
            
            plot_movement(app);
        end

        % Button pushed function: AddrowButton
        function AddrowButtonPushed(app, event)
            app.activationTimes(end+1) = app.activationTimes(end) + 2;
            app.movements(end+1) = app.movements(end);
            plot_movement(app);
            change_table(app);
        end

        % Button pushed function: RemoverowButton
        function RemoverowButtonPushed(app, event)
            app.activationTimes(end) = [];
            app.movements(end) = [];
            change_table(app);
            plot_movement(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [fileName, filePath] = uigetfile({'*.csv'},'Choose movement file to load',[app.CallingApp.defaultPath 'settings/misc/']);
            
            figure(app.CallingApp.EpiMechUIFigure);
            figure(app.glassactivationUIFigure);
            if fileName == 0
                return;
            end
            
            newMovement = csvread([filePath fileName]);
            
            if newMovement(1,1) ~= 0
                uialert(app.glassactivationUIFigure,'First time value must be zero','Bad input');
                return;
            elseif size(newMovement,2) ~= 2
                uialert(app.glassactivationUIFigure,'File must have two columns','Bad input');
                return;
            elseif any(diff(newMovement(:,1)) <= 0)
                uialert(app.glassactivationUIFigure,'The times in the file must be increasing.','Bad input');
                return;
            elseif any((newMovement(:,2)) < -10) || any((newMovement(:,2)) > 10)
                uialert(app.glassactivationUIFigure,'The absolute movement cannot be larger than 10 µm','Bad input');
                return;
            end
            
            app.activationTimes = newMovement(:,1).*1000;
            app.movements = newMovement(:,2);
            plot_movement(app);
            change_table(app);
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [fileName, filePath] = uiputfile({'*.csv'},'Save the current movement settings',[app.CallingApp.defaultPath 'settings/misc/']);
            
            if fileName == 0
                figure(app.CallingApp.EpiMechUIFigure);
                figure(app.glassactivationUIFigure);
                return;
            end
            
            testPath = [app.CallingApp.defaultPath 'settings/misc/'];
            testPath = strrep(testPath,'/','\');
            filePath = strrep(filePath,'/','\');
            figure(app.CallingApp.EpiMechUIFigure);
            figure(app.glassactivationUIFigure);
            
            
            if strcmp(filePath,testPath) && (strcmp(fileName,'default_glass_movement') || strcmp(fileName,'default_glass_movement.csv'))
                userResponse = uiconfirm(app.glassactivationUIFigure,'Do you want to save new default glass movement?', '', 'Options', {'Yes','No'}, 'DefaultOption', 2);
                if strcmp(userResponse,'Yes')
                    csvwrite([filePath fileName],[app.activationTimes./1000 app.movements]);
                end
            else
                csvwrite([filePath fileName],[app.activationTimes./1000 app.movements]);
            end
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.activationTimes = app.CallingApp.glassActivation(:,1)./1000;
            app.movements = app.CallingApp.glassActivation(:,2);
            plot_movement(app);
            change_table(app);
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            newMovement = csvread([app.CallingApp.defaultPath 'settings/misc/default_glass_movements.csv']);
            
            if newMovement(1,1) ~= 0
                uialert(app.glassactivationUIFigure,'First time value must be zero','Bad input');
                return;
            elseif size(newMovement,2) ~= 2
                uialert(app.glassactivationUIFigure,'File must have two columns','Bad input');
                return;
            elseif any(diff(newMovement(:,1)) <= 0)
                uialert(app.glassactivationUIFigure,'The times in the file must be increasing.','Bad input');
                return;
            elseif any((newMovement(:,2)) < -10) || any((newMovement(:,2)) > 10)
                uialert(app.glassactivationUIFigure,'The absolute movement cannot be larger than 10 µm','Bad input');
                return;
            end
            
            app.activationTimes = newMovement(:,1).*1000;
            app.movements = newMovement(:,2);
            plot_movement(app);
            change_table(app);
        end

        % Value changed function: GlasstransitiontimeEditField
        function GlasstransitiontimeEditFieldValueChanged(app, event)
            app.transitionTime = app.GlasstransitiontimeEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.glassactivationUIFigure = uifigure('Visible', 'off');
            app.glassactivationUIFigure.Name = 'Optogenetic activation';
            app.glassactivationUIFigure.CloseRequestFcn = createCallbackFcn(app, @glassactivationUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.glassactivationUIFigure);
            xlabel(app.UIAxes, 'time (msec)')
            ylabel(app.UIAxes, 'Glass movement (µm)')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.glassactivationUIFigure);
            app.UITable.ColumnName = {'Time (msec)'; 'Glass movement (µm)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false false];
            app.UITable.ColumnEditable = [true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.CloseButton = uibutton(app.glassactivationUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.AddrowButton = uibutton(app.glassactivationUIFigure, 'push');
            app.AddrowButton.ButtonPushedFcn = createCallbackFcn(app, @AddrowButtonPushed, true);
            app.AddrowButton.Tooltip = {'Add a row'};
            app.AddrowButton.Text = '';
            app.RemoverowButton = uibutton(app.glassactivationUIFigure, 'push');
            app.RemoverowButton.ButtonPushedFcn = createCallbackFcn(app, @RemoverowButtonPushed, true);
            app.RemoverowButton.Tooltip = {'Remove a row'};
            app.RemoverowButton.Text = '';
            app.LoadButton = uibutton(app.glassactivationUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Load'};
            app.LoadButton.Text = '';
            app.SaveButton = uibutton(app.glassactivationUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Save'};
            app.SaveButton.Text = '';
            app.ResetButton = uibutton(app.glassactivationUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Reset'};
            app.ResetButton.Text = '';
            app.DefaultButton = uibutton(app.glassactivationUIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.TipsImage = uiimage(app.glassactivationUIFigure);
            app.TipsImage.Tooltip = {''};
            app.GlasstransitiontimeEditFieldLabel = uilabel(app.glassactivationUIFigure);
            app.GlasstransitiontimeEditFieldLabel.Text = 'Glass transition time';
            app.GlasstransitiontimeEditField = uieditfield(app.glassactivationUIFigure, 'numeric');
            app.GlasstransitiontimeEditField.Limits = [0 Inf];
            app.GlasstransitiontimeEditField.ValueChangedFcn = createCallbackFcn(app, @GlasstransitiontimeEditFieldValueChanged, true);
            app.msecLabel = uilabel(app.glassactivationUIFigure);
            app.msecLabel.Text = 'msec';
            % Show the figure after all components are created
            app.glassactivationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = glassActivationDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.glassactivationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.glassactivationUIFigure)
        end
    end
end