classdef optoAnalysisDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CentertoselectionCheckBox     matlab.ui.control.CheckBox
        SelectcellsButton             matlab.ui.control.Button
        SimulationListBox             matlab.ui.control.ListBox
        TabGroup                      matlab.ui.container.TabGroup
        DisplacementTab               matlab.ui.container.Tab
        ContoursEditField             matlab.ui.control.EditField
        ContoursEditFieldLabel        matlab.ui.control.Label
        mLabel                        matlab.ui.control.Label
        MaximumdisplacementEditField  matlab.ui.control.NumericEditField
        MaximumdisplacementEditFieldLabel  matlab.ui.control.Label
        mLabel_2                      matlab.ui.control.Label
        WindowsizeEditField           matlab.ui.control.NumericEditField
        WindowsizeEditFieldLabel      matlab.ui.control.Label
        EndDropDown                   matlab.ui.control.DropDown
        EndDropDownLabel              matlab.ui.control.Label
        StartDropDown                 matlab.ui.control.DropDown
        StartDropDownLabel            matlab.ui.control.Label
        AreaplotPanel                 matlab.ui.container.Panel
        ContoursCheckBox              matlab.ui.control.CheckBox
        AreaplotButton                matlab.ui.control.Button
        DisplacementTypeButtonGroup   matlab.ui.container.ButtonGroup
        SubstrateButton               matlab.ui.control.RadioButton
        CellsButton                   matlab.ui.control.RadioButton
        DplotButtonGroup              matlab.ui.container.ButtonGroup
        mLabel_4                      matlab.ui.control.Label
        AveragewidthEditField         matlab.ui.control.NumericEditField
        AveragewidthEditFieldLabel    matlab.ui.control.Label
        ColorpickerCheckBox           matlab.ui.control.CheckBox
        LegendEditField               matlab.ui.control.EditField
        LegendEditFieldLabel          matlab.ui.control.Label
        NewplotCheckBox               matlab.ui.control.CheckBox
        PlotButton                    matlab.ui.control.Button
        FullButton                    matlab.ui.control.RadioButton
        HalfButton                    matlab.ui.control.RadioButton
        ForcesTab                     matlab.ui.container.Tab
        TypeDropDown                  matlab.ui.control.DropDown
        TypeDropDownLabel             matlab.ui.control.Label
        ForceDropDown                 matlab.ui.control.DropDown
        ForceDropDownLabel            matlab.ui.control.Label
        WindowsizeEditField_2         matlab.ui.control.NumericEditField
        WindowsizeEditField_2Label    matlab.ui.control.Label
        TimePointDropDown             matlab.ui.control.DropDown
        TimePointDropDownLabel        matlab.ui.control.Label
        mLabel_3                      matlab.ui.control.Label
        AreaplotPanel_2               matlab.ui.container.Panel
        AreaplotButton_2              matlab.ui.control.Button
        DplotButtonGroup_2            matlab.ui.container.ButtonGroup
        AveragewidthEditField_2       matlab.ui.control.NumericEditField
        AveragewidthEditField_2Label  matlab.ui.control.Label
        mLabel_5                      matlab.ui.control.Label
        ColorpickerCheckBox_2         matlab.ui.control.CheckBox
        LegendEditField_2             matlab.ui.control.EditField
        LegendEditField_2Label        matlab.ui.control.Label
        NewplotCheckBox_2             matlab.ui.control.CheckBox
        PlotButton_2                  matlab.ui.control.Button
        FullButton_2                  matlab.ui.control.RadioButton
        HalfButton_2                  matlab.ui.control.RadioButton
        CloseButton                   matlab.ui.control.Button
    end


    properties (Access = public)
        CallingApp % Description
        analysisFigureHandle = 0;
        numberOfAnalysisPlots = 0;
        barChartData = [];
        enabledObjects = 0;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)

            app.UIFigure.Visible = 'Off';
            app.CallingApp = mainapp;

            mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;

            app.UIFigure.Position(1) = mainWindowPosition(1) + mainWindowPosition(3)/2 - app.UIFigure.Position(3)/2;
            app.UIFigure.Position(2) = mainWindowPosition(2) + mainWindowPosition(4)/2 - app.UIFigure.Position(4)/2;

            for i = 1:length(app.CallingApp.plotImport)
                if strcmp(app.CallingApp.plotImport(i).simulationType,'opto')
                    path = fileparts(app.CallingApp.plotImport(i).folderName);
                    fileName = app.CallingApp.plotImport(i).folderName(length(path)+2:end);
                    app.SimulationListBox.Items{end+1} = fileName;
                end
            end
            app.UIFigure.Visible = 'On';

        end

        % Callback function: CloseButton, UIFigure
        function UIFigureCloseRequest(app, event)
            disable_enable_all_function(app.CallingApp,'On');

            figure(app.CallingApp.EpiMechUIFigure);
            delete(app);
        end

        % Value changed function: SimulationListBox
        function SimulationListBoxValueChanged(app, event)

            updateTimes = false;
            if isa(app.SimulationListBox.Value,'char') || length(app.SimulationListBox.Value) == 1

                for ii = 1:length(app.CallingApp.LoadedfilesDropDown.Items)
                    if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value)
                        fileIdx = ii;
                        break
                    end
                end

                if ~isempty(app.StartDropDown.Items)

                    if length(app.StartDropDown.Items) == app.CallingApp.plotImport(fileIdx).nTimePoints
                        d.spar = app.CallingApp.plotImport(fileIdx).scaledParameters;
                        timeScalingFactor = 1/(app.CallingApp.plotImport(fileIdx).systemParameters.scalingTime*app.CallingApp.plotImport(fileIdx).exportOptions.exportDt);
                        timeString = separate_times(d,(app.CallingApp.plotImport(fileIdx).nTimePoints-1)./timeScalingFactor);
                        if ~strcmp(timeString,app.StartDropDown.Items{end})
                            updateTimes = true;
                        end
                    else
                        updateTimes = true;
                    end
                else
                    updateTimes = true;

                end
                if updateTimes

                    app.StartDropDown.Items = {};
                    app.EndDropDown.Items = {};

                    app.TimePointDropDown.Items = {};

                    d.spar = app.CallingApp.plotImport(fileIdx).scaledParameters;
                    numTemp = num2cell(1:app.CallingApp.plotImport(fileIdx).nTimePoints);

                    timeScalingFactor = 1/(app.CallingApp.plotImport(fileIdx).systemParameters.scalingTime*app.CallingApp.plotImport(fileIdx).exportOptions.exportDt);

                    timeStrings = cellfun(@(i) separate_times(d,(i-1)./timeScalingFactor),numTemp,'UniformOutput',false);
                    app.StartDropDown.Items = timeStrings;
                    app.EndDropDown.Items = timeStrings;
                    app.StartDropDown.Value = app.StartDropDown.Items(1);
                    app.EndDropDown.Value = app.EndDropDown.Items(end);

                    app.TimePointDropDown.Items = timeStrings;
                    app.TimePointDropDown.Value = app.TimePointDropDown.Items(end);

                end
            end

            for i = 1:length(app.SimulationListBox.Value)


                for ii = 1:length(app.CallingApp.LoadedfilesDropDown.Items)
                    if isa(length(app.SimulationListBox.Value),'char')
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value(i))
                            fileIdx = ii;
                            break
                        end
                    else
                        if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value)
                            fileIdx = ii;
                            break
                        end
                    end
                end

                folderName = app.CallingApp.plotImport(fileIdx).folderName;

                forceItems = app.ForceDropDown.Items;


                if exist([folderName '/cell_forces/total/'],'dir') == 7
                    temp = strcmp(forceItems,'Total forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Total forces'];
                    end
                else
                    temp = strcmp(forceItems,'Total forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/cell_forces/cortical/'],'dir') == 7
                    temp = strcmp(forceItems,'Cortical forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Cortical forces'];
                    end
                else
                    temp = strcmp(forceItems,'Cortical forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/cell_forces/junction/'],'dir') == 7
                    temp = strcmp(forceItems,'Junction forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Junction forces'];
                    end
                else
                    temp = strcmp(forceItems,'Junction forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/cell_forces/division/'],'dir') == 7
                    temp = strcmp(forceItems,'Division forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Division forces'];
                    end
                else
                    temp = strcmp(forceItems,'Division forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/cell_forces/membrane/'],'dir') == 7
                    temp = strcmp(forceItems,'Membrane forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Membrane forces'];
                    end
                else
                    temp = strcmp(forceItems,'Membrane forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/cell_forces/contact/'],'dir') == 7
                    temp = strcmp(forceItems,'Contact forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Contact forces'];
                    end
                else
                    temp = strcmp(forceItems,'Contact forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/cell_forces/area/'],'dir') == 7
                    temp = strcmp(forceItems,'Area forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Area forces'];
                    end
                else
                    temp = strcmp(forceItems,'Area forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/cell_forces/focal_adhesion/'],'dir') == 7
                    temp = strcmp(forceItems,'Focal adhesion forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Focal adhesion forces'];
                    end
                else
                    temp = strcmp(forceItems,'Focal adhesion forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/substrate_forces/total/'],'dir') == 7
                    temp = strcmp(forceItems,'Total substrate forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Total substrate forces'];
                    end
                else
                    temp = strcmp(forceItems,'Total substrate forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/substrate_forces/direct/'],'dir') == 7
                    temp = strcmp(forceItems,'Direct substrate forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Direct substrate forces'];
                    end
                else
                    temp = strcmp(forceItems,'Direct substrate forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/substrate_forces/restoration/'],'dir') == 7
                    temp = strcmp(forceItems,'Restorative substrate forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Restorative substrate forces'];
                    end
                else
                    temp = strcmp(forceItems,'Restorative substrate forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/substrate_forces/repulsion/'],'dir') == 7
                    temp = strcmp(forceItems,'Repulsion substrate forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Repulsion substrate forces'];
                    end
                else
                    temp = strcmp(forceItems,'Repulsion substrate forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

                if exist([folderName '/substrate_forces/focal_adhesion/'],'dir') == 7
                    temp = strcmp(forceItems,'Focal adhesion substrate forces');
                    if ~any(temp)
                        forceItems = [forceItems 'Focal adhesion substrate forces'];
                    end
                else
                    temp = strcmp(forceItems,'Focal adhesion substrate forces');
                    if any(temp)
                        forceItems(temp) = [];
                    end
                end

            end

            app.ForceDropDown.Items = forceItems;

            if ~isempty(app.SimulationListBox.Value)
                app.AreaplotButton.Enable = 'on';
                app.AreaplotButton_2.Enable = 'on';
                app.SelectcellsButton.Enable = 'on';
                if length(app.CallingApp.plotImport(fileIdx).optoSelectedCells) == 2
                    app.PlotButton.Enable = 'on';
                    app.PlotButton_2.Enable = 'on';
                    app.CentertoselectionCheckBox.Enable = 'on';
                end
            end
        end

        % Button pushed function: AreaplotButton
        function AreaplotButtonPushed(app, event)
            [xq,yq] = meshgrid(-200:0.5:200,-200:0.5:200);

            initialTime = find(strcmp(app.StartDropDown.Items, app.StartDropDown.Value));
            endTime = find(strcmp(app.EndDropDown.Items, app.EndDropDown.Value));

            previousSelected = app.CallingApp.selectedFile;

            for ii = 1:length(app.CallingApp.LoadedfilesDropDown.Items)
                if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value)
                    fileIdx = ii;
                    break
                end
            end

            app.CallingApp.selectedFile = fileIdx;

            d.spar.scalingLength = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength;
            d.spar.scalingTime = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingTime;
            d.spar.rCell = app.CallingApp.plotImport(fileIdx).scaledParameters.rCell;


            d.pl.substrateStyle = 1;
            d.pl.substrateForcesDirect = 0;
            d.pl.substrateForcesRepulsion = 0;
            d.pl.substrateForcesRestoration = 0;
            d.pl.substrateForcesFocalAdhesions = 0;
            d.pl.substrateForcesTotal = 0;
            d.pl.titleType = 0;
            d.pl.windowSize = app.CallingApp.importPlottingOptions.windowSize;
            d.pl.axisLimits = [app.CallingApp.MiddlepointxEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                app.CallingApp.MiddlepointxEditField_2.Value*1e-6+d.pl.windowSize/2 ...
                app.CallingApp.MiddlepointyEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                app.CallingApp.MiddlepointyEditField_2.Value*1e-6+d.pl.windowSize/2]/d.spar.scalingLength;


            d.simset.simulationType = 5;

            oldTimePoint = app.CallingApp.plotImport(fileIdx).currentTimePoint;

            if app.CentertoselectionCheckBox.Value
                selectedCells = app.CallingApp.plotImport(app.CallingApp.selectedFile).optoSelectedCells;
            else
                selectedCells = [];
            end

            if app.CellsButton.Value
                app.CallingApp.plotImport(fileIdx).currentTimePoint = initialTime;

                initialCells = import_cells(app.CallingApp,'post_plotting');

                app.CallingApp.plotImport(fileIdx).currentTimePoint = endTime;

                endCells = import_cells(app.CallingApp,'post_plotting');

                initialCellCenterX = zeros(1,length(initialCells));
                initialCellCenterY = zeros(1,length(initialCells));
                finalCellCenterX = zeros(1,length(endCells));
                finalCellCenterY = zeros(1,length(endCells));

                for k = 1:length(endCells)
                    initialCellCenterX(k) = mean(initialCells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                    initialCellCenterY(k) = mean(initialCells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                    finalCellCenterX(k) = mean(endCells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                    finalCellCenterY(k) = mean(endCells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                end

                displacements = sqrt((initialCellCenterX - finalCellCenterX).^2 + (initialCellCenterY - finalCellCenterY).^2);

                if numel(selectedCells) > 0
                    initialCellCenterX = initialCellCenterX - mean(initialCellCenterX(selectedCells));
                    initialCellCenterY = initialCellCenterY - mean(initialCellCenterY(selectedCells));
                end

                dispData = griddata(initialCellCenterX,initialCellCenterY,displacements,xq,yq,'cubic');

            else
                if exist([app.CallingApp.plotImport(fileIdx).folderName '/substrate'],'dir') == 7

                    importedPoints = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate/substrate_points_' num2str(initialTime) '.csv']);
                    initialPointsX = importedPoints(:,1);
                    initialPointsY = importedPoints(:,2);

                    importedPoints = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate/substrate_points_' num2str(endTime) '.csv']);
                    endPointsX = importedPoints(:,1);
                    endPointsY = importedPoints(:,2);

                    initialCells = import_cells(app.CallingApp,'post_plotting','initial');

                    displacements = sqrt((endPointsX - initialPointsX).^2 + (endPointsY - initialPointsY).^2)*d.spar.scalingLength/1e-6;

                    if numel(selectedCells) > 0

                        subX = initialPointsX*d.spar.scalingLength/1e-6 - mean([mean(initialCells(selectedCells(1)).verticesX) mean(initialCells(selectedCells(2)).verticesX)]).*d.spar.scalingLength.*1e6;
                        subY = initialPointsY*d.spar.scalingLength/1e-6 - mean([mean(initialCells(selectedCells(1)).verticesY) mean(initialCells(selectedCells(2)).verticesY)]).*d.spar.scalingLength.*1e6;
                    else
                        subX = initialPointsX*d.spar.scalingLength/1e-6;
                        subY = initialPointsY*d.spar.scalingLength/1e-6;
                    end

                    dispData = griddata(subX,subY,displacements,xq,yq,'cubic');

                else
                    app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;
                    app.CallingApp.selectedFile = previousSelected;
                    uialert(app.UIFigure, ['Selected simulation does not have substrate data.'],'Data missing');
                    return
                end

            end
            app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;


            if (~ishandle(app.CallingApp.plotHandle) || app.CallingApp.plotHandle == 0) && ~ishandle(1)
                mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;
                d.pl.figureHandle = figure(1);
                d.pl.figureHandle.Position(1) = mainWindowPosition(1) + 600;
                d.pl.figureHandle.Position(2) = mainWindowPosition(2) + 200;
                app.CallingApp.plotHandle = figure(1);
            else
                d.pl.figureHandle = figure(1);
                clf;
                app.CallingApp.plotHandle = figure(1);
            end

            d.pl.axesHandle = axes;

            figureSize = d.pl.figureHandle.Position(3:4);
            bottomGap = 10/figureSize(2);
            topGap = 10/figureSize(2);
            horzGap = 10/figureSize(1);
            axisHeight = 1 - bottomGap - topGap;
            axisWidth = 1 - 2*horzGap;
            d.pl.axesHandle.Position = [horzGap bottomGap axisWidth axisHeight];

            axisHeight = d.pl.figureHandle.Position(4)*d.pl.axesHandle.Position(4);
            axisWidth = d.pl.figureHandle.Position(3)*d.pl.axesHandle.Position(3);
            if axisHeight > axisWidth
                axisSizeInPixels = axisWidth;
            else
                axisSizeInPixels = axisHeight;
            end

            titleFontSize = axisSizeInPixels/40;
            if titleFontSize > 15
                titleFontSize = 15;
            elseif titleFontSize < 10
                titleFontSize = 10;
            end

            d.pl.axesHandle.Title.FontSize = titleFontSize;

            if any(d.pl.titleType == [2 4 8 10])
                topGap = 20/figureSize(2) + 2*d.pl.axesHandle.Title.FontSize/figureSize(2);
            else
                topGap = 20/figureSize(2) + d.pl.axesHandle.Title.FontSize/figureSize(2);
            end
            d.pl.axesHandle.Position(4) = 1 - bottomGap - topGap;

            axis(app.WindowsizeEditField.Value.*[-0.5 0.5 -0.5 0.5])

            axis square

            d.pl.axesHandle.TickLength = [0 0];
            d.pl.axesHandle.XTick = [];
            d.pl.axesHandle.XTickLabel = [];
            d.pl.axesHandle.YTick = [];
            d.pl.axesHandle.YTickLabel = [];
            d.pl.axesHandle.XColor = 'none';
            d.pl.axesHandle.YColor = 'none';

            set_figure_callbacks(d,app.CallingApp)

            %             set_figure_callbacks(d,app.CallingApp)
            hold on
            if ~app.ContoursCheckBox.Value
                contourf(d.pl.axesHandle,xq,yq,dispData,256,'LineColor','none');
            else
                levels = textscan(app.ContoursEditField.Value,'%f');
                contourf(d.pl.axesHandle,xq,yq,dispData,levels{1},'LineColor','none');
            end

            mymap = csvread('./settings/Misc/mymap.csv');
            colormap(mymap)
            colorbar
            caxis([0, app.MaximumdisplacementEditField.Value]);
            hold off

            app.CallingApp.selectedFile = previousSelected;
            %             plot_title(d,time,0,0);

            %             plot_scale_bar(d)

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)


            initialTime = find(strcmp(app.StartDropDown.Items, app.StartDropDown.Value));
            endTime = find(strcmp(app.EndDropDown.Items, app.EndDropDown.Value));

            [xq,yq] = meshgrid(-100:0.5:100,-100:0.5:100);
            middlePoint = ceil(size(yq,1)/2);

            yPositions =  -yq(:,1);


            app.analysisFigureHandle = figure(2);
            clf
            mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;

            app.analysisFigureHandle.Position(1) = mainWindowPosition(1) + 600;
            app.analysisFigureHandle.Position(2) = mainWindowPosition(2);

            res = get(0,'screensize');

            position = get(gcf, 'Position');

            set(gcf, 'Position',  [position(1), position(2), 600, 800])

            position = get(gcf, 'Position');

            if sum(position([2 4])) + 100 > res(4)
                position(2) = res(4) - position(4) - 100;
            end
            app.analysisFigureHandle.Position(2) = position(2);


            if ~app.ColorpickerCheckBox.Value
                color = 'r';
            else

                color = uisetcolor;
            end

            previousSelected = app.CallingApp.selectedFile;


            for ii = 1:length(app.CallingApp.LoadedfilesDropDown.Items)
                if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value)
                    fileIdx = ii;
                    break
                end
            end

            app.CallingApp.selectedFile = fileIdx;

            d.spar.scalingLength = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength;
            d.spar.scalingTime = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingTime;
            d.spar.rCell = app.CallingApp.plotImport(fileIdx).scaledParameters.rCell;

            d.pl.substrateStyle = 1;
            d.pl.substrateForcesDirect = 0;
            d.pl.substrateForcesRepulsion = 0;
            d.pl.substrateForcesRestoration = 0;
            d.pl.substrateForcesFocalAdhesions = 0;
            d.pl.substrateForcesTotal = 0;
            d.pl.titleType = 0;
            d.pl.windowSize = app.CallingApp.importPlottingOptions.windowSize;
            d.pl.axisLimits = [app.CallingApp.MiddlepointxEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                app.CallingApp.MiddlepointxEditField_2.Value*1e-6+d.pl.windowSize/2 ...
                app.CallingApp.MiddlepointyEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                app.CallingApp.MiddlepointyEditField_2.Value*1e-6+d.pl.windowSize/2]/d.spar.scalingLength;


            d.simset.simulationType = 5;

            oldTimePoint = app.CallingApp.plotImport(fileIdx).currentTimePoint;

            selectedCells = app.CallingApp.plotImport(fileIdx).optoSelectedCells;

            cell1 = selectedCells(1);
            cell2 = selectedCells(2);

            app.CallingApp.plotImport(fileIdx).currentTimePoint = initialTime;
            initialCells = import_cells(app.CallingApp,'opto_analysis');

            app.CallingApp.plotImport(fileIdx).currentTimePoint = endTime;
            endCells = import_cells(app.CallingApp,'opto_analysis');

            vertexIDs = or(initialCells(cell1).junctions.cells(:,1) == cell2,initialCells(cell1).junctions.cells(:,2) == cell2);

            if sum(vertexIDs) > 1
                diffs = diff(vertexIDs([1:end 1]));

                up = find(diffs == 1);
                down = find(diffs == -1);

                if length(up) > 1 || length(down) > 1
                    diffs = [diffs;diffs];
                    up = find(diffs == 1);
                    down = find(diffs == -1);
                    if up(1) < down(1)
                        distances2 = up([2:end 1])-down;
                        [~,maxInd] = max(distances2);
                        if down(maxInd) > initialCells(cell1).nVertices
                            vertex2 = down(maxInd) - initialCells(cell1).nVertices;
                        else
                            vertex2 = down(maxInd);
                        end
                        if up(maxInd+1) > initialCells(cell1).nVertices
                            vertex1 = up(maxInd+1) + 1 - initialCells(cell1).nVertices;
                        else
                            vertex1 = up(maxInd+1) + 1;
                        end
                    else
                        distances2 = up-down;
                        [~,maxInd] = max(distances2);
                        if down(maxInd) > initialCells(cell1).nVertices
                            vertex2 = down(maxInd) - initialCells(cell1).nVertices;
                        else
                            vertex2 = down(maxInd);
                        end
                        if up(maxInd) > initialCells(cell1).nVertices
                            vertex1 = up(maxInd) + 1 - initialCells(cell1).nVertices;
                        else
                            vertex1 = up(maxInd) + 1;
                        end
                    end

                else
                    if up == initialCells(cell1).nVertices
                        vertex1 = 1;
                    else
                        vertex1 = up + 1;
                    end
                    vertex2 = down;
                end

                whichJunction1 = initialCells(cell1).junctions.cells(vertex1,:) == cell2;
                whichJunction2 = initialCells(cell1).junctions.cells(vertex2,:) == cell2;

                vertex3 = initialCells(cell1).junctions.vertices(vertex1,whichJunction1);
                vertex4 = initialCells(cell1).junctions.vertices(vertex2,whichJunction2);

                middlePoint13X = 0.5*(initialCells(cell1).verticesX(vertex1) + initialCells(cell2).verticesX(vertex3));
                middlePoint13Y = 0.5*(initialCells(cell1).verticesY(vertex1) + initialCells(cell2).verticesY(vertex3));
                middlePoint24X = 0.5*(initialCells(cell1).verticesX(vertex2) + initialCells(cell2).verticesX(vertex4));
                middlePoint24Y = 0.5*(initialCells(cell1).verticesY(vertex2) + initialCells(cell2).verticesY(vertex4));
            else
                uialert(app.UIFigure,'Cells are not connected in the initial time point.')
                return
            end

            dy = middlePoint24Y - middlePoint13Y;
            dx = middlePoint24X - middlePoint13X;
            if abs(dx) < 1e-10
                dx = 1e-10;
            end

            moveX = -mean([middlePoint13X middlePoint24X]);
            moveY = -mean([middlePoint13Y middlePoint24Y]);


            angle = pi/2-atan(dy/dx);

            initialCellCenterX = zeros(1,length(initialCells));
            initialCellCenterY = zeros(1,length(initialCells));
            finalCellCenterX = zeros(1,length(endCells));
            finalCellCenterY = zeros(1,length(endCells));

            for k = 1:length(endCells)
                initialCellCenterX(k) = mean(initialCells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                initialCellCenterY(k) = mean(initialCells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                finalCellCenterX(k) = mean(endCells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                finalCellCenterY(k) = mean(endCells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
            end

            displacements = sqrt((initialCellCenterX - finalCellCenterX).^2 + (initialCellCenterY - finalCellCenterY).^2);


            initialCellCenterX = initialCellCenterX + moveX*1e6*app.CallingApp.plotImport(app.CallingApp.selectedFile).scaledParameters.scalingLength;
            initialCellCenterY = initialCellCenterY + moveY*1e6*app.CallingApp.plotImport(app.CallingApp.selectedFile).scaledParameters.scalingLength;

            rotatedCoordinates = [cos(angle) -sin(angle) ; sin(angle) cos(angle)]*[initialCellCenterX(:)' ; initialCellCenterY(:)'];
            initialCellCenterX = rotatedCoordinates(1,:)';
            initialCellCenterY = rotatedCoordinates(2,:)';


            dispData = griddata(initialCellCenterX,initialCellCenterY,displacements,xq,yq,'cubic');

            if app.AveragewidthEditField.Value > 0
                distances = round(app.AveragewidthEditField.Value);

                cellDispData = mean(dispData(:,middlePoint-distances:middlePoint+distances),2);

            else
                cellDispData = dispData(:,middlePoint);
            end


            if exist([app.CallingApp.plotImport(fileIdx).folderName '/substrate'],'dir') == 7

                importedPoints = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate/substrate_points_' num2str(initialTime) '.csv']);
                initialPointsX = importedPoints(:,1);
                initialPointsY = importedPoints(:,2);

                importedPoints = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate/substrate_points_' num2str(endTime) '.csv']);
                endPointsX = importedPoints(:,1);
                endPointsY = importedPoints(:,2);


                displacements = sqrt((endPointsX - initialPointsX).^2 + (endPointsY - initialPointsY).^2)*d.spar.scalingLength/1e-6;

                subX = initialPointsX*d.spar.scalingLength/1e-6 + moveX*1e6*app.CallingApp.plotImport(app.CallingApp.selectedFile).scaledParameters.scalingLength;
                subY = initialPointsY*d.spar.scalingLength/1e-6 + moveY*1e6*app.CallingApp.plotImport(app.CallingApp.selectedFile).scaledParameters.scalingLength;

                rotatedCoordinates = [cos(angle) -sin(angle) ; sin(angle) cos(angle)]*[subX(:)' ; subY(:)'];
                subX = rotatedCoordinates(1,:)';
                subY = rotatedCoordinates(2,:)';


                dispData = griddata(subX,subY,displacements,xq,yq,'cubic');

                if app.AveragewidthEditField.Value > 0
                    distances = round(app.AveragewidthEditField.Value);

                    subDispData = mean(dispData(:,middlePoint-distances:middlePoint+distances),2);

                else
                    subDispData = dispData(:,middlePoint);
                end

            else
                app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;
                app.CallingApp.selectedFile = previousSelected;
                uialert(app.UIFigure, 'Selected simulation does not have substrate data.','Data missing');
                return
            end

            app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;

%             csvwrite(['./opto_results/' app.CallingApp.plotImport(app.CallingApp.selectedFile).folderName(end-19:end) '_sub_displacement.csv'],[yPositions subDispData]);

            app.analysisFigureHandle;
            subplot(2,1,1);
            hold on
            if ~strcmp(app.LegendEditField.Value,'')
                plot(yPositions(1:end),cellDispData(1:end),'Color',color,'LineWidth',1,'DisplayName',app.LegendEditField.Value);
                legend('show');
                app.LegendEditField.Value = '';
            else
                plot(yPositions(1:end),cellDispData(1:end),'Color', color,'LineWidth',1,'HandleVisibility','off');
            end

            middlePoint13X = middlePoint13X + moveX;
            middlePoint24X = middlePoint24X + moveX;
            middlePoint13Y = middlePoint13Y + moveY;
            middlePoint24Y = middlePoint24Y + moveY;
            
            rotatedCoordinates = [cos(angle) -sin(angle) ; sin(angle) cos(angle)]*[middlePoint13X middlePoint24X ; middlePoint13Y middlePoint24Y];
            initialJunctionCoordsX = rotatedCoordinates(2,:)'.*d.spar.scalingLength/1e-6;

            plot([initialJunctionCoordsX(1) initialJunctionCoordsX(1)], [0 1000],'--k','DisplayName','Initial junction position');
            plot([initialJunctionCoordsX(2) initialJunctionCoordsX(2)], [0 1000],'--k','HandleVisibility','off');
            legend('show');

            hold off

            title('Cell diplacement', 'FontSize', 14)
            if app.HalfButton.Value
                axis([0 app.WindowsizeEditField.Value/2 0 app.MaximumdisplacementEditField.Value])
            else
                axis([-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2 0 app.MaximumdisplacementEditField.Value])
            end
            xlabel('Distance along the junction axis ({\mu}m)', 'FontSize', 12)
            ylabel('Displacement {\mu}m', 'FontSize', 12)

            subplot(2,1,2);
            hold on

            plot(yPositions(1:end),subDispData(1:end),'Color',color,'LineWidth',1,'HandleVisibility','off');


            plot([initialJunctionCoordsX(1) initialJunctionCoordsX(1)], [0 1000],'--k','HandleVisibility','off');
            plot([initialJunctionCoordsX(2) initialJunctionCoordsX(2)], [0 1000],'--k','HandleVisibility','off');

            hold off

            title('Substrate diplacement', 'FontSize', 14)

            if app.HalfButton.Value
                axis([0 app.WindowsizeEditField.Value/2 0 app.MaximumdisplacementEditField.Value])
            else
                axis([-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2 0 app.MaximumdisplacementEditField.Value])
            end

            xlabel('Distance along the junction axis ({\mu}m)', 'FontSize', 12)
            ylabel('Displacement in {\mu}m', 'FontSize', 12)

            app.CallingApp.selectedFile = previousSelected;


        end

        % Value changed function: ContoursEditField
        function ContoursEditFieldValueChanged(app, event)
            value = app.ContoursEditField.Value;
            if strcmp(value,'')
                app.ContoursCheckBox.Enable = 'off';
                app.ContoursCheckBox.Value = 0;
            else
                app.ContoursCheckBox.Enable = 'on';
            end
        end

        % Button pushed function: AreaplotButton_2
        function AreaplotButton_2Pushed(app, event)

            switch app.ForceDropDown.Value
                case 'Total forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesTotal;
                    app.CallingApp.importPlottingOptions.cellForcesTotal = true;
                case 'Cortical forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesCortical;
                    app.CallingApp.importPlottingOptions.cellForcesCortical = true;
                case 'Junction forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesJunctions;
                    app.CallingApp.importPlottingOptions.cellForcesJunctions = true;
                case 'Division forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesDivision;
                    app.CallingApp.importPlottingOptions.cellForcesDivision = true;
                case 'Membrane forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesMembrane;
                    app.CallingApp.importPlottingOptions.cellForcesMembrane = true;
                case 'Contact forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesContact;
                    app.CallingApp.importPlottingOptions.cellForcesContact = true;
                case 'Area forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesArea;
                    app.CallingApp.importPlottingOptions.cellForcesArea = true;
                case 'Pointlike forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesPointlike;
                    app.CallingApp.importPlottingOptions.cellForcesPointlike = true;
                case 'Focal adhesion forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesFocalAdhesions;
                    app.CallingApp.importPlottingOptions.cellForcesFocalAdhesions = true;
                case 'Total substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesTotal;
                    app.CallingApp.importPlottingOptions.substrateForcesTotal = true;
                case 'Direct substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesDirect;
                    app.CallingApp.importPlottingOptions.substrateForcesDirect = true;
                case 'Restorative substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesRepulsion;
                    app.CallingApp.importPlottingOptions.substrateForcesRepulsion = true;
                case 'Repulsion substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesRestoration;
                    app.CallingApp.importPlottingOptions.substrateForcesRestoration = true;
                case 'Focal adhesion substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesFocalAdhesions;
                    app.CallingApp.importPlottingOptions.substrateForcesFocalAdhesions = true;
            end


            [xq,yq] = meshgrid(-200:0.5:200,-200:0.5:200);

            timePoint = find(strcmp(app.TimePointDropDown.Items, app.TimePointDropDown.Value));


            previousSelected = app.CallingApp.selectedFile;


            for ii = 1:length(app.CallingApp.LoadedfilesDropDown.Items)
                if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value)
                    fileIdx = ii;
                    break
                end
            end

            app.CallingApp.selectedFile = fileIdx;

            d.spar.scalingLength = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength;
            d.spar.scalingTime = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingTime;
            d.spar.rCell = app.CallingApp.plotImport(fileIdx).scaledParameters.rCell;


            d.pl.substrateStyle = 1;
            d.pl.substrateForcesDirect = 0;
            d.pl.substrateForcesRepulsion = 0;
            d.pl.substrateForcesRestoration = 0;
            d.pl.substrateForcesFocalAdhesions = 0;
            d.pl.substrateForcesTotal = 0;
            d.pl.titleType = 0;
            d.pl.windowSize = app.CallingApp.importPlottingOptions.windowSize;
            d.pl.axisLimits = [app.CallingApp.MiddlepointxEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                app.CallingApp.MiddlepointxEditField_2.Value*1e-6+d.pl.windowSize/2 ...
                app.CallingApp.MiddlepointyEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                app.CallingApp.MiddlepointyEditField_2.Value*1e-6+d.pl.windowSize/2]/d.spar.scalingLength;


            d.simset.simulationType = 5;

            oldTimePoint = app.CallingApp.plotImport(fileIdx).currentTimePoint;

            app.CallingApp.plotImport(fileIdx).currentTimePoint = timePoint;

            if app.CentertoselectionCheckBox.Value
                selectedCells = app.CallingApp.plotImport(app.CallingApp.selectedFile).optoSelectedCells;
            else
                selectedCells = [];
            end

            substrateForces = {'Total substrate forces','Direct substrate forces','Restorative substrate forces','Repulsion substrate forces','Focal adhesion substrate forces'};

            if ~any(strcmp(app.ForceDropDown.Value,substrateForces))

                cells = import_cells(app.CallingApp,'post_plotting');

                switch app.TypeDropDown.Value

                    case 'Average'

                        cellPositionsX = zeros(1,length(cells));
                        cellPositionsY = zeros(1,length(cells));

                        forces = zeros(1,length(cells));


                        for k = 1:length(cells)
                            cellPositionsX(k) = mean(cells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                            cellPositionsY(k) = mean(cells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;

                            switch app.ForceDropDown.Value
                                case 'Total forces'
                                    forces(k) = mean(sqrt((cells(k).forces.totalX).^2 + (cells(k).forces.totalY).^2));
                                case 'Cortical forces'
                                    forces(k) = mean(sqrt((cells(k).forces.corticalX).^2 + (cells(k).forces.corticalY).^2));
                                case 'Junction forces'
                                    forces(k) = mean(sqrt((cells(k).forces.junctionX).^2 + (cells(k).forces.junctionY).^2));
                                case 'Division forces'
                                    forces(k) = mean(sqrt((cells(k).forces.divisionX).^2 + (cells(k).forces.divisionY).^2));
                                case 'Membrane forces'
                                    forces(k) = mean(sqrt((cells(k).forces.membraneX).^2 + (cells(k).forces.membraneY).^2));
                                case 'Contact forces'
                                    forces(k) = mean(sqrt((cells(k).forces.contactX).^2 + (cells(k).forces.contactY).^2));
                                case 'Area forces'
                                    forces(k) = mean(sqrt((cells(k).forces.areaX).^2 + (cells(k).forces.areaY).^2));
                                case 'Pointlike forces'
                                    forces(k) = mean(sqrt((cells(k).forces.pointlikeX).^2 + (cells(k).forces.pointlikeY).^2));
                                case 'Focal adhesion forces'
                                    forces(k) = mean(sqrt((cells(k).forces.substrateX).^2 + (cells(k).forces.substrateY).^2));
                            end

                        end
                    case 'Maximum'
                        cellPositionsX = zeros(1,length(cells));
                        cellPositionsY = zeros(1,length(cells));

                        forces = zeros(1,length(cells));


                        for k = 1:length(cells)
                            cellPositionsX(k) = mean(cells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                            cellPositionsY(k) = mean(cells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;

                            switch app.ForceDropDown.Value
                                case 'Total forces'
                                    forces(k) = max(sqrt((cells(k).forces.totalX).^2 + (cells(k).forces.totalY).^2));
                                case 'Cortical forces'
                                    forces(k) = max(sqrt((cells(k).forces.corticalX).^2 + (cells(k).forces.corticalY).^2));
                                case 'Junction forces'
                                    forces(k) = max(sqrt((cells(k).forces.junctionX).^2 + (cells(k).forces.junctionY).^2));
                                case 'Division forces'
                                    forces(k) = max(sqrt((cells(k).forces.divisionX).^2 + (cells(k).forces.divisionY).^2));
                                case 'Membrane forces'
                                    forces(k) = max(sqrt((cells(k).forces.membraneX).^2 + (cells(k).forces.membraneY).^2));
                                case 'Contact forces'
                                    forces(k) = max(sqrt((cells(k).forces.contactX).^2 + (cells(k).forces.contactY).^2));
                                case 'Area forces'
                                    forces(k) = max(sqrt((cells(k).forces.areaX).^2 + (cells(k).forces.areaY).^2));
                                case 'Pointlike forces'
                                    forces(k) = max(sqrt((cells(k).forces.pointlikeX).^2 + (cells(k).forces.pointlikeY).^2));
                                case 'Focal adhesion forces'
                                    forces(k) = max(sqrt((cells(k).forces.substrateX).^2 + (cells(k).forces.substrateY).^2));
                            end

                        end
                    case 'Minimum'
                        cellPositionsX = zeros(1,length(cells));
                        cellPositionsY = zeros(1,length(cells));

                        forces = zeros(1,length(cells));


                        for k = 1:length(cells)
                            cellPositionsX(k) = mean(cells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                            cellPositionsY(k) = mean(cells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;

                            switch app.ForceDropDown.Value
                                case 'Total forces'
                                    forces(k) = min(sqrt((cells(k).forces.totalX).^2 + (cells(k).forces.totalY).^2));
                                case 'Cortical forces'
                                    forces(k) = min(sqrt((cells(k).forces.corticalX).^2 + (cells(k).forces.corticalY).^2));
                                case 'Junction forces'
                                    forces(k) = min(sqrt((cells(k).forces.junctionX).^2 + (cells(k).forces.junctionY).^2));
                                case 'Division forces'
                                    forces(k) = min(sqrt((cells(k).forces.divisionX).^2 + (cells(k).forces.divisionY).^2));
                                case 'Membrane forces'
                                    forces(k) = min(sqrt((cells(k).forces.membraneX).^2 + (cells(k).forces.membraneY).^2));
                                case 'Contact forces'
                                    forces(k) = min(sqrt((cells(k).forces.contactX).^2 + (cells(k).forces.contactY).^2));
                                case 'Area forces'
                                    forces(k) = min(sqrt((cells(k).forces.areaX).^2 + (cells(k).forces.areaY).^2));
                                case 'Pointlike forces'
                                    forces(k) = min(sqrt((cells(k).forces.pointlikeX).^2 + (cells(k).forces.pointlikeY).^2));
                                case 'Focal adhesion forces'
                                    forces(k) = min(sqrt((cells(k).forces.substrateX).^2 + (cells(k).forces.substrateY).^2));
                            end

                        end
                    case 'Individual'
                        cellPositionsX = [];
                        cellPositionsY = [];

                        forces = [];


                        for k = 1:length(cells)
                            cellPositionsX = [cellPositionsX ; cells(k).verticesX.*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6];
                            cellPositionsY = [cellPositionsY ; cells(k).verticesY.*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6];

                            switch app.ForceDropDown.Value
                                case 'Total forces'
                                    forces = [forces ; sqrt((cells(k).forces.totalX).^2 + (cells(k).forces.totalY).^2)];
                                case 'Cortical forces'
                                    forces = [forces ; sqrt((cells(k).forces.corticalX).^2 + (cells(k).forces.corticalY).^2)];
                                case 'Junction forces'
                                    forces = [forces ; sqrt((cells(k).forces.junctionX).^2 + (cells(k).forces.junctionY).^2)];
                                case 'Division forces'
                                    forces = [forces ; sqrt((cells(k).forces.divisionX).^2 + (cells(k).forces.divisionY).^2)];
                                case 'Membrane forces'
                                    forces = [forces ; sqrt((cells(k).forces.membraneX).^2 + (cells(k).forces.membraneY).^2)];
                                case 'Contact forces'
                                    forces = [forces ; sqrt((cells(k).forces.contactX).^2 + (cells(k).forces.contactY).^2)];
                                case 'Area forces'
                                    forces = [forces ; sqrt((cells(k).forces.areaX).^2 + (cells(k).forces.areaY).^2)];
                                case 'Pointlike forces'
                                    forces = [forces ; sqrt((cells(k).forces.pointlikeX).^2 + (cells(k).forces.pointlikeY).^2)];
                                case 'Focal adhesion forces'
                                    forces = [forces ; sqrt((cells(k).forces.substrateX).^2 + (cells(k).forces.substrateY).^2)];
                            end

                        end

                end

                if numel(selectedCells) > 0 && app.CentertoselectionCheckBox.Value
                    initialCells = import_cells(app.CallingApp,'post_plotting','initial');

                    cellPositionsX = cellPositionsX - mean([mean(initialCells(selectedCells(1)).verticesX) mean(initialCells(selectedCells(2)).verticesX)]).*d.spar.scalingLength.*1e6;
                    cellPositionsY = cellPositionsY - mean([mean(initialCells(selectedCells(1)).verticesY) mean(initialCells(selectedCells(2)).verticesY)]).*d.spar.scalingLength.*1e6;
                end

                dispData = griddata(cellPositionsX,cellPositionsY,forces,xq,yq,'cubic');

            else

                if exist([app.CallingApp.plotImport(fileIdx).folderName '/substrate'],'dir') == 7

                    importedPoints = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate/substrate_points_' num2str(timePoint) '.csv']);
                    pointsX = importedPoints(:,1);
                    pointsY = importedPoints(:,2);

                    switch app.ForceDropDown.Value
                        case 'Total substrate forces'
                            importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/total/total_' num2str(timePoint) '.csv']);
                            forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);
                        case 'Direct substrate forces'
                            importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/direct/direct_' num2str(timePoint) '.csv']);
                            forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);
                        case 'Restorative substrate forces'
                            importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/restoration/restoration_' num2str(timePoint) '.csv']);
                            forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);
                        case 'Repulsion substrate forces'
                            importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/repulsion/repulsion_' num2str(timePoint) '.csv']);
                            forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);
                        case 'Focal adhesion substrate forces'
                            importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/focal_adhesion/focal_adhesion_' num2str(timePoint) '.csv']);
                            forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);

                    end

                    if numel(selectedCells) > 0 && app.CentertoselectionCheckBox.Value
                        initialCells = import_cells(app.CallingApp,'post_plotting','initial');
                        subX = pointsX*d.spar.scalingLength/1e-6 - mean([mean(initialCells(selectedCells(1)).verticesX) mean(initialCells(selectedCells(2)).verticesX)]).*d.spar.scalingLength.*1e6;
                        subY = pointsY*d.spar.scalingLength/1e-6 - mean([mean(initialCells(selectedCells(1)).verticesY) mean(initialCells(selectedCells(2)).verticesY)]).*d.spar.scalingLength.*1e6;
                    else
                        subX = pointsX*d.spar.scalingLength/1e-6;
                        subY = pointsY*d.spar.scalingLength/1e-6;
                    end

                    dispData = griddata(subX,subY,forces,xq,yq,'cubic');

                else
                    app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;
                    app.CallingApp.selectedFile = previousSelected;
                    uialert(app.UIFigure, ['Selected simulation number does not have substrate data.'],'Data missing');
                    return
                end

            end

            app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;


            if (~ishandle(app.CallingApp.plotHandle) || app.CallingApp.plotHandle == 0) && ~ishandle(1)
                mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;
                d.pl.figureHandle = figure(1);
                d.pl.figureHandle.Position(1) = mainWindowPosition(1) + 600;
                d.pl.figureHandle.Position(2) = mainWindowPosition(2) + 200;
                app.CallingApp.plotHandle = figure(1);
            else
                d.pl.figureHandle = figure(1);
                clf;
                app.CallingApp.plotHandle = figure(1);
            end

            d.pl.axesHandle = axes;

            figureSize = d.pl.figureHandle.Position(3:4);
            bottomGap = 10/figureSize(2);
            topGap = 10/figureSize(2);
            horzGap = 10/figureSize(1);
            axisHeight = 1 - bottomGap - topGap;
            axisWidth = 1 - 2*horzGap;
            d.pl.axesHandle.Position = [horzGap bottomGap axisWidth axisHeight];

            axisHeight = d.pl.figureHandle.Position(4)*d.pl.axesHandle.Position(4);
            axisWidth = d.pl.figureHandle.Position(3)*d.pl.axesHandle.Position(3);
            if axisHeight > axisWidth
                axisSizeInPixels = axisWidth;
            else
                axisSizeInPixels = axisHeight;
            end

            titleFontSize = axisSizeInPixels/40;
            if titleFontSize > 15
                titleFontSize = 15;
            elseif titleFontSize < 10
                titleFontSize = 10;
            end

            d.pl.axesHandle.Title.FontSize = titleFontSize;

            if any(d.pl.titleType == [2 4 8 10])
                topGap = 20/figureSize(2) + 2*d.pl.axesHandle.Title.FontSize/figureSize(2);
            else
                topGap = 20/figureSize(2) + d.pl.axesHandle.Title.FontSize/figureSize(2);
            end
            d.pl.axesHandle.Position(4) = 1 - bottomGap - topGap;

            axis(app.WindowsizeEditField_2.Value.*[-0.5 0.5 -0.5 0.5])

            axis square

            d.pl.axesHandle.TickLength = [0 0];
            d.pl.axesHandle.XTick = [];
            d.pl.axesHandle.XTickLabel = [];
            d.pl.axesHandle.YTick = [];
            d.pl.axesHandle.YTickLabel = [];
            d.pl.axesHandle.XColor = 'none';
            d.pl.axesHandle.YColor = 'none';

            set_figure_callbacks(d,app.CallingApp)

            %             set_figure_callbacks(d,app.CallingApp)
            hold on
            contourf(d.pl.axesHandle,xq,yq,dispData,256,'LineColor','none');

            mymap = csvread('./settings/Misc/mymap.csv');
            colormap(mymap)
            colorbar
            caxis([0 max(max(dispData(:)),1e-20)]);
            hold off

            app.CallingApp.selectedFile = previousSelected;


            switch app.ForceDropDown.Value
                case 'Total forces'
                    app.CallingApp.importPlottingOptions.cellForcesTotal = pastOption;
                case 'Cortical forces'
                    app.CallingApp.importPlottingOptions.cellForcesCortical = pastOption;
                case 'Junction forces'
                    app.CallingApp.importPlottingOptions.cellForcesJunctions = pastOption;
                case 'Division forces'
                    app.CallingApp.importPlottingOptions.cellForcesDivision = pastOption;
                case 'Membrane forces'
                    app.CallingApp.importPlottingOptions.cellForcesMembrane = pastOption;
                case 'Contact forces'
                    app.CallingApp.importPlottingOptions.cellForcesContact = pastOption;
                case 'Area forces'
                    app.CallingApp.importPlottingOptions.cellForcesArea = pastOption;
                case 'Pointlike forces'
                    app.CallingApp.importPlottingOptions.cellForcesPointlike = pastOption;
                case 'Focal adhesion forces'
                    app.CallingApp.importPlottingOptions.cellForcesFocalAdhesions = pastOption;
                case 'Total substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesTotal = pastOption;
                case 'Direct substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesTotal = pastOption;
                case 'Restorative substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesRepulsion = pastOption;
                case 'Repulsion substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesRestoration = pastOption;
                case 'Focal adhesion substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesFocalAdhesions = pastOption;
            end


        end

        % Value changed function: StartDropDown
        function StartDropDownValueChanged(app, event)
            if app.StartDropDown.Value > app.EndDropDown.Value
                uialert(app.UIFigure,'Start time must be smaller than end time.')
                app.StartDropDown.Value = event.PreviousValue;
            end

        end

        % Value changed function: EndDropDown
        function EndDropDownValueChanged(app, event)
            if app.StartDropDown.Value > app.EndDropDown.Value
                uialert(app.UIFigure,'End time must be larger than start time.')
                app.EndDropDown.Value = event.PreviousValue;
            end
        end

        % Value changed function: WindowsizeEditField
        function WindowsizeEditFieldValueChanged(app, event)
            app.WindowsizeEditField_2.Value = app.WindowsizeEditField.Value;

        end

        % Value changed function: WindowsizeEditField_2
        function WindowsizeEditField_2ValueChanged(app, event)
            app.WindowsizeEditField.Value = app.WindowsizeEditField_2.Value;

        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)

            switch app.ForceDropDown.Value
                case 'Total forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesTotal;
                    app.CallingApp.importPlottingOptions.cellForcesTotal = true;
                case 'Cortical forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesCortical;
                    app.CallingApp.importPlottingOptions.cellForcesCortical = true;
                case 'Junction forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesJunctions;
                    app.CallingApp.importPlottingOptions.cellForcesJunctions = true;
                case 'Division forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesDivision;
                    app.CallingApp.importPlottingOptions.cellForcesDivision = true;
                case 'Membrane forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesMembrane;
                    app.CallingApp.importPlottingOptions.cellForcesMembrane = true;
                case 'Contact forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesContact;
                    app.CallingApp.importPlottingOptions.cellForcesContact = true;
                case 'Area forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesArea;
                    app.CallingApp.importPlottingOptions.cellForcesArea = true;
                case 'Pointlike forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesPointlike;
                    app.CallingApp.importPlottingOptions.cellForcesPointlike = true;
                case 'Focal adhesion forces'
                    pastOption = app.CallingApp.importPlottingOptions.cellForcesFocalAdhesions;
                    app.CallingApp.importPlottingOptions.cellForcesFocalAdhesions = true;
                case 'Total substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesTotal;
                    app.CallingApp.importPlottingOptions.substrateForcesTotal = true;
                case 'Direct substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesDirect;
                    app.CallingApp.importPlottingOptions.substrateForcesDirect = true;
                case 'Restorative substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesRepulsion;
                    app.CallingApp.importPlottingOptions.substrateForcesRepulsion = true;
                case 'Repulsion substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesRestoration;
                    app.CallingApp.importPlottingOptions.substrateForcesRestoration = true;
                case 'Focal adhesion substrate forces'
                    pastOption = app.CallingApp.importPlottingOptions.substrateForcesFocalAdhesions;
                    app.CallingApp.importPlottingOptions.substrateForcesFocalAdhesions = true;
            end


            timePoint = find(strcmp(app.TimePointDropDown.Items, app.TimePointDropDown.Value));


            [xq,yq] = meshgrid(-400:0.5:400,-400:0.5:400);
            middlePoint = ceil(size(yq,1)/2);


            yPositions =  -yq(:,1);
            xPositions = -xq(1,:);


            app.analysisFigureHandle = figure(2);
            clf
            app.numberOfAnalysisPlots = 0;
            mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;

            app.analysisFigureHandle.Position(1) = mainWindowPosition(1) + 600;
            app.analysisFigureHandle.Position(2) = mainWindowPosition(2);

            res = get(0,'screensize');

            position = get(gcf, 'Position');

            set(gcf, 'Position',  [position(1), position(2),  600, 600])

            position = get(gcf, 'Position');

            if sum(position([2 4])) + 100 > res(4)
                position(2) = res(4) - position(4) - 100;
            end
            app.analysisFigureHandle.Position(2) = position(2);


            if ~app.ColorpickerCheckBox_2.Value
                color = 'r';
            else
                color = uisetcolor;
            end

            previousSelected = app.CallingApp.selectedFile;


            for ii = 1:length(app.CallingApp.LoadedfilesDropDown.Items)
                if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value)
                    fileIdx = ii;
                    break
                end
            end

            app.CallingApp.selectedFile = fileIdx;

            d.spar.scalingLength = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength;
            d.spar.scalingTime = app.CallingApp.plotImport(fileIdx).scaledParameters.scalingTime;
            d.spar.rCell = app.CallingApp.plotImport(fileIdx).scaledParameters.rCell;

            d.pl.substrateStyle = 1;
            d.pl.substrateForcesDirect = 0;
            d.pl.substrateForcesRepulsion = 0;
            d.pl.substrateForcesRestoration = 0;
            d.pl.substrateForcesFocalAdhesions = 0;
            d.pl.substrateForcesTotal = 0;
            d.pl.titleType = 0;
            d.pl.windowSize = app.CallingApp.importPlottingOptions.windowSize;
            d.pl.axisLimits = [app.CallingApp.MiddlepointxEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                app.CallingApp.MiddlepointxEditField_2.Value*1e-6+d.pl.windowSize/2 ...
                app.CallingApp.MiddlepointyEditField_2.Value*1e-6-d.pl.windowSize/2 ...
                app.CallingApp.MiddlepointyEditField_2.Value*1e-6+d.pl.windowSize/2]/d.spar.scalingLength;


            d.simset.simulationType = 5;

            oldTimePoint = app.CallingApp.plotImport(fileIdx).currentTimePoint;

            selectedCells = app.CallingApp.plotImport(fileIdx).optoSelectedCells;

            cell1 = selectedCells(1);
            cell2 = selectedCells(2);

            app.CallingApp.plotImport(fileIdx).currentTimePoint = timePoint;
            cells = import_cells(app.CallingApp,'opto_analysis');

            vertexIDs = or(cells(cell1).junctions.cells(:,1) == cell2,cells(cell1).junctions.cells(:,2) == cell2);

            if sum(vertexIDs) > 1
                diffs = diff(vertexIDs([1:end 1]));

                up = find(diffs == 1);
                down = find(diffs == -1);

                if length(up) > 1 || length(down) > 1
                    diffs = [diffs;diffs];
                    up = find(diffs == 1);
                    down = find(diffs == -1);
                    if up(1) < down(1)
                        distances2 = up([2:end 1])-down;
                        [~,maxInd] = max(distances2);
                        if down(maxInd) > cells(cell1).nVertices
                            vertex2 = down(maxInd) - cells(cell1).nVertices;
                        else
                            vertex2 = down(maxInd);
                        end
                        if up(maxInd+1) > cells(cell1).nVertices
                            vertex1 = up(maxInd+1) + 1 - cells(cell1).nVertices;
                        else
                            vertex1 = up(maxInd+1) + 1;
                        end
                    else
                        distances2 = up-down;
                        [~,maxInd] = max(distances2);
                        if down(maxInd) > cells(cell1).nVertices
                            vertex2 = down(maxInd) - cells(cell1).nVertices;
                        else
                            vertex2 = down(maxInd);
                        end
                        if up(maxInd) > cells(cell1).nVertices
                            vertex1 = up(maxInd) + 1 - cells(cell1).nVertices;
                        else
                            vertex1 = up(maxInd) + 1;
                        end
                    end

                else
                    if up == cells(cell1).nVertices
                        vertex1 = 1;
                    else
                        vertex1 = up + 1;
                    end
                    vertex2 = down;
                end

                whichJunction1 = cells(cell1).junctions.cells(vertex1,:) == cell2;
                whichJunction2 = cells(cell1).junctions.cells(vertex2,:) == cell2;

                vertex3 = cells(cell1).junctions.vertices(vertex1,whichJunction1);
                vertex4 = cells(cell1).junctions.vertices(vertex2,whichJunction2);

                middlePoint13X = 0.5*(cells(cell1).verticesX(vertex1) + cells(cell2).verticesX(vertex3));
                middlePoint13Y = 0.5*(cells(cell1).verticesY(vertex1) + cells(cell2).verticesY(vertex3));
                middlePoint24X = 0.5*(cells(cell1).verticesX(vertex2) + cells(cell2).verticesX(vertex4));
                middlePoint24Y = 0.5*(cells(cell1).verticesY(vertex2) + cells(cell2).verticesY(vertex4));
            else
                uialert(app.UIFigure,'Cells are not connected in the initial time point.')
                return
            end

            dy = middlePoint24Y - middlePoint13Y;
            dx = middlePoint24X - middlePoint13X;
            if dx < 1e-10
                dx = 1e-10;
            end

            moveX = -mean([middlePoint13X middlePoint24X]);
            moveY = -mean([middlePoint13Y middlePoint24Y]);

            angle = pi/2 - atan(dy/dx);


            app.CallingApp.plotImport(fileIdx).currentTimePoint = timePoint;

            substrateForces = {'Total substrate forces','Direct substrate forces','Restorative substrate forces','Repulsion substrate forces','Focal adhesion substrate forces'};

            if ~any(strcmp(app.ForceDropDown.Value,substrateForces))

                cells = import_cells(app.CallingApp,'post_plotting');

                switch app.TypeDropDown.Value

                    case 'Average'
                        cellPositionsX = zeros(1,length(cells));
                        cellPositionsY = zeros(1,length(cells));

                        forces = zeros(1,length(cells));


                        for k = 1:length(cells)
                            cellPositionsX(k) = mean(cells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                            cellPositionsY(k) = mean(cells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                            switch app.ForceDropDown.Value
                                case 'Total forces'
                                    forces(k) = mean(sqrt((cells(k).forces.totalX).^2 + (cells(k).forces.totalY).^2));
                                case 'Cortical forces'
                                    forces(k) = mean(sqrt((cells(k).forces.corticalX).^2 + (cells(k).forces.corticalY).^2));
                                case 'Junction forces'
                                    forces(k) = mean(sqrt((cells(k).forces.junctionX).^2 + (cells(k).forces.junctionY).^2));
                                case 'Division forces'
                                    forces(k) = mean(sqrt((cells(k).forces.divisionX).^2 + (cells(k).forces.divisionY).^2));
                                case 'Membrane forces'
                                    forces(k) = mean(sqrt((cells(k).forces.membraneX).^2 + (cells(k).forces.membraneY).^2));
                                case 'Contact forces'
                                    forces(k) = mean(sqrt((cells(k).forces.contactX).^2 + (cells(k).forces.contactY).^2));
                                case 'Area forces'
                                    forces(k) = mean(sqrt((cells(k).forces.areaX).^2 + (cells(k).forces.areaY).^2));
                                case 'Pointlike forces'
                                    forces(k) = mean(sqrt((cells(k).forces.pointlikeX).^2 + (cells(k).forces.pointlikeY).^2));
                                case 'Focal adhesion forces'
                                    forces(k) = mean(sqrt((cells(k).forces.substrateX).^2 + (cells(k).forces.substrateY).^2));
                            end

                        end
                    case 'Maximum'
                        cellPositionsX = zeros(1,length(cells));
                        cellPositionsY = zeros(1,length(cells));

                        forces = zeros(1,length(cells));


                        for k = 1:length(cells)
                            cellPositionsX(k) = mean(cells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                            cellPositionsY(k) = mean(cells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;

                            switch app.ForceDropDown.Value
                                case 'Total forces'
                                    forces(k) = max(sqrt((cells(k).forces.totalX).^2 + (cells(k).forces.totalY).^2));
                                case 'Cortical forces'
                                    forces(k) = max(sqrt((cells(k).forces.corticalX).^2 + (cells(k).forces.corticalY).^2));
                                case 'Junction forces'
                                    forces(k) = max(sqrt((cells(k).forces.junctionX).^2 + (cells(k).forces.junctionY).^2));
                                case 'Division forces'
                                    forces(k) = max(sqrt((cells(k).forces.divisionX).^2 + (cells(k).forces.divisionY).^2));
                                case 'Membrane forces'
                                    forces(k) = max(sqrt((cells(k).forces.membraneX).^2 + (cells(k).forces.membraneY).^2));
                                case 'Contact forces'
                                    forces(k) = max(sqrt((cells(k).forces.contactX).^2 + (cells(k).forces.contactY).^2));
                                case 'Area forces'
                                    forces(k) = max(sqrt((cells(k).forces.areaX).^2 + (cells(k).forces.areaY).^2));
                                case 'Pointlike forces'
                                    forces(k) = max(sqrt((cells(k).forces.pointlikeX).^2 + (cells(k).forces.pointlikeY).^2));
                                case 'Focal adhesion forces'
                                    forces(k) = max(sqrt((cells(k).forces.substrateX).^2 + (cells(k).forces.substrateY).^2));
                            end

                        end
                    case 'Minimum'
                        cellPositionsX = zeros(1,length(cells));
                        cellPositionsY = zeros(1,length(cells));

                        forces = zeros(1,length(cells));


                        for k = 1:length(cells)
                            cellPositionsX(k) = mean(cells(k).verticesX).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;
                            cellPositionsY(k) = mean(cells(k).verticesY).*app.CallingApp.plotImport(fileIdx).scaledParameters.scalingLength.*1e6;

                            switch app.ForceDropDown.Value
                                case 'Total forces'
                                    forces(k) = min(sqrt((cells(k).forces.totalX).^2 + (cells(k).forces.totalY).^2));
                                case 'Cortical forces'
                                    forces(k) = min(sqrt((cells(k).forces.corticalX).^2 + (cells(k).forces.corticalY).^2));
                                case 'Junction forces'
                                    forces(k) = min(sqrt((cells(k).forces.junctionX).^2 + (cells(k).forces.junctionY).^2));
                                case 'Division forces'
                                    forces(k) = min(sqrt((cells(k).forces.divisionX).^2 + (cells(k).forces.divisionY).^2));
                                case 'Membrane forces'
                                    forces(k) = min(sqrt((cells(k).forces.membraneX).^2 + (cells(k).forces.membraneY).^2));
                                case 'Contact forces'
                                    forces(k) = min(sqrt((cells(k).forces.contactX).^2 + (cells(k).forces.contactY).^2));
                                case 'Area forces'
                                    forces(k) = min(sqrt((cells(k).forces.areaX).^2 + (cells(k).forces.areaY).^2));
                                case 'Pointlike forces'
                                    forces(k) = min(sqrt((cells(k).forces.pointlikeX).^2 + (cells(k).forces.pointlikeY).^2));
                                case 'Focal adhesion forces'
                                    forces(k) = min(sqrt((cells(k).forces.substrateX).^2 + (cells(k).forces.substrateY).^2));
                            end

                        end
                end

                cellPositionsX = cellPositionsX + moveX*1e6*app.CallingApp.plotImport(app.CallingApp.selectedFile).scaledParameters.scalingLength;
                cellPositionsY = cellPositionsY + moveY*1e6*app.CallingApp.plotImport(app.CallingApp.selectedFile).scaledParameters.scalingLength;

                rotatedCoordinates = [cos(angle) -sin(angle) ; sin(angle) cos(angle)]*[cellPositionsX(:)' ; cellPositionsY(:)'];
                cellPositionsX = rotatedCoordinates(1,:)';
                cellPositionsY = rotatedCoordinates(2,:)';

                dispData = griddata(cellPositionsX,cellPositionsY,forces,xq,yq,'cubic');

                if app.AveragewidthEditField_2.Value > 0
                    distances = round(app.AveragewidthEditField_2.Value);

                    dispData = mean(dispData(:,middlePoint-distances:middlePoint+distances),2);

                else
                    dispData = dispData(:,middlePoint);
                end

            else
                if exist([app.CallingApp.plotImport(fileIdx).folderName '/substrate'],'dir') == 7

                    importedPoints = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate/substrate_points_' num2str(timePoint) '.csv']);
                    pointsX = importedPoints(:,1);
                    pointsY = importedPoints(:,2);

                    switch app.ForceDropDown.Value
                        case 'Total substrate forces'
                            importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/total/total_' num2str(timePoint) '.csv']);
                            forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);
                        case 'Direct substrate forces'
                            importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/direct/direct_' num2str(timePoint) '.csv']);
                            forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);
                        case 'Restorative substrate forces'
                            importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/restoration/restoration_' num2str(timePoint) '.csv']);
                            forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);
                        case 'Repulsion substrate forces'
                            importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/repulsion/repulsion_' num2str(timePoint) '.csv']);
                            forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);
                        case 'Focal adhesion substrate forces'
                            importForces = csvread([app.CallingApp.plotImport(fileIdx).folderName '/substrate_forces/focal_adhesion/focal_adhesion_' num2str(timePoint) '.csv']);
                            forces = sqrt(importForces(:,1).^2 + importForces(:,2).^2);

                    end


                    subX = pointsX*d.spar.scalingLength/1e-6 + moveX*1e6*app.CallingApp.plotImport(app.CallingApp.selectedFile).scaledParameters.scalingLength;
                    subY = pointsY*d.spar.scalingLength/1e-6 + moveY*1e6*app.CallingApp.plotImport(app.CallingApp.selectedFile).scaledParameters.scalingLength;

                    rotatedCoordinates = [cos(angle) -sin(angle) ; sin(angle) cos(angle)]*[subX(:)' ; subY(:)'];
                    subX = rotatedCoordinates(1,:)';
                    subY = rotatedCoordinates(2,:)';


                    dispData = griddata(subX,subY,forces,xq,yq,'cubic');

                    if app.AveragewidthEditField_2.Value > 0
                        distances = round(app.AveragewidthEditField_2.Value);

                        dispData = mean(dispData(:,middlePoint-distances:middlePoint+distances),2);

                    else
                        dispData = dispData(:,middlePoint);
                    end


                else
                    app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;
                    app.CallingApp.selectedFile = previousSelected;
                    uialert(app.UIFigure, ['Selected simulation does not have substrate data.'],'Data missing');
                    return
                end

            end


            app.CallingApp.plotImport(fileIdx).currentTimePoint = oldTimePoint;


            app.analysisFigureHandle;

            hold on
            if ~strcmp(app.LegendEditField_2.Value,'')
                plot(yPositions(1:1:end),dispData(1:1:end),'Color',color,'LineWidth',1,'DisplayName',app.LegendEditField_2.Value);
                legend('show');
                app.LegendEditField_2.Value = '';
            else
                plot(yPositions(1:end),dispData(1:end),'Color', color,'LineWidth',1,'HandleVisibility','off');
            end
            
            middlePoint13X = middlePoint13X + moveX;
            middlePoint24X = middlePoint24X + moveX;
            middlePoint13Y = middlePoint13Y + moveY;
            middlePoint24Y = middlePoint24Y + moveY;

            rotatedCoordinates = [cos(angle) -sin(angle) ; sin(angle) cos(angle)]*[middlePoint13X middlePoint24X ; middlePoint13Y middlePoint24Y];
            initialJunctionCoordsX = rotatedCoordinates(2,:)'.*d.spar.scalingLength/1e-6;

            plot([initialJunctionCoordsX(1) initialJunctionCoordsX(1)], [0 1000],'--k','DisplayName','Initial junction position');
            plot([initialJunctionCoordsX(2) initialJunctionCoordsX(2)], [0 1000],'--k','HandleVisibility','off');
            legend('show');
            
            hold off

            title([app.ForceDropDown.Value], 'FontSize', 14)
            if app.HalfButton_2.Value

                axis([0 app.WindowsizeEditField.Value/2 0 max(dispData(:))*1.05])

            else

                axis([-app.WindowsizeEditField.Value/2 app.WindowsizeEditField.Value/2 0 max(dispData(:))*1.05])

            end
            
            xlabel('Distance along the junction axis ({\mu}m)', 'FontSize', 12)
            ylabel('Force magnitude', 'FontSize', 12)


            app.CallingApp.selectedFile = previousSelected;

            switch app.ForceDropDown.Value
                case 'Total forces'
                    app.CallingApp.importPlottingOptions.cellForcesTotal = pastOption;
                case 'Cortical forces'
                    app.CallingApp.importPlottingOptions.cellForcesCortical = pastOption;
                case 'Junction forces'
                    app.CallingApp.importPlottingOptions.cellForcesJunctions = pastOption;
                case 'Division forces'
                    app.CallingApp.importPlottingOptions.cellForcesDivision = pastOption;
                case 'Membrane forces'
                    app.CallingApp.importPlottingOptions.cellForcesMembrane = pastOption;
                case 'Contact forces'
                    app.CallingApp.importPlottingOptions.cellForcesContact = pastOption;
                case 'Area forces'
                    app.CallingApp.importPlottingOptions.cellForcesArea = pastOption;
                case 'Pointlike forces'
                    app.CallingApp.importPlottingOptions.cellForcesPointlike = pastOption;
                case 'Focal adhesion forces'
                    app.CallingApp.importPlottingOptions.cellForcesFocalAdhesions = pastOption;
                case 'Total substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesTotal = pastOption;
                case 'Direct substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesTotal = pastOption;
                case 'Restorative substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesRepulsion = pastOption;
                case 'Repulsion substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesRestoration = pastOption;
                case 'Focal adhesion substrate forces'
                    app.CallingApp.importPlottingOptions.substrateForcesFocalAdhesions = pastOption;
            end

        end

        % Button pushed function: SelectcellsButton
        function SelectcellsButtonPushed(app, event)
            disable_enable_all_function(app,'Off');

            d.simset.simulationType = 0;

            for ii = 1:length(app.CallingApp.LoadedfilesDropDown.Items)
                if isa(length(app.SimulationListBox.Value),'char')
                    if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value(i))
                        fileIdx = ii;
                        break
                    end
                else
                    if strcmp(app.CallingApp.LoadedfilesDropDown.Items{ii},app.SimulationListBox.Value)
                        fileIdx = ii;
                        break
                    end
                end
            end

            prevSelection = app.CallingApp.selectedFile;

            app.CallingApp.selectedFile = fileIdx;

            d.spar.scalingTime = app.CallingApp.plotImport(app.CallingApp.selectedFile).scaledParameters.scalingTime;
            d.spar.scalingLength = app.CallingApp.plotImport(app.CallingApp.selectedFile).scaledParameters.scalingLength;
            d.spar.rCell = app.CallingApp.plotImport(app.CallingApp.selectedFile).scaledParameters.rCell;
            d.spar.simulationTime = app.CallingApp.plotImport(app.CallingApp.selectedFile).scaledParameters.simulationTime;

            d = set_plotting_options(app.CallingApp,'opto_post_cell',d);

            d.cells = import_cells(app.CallingApp,'get_neighbors');

            if length(d.cells) < 2
                uialert(app.UIFigure,'The import must have more than one cell.','Bad Input');
                disable_enable_all_function(app,'On');
                return
            end

            plot_function(d, 1);

            while 1

                selectedCell = [];

                try
                    coords = ginput(1);
                    for k = 1:length(d.cells)
                        if check_if_inside(d.cells(k).verticesX,d.cells(k).verticesY,coords(:,1),coords(:,2))
                            selectedCell = k;
                            break
                        end
                    end
                catch
                    break
                end

                if ~isempty(selectedCell)
                    hold on

                    if numel(app.CallingApp.plotImport(app.CallingApp.selectedFile).optoSelectedCells) == 0
                        app.CallingApp.plotImport(app.CallingApp.selectedFile).optoSelectedCells(end+1) = selectedCell;
                        d.pl.highlightedCells(end+1) = selectedCell;
                        highlight_cell(d,selectedCell);
                    elseif any(app.CallingApp.plotImport(app.CallingApp.selectedFile).optoSelectedCells == selectedCell)
                        app.CallingApp.plotImport(app.CallingApp.selectedFile).optoSelectedCells(app.CallingApp.plotImport(app.CallingApp.selectedFile).optoSelectedCells == selectedCell) = [];
                        d.pl.highlightedCells(d.pl.highlightedCells == selectedCell) = [];
                        highlight_cell(d,-1,selectedCell);
                    elseif length(app.CallingApp.plotImport(app.CallingApp.selectedFile).optoSelectedCells) == 1
                        if any(d.cells(app.CallingApp.plotImport(app.CallingApp.selectedFile).optoSelectedCells(1)).junctions.cells(:) == selectedCell)
                            app.CallingApp.plotImport(app.CallingApp.selectedFile).optoSelectedCells(end+1) = selectedCell;
                            d.pl.highlightedCells(end+1) = selectedCell;
                            highlight_cell(d,selectedCell);
                        else
                            uialert(app.UIFigure,'The two selected cells are not neighbors.','Bad Input');
                        end
                    elseif length(app.CallingApp.plotImport(app.CallingApp.selectedFile).optoSelectedCells) == 2
                        uialert(app.UIFigure,'Deselect one cell before selecting a new neighbor.','Bad Input');
                    end

                    plot_cell_numbers(d,k);
                    hold off
                end
            end

            app.CallingApp.selectedFile = prevSelection;

            disable_enable_all_function(app,'On');

            if length(app.CallingApp.plotImport(app.CallingApp.selectedFile).optoSelectedCells) == 2
                app.PlotButton.Enable = 'On';
                app.PlotButton_2.Enable = 'On';
                app.CentertoselectionCheckBox.Enable = 'On';
            else
                app.PlotButton.Enable = 'Off';
                app.PlotButton_2.Enable = 'Off';
                app.CentertoselectionCheckBox.Enable = 'Off';
                app.CentertoselectionCheckBox.Value = 0;
            end
        end

        % Value changed function: AveragewidthEditField_2
        function AveragewidthEditField_2ValueChanged(app, event)
            app.AveragewidthEditField.Value = app.AveragewidthEditField_2.Value;

        end

        % Value changed function: AveragewidthEditField
        function AveragewidthEditFieldValueChanged(app, event)
            app.AveragewidthEditField_2.Value = app.AveragewidthEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CloseButton.Text = 'Close';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DisplacementTab = uitab(app.TabGroup);
            app.DisplacementTab.Title = 'Displacement';
            app.DplotButtonGroup = uibuttongroup(app.DisplacementTab);
            app.DplotButtonGroup.Title = '1D plot';
            app.HalfButton = uiradiobutton(app.DplotButtonGroup);
            app.HalfButton.Text = 'Half';
            app.HalfButton.Value = true;
            app.FullButton = uiradiobutton(app.DplotButtonGroup);
            app.FullButton.Text = 'Full';
            app.PlotButton = uibutton(app.DplotButtonGroup, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.NewplotCheckBox = uicheckbox(app.DplotButtonGroup);
            app.NewplotCheckBox.Text = 'New plot';
            app.LegendEditFieldLabel = uilabel(app.DplotButtonGroup);
            app.LegendEditFieldLabel.Text = 'Legend';
            app.LegendEditField = uieditfield(app.DplotButtonGroup, 'text');
            app.ColorpickerCheckBox = uicheckbox(app.DplotButtonGroup);
            app.ColorpickerCheckBox.Text = 'Colorpicker';
            app.AveragewidthEditFieldLabel = uilabel(app.DplotButtonGroup);
            app.AveragewidthEditFieldLabel.Text = 'Average width';
            app.AveragewidthEditField = uieditfield(app.DplotButtonGroup, 'numeric');
            app.AveragewidthEditField.Limits = [0 Inf];
            app.AveragewidthEditField.ValueChangedFcn = createCallbackFcn(app, @AveragewidthEditFieldValueChanged, true);
            app.mLabel_4 = uilabel(app.DplotButtonGroup);
            app.mLabel_4.Text = 'µm';
            app.AreaplotPanel = uipanel(app.DisplacementTab);
            app.AreaplotPanel.Title = 'Area plot';
            app.DisplacementTypeButtonGroup = uibuttongroup(app.AreaplotPanel);
            app.CellsButton = uiradiobutton(app.DisplacementTypeButtonGroup);
            app.CellsButton.Text = 'Cells';
            app.CellsButton.Value = true;
            app.SubstrateButton = uiradiobutton(app.DisplacementTypeButtonGroup);
            app.SubstrateButton.Text = 'Substrate';
            app.AreaplotButton = uibutton(app.AreaplotPanel, 'push');
            app.AreaplotButton.ButtonPushedFcn = createCallbackFcn(app, @AreaplotButtonPushed, true);
            app.AreaplotButton.Enable = 'off';
            app.AreaplotButton.Text = 'Area plot';
            app.ContoursCheckBox = uicheckbox(app.AreaplotPanel);
            app.ContoursCheckBox.Text = 'Contours';
            app.StartDropDownLabel = uilabel(app.DisplacementTab);
            app.StartDropDownLabel.Text = 'Start';
            app.StartDropDown = uidropdown(app.DisplacementTab);
            app.StartDropDown.Items = {};
            app.StartDropDown.ValueChangedFcn = createCallbackFcn(app, @StartDropDownValueChanged, true);
            app.StartDropDown.Value = {};
            app.EndDropDownLabel = uilabel(app.DisplacementTab);
            app.EndDropDownLabel.Text = 'End';
            app.EndDropDown = uidropdown(app.DisplacementTab);
            app.EndDropDown.Items = {};
            app.EndDropDown.ValueChangedFcn = createCallbackFcn(app, @EndDropDownValueChanged, true);
            app.EndDropDown.Value = {};
            app.WindowsizeEditFieldLabel = uilabel(app.DisplacementTab);
            app.WindowsizeEditFieldLabel.Text = 'Window size';
            app.WindowsizeEditField = uieditfield(app.DisplacementTab, 'numeric');
            app.WindowsizeEditField.Limits = [0 Inf];
            app.WindowsizeEditField.ValueChangedFcn = createCallbackFcn(app, @WindowsizeEditFieldValueChanged, true);
            app.WindowsizeEditField.Value = 50;
            app.mLabel_2 = uilabel(app.DisplacementTab);
            app.mLabel_2.Text = 'µm';
            app.MaximumdisplacementEditFieldLabel = uilabel(app.DisplacementTab);
            app.MaximumdisplacementEditFieldLabel.Text = 'Max displacement';
            app.MaximumdisplacementEditField = uieditfield(app.DisplacementTab, 'numeric');
            app.MaximumdisplacementEditField.Value = 0.5;
            app.mLabel = uilabel(app.DisplacementTab);
            app.mLabel.Text = 'µm';
            app.ContoursEditFieldLabel = uilabel(app.DisplacementTab);
            app.ContoursEditFieldLabel.Text = 'Contours';
            app.ContoursEditField = uieditfield(app.DisplacementTab, 'text');
            app.ContoursEditField.ValueChangedFcn = createCallbackFcn(app, @ContoursEditFieldValueChanged, true);
            app.ContoursEditField.Value = '0.1 0.2 0.3 0.4 0.5';
            app.ForcesTab = uitab(app.TabGroup);
            app.ForcesTab.Title = 'Forces';
            app.DplotButtonGroup_2 = uibuttongroup(app.ForcesTab);
            app.DplotButtonGroup_2.Title = '1D plot';
            app.HalfButton_2 = uiradiobutton(app.DplotButtonGroup_2);
            app.HalfButton_2.Text = 'Half';
            app.HalfButton_2.Value = true;
            app.FullButton_2 = uiradiobutton(app.DplotButtonGroup_2);
            app.FullButton_2.Text = 'Full';
            app.PlotButton_2 = uibutton(app.DplotButtonGroup_2, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Enable = 'off';
            app.PlotButton_2.Text = 'Plot';
            app.NewplotCheckBox_2 = uicheckbox(app.DplotButtonGroup_2);
            app.NewplotCheckBox_2.Text = 'New plot';
            app.LegendEditField_2Label = uilabel(app.DplotButtonGroup_2);
            app.LegendEditField_2Label.Text = 'Legend';
            app.LegendEditField_2 = uieditfield(app.DplotButtonGroup_2, 'text');
            app.ColorpickerCheckBox_2 = uicheckbox(app.DplotButtonGroup_2);
            app.ColorpickerCheckBox_2.Text = 'Colorpicker';
            app.mLabel_5 = uilabel(app.DplotButtonGroup_2);
            app.mLabel_5.Text = 'µm';
            app.AveragewidthEditField_2Label = uilabel(app.DplotButtonGroup_2);
            app.AveragewidthEditField_2Label.Text = 'Average width';
            app.AveragewidthEditField_2 = uieditfield(app.DplotButtonGroup_2, 'numeric');
            app.AveragewidthEditField_2.Limits = [0 Inf];
            app.AveragewidthEditField_2.ValueChangedFcn = createCallbackFcn(app, @AveragewidthEditField_2ValueChanged, true);
            app.AreaplotPanel_2 = uipanel(app.ForcesTab);
            app.AreaplotPanel_2.Title = 'Area plot';
            app.AreaplotButton_2 = uibutton(app.AreaplotPanel_2, 'push');
            app.AreaplotButton_2.ButtonPushedFcn = createCallbackFcn(app, @AreaplotButton_2Pushed, true);
            app.AreaplotButton_2.Enable = 'off';
            app.AreaplotButton_2.Text = 'Area plot';
            app.mLabel_3 = uilabel(app.ForcesTab);
            app.mLabel_3.Text = 'µm';
            app.TimePointDropDownLabel = uilabel(app.ForcesTab);
            app.TimePointDropDownLabel.Text = 'Time Point';
            app.TimePointDropDown = uidropdown(app.ForcesTab);
            app.TimePointDropDown.Items = {};
            app.TimePointDropDown.Value = {};
            app.WindowsizeEditField_2Label = uilabel(app.ForcesTab);
            app.WindowsizeEditField_2Label.Text = 'Window size';
            app.WindowsizeEditField_2 = uieditfield(app.ForcesTab, 'numeric');
            app.WindowsizeEditField_2.Limits = [0 Inf];
            app.WindowsizeEditField_2.ValueChangedFcn = createCallbackFcn(app, @WindowsizeEditField_2ValueChanged, true);
            app.WindowsizeEditField_2.Value = 50;
            app.ForceDropDownLabel = uilabel(app.ForcesTab);
            app.ForceDropDownLabel.Text = 'Force';
            app.ForceDropDown = uidropdown(app.ForcesTab);
            app.ForceDropDown.Items = {};
            app.ForceDropDown.Value = {};
            app.TypeDropDownLabel = uilabel(app.ForcesTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.ForcesTab);
            app.TypeDropDown.Items = {'Average', 'Maximum', 'Minimum', 'Individual'};
            app.TypeDropDown.Value = 'Average';
            app.SimulationListBox = uilistbox(app.UIFigure);
            app.SimulationListBox.Items = {};
            app.SimulationListBox.ValueChangedFcn = createCallbackFcn(app, @SimulationListBoxValueChanged, true);
            app.SimulationListBox.Value = {};
            app.SelectcellsButton = uibutton(app.UIFigure, 'push');
            app.SelectcellsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectcellsButtonPushed, true);
            app.SelectcellsButton.Enable = 'off';
            app.SelectcellsButton.Text = 'Select cells';
            app.CentertoselectionCheckBox = uicheckbox(app.UIFigure);
            app.CentertoselectionCheckBox.Enable = 'off';
            app.CentertoselectionCheckBox.Text = 'Center to selection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optoAnalysisDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end