classdef optogeneticActivationDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptogeneticactivationUIFigure  matlab.ui.Figure
        TipsImage                      matlab.ui.control.Image
        DefaultButton                  matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        RemoverowButton                matlab.ui.control.Button
        AddrowButton                   matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Description
        activationTimes = []; % Description
        activationLevels = [];
    end
    
    methods (Access = private)
        
        function plot_activation(app)
            
            stairs(app.UIAxes,[app.activationTimes ; app.activationTimes(end)+1000],[app.activationLevels ; app.activationLevels(end)]);
            axis(app.UIAxes,[0 app.activationTimes(end) + 2 -0.5 max(app.activationLevels)+1]);
            
        end
        
        function change_table(app)
            app.UITable.Data = num2cell([app.activationTimes app.activationLevels]);
            if length(app.activationTimes) < 2
                app.RemoverowButton.Enable = 'off';
            else
                app.RemoverowButton.Enable = 'on';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.OptogeneticactivationUIFigure.Visible = 'Off';
            app.CallingApp = mainapp;
            
            mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;
            
            app.OptogeneticactivationUIFigure.Position(1) = mainWindowPosition(1) + mainWindowPosition(3)/2 - app.OptogeneticactivationUIFigure.Position(3)/2;
            app.OptogeneticactivationUIFigure.Position(2) = mainWindowPosition(2) + mainWindowPosition(4)/2 - app.OptogeneticactivationUIFigure.Position(4)/2;


            app.activationTimes = app.CallingApp.optoActivation(:,1)./60;
            app.activationLevels = app.CallingApp.optoActivation(:,2);
            plot_activation(app);
            change_table(app);
            
            app.LoadButton.Icon = [app.CallingApp.defaultPath 'settings/images/load.png'];
            app.SaveButton.Icon = [app.CallingApp.defaultPath 'settings/images/save.png'];
            app.AddrowButton.Icon = [app.CallingApp.defaultPath 'settings/images/plus.png'];
            app.RemoverowButton.Icon = [app.CallingApp.defaultPath 'settings/images/minus.png'];
            app.ResetButton.Icon = [app.CallingApp.defaultPath 'settings/images/reload.png'];
            app.TipsImage.ImageSource = [app.CallingApp.defaultPath 'settings/images/tips.png']; 
            app.TipsImage.Tooltip = fileread([app.CallingApp.defaultPath 'settings/tooltips/opto_activation_settings.txt']);
            
            app.OptogeneticactivationUIFigure.Icon = [app.CallingApp.defaultPath 'settings/images/logo_v2.png'];
            
            app.OptogeneticactivationUIFigure.Visible = 'On';
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.CallingApp.optoActivation = [app.activationTimes.*60 app.activationLevels];
            disable_enable_all_function(app.CallingApp,'On');
            
            figure(app.CallingApp.EpiMechUIFigure);
            delete(app);
        end

        % Close request function: OptogeneticactivationUIFigure
        function OptogeneticactivationUIFigureCloseRequest(app, event)
            app.CallingApp.optoActivation = [app.activationTimes.*60 app.activationLevels];
            disable_enable_all_function(app.CallingApp,'On');
            
            figure(app.CallingApp.EpiMechUIFigure);
            delete(app)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            if indices(2) == 1
                if indices(1) > 1
                    if event.NewData <= app.activationTimes(indices(1)-1)
                        app.UITable.Data{indices(1),indices(2)} = event.PreviousData;
                        uialert(app.OptogeneticactivationUIFigure,'The time must be larger than previous time','Bad input');
                    else
                        temp = cell2mat(app.UITable.Data);
                        app.activationTimes = temp(:,1);
                        app.activationLevels = temp(:,2);
                    end
                else
                    app.UITable.Data{indices(1),indices(2)} = event.PreviousData;
                    uialert(app.OptogeneticactivationUIFigure,'First time point cannot be edited','Bad input');
                end
            else
                if event.NewData < 0
                    app.UITable.Data{indices(1),indices(2)} = event.PreviousData;
                    uialert(app.OptogeneticactivationUIFigure,'Activation cannot be negative','Bad input');
                elseif event.NewData > 1
                    app.UITable.Data{indices(1),indices(2)} = event.PreviousData;
                    uialert(app.OptogeneticactivationUIFigure,'Activation cannot be more than 1','Bad input');
                else
                    temp = cell2mat(app.UITable.Data);
                    app.activationTimes = temp(:,1);
                    app.activationLevels = temp(:,2);
                end
            end
            
            plot_activation(app);
        end

        % Button pushed function: AddrowButton
        function AddrowButtonPushed(app, event)
            app.activationTimes(end+1) = app.activationTimes(end) + 2;
            app.activationLevels(end+1) = 0;
            plot_activation(app);
            change_table(app);
        end

        % Button pushed function: RemoverowButton
        function RemoverowButtonPushed(app, event)
            app.activationTimes(end) = [];
            app.activationLevels(end) = [];
            change_table(app);
            plot_activation(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [fileName, filePath] = uigetfile({'*.csv'},'Choose activation file to load',[app.CallingApp.defaultPath 'settings/misc/']);
            
            figure(app.CallingApp.EpiMechUIFigure);
            figure(app.OptogeneticactivationUIFigure);
            if fileName == 0
                return;
            end
            
            newActivation = csvread([filePath fileName]);
            
            if newActivation(1,1) ~= 0
                uialert(app.OptogeneticactivationUIFigure,'First time value must be zero','Bad input');
                return;
            elseif size(newActivation,2) ~= 2
                uialert(app.OptogeneticactivationUIFigure,'File must have two columns','Bad input');
                return;
            elseif any(diff(newActivation(:,1)) <= 0)
                uialert(app.OptogeneticactivationUIFigure,'The times in the file must be increasing.','Bad input');
                return;
            elseif any((newActivation(:,2)) < 0)
                uialert(app.OptogeneticactivationUIFigure,'The activations cannot be negative','Bad input');
                return;
            end
            
            app.activationTimes = newActivation(:,1)./60;
            app.activationLevels = newActivation(:,2);
            plot_activation(app);
            change_table(app);
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [fileName, filePath] = uiputfile({'*.csv'},'Save the current activation settings',[app.CallingApp.defaultPath 'settings/misc/']);
            
            if fileName == 0
                figure(app.CallingApp.EpiMechUIFigure);
                figure(app.OptogeneticactivationUIFigure);
                return;
            end
            
            testPath = [app.CallingApp.defaultPath 'settings/misc/'];
            testPath = strrep(testPath,'/','\');
            filePath = strrep(filePath,'/','\');
            figure(app.CallingApp.EpiMechUIFigure);
            figure(app.OptogeneticactivationUIFigure);
            
            
            if strcmp(filePath,testPath) && (strcmp(fileName,'default_opto_activation') || strcmp(fileName,'default_opto_activation.csv'))
                userResponse = uiconfirm(app.OptogeneticactivationUIFigure,'Do you want to save new default optogenetic activation?', '', 'Options', {'Yes','No'}, 'DefaultOption', 2);
                if strcmp(userResponse,'Yes')
                    csvwrite([filePath fileName],[app.activationTimes.*60 app.activationLevels]);
                end
            else
                csvwrite([filePath fileName],[app.activationTimes.*60 app.activationLevels]);
            end
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.activationTimes = app.CallingApp.optoActivation(:,1)./60;
            app.activationLevels = app.CallingApp.optoActivation(:,2);
            plot_activation(app);
            change_table(app);
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            newActivation = csvread([app.CallingApp.defaultPath 'settings/misc/default_opto_activation.csv']);
            
            if newActivation(1,1) ~= 0
                uialert(app.OptogeneticactivationUIFigure,'First time value must be zero','Bad input');
                return;
            elseif size(newActivation,2) ~= 2
                uialert(app.OptogeneticactivationUIFigure,'File must have two columns','Bad input');
                return;
            elseif any(diff(newActivation(:,1)) <= 0)
                uialert(app.OptogeneticactivationUIFigure,'The times in the file must be increasing.','Bad input');
                return;
            elseif any((newActivation(:,2)) < 0)
                uialert(app.OptogeneticactivationUIFigure,'The activations cannot be negative','Bad input');
                return;
            end
            
            app.activationTimes = newActivation(:,1)./60;
            app.activationLevels = newActivation(:,2);
            plot_activation(app);
            change_table(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptogeneticactivationUIFigure = uifigure('Visible', 'off');
            app.OptogeneticactivationUIFigure.Name = 'Optogenetic activation';
            app.OptogeneticactivationUIFigure.CloseRequestFcn = createCallbackFcn(app, @OptogeneticactivationUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.OptogeneticactivationUIFigure);
            xlabel(app.UIAxes, 'time (min)')
            ylabel(app.UIAxes, 'Activation level')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.OptogeneticactivationUIFigure);
            app.UITable.ColumnName = {'Time (min)'; 'Activation level'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false false];
            app.UITable.ColumnEditable = [true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.CloseButton = uibutton(app.OptogeneticactivationUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.AddrowButton = uibutton(app.OptogeneticactivationUIFigure, 'push');
            app.AddrowButton.ButtonPushedFcn = createCallbackFcn(app, @AddrowButtonPushed, true);
            app.AddrowButton.Tooltip = {'Add a row'};
            app.AddrowButton.Text = '';
            app.RemoverowButton = uibutton(app.OptogeneticactivationUIFigure, 'push');
            app.RemoverowButton.ButtonPushedFcn = createCallbackFcn(app, @RemoverowButtonPushed, true);
            app.RemoverowButton.Tooltip = {'Remove a row'};
            app.RemoverowButton.Text = '';
            app.LoadButton = uibutton(app.OptogeneticactivationUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Load'};
            app.LoadButton.Text = '';
            app.SaveButton = uibutton(app.OptogeneticactivationUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Save'};
            app.SaveButton.Text = '';
            app.ResetButton = uibutton(app.OptogeneticactivationUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Reset'};
            app.ResetButton.Text = '';
            app.DefaultButton = uibutton(app.OptogeneticactivationUIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.TipsImage = uiimage(app.OptogeneticactivationUIFigure);
            app.TipsImage.Tooltip = {''};
            % Show the figure after all components are created
            app.OptogeneticactivationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optogeneticActivationDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptogeneticactivationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptogeneticactivationUIFigure)
        end
    end
end