classdef removeCellsDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RemovecellsUIFigure      matlab.ui.Figure
        TipsImage                matlab.ui.control.Image
        LoadButton               matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        Panel                    matlab.ui.container.Panel
        ShapeSizeEditField       matlab.ui.control.NumericEditField
        SizeLabel                matlab.ui.control.Label
        mLabel                   matlab.ui.control.Label
        ButtonGroup              matlab.ui.container.ButtonGroup
        CircleButton             matlab.ui.control.RadioButton
        SquareButton             matlab.ui.control.RadioButton
        ApplyshapeButton         matlab.ui.control.Button
        VisualizeshapeButton     matlab.ui.control.Button
        CloseButton              matlab.ui.control.Button
        ResetButton              matlab.ui.control.Button
        RemovesinglecellsButton  matlab.ui.control.Button
    end

    properties (Access = public)
        CallingApp % Description
        enabledObjects = []; % Description
        cellsToBeRemoved = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.RemovecellsUIFigure.Visible = 'Off';
            app.CallingApp = mainapp;
            mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;

            app.RemovecellsUIFigure.Position(1) = mainWindowPosition(1) + mainWindowPosition(3)/2 - app.RemovecellsUIFigure.Position(3)/2;
            app.RemovecellsUIFigure.Position(2) = mainWindowPosition(2) + mainWindowPosition(4)/2 - app.RemovecellsUIFigure.Position(4)/2;
            
            app.LoadButton.Icon = [app.CallingApp.defaultPath 'settings/images/load.png'];
            app.SaveButton.Icon = [app.CallingApp.defaultPath 'settings/images/save.png'];
            app.ResetButton.Icon = [app.CallingApp.defaultPath 'settings/images/reload.png'];
            app.TipsImage.ImageSource = [app.CallingApp.defaultPath 'settings/images/tips.png']; 
            app.TipsImage.Tooltip = fileread([app.CallingApp.defaultPath 'settings/tooltips/remove_cells_settings.txt']);
            
            app.RemovecellsUIFigure.Icon = [app.CallingApp.defaultPath 'settings/images/logo_v2.png'];
            
            app.RemovecellsUIFigure.Visible = 'On';
            
            app.cellsToBeRemoved = app.CallingApp.import.removedCells;
        end

        % Button pushed function: RemovesinglecellsButton
        function RemovesinglecellsButtonPushed(app, event)
            
            disable_enable_all_function(app,'Off');


            d = scale_parameters(app.CallingApp);
            d.spar.scalingTime = app.CallingApp.import.scaledParameters.scalingTime;
            
            d = setup_plotting_options(app.CallingApp,d,'remove_cells');
            
            d.pl.highlightedCells = app.cellsToBeRemoved;
            
            d.cells = import_cells(app.CallingApp,d,'basic_plotting');
            if app.CallingApp.CentercellsCheckBox.Value
                d.cells = center_cells(d.cells);
            end
            
            plot_function(d, 1);
            
            while 1
                
                removedCell = [];
                
                try
                    coords = ginput(1);
                    for k = 1:length(d.cells)
                        if check_if_inside(d.cells(k).verticesX,d.cells(k).verticesY,coords(:,1),coords(:,2))
                            if ~(strcmp(app.CallingApp.simulationType,'pointlike') && app.CallingApp.pointlikeProperties.cell == k)
                                removedCell = k;
                            end
                            break
                        end
                    end
                catch
                    
                    break
                end
                
                if ~isempty(removedCell)
                    hold on
                    
                    if any(d.pl.highlightedCells == removedCell)
                        d.pl.highlightedCells(d.pl.highlightedCells == removedCell) = 0;
                        highlight_cell(d,removedCell,1);
                    else
                        d.pl.highlightedCells(end+1) = removedCell;
                        highlight_cell(d,removedCell);
                    end
                    plot_cell_numbers(d,removedCell);
                    hold off
                end
            end
            
            app.cellsToBeRemoved = d.pl.highlightedCells;
            disable_enable_all_function(app,'On');
        end

        % Close request function: RemovecellsUIFigure
        function RemovecellsUIFigureCloseRequest(app, event)
            app.CallingApp.import.removedCells = app.cellsToBeRemoved;
            disable_enable_all_function(app.CallingApp,'On');

            if sum(app.cellsToBeRemoved) > 0
                set_object_properties_function(app.CallingApp,{'FittedButton','SquareButton', 'SubstrateparametersButton'},'Enable',{'On'});
                set_object_properties_function(app.CallingApp,{'EditstiffnessButton'},'Enable',{'Off'});
                set_object_properties_function(app.CallingApp,{'ConstantButton','HeterogeneousButton','GradientButton','YoungsmodulusEditField', 'YoungsmodulusEditFieldLabel','kPaLabel'},'Enable',{'On'});
                app.CallingApp.FittedButton.Value = 1;
                app.CallingApp.UseimportedsubstratedataCheckBox.Value = 0;
                app.CallingApp.UseimportedsubstratedataCheckBox_2.Value = 0;
                app.CallingApp.UseimportedsubstratedataCheckBox.Enable = 'Off';
                app.CallingApp.UseimportedsubstratedataCheckBox_2.Enable = 'Off';
            else
                if strcmp(app.CallingApp.UseimportedsubstratedataCheckBox.Enable, 'Off')
                    app.CallingApp.UseimportedsubstratedataCheckBox.Enable = 'On';
                    app.CallingApp.UseimportedsubstratedataCheckBox_2.Enable = 'On';
                end
            end
            
            figure(app.CallingApp.EpiMechUIFigure)
            delete(app)
            
        end

        % Button pushed function: VisualizeshapeButton
        function VisualizeshapeButtonPushed(app, event)
            
            disable_enable_all_function(app,'Off');
            
            
            d = scale_parameters(app.CallingApp);
            d.spar.scalingTime = app.CallingApp.import.scaledParameters.scalingTime;
            
            d = setup_plotting_options(app.CallingApp,d,'remove_with_shape');
            
            switch app.ButtonGroup.SelectedObject.Text
                case 'Square'
                    d.pl.shape = 1;
                case 'Circle'
                    d.pl.shape = 2;
            end
            d.pl.shapeSize = app.ShapeSizeEditField.Value*1e-6/d.spar.scalingLength/2;
            
            d.cells = import_cells(app.CallingApp,d,'basic_plotting');
            
            if app.CallingApp.CentercellsCheckBox.Value
                d.cells = center_cells(d.cells);
            end
            
            d.pl.outsideShape = [];
            for k = 1:length(d.cells)
                if ~(strcmp(app.CallingApp.simulationType,'pointlike') && app.CallingApp.pointlikeProperties.cell == k)
                    cellCenterX = mean(d.cells(k).verticesX);
                    cellCenterY = mean(d.cells(k).verticesY);
                    if d.pl.shape == 1
                        if ~(cellCenterX > -d.pl.shapeSize && cellCenterX < d.pl.shapeSize && cellCenterY > -d.pl.shapeSize && cellCenterY < d.pl.shapeSize)
                            d.pl.outsideShape(end+1) = k;
                        end
                    elseif d.pl.shape == 2
                        if ~(sqrt(cellCenterX^2 + cellCenterY^2) <= d.pl.shapeSize)
                            d.pl.outsideShape(end+1) = k;
                        end
                    end
                end
            end
            
            % find the square size that fits the epithelium
            maxSize = get_maximum_epithelium_size(d);
            
            if maxSize > d.pl.shapeSize
                d.pl.windowSize = maxSize + d.spar.rCell;
            else
                d.pl.windowSize = d.pl.shapeSize + d.spar.rCell;
            end
            d.pl.highlightedCells = app.cellsToBeRemoved;
            plot_function(d,1);
            
            disable_enable_all_function(app,'On');
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            switch app.ButtonGroup.SelectedObject.Text
                case 'Circle'
                    app.SizeLabel.Text = 'Diameter';
                case 'Square'
                    app.SizeLabel.Text = 'Size';
            end
            
        end

        % Button pushed function: ApplyshapeButton
        function ApplyshapeButtonPushed(app, event)
            
            
            d= scale_parameters(app.CallingApp);
            
            cells = import_cells(app.CallingApp,d,'basic_plotting');
            
            shapeSize = app.ShapeSizeEditField.Value*1e-6/d.spar.scalingLength/2;
            
            outsideShape = [];
            for k = 1:length(cells)
                if ~(strcmp(app.CallingApp.simulationType,'pointlike') && app.CallingApp.pointlikeProperties.cell == k)
                    cellCenterX = mean(cells(k).verticesX);
                    cellCenterY = mean(cells(k).verticesY);
                    if strcmp(app.ButtonGroup.SelectedObject.Text,'Square')
                        if ~(cellCenterX > -shapeSize && cellCenterX < shapeSize && cellCenterY > -shapeSize && cellCenterY < shapeSize)
                            outsideShape(end+1) = k;
                        end
                    elseif strcmp(app.ButtonGroup.SelectedObject.Text,'Circle')
                        if ~(sqrt(cellCenterX^2 + cellCenterY^2) <= shapeSize)
                            outsideShape(end+1) = k;
                        end
                    end
                end
            end
            
            app.cellsToBeRemoved = unique([app.cellsToBeRemoved outsideShape]);
            
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if numel(app.cellsToBeRemoved) > 0
                if app.CallingApp.UseimportedsubstratedataCheckBox.Enable == 'off'
                    app.CallingApp.UseimportedsubstratedataCheckBox.Enable = 'On';
                    app.CallingApp.UseimportedsubstratedataCheckBox_2.Enable = 'On';
                end
            end
            app.cellsToBeRemoved = [];
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % choose file
            [filename, filepath] = uigetfile('*.txt','Choose removed cells to load');
            if ~ischar(filename)
                return
            end
            figure(app.RemovecellsUIFigure)
            
            app.cellsToBeRemoved = csvread([filepath '/' filename]);

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [filename,filepath] = uiputfile({'*.txt'},'Save the removed cells','removed_cells.txt');
            if ~ischar(filepath)
                return
            end
            figure(app.RemovecellsUIFigure)
            
            writematrix(app.cellsToBeRemoved,[filepath filename]);
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.CallingApp.import.removedCells = app.cellsToBeRemoved;
            disable_enable_all_function(app.CallingApp,'On');

            if sum(app.cellsToBeRemoved) > 0
                set_object_properties_function(app.CallingApp,{'FittedButton','SquareButton', 'SubstrateparametersButton'},'Enable',{'On'});
                set_object_properties_function(app.CallingApp,{'EditstiffnessButton'},'Enable',{'Off'});
                set_object_properties_function(app.CallingApp,{'ConstantButton','HeterogeneousButton','GradientButton','YoungsmodulusEditField', 'YoungsmodulusEditFieldLabel','kPaLabel'},'Enable',{'On'});
                app.CallingApp.FittedButton.Value = 1;
                app.CallingApp.UseimportedsubstratedataCheckBox.Value = 0;
                app.CallingApp.UseimportedsubstratedataCheckBox_2.Value = 0;
                app.CallingApp.UseimportedsubstratedataCheckBox.Enable = 'Off';
                app.CallingApp.UseimportedsubstratedataCheckBox_2.Enable = 'Off';
            end
            
            figure(app.CallingApp.EpiMechUIFigure)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RemovecellsUIFigure = uifigure('Visible', 'off');
            app.RemovecellsUIFigure.Name = 'Remove cells';
            app.RemovecellsUIFigure.CloseRequestFcn = createCallbackFcn(app, @RemovecellsUIFigureCloseRequest, true);
            app.RemovesinglecellsButton = uibutton(app.RemovecellsUIFigure, 'push');
            app.RemovesinglecellsButton.ButtonPushedFcn = createCallbackFcn(app, @RemovesinglecellsButtonPushed, true);
            app.RemovesinglecellsButton.Text = 'Remove single cells';
            app.ResetButton = uibutton(app.RemovecellsUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Reset'};
            app.ResetButton.Text = '';
            app.CloseButton = uibutton(app.RemovecellsUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Panel = uipanel(app.RemovecellsUIFigure);
            app.VisualizeshapeButton = uibutton(app.Panel, 'push');
            app.VisualizeshapeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeshapeButtonPushed, true);
            app.VisualizeshapeButton.Text = 'Visualize shape';
            app.ApplyshapeButton = uibutton(app.Panel, 'push');
            app.ApplyshapeButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyshapeButtonPushed, true);
            app.ApplyshapeButton.Text = 'Apply shape';
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.SquareButton = uiradiobutton(app.ButtonGroup);
            app.SquareButton.Text = 'Square';
            app.CircleButton = uiradiobutton(app.ButtonGroup);
            app.CircleButton.Text = 'Circle';
            app.CircleButton.Value = true;
            app.mLabel = uilabel(app.Panel);
            app.mLabel.Text = 'µm';
            app.SizeLabel = uilabel(app.Panel);
            app.SizeLabel.Text = 'Size';
            app.ShapeSizeEditField = uieditfield(app.Panel, 'numeric');
            app.ShapeSizeEditField.Value = 250;
            app.SaveButton = uibutton(app.RemovecellsUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Save'};
            app.SaveButton.Text = '';
            app.LoadButton = uibutton(app.RemovecellsUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Load'};
            app.LoadButton.Text = '';
            app.TipsImage = uiimage(app.RemovecellsUIFigure);
            app.TipsImage.Tooltip = {''};
            % Show the figure after all components are created
            app.RemovecellsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = removeCellsDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RemovecellsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RemovecellsUIFigure)
        end
    end
end