classdef scaleBarSettingsDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScalebarsettingsUIFigure      matlab.ui.Figure
        TipsImage                     matlab.ui.control.Image
        mLabel                        matlab.ui.control.Label
        ScalebarlengthEditField       matlab.ui.control.NumericEditField
        ScalebarlengthEditFieldLabel  matlab.ui.control.Label
        ButtonGroup                   matlab.ui.container.ButtonGroup
        ScalingbarButton              matlab.ui.control.RadioButton
        StaticbarButton               matlab.ui.control.RadioButton
        ScalebarCheckBox              matlab.ui.control.CheckBox
        UITable                       matlab.ui.control.Table
        CloseButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
        
    end
    
    properties (Access = public)
        enabledObjects = []; % Description
        cellsToBeRemoved = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.ScalebarsettingsUIFigure.Visible = 'Off';
            app.CallingApp = mainapp;
            mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;

            app.ScalebarsettingsUIFigure.Position(1) = mainWindowPosition(1) + mainWindowPosition(3)/2 - app.ScalebarsettingsUIFigure.Position(3)/2;
            app.ScalebarsettingsUIFigure.Position(2) = mainWindowPosition(2) + mainWindowPosition(4)/2 - app.ScalebarsettingsUIFigure.Position(4)/2;
            if size(app.CallingApp.scaleBarSettings.barLenghtData,1) > 1
                app.UITable.Data = app.CallingApp.scaleBarSettings.barLenghtData;
            end
                      
            app.TipsImage.ImageSource = [app.CallingApp.defaultPath 'settings/images/tips.png']; 
            app.TipsImage.Tooltip = fileread([app.CallingApp.defaultPath 'settings/tooltips/scale_bar_settings.txt']);
            app.ScalebarsettingsUIFigure.Icon = [app.CallingApp.defaultPath 'settings/images/logo_v2.png'];
            
            app.ScalebarsettingsUIFigure.Visible = 'On';
            app.ScalebarCheckBox.Value = (app.CallingApp.ScalebarCheckBox.Value == 1 || app.CallingApp.ScalebarCheckBox_2.Value == 1);
            switch app.ScalebarCheckBox.Value
                case 1
                    if app.CallingApp.scaleBarSettings.type == 1
                        set_object_properties_function(app,{'ScalingbarButton','StaticbarButton','ScalebarlengthEditField','ScalebarlengthEditFieldLabel','mLabel'},'Enable',{'On'});
                        app.StaticbarButton.Value = 1;
                    elseif app.CallingApp.scaleBarSettings.type == 2
                        set_object_properties_function(app,{'UITable','ScalingbarButton','StaticbarButton'},'Enable',{'On'});
                        app.ScalingbarButton.Value = 1;
                    end
                case 0
                    set_object_properties_function(app,{'UITable','ScalingbarButton','StaticbarButton','ScalebarlengthEditField','ScalebarlengthEditFieldLabel','mLabel'},'Enable',{'Off'});
                    if app.CallingApp.scaleBarSettings.type == 1
                        app.StaticbarButton.Value = 1;
                    elseif app.CallingApp.scaleBarSettings.type == 2
                        app.ScalingbarButton.Value = 1;
                    end
            end
            
        end

        % Close request function: ScalebarsettingsUIFigure
        function ScalebarsettingsUIFigureCloseRequest(app, event)
            if app.StaticbarButton.Value
                app.CallingApp.scaleBarSettings.type = 1;
            else
                app.CallingApp.scaleBarSettings.type = 2;
                app.CallingApp.scaleBarSettings.barLenghtData = app.UITable.Data;
            end

            disable_enable_all_function(app.CallingApp,'On');

            figure(app.CallingApp.EpiMechUIFigure)
            delete(app)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            if app.StaticbarButton.Value
                app.CallingApp.scaleBarSettings.type = 1;
                app.CallingApp.scaleBarSettings.barLength = app.ScalebarlengthEditField.Value;
            else
                app.CallingApp.scaleBarSettings.type = 2;
                app.CallingApp.scaleBarSettings.barLenghtData = app.UITable.Data;
            end

            disable_enable_all_function(app.CallingApp,'On');

            figure(app.CallingApp.EpiMechUIFigure)
            delete(app)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            data = app.UITable.Data;
            
            if indices(2) == 2
                if indices(1) > 1
                    if newData <= data(indices(1)-1,indices(2))
                        app.UITable.Data(indices(1),indices(2)) = event.PreviousData;
                    elseif newData >= data(indices(1),indices(2)+1)
                        app.UITable.Data(indices(1),indices(2)) = event.PreviousData;
                    else
                        app.UITable.Data(indices(1)-1,indices(2)+1) = newData;
                    end
                else
                    if newData >= data(indices(1),indices(2)+1)
                        app.UITable.Data(indices(1),indices(2)) = event.PreviousData;
                    end
                end
            elseif indices(2) == 3
                if indices(1) < size(app.UITable.Data,1)
                    if newData <= data(indices(1),indices(2)-1)
                        app.UITable.Data(indices(1),indices(2)) = event.PreviousData;
                    elseif newData >= data(indices(1)+1,indices(2))
                        app.UITable.Data(indices(1),indices(2)) = event.PreviousData;
                    else
                        app.UITable.Data(indices(1)+1,indices(2)-1) = newData;
                    end
                else
                    if newData <= data(indices(1),indices(2)-1)
                        app.UITable.Data(indices(1),indices(2)) = event.PreviousData;
                    end
                end
            end       
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged2(app, event)
            switch app.ButtonGroup.SelectedObject.Text
                case 'Static bar'
                    set_object_properties_function(app,{'UITable'},'Enable',{'Off'});
                    set_object_properties_function(app,{'ScalebarlengthEditField','ScalebarlengthEditFieldLabel','mLabel'},'Enable',{'On'});
                case 'Scaling bar'
                    if size(app.UITable.Data,1) == 1
                        app.StaticbarButton.Value = 1;
                        return
                    end
                    set_object_properties_function(app,{'UITable'},'Enable',{'On'});
                    set_object_properties_function(app,{'ScalebarlengthEditField','ScalebarlengthEditFieldLabel','mLabel'},'Enable',{'Off'});
            end
        end

        % Value changed function: ScalebarCheckBox
        function ScalebarCheckBoxValueChanged(app, event)
            switch app.ScalebarCheckBox.Value
                case 1
                    switch app.ButtonGroup.SelectedObject.Text
                        case 'Static bar'
                            set_object_properties_function(app,{'UITable'},'Enable',{'Off'});
                            set_object_properties_function(app,{'ScalebarlengthEditField','ScalebarlengthEditFieldLabel','mLabel'},'Enable',{'On'});
                        case 'Scaling bar'
                            if size(app.UITable.Data,1) == 1
                                app.StaticbarButton.Value = 1;
                                return
                            end
                            set_object_properties_function(app,{'UITable'},'Enable',{'On'});
                            set_object_properties_function(app,{'ScalebarlengthEditField','ScalebarlengthEditFieldLabel','mLabel'},'Enable',{'Off'});
                    end
                    set_object_properties_function(app,{'ScalingbarButton','StaticbarButton'},'Enable',{'On'});
                    app.CallingApp.ScalebarCheckBox.Value = 1;
                    app.CallingApp.ScalebarCheckBox_2.Value = 1;
                case 0
                    set_object_properties_function(app,{'UITable','ScalingbarButton','StaticbarButton','ScalebarlengthEditField','ScalebarlengthEditFieldLabel','mLabel'},'Enable',{'Off'});
                    app.CallingApp.ScalebarCheckBox.Value = 0;
                    app.CallingApp.ScalebarCheckBox_2.Value = 0;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScalebarsettingsUIFigure = uifigure('Visible', 'off');
            app.ScalebarsettingsUIFigure.Name = 'Scale bar settings';
            app.ScalebarsettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @ScalebarsettingsUIFigureCloseRequest, true);
            app.CloseButton = uibutton(app.ScalebarsettingsUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.UITable = uitable(app.ScalebarsettingsUIFigure);
            app.UITable.ColumnName = {'Bar length'; 'Low limit'; 'High limit'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.Enable = 'off';
            app.ScalebarCheckBox = uicheckbox(app.ScalebarsettingsUIFigure);
            app.ScalebarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScalebarCheckBoxValueChanged, true);
            app.ScalebarCheckBox.Text = 'Scale bar';
            app.ButtonGroup = uibuttongroup(app.ScalebarsettingsUIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged2, true);
            app.StaticbarButton = uiradiobutton(app.ButtonGroup);
            app.StaticbarButton.Enable = 'off';
            app.StaticbarButton.Text = 'Static bar';
            app.StaticbarButton.Value = true;
            app.ScalingbarButton = uiradiobutton(app.ButtonGroup);
            app.ScalingbarButton.Enable = 'off';
            app.ScalingbarButton.Text = 'Scaling bar';
            app.ScalebarlengthEditFieldLabel = uilabel(app.ScalebarsettingsUIFigure);
            app.ScalebarlengthEditFieldLabel.Enable = 'off';
            app.ScalebarlengthEditFieldLabel.Text = 'Scale bar length';
            app.ScalebarlengthEditField = uieditfield(app.ScalebarsettingsUIFigure, 'numeric');
            app.ScalebarlengthEditField.Enable = 'off';
            app.ScalebarlengthEditField.Value = 30;
            app.mLabel = uilabel(app.ScalebarsettingsUIFigure);
            app.mLabel.Enable = 'off';
            app.mLabel.Text = 'µm';
            app.TipsImage = uiimage(app.ScalebarsettingsUIFigure);
            app.TipsImage.Tooltip = {''};
            % Show the figure after all components are created
            app.ScalebarsettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scaleBarSettingsDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScalebarsettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScalebarsettingsUIFigure)
        end
    end
end