classdef stretchingDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LateralcompressionorstretchsettingsUIFigure  matlab.ui.Figure
        TipsImage                 matlab.ui.control.Image
        Panel_2                   matlab.ui.container.Panel
        StartingphaseSlider       matlab.ui.control.Slider
        StartingphaseSliderLabel  matlab.ui.control.Label
        HzLabel                   matlab.ui.control.Label
        FrequencyEditField        matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel   matlab.ui.control.Label
        AmplitudeEditField        matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel   matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        RemoverowButton           matlab.ui.control.Button
        AddrowButton              matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        StretchtypeButtonGroup    matlab.ui.container.ButtonGroup
        SineButton                matlab.ui.control.RadioButton
        PiecewiseButton           matlab.ui.control.RadioButton
        DefaultButton             matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        LoadButton                matlab.ui.control.Button
        CloseButton               matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Description
        piecewise = struct(); % Description
        sine = struct();
        type = [];
    end
    
    methods (Access = private)
        
        function plot_stretch(app)
            if app.type == 1
                plot(app.UIAxes,[app.piecewise.times ; app.piecewise.times(end)+1000],[app.piecewise.levels ; app.piecewise.levels(end)]);
                axis(app.UIAxes,[0 app.piecewise.times(end) + 2 max(min(app.piecewise.levels)-0.1,0) max(app.piecewise.levels)+0.1]);
            else
                dt = min(0.001,60/app.sine.frequency*60);
                maxT = max(30,1/(app.sine.frequency*60));
                timeVector = 0:dt:maxT;
                sineWave = 1 + app.sine.amplitude.*sin(2.*pi.*app.sine.frequency.*60.*timeVector + app.sine.phase);
                plot(app.UIAxes,timeVector,sineWave)
                axis(app.UIAxes,[0 maxT max(min(sineWave)-0.1,0) max(sineWave)+0.1]);
                
            end
            
        end
        
        function change_table(app)
            app.UITable.Data = num2cell([app.piecewise.times app.piecewise.levels]);
            if length(app.piecewise.times) < 2
                app.RemoverowButton.Enable = 'off';
            else
                app.RemoverowButton.Enable = 'on';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.LateralcompressionorstretchsettingsUIFigure.Visible = 'Off';
            app.CallingApp = mainapp;
            
            mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;
            
            app.LateralcompressionorstretchsettingsUIFigure.Position(1) = mainWindowPosition(1) + mainWindowPosition(3)/2 - app.LateralcompressionorstretchsettingsUIFigure.Position(3)/2;
            app.LateralcompressionorstretchsettingsUIFigure.Position(2) = mainWindowPosition(2) + mainWindowPosition(4)/2 - app.LateralcompressionorstretchsettingsUIFigure.Position(4)/2;


            app.piecewise.times = app.CallingApp.stretch.piecewise(:,1)./60;
            app.piecewise.levels = app.CallingApp.stretch.piecewise(:,2);
            app.sine.amplitude = app.CallingApp.stretch.sine(1);
            app.sine.frequency = app.CallingApp.stretch.sine(2);
            app.sine.phase = app.CallingApp.stretch.sine(3);
            
            app.AmplitudeEditField.Value = app.sine.amplitude;
            app.FrequencyEditField.Value = app.sine.frequency;
            app.StartingphaseSlider.Value = app.sine.phase;
            
            app.type = app.CallingApp.stretch.type;
            
            app.Panel_2.Position = app.Panel.Position;
            
            
            if app.type == 1
                app.PiecewiseButton.Value = 1;
                app.Panel.Visible = 'On';
                app.Panel_2.Visible = 'Off';
            elseif app.type == 2
                app.SineButton.Value = 1;
                app.Panel.Visible = 'Off';
                app.Panel_2.Visible = 'On';
            end
            
            app.LateralcompressionorstretchsettingsUIFigure.Icon = [app.CallingApp.defaultPath 'settings/images/logo_v2.png'];
            app.LoadButton.Icon = [app.CallingApp.defaultPath 'settings/images/load.png'];
            app.SaveButton.Icon = [app.CallingApp.defaultPath 'settings/images/save.png'];
            app.AddrowButton.Icon = [app.CallingApp.defaultPath 'settings/images/plus.png'];
            app.RemoverowButton.Icon = [app.CallingApp.defaultPath 'settings/images/minus.png'];
            app.ResetButton.Icon = [app.CallingApp.defaultPath 'settings/images/reload.png'];
            app.TipsImage.ImageSource = [app.CallingApp.defaultPath 'settings/images/tips.png']; 
            app.TipsImage.Tooltip = fileread([app.CallingApp.defaultPath 'settings/tooltips/piecewise_stretch_settings.txt']);
            
            
            plot_stretch(app);
            change_table(app);
            
            app.LateralcompressionorstretchsettingsUIFigure.Visible = 'On';
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.CallingApp.stretch.piecewise = [app.piecewise.times.*60 app.piecewise.levels];
            app.CallingApp.stretch.sine = [app.sine.amplitude app.sine.frequency app.sine.phase];
            app.CallingApp.stretch.type = app.type;
            disable_enable_all_function(app.CallingApp,'On');
            
            figure(app.CallingApp.EpiMechUIFigure);
            delete(app);
        end

        % Close request function: 
        % LateralcompressionorstretchsettingsUIFigure
        function LateralcompressionorstretchsettingsUIFigureCloseRequest(app, event)
            app.CallingApp.stretch.piecewise = [app.piecewise.times.*60 app.piecewise.levels];
            app.CallingApp.stretch.sine = [app.sine.amplitude app.sine.frequency app.sine.phase];
            app.CallingApp.stretch.type = app.type;
            disable_enable_all_function(app.CallingApp,'On');
            
            figure(app.CallingApp.EpiMechUIFigure);
            delete(app)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            if indices(2) == 1
                if indices(1) > 1
                    if event.NewData <= app.piecewise.times(indices(1)-1)
                        app.UITable.Data{indices(1),indices(2)} = event.PreviousData;
                        uialert(app.LateralcompressionorstretchsettingsUIFigure,'The time must be larger than previous time','Bad input');
                    elseif indices(1) < length(app.piecewise.times) && event.NewData >= app.piecewise.times(indices(1)+1)
                        app.UITable.Data{indices(1),indices(2)} = event.PreviousData;
                        uialert(app.LateralcompressionorstretchsettingsUIFigure,'The time must be smaller than next time','Bad input');
                    else
                        temp = cell2mat(app.UITable.Data);
                        app.piecewise.times = temp(:,1);
                        app.piecewise.levels = temp(:,2);
                    end
                else
                    app.UITable.Data{indices(1),indices(2)} = event.PreviousData;
                    uialert(app.LateralcompressionorstretchsettingsUIFigure,'First time point cannot be edited','Bad input');
                end
            else
                if event.NewData <= 0
                    app.UITable.Data{indices(1),indices(2)} = event.PreviousData;
                    uialert(app.LateralcompressionorstretchsettingsUIFigure,'Stretch must be larger than zero','Bad input');
                else
                    temp = cell2mat(app.UITable.Data);
                    app.piecewise.times = temp(:,1);
                    app.piecewise.levels = temp(:,2);
                end
            end
            
            plot_stretch(app);
        end

        % Button pushed function: AddrowButton
        function AddrowButtonPushed(app, event)
            app.piecewise.times(end+1) = app.piecewise.times(end) + 2;
            app.piecewise.levels(end+1) = app.piecewise.levels(end);
            plot_stretch(app);
            change_table(app);
        end

        % Button pushed function: RemoverowButton
        function RemoverowButtonPushed(app, event)
            app.piecewise.times(end) = [];
            app.piecewise.levels(end) = [];
            change_table(app);
            plot_stretch(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if app.type == 1
                [fileName, filePath] = uigetfile({'*.csv'},'Choose a piecewise stretch file to load',[app.CallingApp.defaultPath 'settings/misc/']);
                
                figure(app.CallingApp.EpiMechUIFigure);
                figure(app.LateralcompressionorstretchsettingsUIFigure);
                if fileName == 0
                    return;
                end
                
                newStretch = csvread([filePath fileName]);
                
                if newStretch(1,1) ~= 0
                    uialert(app.LateralcompressionorstretchsettingsUIFigure,'First time value must be zero','Bad input');
                    return;
                elseif size(newStretch,2) ~= 2
                    uialert(app.LateralcompressionorstretchsettingsUIFigure,'File must have two columns','Bad input');
                    return;
                elseif any(diff(newStretch(:,1)) <= 0)
                    uialert(app.LateralcompressionorstretchsettingsUIFigure,'The times in the file must be increasing.','Bad input');
                    return;
                elseif any((newStretch(:,2)) <= 0)
                    uialert(app.LateralcompressionorstretchsettingsUIFigure,'The stretch must be larger than zero','Bad input');
                    return;
                end
                
                app.piecewise.times = newStretch(:,1)./60;
                app.piecewise.levels = newStretch(:,2);
                plot_stretch(app);
                change_table(app);
            else
                [fileName, filePath] = uigetfile({'*.csv'},'Choose a sine stretch file to load',[app.CallingApp.defaultPath 'settings/misc/']);
                
                figure(app.CallingApp.EpiMechUIFigure);
                figure(app.LateralcompressionorstretchsettingsUIFigure);
                if fileName == 0
                    return;
                end
                
                newStretch = csvread([filePath fileName]);
                
                if length(newStretch) ~=3
                    uialert(app.LateralcompressionorstretchsettingsUIFigure,'There must be three values in the file','Bad input');
                    return;
                elseif newStretch(1) < 0 || newStretch(1) >= 1
                    uialert(app.LateralcompressionorstretchsettingsUIFigure,'The amplitude cannot be negative or larger or equal to one','Bad input');
                    return;
                elseif newStretch(2) <= 0
                    uialert(app.LateralcompressionorstretchsettingsUIFigure,'The frequency must be positive.','Bad input');
                    return;
                elseif newStretch(3) < 0 || newStretch(3) > 2*pi
                    uialert(app.LateralcompressionorstretchsettingsUIFigure,'The phase must be between 0 and 2pi','Bad input');
                    return;
                end
                
                app.sine.amplitude = newStretch(1);
                app.sine.frequency = newStretch(2);
                app.sine.phase = newStretch(3);
                
                app.AmplitudeEditField.Value = app.sine.amplitude;
                app.FrequencyEditField.Value = app.sine.frequency;
                app.StartingphaseSlider.Value = app.sine.phase;
                
                plot_stretch(app);

            end
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if app.type == 1
                [fileName, filePath] = uiputfile({'*.csv'},'Save the current piecewise stretch settings',[app.CallingApp.defaultPath 'settings/misc/']);
                
                if fileName == 0
                    figure(app.CallingApp.EpiMechUIFigure);
                    figure(app.LateralcompressionorstretchsettingsUIFigure);
                    return;
                end
                
                testPath = [app.CallingApp.defaultPath 'settings/misc/'];
                testPath = strrep(testPath,'/','\');
                filePath = strrep(filePath,'/','\');
                figure(app.CallingApp.EpiMechUIFigure);
                figure(app.LateralcompressionorstretchsettingsUIFigure);
                
                
                if strcmp(filePath,testPath) && (strcmp(fileName,'default_piecewise_stretch') || strcmp(fileName,'default_piecewise_stretch.csv'))
                    userResponse = uiconfirm(app.LateralcompressionorstretchsettingsUIFigure,'Do you want to save new default piecewise stretch function?', '', 'Options', {'Yes','No'}, 'DefaultOption', 2);
                    if strcmp(userResponse,'Yes')
                        csvwrite([filePath fileName],[app.piecewise.times.*60 app.piecewise.levels]);
                    end
                else
                    csvwrite([filePath fileName],[app.piecewise.times.*60 app.piecewise.levels]);
                end
            else
                [fileName, filePath] = uiputfile({'*.csv'},'Save the current sine stretch settings',[app.CallingApp.defaultPath 'settings/misc/']);
                
                if fileName == 0
                    figure(app.CallingApp.EpiMechUIFigure);
                    figure(app.LateralcompressionorstretchsettingsUIFigure);
                    return;
                end
                
                testPath = [app.CallingApp.defaultPath 'settings/misc/'];
                testPath = strrep(testPath,'/','\');
                filePath = strrep(filePath,'/','\');
                figure(app.CallingApp.EpiMechUIFigure);
                figure(app.LateralcompressionorstretchsettingsUIFigure);
                
                
                if strcmp(filePath,testPath) && (strcmp(fileName,'default_sine_stretch') || strcmp(fileName,'default_sine_stretch.csv'))
                    userResponse = uiconfirm(app.LateralcompressionorstretchsettingsUIFigure,'Do you want to save new default sine stretch function?', '', 'Options', {'Yes','No'}, 'DefaultOption', 2);
                    if strcmp(userResponse,'Yes')
                        csvwrite([filePath fileName],[app.sine.amplitude app.sine.frequency app.sine.phase]);
                    end
                else
                    csvwrite([filePath fileName],[app.sine.amplitude app.sine.frequency app.sine.phase]);
                end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if app.type == 1
                app.piecewise.times = app.CallingApp.stretch.piecewise(:,1)./60;
                app.piecewise.levels = app.CallingApp.stretch.piecewise(:,2);
                plot_stretch(app);
                change_table(app);
            else
                app.sine.amplitude = app.CallingApp.stretch.sine(1);
                app.sine.frequency = app.CallingApp.stretch.sine(2);
                app.sine.phase = app.CallingApp.stretch.sine(3);
                
                app.AmplitudeEditField.Value = app.sine.amplitude;
                app.FrequencyEditField.Value = app.sine.frequency;
                app.StartingphaseSlider.Value = app.sine.phase;
            end
            
            
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            if app.type == 1
                newStretch = csvread([app.CallingApp.defaultPath 'settings/misc/default_piecewise_stretch.csv']);
                
                if newStretch(1,1) ~= 0
                    uialert(app.LateralcompressionorstretchsettingsUIFigure,'First time value must be zero','Bad input');
                    return;
                elseif size(newStretch,2) ~= 2
                    uialert(app.LateralcompressionorstretchsettingsUIFigure,'File must have two columns','Bad input');
                    return;
                elseif any(diff(newStretch(:,1)) <= 0)
                    uialert(app.LateralcompressionorstretchsettingsUIFigure,'The times in the file must be increasing.','Bad input');
                    return;
                elseif any((newStretch(:,2)) <= 0)
                    uialert(app.LateralcompressionorstretchsettingsUIFigure,'The stretch must be larger than zero','Bad input');
                    return;
                end
                
                app.piecewise.times = newStretch(:,1)./60;
                app.piecewise.levels = newStretch(:,2);
                plot_stretch(app);
                change_table(app);
            else
                newStretch = csvread([app.CallingApp.defaultPath 'settings/misc/default_sine_stretch.csv']);
                
                if length(newStretch) ~=3
                    uialert(app.LateralcompressionorstretchsettingsUIFigure,'There must be three values in the file','Bad input');
                    return;
                elseif newStretch(1) < 0 || newStretch(1) >= 1
                    uialert(app.LateralcompressionorstretchsettingsUIFigure,'The amplitude cannot be negative or larger or equal to one','Bad input');
                    return;
                elseif newStretch(2) <= 0
                    uialert(app.LateralcompressionorstretchsettingsUIFigure,'The frequency must be positive.','Bad input');
                    return;
                elseif newStretch(3) < 0 || newStretch(3) > 2*pi
                    uialert(app.LateralcompressionorstretchsettingsUIFigure,'The phase must be between 0 and 2pi','Bad input');
                    return;
                end
                
                app.sine.amplitude = newStretch(1);
                app.sine.frequency = newStretch(2);
                app.sine.phase = newStretch(3);
                
                app.AmplitudeEditField.Value = app.sine.amplitude;
                app.FrequencyEditField.Value = app.sine.frequency;
                app.StartingphaseSlider.Value = app.sine.phase;
                
                plot_stretch(app);
            end
        end

        % Selection changed function: StretchtypeButtonGroup
        function StretchtypeButtonGroupSelectionChanged(app, event)
            switch app.StretchtypeButtonGroup.SelectedObject.Text
                case 'Piecewise'
                    app.Panel.Visible = 'On';
                    app.Panel_2.Visible = 'Off';
                    app.type = 1;
                    plot_stretch(app)
                    app.TipsImage.Tooltip = fileread([app.CallingApp.defaultPath 'settings/tooltips/piecewise_stretch_settings.txt']);
                    
                case 'Sine'
                    app.Panel.Visible = 'Off';
                    app.Panel_2.Visible = 'On';
                    app.type = 2;
                    plot_stretch(app)
                    
                    app.TipsImage.Tooltip = fileread([app.CallingApp.defaultPath 'settings/tooltips/sine_stretch_settings.txt']);
            end
            
        end

        % Value changing function: StartingphaseSlider
        function StartingphaseSliderValueChanging(app, event)
            app.sine.phase = event.Value;
            plot_stretch(app)
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            app.sine.frequency = app.FrequencyEditField.Value;
            plot_stretch(app)
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            app.sine.amplitude = app.AmplitudeEditField.Value;
            plot_stretch(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LateralcompressionorstretchsettingsUIFigure = uifigure('Visible', 'off');
            app.LateralcompressionorstretchsettingsUIFigure.Name = 'Lateral compression or stretch settings';
            app.LateralcompressionorstretchsettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @LateralcompressionorstretchsettingsUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.LateralcompressionorstretchsettingsUIFigure);
            xlabel(app.UIAxes, 'time (min)')
            ylabel(app.UIAxes, 'Stretch')
            zlabel(app.UIAxes, 'Z')
            app.CloseButton = uibutton(app.LateralcompressionorstretchsettingsUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.LoadButton = uibutton(app.LateralcompressionorstretchsettingsUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Load'};
            app.LoadButton.Text = '';
            app.SaveButton = uibutton(app.LateralcompressionorstretchsettingsUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Save'};
            app.SaveButton.Text = '';
            app.ResetButton = uibutton(app.LateralcompressionorstretchsettingsUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Reset'};
            app.ResetButton.Text = '';
            app.DefaultButton = uibutton(app.LateralcompressionorstretchsettingsUIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.StretchtypeButtonGroup = uibuttongroup(app.LateralcompressionorstretchsettingsUIFigure);
            app.StretchtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StretchtypeButtonGroupSelectionChanged, true);
            app.StretchtypeButtonGroup.Title = 'Stretch type';
            app.PiecewiseButton = uiradiobutton(app.StretchtypeButtonGroup);
            app.PiecewiseButton.Text = 'Piecewise';
            app.PiecewiseButton.Value = true;
            app.SineButton = uiradiobutton(app.StretchtypeButtonGroup);
            app.SineButton.Text = 'Sine';
            app.Panel = uipanel(app.LateralcompressionorstretchsettingsUIFigure);
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'Time (min)'; 'Relative stretch'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false false];
            app.UITable.ColumnEditable = [true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.AddrowButton = uibutton(app.Panel, 'push');
            app.AddrowButton.ButtonPushedFcn = createCallbackFcn(app, @AddrowButtonPushed, true);
            app.AddrowButton.Tooltip = {'Add a row'};
            app.AddrowButton.Text = '';
            app.RemoverowButton = uibutton(app.Panel, 'push');
            app.RemoverowButton.ButtonPushedFcn = createCallbackFcn(app, @RemoverowButtonPushed, true);
            app.RemoverowButton.Tooltip = {'Remove a row'};
            app.RemoverowButton.Text = '';
            app.Panel_2 = uipanel(app.LateralcompressionorstretchsettingsUIFigure);
            app.AmplitudeEditFieldLabel = uilabel(app.Panel_2);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.Panel_2, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.FrequencyEditFieldLabel = uilabel(app.Panel_2);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.Panel_2, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.HzLabel = uilabel(app.Panel_2);
            app.HzLabel.Text = 'Hz';
            app.StartingphaseSliderLabel = uilabel(app.Panel_2);
            app.StartingphaseSliderLabel.Text = 'Starting phase';
            app.StartingphaseSlider = uislider(app.Panel_2);
            app.StartingphaseSlider.Limits = [0 6.2832];
            app.StartingphaseSlider.MajorTicks = [0 1.57 3.1415 4.7124 6.2832];
            app.StartingphaseSlider.MajorTickLabels = {'0', 'pi/4', 'pi', '3pi/4', '2pi'};
            app.StartingphaseSlider.ValueChangingFcn = createCallbackFcn(app, @StartingphaseSliderValueChanging, true);
            app.StartingphaseSlider.MinorTicks = [];
            app.TipsImage = uiimage(app.LateralcompressionorstretchsettingsUIFigure);
            app.TipsImage.Tooltip = {''};
            % Show the figure after all components are created
            app.LateralcompressionorstretchsettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stretchingDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LateralcompressionorstretchsettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LateralcompressionorstretchsettingsUIFigure)
        end
    end
end