classdef substrateGradientDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StiffnessGradientDialog       matlab.ui.Figure
        TipsImage                     matlab.ui.control.Image
        RemoverowButton               matlab.ui.control.Button
        AddrowButton                  matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        Label                         matlab.ui.control.Label
        RotategradientEditField       matlab.ui.control.NumericEditField
        RotategradientEditFieldLabel  matlab.ui.control.Label
        ResetButton                   matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        LoadButton                    matlab.ui.control.Button
        DefaultButton                 matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CallingApp % Description
        positions = [];
        stiffnesses = [];
        leftValue = []; % Description
        rightValue = [];
        midPoint = [];
        width = [];
        angle = [];
    end

    methods (Access = private)

        function updatePlot(app)
            plot(app.UIAxes,[app.positions(1)-1000 ; app.positions ; app.positions(end)+1000],[app.stiffnesses(1)  ; app.stiffnesses ; app.stiffnesses(end)]);
            axis(app.UIAxes,[app.positions(1)-10 max(app.positions(end) + 10,10) 0 max(app.stiffnesses)*1.1]);
        end

        function change_table(app)
            app.UITable.Data = num2cell([app.positions app.stiffnesses]);
            if length(app.positions) < 2
                app.RemoverowButton.Enable = 'off';
            else
                app.RemoverowButton.Enable = 'on';
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.StiffnessGradientDialog.Visible = 'Off';
            app.CallingApp = mainapp;
            mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;

            app.StiffnessGradientDialog.Position(1) = mainWindowPosition(1) + mainWindowPosition(3)/2 - app.StiffnessGradientDialog.Position(3)/2;
            app.StiffnessGradientDialog.Position(2) = mainWindowPosition(2) + mainWindowPosition(4)/2 - app.StiffnessGradientDialog.Position(4)/2;

            
            app.angle = app.CallingApp.stiffnessGradientInformation(1,3);
            if app.angle > pi/2 || app.angle < -pi/2
                remainder = mod(app.angle,2*pi);
                if remainder > pi/2
                    app.angle = remainder - 2*pi;
                else
                    app.angle = -remainder;
                end
            end

            app.positions = app.CallingApp.stiffnessGradientInformation(:,1);
            app.stiffnesses = app.CallingApp.stiffnessGradientInformation(:,2);
            app.RotategradientEditField.Value = app.angle;

            updatePlot(app);
            change_table(app);

            app.StiffnessGradientDialog.Icon = [app.CallingApp.defaultPath 'settings/images/logo_v2.png'];
            app.LoadButton.Icon = [app.CallingApp.defaultPath 'settings/images/load.png'];
            app.SaveButton.Icon = [app.CallingApp.defaultPath 'settings/images/save.png'];
            app.AddrowButton.Icon = [app.CallingApp.defaultPath 'settings/images/plus.png'];
            app.RemoverowButton.Icon = [app.CallingApp.defaultPath 'settings/images/minus.png'];
            app.ResetButton.Icon = [app.CallingApp.defaultPath 'settings/images/reload.png'];
            app.TipsImage.ImageSource = [app.CallingApp.defaultPath 'settings/images/tips.png']; 
            app.TipsImage.Tooltip = fileread([app.CallingApp.defaultPath 'settings/tooltips/gradient_settings.txt']);
            
            app.StiffnessGradientDialog.Visible = 'On';


        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            if length(app.positions) > 1
                matrix = [app.positions app.stiffnesses [app.angle; zeros(length(app.positions)-1,1)]];
            else
                matrix = [app.positions app.stiffnesses app.angle];
            end
            app.CallingApp.stiffnessGradientInformation = matrix;
            disable_enable_all_function(app.CallingApp,'On');

            figure(app.CallingApp.EpiMechUIFigure)
            delete(app)
        end

        % Close request function: StiffnessGradientDialog
        function StiffnessGradientDialogCloseRequest(app, event)
            if length(app.positions) > 1
                matrix = [app.positions app.stiffnesses [app.angle; zeros(length(app.positions)-1,1)]];
            else
                matrix = [app.positions app.stiffnesses app.angle];
            end
            app.CallingApp.stiffnessGradientInformation = matrix;
            disable_enable_all_function(app.CallingApp,'On');

            figure(app.CallingApp.EpiMechUIFigure)
            delete(app)
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)

            newGradientInfo = csvread([app.CallingApp.defaultPath 'settings/misc/default_stiffness_gradient.csv']);

            figure(app.CallingApp.EpiMechUIFigure);
            figure(app.StiffnessGradientDialog);

            if size(newGradientInfo,2) ~= 3
                uialert(app.StiffnessGradientDialog,'File must have 3 columns','Bad input');
                return;
            elseif any(newGradientInfo(:,2) <= 0)
                uialert(app.StiffnessGradientDialog,'All values in the second column must be larger than zero','Bad input');
                return;
            elseif any(diff(newGradientInfo(:,1)) <= 0)
                uialert(app.UIFigure,'The values in the first column must be increasing.','Bad input');
                return;
            elseif size(newGradientInfo,1) > 1 && ~any(newGradientInfo(2:end,3) == 0)
                uialert(app.StiffnessGradientDialog,'The values in the third column should be zeroes excluding the first value');
                return;

            end

            app.angle = newGradientInfo(1,3);
            if app.angle > pi/2 || app.angle < -pi/2
                remainder = mod(app.angle,2*pi);
                if remainder > pi/2
                    app.angle = remainder - 2*pi;
                else
                    app.angle = -remainder;
                end
            end

            app.positions = newGradientInfo(:,1);
            app.stiffnesses = newGradientInfo(:,2);

            app.RotategradientEditField.Value = app.angle;

            change_table(app);
            updatePlot(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.angle = app.CallingApp.stiffnessGradientInformation(1,3);
            if app.angle > pi/2 || app.angle < -pi/2
                remainder = mod(app.angle,2*pi);
                if remainder > pi/2
                    app.angle = remainder - 2*pi;
                else
                    app.angle = -remainder;
                end
            end

            app.positions = app.CallingApp.stiffnessGradientInformation(:,1);
            app.stiffnesses = app.CallingApp.stiffnessGradientInformation(:,2);

            app.RotategradientEditField.Value = app.angle;

            change_table(app);

            updatePlot(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [fileName, filePath] = uigetfile({'*.csv'},'Choose gradient file to load',[app.CallingApp.defaultPath 'settings/misc/']);

            figure(app.CallingApp.EpiMechUIFigure);
            figure(app.StiffnessGradientDialog);
            if fileName == 0
                return;
            end
            newGradientInfo = csvread([filePath fileName]);


            if size(newGradientInfo,2) ~= 3
                uialert(app.StiffnessGradientDialog,'File must have 3 columns','Bad input');
                return;
            elseif any(newGradientInfo(:,2) <= 0)
                uialert(app.StiffnessGradientDialog,'All values in the second column must be larger than zero','Bad input');
                return;
            elseif any(diff(newGradientInfo(:,1)) <= 0)
                uialert(app.UIFigure,'The values in the first column must be increasing.','Bad input');
                return;
            elseif size(newGradientInfo,1) > 1 && ~any(newGradientInfo(2:end,3) == 0)
                uialert(app.StiffnessGradientDialog,'The values in the third column should be zeroes excluding the first value');
                return;

            end

            app.angle = app.CallingApp.stiffnessGradientInformation(1,3);
            if app.angle > pi/2 || app.angle < -pi/2
                remainder = mod(app.angle,2*pi);
                if remainder > pi/2
                    app.angle = remainder - 2*pi;
                else
                    app.angle = -remainder;
                end
            end

            app.positions = app.CallingApp.stiffnessGradientInformation(:,1);
            app.stiffnesses = app.CallingApp.stiffnessGradientInformation(:,2);

            app.RotategradientEditField.Value = app.angle;

            change_table(app);
            updatePlot(app);

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [fileName, filePath] = uiputfile({'*.csv'},'Save the current gradient settings',[app.CallingApp.defaultPath 'settings/misc/']);
            figure(app.CallingApp.EpiMechUIFigure);
            figure(app.StiffnessGradientDialog);
            if fileName == 0
                return;
            end
            testPath = [app.CallingApp.defaultPath 'settings/misc/'];
            testPath = strrep(testPath,'/','\');
            filePath = strrep(filePath,'/','\');


            if length(app.positions) > 1
                matrix = [app.positions app.stiffnesses [app.angle; zeros(length(app.positions)-1,1)]];
            else
                matrix = [app.positions app.stiffnesses app.angle];
            end


            if strcmp(filePath,testPath) && (strcmp(fileName,'default_stiffness_gradient') || strcmp(fileName,'default_stiffness_gradient.csv'))
                userResponse = uiconfirm(app.StiffnessGradientDialog,'Do you want to save new default stiffness gradient?', '', 'Options', {'Yes','No'}, 'DefaultOption', 2);
                if strcmp(userResponse,'Yes')
                    csvwrite([filePath fileName],matrix);
                end
            else
                csvwrite([filePath fileName],matrix);
            end


        end

        % Value changed function: RotategradientEditField
        function RotategradientEditFieldValueChanged(app, event)
            app.angle = app.RotategradientEditField.Value;

        end

        % Button pushed function: AddrowButton
        function AddrowButtonPushed(app, event)
            app.positions(end+1) = app.positions(end) + 10;
            app.stiffnesses(end+1) = app.stiffnesses(end);
            if size(app.positions,2) > 1
                app.positions = app.positions';
            end
            if size(app.stiffnesses,2) > 1
                app.stiffnesses = app.stiffnesses';
            end
            updatePlot(app);
            change_table(app);
        end

        % Button pushed function: RemoverowButton
        function RemoverowButtonPushed(app, event)
            app.positions(end) = [];
            app.stiffnesses(end) = [];
            change_table(app);
            updatePlot(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)

            indices = event.Indices;
            if indices(2) == 1
                if indices(1) > 1
                    if event.NewData <= app.positions(indices(1)-1)
                        app.UITable.Data{indices(1),indices(2)} = event.PreviousData;
                        uialert(app.StiffnessGradientDialog,'The position must be larger than the previous one','Bad input');
                    elseif indices(1) < length(app.positions) && event.NewData >= app.positions(indices(1)+1)
                        app.UITable.Data{indices(1),indices(2)} = event.PreviousData;
                        uialert(app.StiffnessGradientDialog,'The position must be smaller than the next one','Bad input');
                    else
                        temp = cell2mat(app.UITable.Data);
                        app.positions = temp(:,1);
                        app.stiffnesses = temp(:,2);
                    end
                else
                    if indices(1) < length(app.positions) && event.NewData >= app.positions(indices(1)+1)
                        app.UITable.Data{indices(1),indices(2)} = event.PreviousData;
                        uialert(app.StiffnessGradientDialog,'The position must be smaller than the next one','Bad input');
                    else
                        temp = cell2mat(app.UITable.Data);
                        app.positions = temp(:,1);
                        app.stiffnesses = temp(:,2);
                    end
                end
            else
                if event.NewData <= 0
                    app.UITable.Data{indices(1),indices(2)} = event.PreviousData;
                    uialert(app.StiffnessGradientDialog,'Stiffness must be larger than zero','Bad input');
                else
                    temp = cell2mat(app.UITable.Data);
                    app.positions = temp(:,1);
                    app.stiffnesses = temp(:,2);
                end
            end

            updatePlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StiffnessGradientDialog = uifigure('Visible', 'off');
            app.StiffnessGradientDialog.Name = 'Stiffness gradient settings';
            app.StiffnessGradientDialog.CloseRequestFcn = createCallbackFcn(app, @StiffnessGradientDialogCloseRequest, true);
            app.UIAxes = uiaxes(app.StiffnessGradientDialog);
            xlabel(app.UIAxes, 'Position (µm)')
            ylabel(app.UIAxes, 'Young''s modulus (kPA)')
            app.CloseButton = uibutton(app.StiffnessGradientDialog, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.DefaultButton = uibutton(app.StiffnessGradientDialog, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.LoadButton = uibutton(app.StiffnessGradientDialog, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Load'};
            app.LoadButton.Text = '';
            app.SaveButton = uibutton(app.StiffnessGradientDialog, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Save'};
            app.SaveButton.Text = '';
            app.ResetButton = uibutton(app.StiffnessGradientDialog, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Reset'};
            app.ResetButton.Text = '';
            app.RotategradientEditFieldLabel = uilabel(app.StiffnessGradientDialog);
            app.RotategradientEditFieldLabel.Text = 'Rotate gradient';
            app.RotategradientEditField = uieditfield(app.StiffnessGradientDialog, 'numeric');
            app.RotategradientEditField.Limits = [-179.99999 179.99999];
            app.RotategradientEditField.ValueChangedFcn = createCallbackFcn(app, @RotategradientEditFieldValueChanged, true);
            app.Label = uilabel(app.StiffnessGradientDialog);
            app.Label.Text = '°';
            app.UITable = uitable(app.StiffnessGradientDialog);
            app.UITable.ColumnName = {'Position (µm)'; 'Stiffness'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false false];
            app.UITable.ColumnEditable = [true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.AddrowButton = uibutton(app.StiffnessGradientDialog, 'push');
            app.AddrowButton.ButtonPushedFcn = createCallbackFcn(app, @AddrowButtonPushed, true);
            app.AddrowButton.Tooltip = {'Add a row'};
            app.AddrowButton.Text = '';
            app.RemoverowButton = uibutton(app.StiffnessGradientDialog, 'push');
            app.RemoverowButton.ButtonPushedFcn = createCallbackFcn(app, @RemoverowButtonPushed, true);
            app.RemoverowButton.Tooltip = {'remove a row'};
            app.RemoverowButton.Text = '';
            app.TipsImage = uiimage(app.StiffnessGradientDialog);
            app.TipsImage.Tooltip = {''};
            % Show the figure after all components are created
            app.StiffnessGradientDialog.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = substrateGradientDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StiffnessGradientDialog)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StiffnessGradientDialog)
        end
    end
end