classdef substrateHeterogeneityDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StiffnessheterogeneitysettingsUIFigure  matlab.ui.Figure
        TipsImage                    matlab.ui.control.Image
        CloseButton                  matlab.ui.control.Button
        DefaultButton                matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        LoadButton                   matlab.ui.control.Button
        kPaLabel                     matlab.ui.control.Label
        BasestiffnessEditField       matlab.ui.control.NumericEditField
        BasestiffnessEditFieldLabel  matlab.ui.control.Label
        mLabel_3                     matlab.ui.control.Label
        mLabel_2                     matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        AngleEditField               matlab.ui.control.NumericEditField
        AngleEditFieldLabel          matlab.ui.control.Label
        RMSheightEditField           matlab.ui.control.NumericEditField
        RMSheightEditFieldLabel      matlab.ui.control.Label
        HorizonalACLEditField        matlab.ui.control.NumericEditField
        HorizonalACLEditFieldLabel   matlab.ui.control.Label
        VerticalACLEditField         matlab.ui.control.NumericEditField
        VerticalACLEditFieldLabel    matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp;
        xAutoCorrelation;
        yAutoCorrelation;
        angle;
        height;
        baseStiffness;
    end
    
    methods (Access = private)
        
        function plot_surface(app)


            if app.angle ~= 0

                [f,x,y] = rsgeng2D(213,426,app.height,app.xAutoCorrelation,app.yAutoCorrelation);
                
                [substrateGridX, substrateGridY] = meshgrid(x, y);
                
                rotatedCoordinates = [cosd(-app.angle) -sind(-app.angle) ; sind(-app.angle) cosd(-app.angle)]*[substrateGridX(:)' ; substrateGridY(:)'];
                substrateGridX = rotatedCoordinates(1,:)';
                substrateGridY = rotatedCoordinates(2,:)';
                
                [xPoints, yPoints] = meshgrid(-150:150, -150:150);

                fData = griddata(substrateGridX,substrateGridY,f(:),xPoints,yPoints);
                substrateGridX = xPoints;
                substrateGridY = yPoints;
            else
                [f,x,y] = rsgeng2D(150,300,app.height,app.xAutoCorrelation,app.yAutoCorrelation);

                substrateGridX = x;
                substrateGridY = y;
                fData = f;
            end
            
            fData = app.baseStiffness + fData;
            
            maxValue = max(max(fData(:)),abs(min(fData(:))));
            
            fData(fData < 0.5) = 0.5;
            
            contourf(app.UIAxes, substrateGridX,substrateGridY,fData,256,'LineColor','none');
            colorbar(app.UIAxes)
            colormap(app.UIAxes,'gray')
            set(app.UIAxes,'xtick',[],'ytick',[]);
            axis(app.UIAxes,'square');
            
            if app.baseStiffness - (maxValue - app.baseStiffness) < 0.5
                minValue = 0.5;
            else
                minValue = app.baseStiffness - (maxValue - app.baseStiffness);
            end
            caxis(app.UIAxes,[minValue maxValue]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.StiffnessheterogeneitysettingsUIFigure.Visible = 'Off';
            app.CallingApp = mainapp;
            mainWindowPosition = app.CallingApp.EpiMechUIFigure.Position;
            
            app.StiffnessheterogeneitysettingsUIFigure.Position(1) = mainWindowPosition(1) + mainWindowPosition(3)/2 - app.StiffnessheterogeneitysettingsUIFigure.Position(3)/2;
            app.StiffnessheterogeneitysettingsUIFigure.Position(2) = mainWindowPosition(2) + mainWindowPosition(4)/2 - app.StiffnessheterogeneitysettingsUIFigure.Position(4)/2;
            
            app.xAutoCorrelation = app.CallingApp.heterogeneousStiffness(1);
            app.yAutoCorrelation = app.CallingApp.heterogeneousStiffness(2);
            app.height = app.CallingApp.heterogeneousStiffness(3);
            app.angle = app.CallingApp.heterogeneousStiffness(4);
            app.baseStiffness = app.CallingApp.substrateParameters.youngsModulus/1000;
            if app.angle > pi/2 || app.angle < -pi/2
                remainder = mod(app.angle,2*pi);
                if remainder > pi/2
                    app.angle = remainder - 2*pi;
                else
                    app.angle = -remainder;
                end
            end
            
            app.HorizonalACLEditField.Value = app.xAutoCorrelation;
            app.VerticalACLEditField.Value = app.yAutoCorrelation;
            app.RMSheightEditField.Value = app.height;
            app.AngleEditField.Value = app.angle;
            app.BasestiffnessEditField.Value = app.baseStiffness;
            
            app.StiffnessheterogeneitysettingsUIFigure.Icon = [app.CallingApp.defaultPath 'settings/images/logo_v2.png'];
            app.LoadButton.Icon = [app.CallingApp.defaultPath 'settings/images/load.png'];
            app.SaveButton.Icon = [app.CallingApp.defaultPath 'settings/images/save.png'];
            app.ResetButton.Icon = [app.CallingApp.defaultPath 'settings/images/reload.png'];          
            app.TipsImage.ImageSource = [app.CallingApp.defaultPath 'settings/images/tips.png']; 
            app.TipsImage.Tooltip = fileread([app.CallingApp.defaultPath 'settings/tooltips/heterogeneous_settings.txt']);
            
            app.StiffnessheterogeneitysettingsUIFigure.Visible = 'On';
            
            plot_surface(app)
        end

        % Value changed function: HorizonalACLEditField
        function HorizonalACLEditFieldValueChanged(app, event)
            app.xAutoCorrelation = app.HorizonalACLEditField.Value;
            plot_surface(app);
        end

        % Value changed function: VerticalACLEditField
        function VerticalACLEditFieldValueChanged(app, event)
            app.yAutoCorrelation = app.VerticalACLEditField.Value;
            plot_surface(app);
        end

        % Value changed function: AngleEditField
        function AngleEditFieldValueChanged(app, event)
            app.angle = app.AngleEditField.Value;
            plot_surface(app);
        end

        % Value changed function: RMSheightEditField
        function RMSheightEditFieldValueChanged(app, event)
            app.height = app.RMSheightEditField.Value;
            plot_surface(app);
        end

        % Value changed function: BasestiffnessEditField
        function BasestiffnessEditFieldValueChanged(app, event)
            app.baseStiffness = app.BasestiffnessEditField.Value;
            plot_surface(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [fileName, filePath] = uigetfile({'*.csv'},'Choose stiffness file to load',[app.CallingApp.defaultPath 'settings/misc/']);
            
            figure(app.CallingApp.EpiMechUIFigure);
            figure(app.StiffnessheterogeneitysettingsUIFigure);
            if fileName == 0
                return;
            end
            newStiffnessInfo = csvread([filePath fileName]);
            
            
            if length(newStiffnessInfo) ~= 4
                uialert(app.StiffnessheterogeneitysettingsUIFigure,'File must contain 4 values','Bad input');
                return;
            elseif newStiffnessInfo(1) < 0 
                uialert(app.StiffnessheterogeneitysettingsUIFigure,'First value in the file must be positive','Bad input');
                return;
            elseif newStiffnessInfo(2) < 0 
                uialert(app.StiffnessheterogeneitysettingsUIFigure,'Second value in the file must be positive','Bad input');
                return;
            elseif newStiffnessInfo(3) < 0
                uialert(app.StiffnessheterogeneitysettingsUIFigure,'Third value in the file must be between positive','Bad input');
                return;
            end
            
            app.xAutoCorrelation = newStiffnessInfo(1);
            app.yAutoCorrelation = newStiffnessInfo(2);
            app.height = newStiffnessInfo(3);
            app.angle = newStiffnessInfo(4);
            if app.angle > pi/2 || app.angle < -pi/2
                remainder = mod(app.angle,2*pi);
                if remainder > pi/2
                    app.angle = remainder - 2*pi;
                else
                    app.angle = -remainder;
                end
            end
            
            app.HorizonalACLEditField.Value = app.xAutoCorrelation;
            app.VerticalACLEditField.Value = app.yAutoCorrelation;
            app.RMSheightEditField.Value = app.height;
            app.AngleEditField.Value = app.angle;
            
            app.StiffnessheterogeneitysettingsUIFigure.Visible = 'On';
            
            plot_surface(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [fileName, filePath] = uiputfile({'*.csv'},'Save the current stiffness settings',[app.CallingApp.defaultPath 'settings/misc/']);
            figure(app.CallingApp.EpiMechUIFigure);
            figure(app.StiffnessheterogeneitysettingsUIFigure);
            if fileName == 0
                return;
            end
            testPath = [app.CallingApp.defaultPath 'settings/misc/'];
            testPath = strrep(testPath,'/','\');
            filePath = strrep(filePath,'/','\');
            
            if strcmp(filePath,testPath) && (strcmp(fileName,'default_heterogeneous_data') || strcmp(fileName,'default_heterogeneous_data.csv'))
                userResponse = uiconfirm(app.StiffnessheterogeneitysettingsUIFigure,'Do you want to save new default heterogeneous stiffness data?', '', 'Options', {'Yes','No'}, 'DefaultOption', 2);
                if strcmp(userResponse,'Yes')
                    csvwrite([filePath fileName],[app.xAutoCorrelation app.yAutoCorrelation app.height app.angle]);
                end
            else
                csvwrite([filePath fileName],[app.xAutoCorrelation app.yAutoCorrelation app.height app.angle]);
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.xAutoCorrelation = app.CallingApp.heterogeneousStiffness(1);
            app.yAutoCorrelation = app.CallingApp.heterogeneousStiffness(2);
            app.height = app.CallingApp.heterogeneousStiffness(3);
            app.angle = app.CallingApp.heterogeneousStiffness(4);
            app.baseStiffness = app.CallingApp.substrateParameters.youngsModulus/1000;
            if app.angle > pi/2 || app.angle < -pi/2
                remainder = mod(app.angle,2*pi);
                if remainder > pi/2
                    app.angle = remainder - 2*pi;
                else
                    app.angle = -remainder;
                end
            end

            app.HorizonalACLEditField.Value = app.xAutoCorrelation;
            app.VerticalACLEditField.Value = app.yAutoCorrelation;
            app.RMSheightEditField.Value = app.height;
            app.AngleEditField.Value = app.angle;
            app.BasestiffnessEditField.Value = app.baseStiffness;

            app.StiffnessheterogeneitysettingsUIFigure.Visible = 'On';

            plot_surface(app)
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            newStiffnessInfo = csvread([app.defaultPath 'settings/misc/default_heterogeneous_data.csv']);
                        
            if length(newStiffnessInfo) ~= 4
                uialert(app.StiffnessheterogeneitysettingsUIFigure,'File must contain 4 values','Bad input');
                return;
            elseif newStiffnessInfo(1) < 0 
                uialert(app.StiffnessheterogeneitysettingsUIFigure,'First value in the file must be positive','Bad input');
                return;
            elseif newStiffnessInfo(2) < 0 
                uialert(app.StiffnessheterogeneitysettingsUIFigure,'Second value in the file must be positive','Bad input');
                return;
            elseif newStiffnessInfo(3) < 0
                uialert(app.StiffnessheterogeneitysettingsUIFigure,'Third value in the file must be between positive','Bad input');
                return;
            end
            
            app.xAutoCorrelation = newStiffnessInfo(1);
            app.yAutoCorrelation = newStiffnessInfo(2);
            app.height = newStiffnessInfo(3);
            app.angle = newStiffnessInfo(4);
            if app.angle > pi/2 || app.angle < -pi/2
                remainder = mod(app.angle,2*pi);
                if remainder > pi/2
                    app.angle = remainder - 2*pi;
                else
                    app.angle = -remainder;
                end
            end
            
            app.HorizonalACLEditField.Value = app.xAutoCorrelation;
            app.VerticalACLEditField.Value = app.yAutoCorrelation;
            app.RMSheightEditField.Value = app.height;
            app.AngleEditField.Value = app.angle;
            
            app.StiffnessheterogeneitysettingsUIFigure.Visible = 'On';
            
            plot_surface(app)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.CallingApp.heterogeneousStiffness = [app.xAutoCorrelation app.yAutoCorrelation app.height app.angle];
            app.CallingApp.substrateParameters.youngsModulus = app.baseStiffness*1000;
            app.CallingApp.YoungsmodulusEditField.Value = app.baseStiffness;
            disable_enable_all_function(app.CallingApp,'On');
            
            figure(app.CallingApp.EpiMechUIFigure)
            delete(app)
        end

        % Close request function: 
        % StiffnessheterogeneitysettingsUIFigure
        function StiffnessheterogeneitysettingsUIFigureCloseRequest(app, event)
            app.CallingApp.heterogeneousStiffness = [app.xAutoCorrelation app.yAutoCorrelation app.height app.angle];
            app.CallingApp.substrateParameters.youngsModulus = app.baseStiffness*1000;
            app.CallingApp.YoungsmodulusEditField.Value = app.baseStiffness;
            disable_enable_all_function(app.CallingApp,'On');
            
            figure(app.CallingApp.EpiMechUIFigure)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StiffnessheterogeneitysettingsUIFigure = uifigure('Visible', 'off');
            app.StiffnessheterogeneitysettingsUIFigure.Name = 'Stiffness heterogeneity settings';
            app.StiffnessheterogeneitysettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @StiffnessheterogeneitysettingsUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.StiffnessheterogeneitysettingsUIFigure);
            xlabel(app.UIAxes, '300 µm')
            ylabel(app.UIAxes, '300 µm')
            zlabel(app.UIAxes, 'Z')
            app.VerticalACLEditFieldLabel = uilabel(app.StiffnessheterogeneitysettingsUIFigure);
            app.VerticalACLEditFieldLabel.Text = 'Vertical ACL';
            app.VerticalACLEditField = uieditfield(app.StiffnessheterogeneitysettingsUIFigure, 'numeric');
            app.VerticalACLEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalACLEditFieldValueChanged, true);
            app.VerticalACLEditField.Value = 2;
            app.HorizonalACLEditFieldLabel = uilabel(app.StiffnessheterogeneitysettingsUIFigure);
            app.HorizonalACLEditFieldLabel.Text = 'Horizonal ACL';
            app.HorizonalACLEditField = uieditfield(app.StiffnessheterogeneitysettingsUIFigure, 'numeric');
            app.HorizonalACLEditField.ValueChangedFcn = createCallbackFcn(app, @HorizonalACLEditFieldValueChanged, true);
            app.HorizonalACLEditField.Value = 1;
            app.RMSheightEditFieldLabel = uilabel(app.StiffnessheterogeneitysettingsUIFigure);
            app.RMSheightEditFieldLabel.Text = 'RMS height';
            app.RMSheightEditField = uieditfield(app.StiffnessheterogeneitysettingsUIFigure, 'numeric');
            app.RMSheightEditField.ValueChangedFcn = createCallbackFcn(app, @RMSheightEditFieldValueChanged, true);
            app.AngleEditFieldLabel = uilabel(app.StiffnessheterogeneitysettingsUIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.StiffnessheterogeneitysettingsUIFigure, 'numeric');
            app.AngleEditField.ValueChangedFcn = createCallbackFcn(app, @AngleEditFieldValueChanged, true);
            app.Label = uilabel(app.StiffnessheterogeneitysettingsUIFigure);
            app.Label.Text = '°';
            app.mLabel_2 = uilabel(app.StiffnessheterogeneitysettingsUIFigure);
            app.mLabel_2.Text = 'µm';
            app.mLabel_3 = uilabel(app.StiffnessheterogeneitysettingsUIFigure);
            app.mLabel_3.Text = 'µm';
            app.BasestiffnessEditFieldLabel = uilabel(app.StiffnessheterogeneitysettingsUIFigure);
            app.BasestiffnessEditFieldLabel.Text = 'Base stiffness';
            app.BasestiffnessEditField = uieditfield(app.StiffnessheterogeneitysettingsUIFigure, 'numeric');
            app.BasestiffnessEditField.Limits = [0 Inf];
            app.BasestiffnessEditField.ValueChangedFcn = createCallbackFcn(app, @BasestiffnessEditFieldValueChanged, true);
            app.kPaLabel = uilabel(app.StiffnessheterogeneitysettingsUIFigure);
            app.kPaLabel.Text = 'kPa';
            app.LoadButton = uibutton(app.StiffnessheterogeneitysettingsUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = '';
            app.SaveButton = uibutton(app.StiffnessheterogeneitysettingsUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = '';
            app.ResetButton = uibutton(app.StiffnessheterogeneitysettingsUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = '';
            app.DefaultButton = uibutton(app.StiffnessheterogeneitysettingsUIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.CloseButton = uibutton(app.StiffnessheterogeneitysettingsUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TipsImage = uiimage(app.StiffnessheterogeneitysettingsUIFigure);
            app.TipsImage.Tooltip = {''};
            % Show the figure after all components are created
            app.StiffnessheterogeneitysettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = substrateHeterogeneityDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StiffnessheterogeneitysettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StiffnessheterogeneitysettingsUIFigure)
        end
    end
end